classdef ExperimentEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        LaserLamp                      matlab.ui.control.Lamp
        LaserLampLabel                 matlab.ui.control.Label
        LaserUnload                    matlab.ui.control.Button
        LaserLoad                      matlab.ui.control.Button
        BoxAddress                     matlab.ui.control.EditField
        LocalboxaddressEditFieldLabel  matlab.ui.control.Label
        AllLamp                        matlab.ui.control.Lamp
        AllLampLabel                   matlab.ui.control.Label
        UnloadAll                      matlab.ui.control.Button
        LoadAll                        matlab.ui.control.Button
        DAQUnload                      matlab.ui.control.Button
        PBUnload                       matlab.ui.control.Button
        StageUnload                    matlab.ui.control.Button
        DAQLoad                        matlab.ui.control.Button
        PBLoad                         matlab.ui.control.Button
        StageLoad                      matlab.ui.control.Button
        RFUnload                       matlab.ui.control.Button
        RFLoad                         matlab.ui.control.Button
        DAQLamp                        matlab.ui.control.Lamp
        DAQLampLabel                   matlab.ui.control.Label
        PulseBlasterLamp               matlab.ui.control.Lamp
        PulseBlasterLampLabel          matlab.ui.control.Label
        StageLamp                      matlab.ui.control.Lamp
        StageLampLabel                 matlab.ui.control.Label
        RFLamp                         matlab.ui.control.Lamp
        RFLampLabel                    matlab.ui.control.Label
        GeneralTab                     matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        RFAmp                          matlab.ui.control.NumericEditField
        RFAmplitudedBmLabel            matlab.ui.control.Label
        RFmodulationCheckBox           matlab.ui.control.CheckBox
        ConfocalWideField              matlab.ui.control.DropDown
        FuzzyCounts                    matlab.ui.control.CheckBox
        LaserSwitch                    matlab.ui.control.Switch
        LaserswitchSwitchLabel         matlab.ui.control.Label
        LaserPower                     matlab.ui.control.NumericEditField
        LaserpowerEditFieldLabel       matlab.ui.control.Label
        PulseSequence                  matlab.ui.container.Panel
        RFFreq                         matlab.ui.control.NumericEditField
        RFFrequencyGHzLabel            matlab.ui.control.Label
        PulseSequenceEditorButton      matlab.ui.control.Button
        SequenceName                   matlab.ui.control.EditField
        IterationsType                 matlab.ui.container.ButtonGroup
        PerpetualButton                matlab.ui.control.ToggleButton
        TimeButton                     matlab.ui.control.ToggleButton
        NumberButton                   matlab.ui.control.ToggleButton
        IterationsNum                  matlab.ui.control.NumericEditField
        LoadpulsesequenceButton        matlab.ui.control.Button
        StageTab                       matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        IgnoreSettling                 matlab.ui.control.CheckBox
        OptAddress                     matlab.ui.control.EditField
        OptimizationaddressEditFieldLabel  matlab.ui.control.Label
        DeletestepButton               matlab.ui.control.Button
        AxisLabel                      matlab.ui.control.Label
        StageHeader                    matlab.ui.control.Label
        OptTable                       matlab.ui.control.Table
        AddstepButton                  matlab.ui.control.Button
        OptSteps                       matlab.ui.control.NumericEditField
        StepsEditFieldLabel            matlab.ui.control.Label
        OptRadius                      matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        OptAxis                        matlab.ui.control.DropDown
        OptimizationOn                 matlab.ui.control.CheckBox
        OptimizationTiming             matlab.ui.control.NumericEditField
        TimebetweenoptimizationsminLabel_2  matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        IncrementLabel                 matlab.ui.control.Label
        XaxisSliderLabel_2             matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        XPosition                      matlab.ui.control.NumericEditField
        XIncrement                     matlab.ui.control.NumericEditField
        XFineReset                     matlab.ui.control.Button
        XDown                          matlab.ui.control.Button
        XUp                            matlab.ui.control.Button
        YaxisSliderLabel_2             matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        YPosition                      matlab.ui.control.NumericEditField
        YIncrement                     matlab.ui.control.NumericEditField
        YFineReset                     matlab.ui.control.Button
        YDown                          matlab.ui.control.Button
        YUp                            matlab.ui.control.Button
        ZaxisSliderLabel_2             matlab.ui.control.Label
        ZSlider                        matlab.ui.control.Slider
        ZPosition                      matlab.ui.control.NumericEditField
        ZIncrement                     matlab.ui.control.NumericEditField
        ZFineReset                     matlab.ui.control.Button
        ZDown                          matlab.ui.control.Button
        ZUp                            matlab.ui.control.Button
        ScanningTab                    matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        ScanNum                        matlab.ui.control.EditField
        ScannumberLabel                matlab.ui.control.Label
        Overwrite                      matlab.ui.control.DropDown
        DeleteScanButton               matlab.ui.control.Button
        AddScanButton                  matlab.ui.control.Button
        ScanTable                      matlab.ui.control.Table
        StepSizeField                  matlab.ui.control.NumericEditField
        NPointsStepSize                matlab.ui.container.ButtonGroup
        StepSizeButton                 matlab.ui.control.ToggleButton
        NPointsButton                  matlab.ui.control.ToggleButton
        PulseAddresses                 matlab.ui.control.EditField
        PulseaddressesifapplicableLabel  matlab.ui.control.Label
        EndScan                        matlab.ui.control.EditField
        EndEditFieldLabel              matlab.ui.control.Label
        StartScan                      matlab.ui.control.EditField
        StartEditFieldLabel            matlab.ui.control.Label
        ScanType                       matlab.ui.control.DropDown
        ScantypeDropDownLabel          matlab.ui.control.Label
        OutputSettingsTab              matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        AdditionalyOffset              matlab.ui.control.NumericEditField
        AdditionalyoffsetEditFieldLabel  matlab.ui.control.Label
        AdditionalxOffset              matlab.ui.control.NumericEditField
        AdditionalxoffsetEditFieldLabel  matlab.ui.control.Label
        Incorporatey                   matlab.ui.control.CheckBox
        Incorporatex                   matlab.ui.control.CheckBox
        PulseAxis                      matlab.ui.control.NumericEditField
        PulsenumberforplottingLabel    matlab.ui.control.Label
        PlotLimitsTable                matlab.ui.control.Table
        InvertXAxis                    matlab.ui.control.CheckBox
        NormCounts                     matlab.ui.control.CheckBox
        ContrastType                   matlab.ui.control.DropDown
        ContrasttypeDropDownLabel      matlab.ui.control.Label
        MeasurementparameterLabel      matlab.ui.control.Label
        Parameter                      matlab.ui.control.DropDown
        UpdatePeriod                   matlab.ui.control.NumericEditField
        UpdateperiodsLabel             matlab.ui.control.Label
        Progress                       matlab.ui.container.Panel
        YDimension                     matlab.ui.control.NumericEditField
        YaxisdimensionLabel            matlab.ui.control.Label
        XDimension                     matlab.ui.control.NumericEditField
        XaxisdimensionLabel            matlab.ui.control.Label
        NotificationsManualOperationTab  matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        DisplayedChannels              matlab.ui.control.Spinner
        DisplayedpulsechannelsSpinnerLabel  matlab.ui.control.Label
        BeginContinuousOptimizationButton  matlab.ui.control.Button
        EmailIterations                matlab.ui.control.NumericEditField
        OptimizationResults            matlab.ui.control.TextArea
        OptimizationresultsLabel       matlab.ui.control.Label
        CountssecondRFsettingButtonGroup  matlab.ui.container.ButtonGroup
        QuickCountsRFon                matlab.ui.control.ToggleButton
        QuickCountsRFoff               matlab.ui.control.ToggleButton
        QuickCountsOutput              matlab.ui.control.TextArea
        QuickCountsButton              matlab.ui.control.Button
        RefreshAppButton               matlab.ui.control.Button
        EmailAddress                   matlab.ui.control.EditField
        EmailaddresstosendcompletionnotificationLabel  matlab.ui.control.Label
        ForceUpdate                    matlab.ui.control.Button
        AutoSaveCheckbox               matlab.ui.control.CheckBox
        AutoSaveName                   matlab.ui.control.EditField
        AutoSaveIterations             matlab.ui.control.NumericEditField
        EmailIterationsCheckbox        matlab.ui.control.CheckBox
        CompletionEmail                matlab.ui.control.CheckBox
        Notifications                  matlab.ui.control.CheckBox
        ManualOptNum                   matlab.ui.control.NumericEditField
        NumberofmanualoptimizationsLabel  matlab.ui.control.Label
        BeginManualOpt                 matlab.ui.control.Button
        InformationTab                 matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        PrevSeqs                       matlab.ui.control.TextArea
        PrevSetups                     matlab.ui.control.TextArea
        Warnings                       matlab.ui.control.TextArea
        ObscureSettingsTab             matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        DimensionSet                   matlab.ui.control.Table
        WFCutoffVoltage                matlab.ui.control.NumericEditField
        WidefieldvoltagecutoffVLabel   matlab.ui.control.Label
        RFModulationType               matlab.ui.control.DropDown
        RFmodulationtypeDropDownLabel  matlab.ui.control.Label
        RandomizeScan                  matlab.ui.control.CheckBox
        ComputerEditField              matlab.ui.control.EditField
        ComputerEditFieldLabel         matlab.ui.control.Label
        FinePauseTime                  matlab.ui.control.NumericEditField
        PausedurationforfinestagemovementsmsLabel  matlab.ui.control.Label
        CoarsePauseTime                matlab.ui.control.NumericEditField
        PausedurationforcoarsestagemovementsmsLabel  matlab.ui.control.Label
        ResetLocation                  matlab.ui.container.ButtonGroup
        ResetMax                       matlab.ui.control.ToggleButton
        ResetCenter                    matlab.ui.control.ToggleButton
        ResetMin                       matlab.ui.control.ToggleButton
        LaserCleanOff                  matlab.ui.control.CheckBox
        LaserEquilibrium               matlab.ui.control.CheckBox
        DisplayDigits                  matlab.ui.control.NumericEditField
        NumberofdigitsdisplayedLabel   matlab.ui.control.Label
        StagePrecision                 matlab.ui.control.NumericEditField
        StagetolerancemLabel           matlab.ui.control.Label
        OptimizationParameter          matlab.ui.container.ButtonGroup
        ContrastButton                 matlab.ui.control.ToggleButton
        RFonButton                     matlab.ui.control.ToggleButton
        RFoffButton                    matlab.ui.control.ToggleButton
        OptReadoutDur                  matlab.ui.control.NumericEditField
        DurationofcountreadoutforoptimizationnsLabel  matlab.ui.control.Label
        OpttargetButtonGroup           matlab.ui.container.ButtonGroup
        GaussianPeakButton             matlab.ui.control.ToggleButton
        RawValueButton                 matlab.ui.control.ToggleButton
        StagealtTab                    matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        FineZ                          matlab.ui.control.NumericEditField
        CoarseZ                        matlab.ui.control.NumericEditField
        FineY                          matlab.ui.control.NumericEditField
        CoarseY                        matlab.ui.control.NumericEditField
        FineX                          matlab.ui.control.NumericEditField
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        FinemLabel                     matlab.ui.control.Label
        CoarsemmLabel                  matlab.ui.control.Label
        CoarseX                        matlab.ui.control.NumericEditField
        AltStageScan                   matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ScanInfo                       matlab.ui.control.TextArea
        Colorbar                       matlab.ui.container.Panel
        OutputPanel                    matlab.ui.container.Panel
        StatusLamp                     matlab.ui.control.Lamp
        ResetButton                    matlab.ui.control.Button
        LoadoutputButton               matlab.ui.control.Button
        OutputLoadName                 matlab.ui.control.EditField
        OutputSaveName                 matlab.ui.control.EditField
        SetupSaveName                  matlab.ui.control.EditField
        AbortButton                    matlab.ui.control.Button
        SetupLoadName                  matlab.ui.control.EditField
        LoadsetupButton                matlab.ui.control.Button
        SaveOutputButton               matlab.ui.control.Button
        SavesetupButton                matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

   % ExperimentEditor v1.1 7/14/22


   properties (Access = private)
      progresslayout
      progresstiles
      emptypatches
      fullpatches
      outputdisplay
      pulsetiles
      seqlayout
      pulselines
      colorbaraxes
      colorbarobject
   end

   methods (Access = private)

      function  appUpdate(app)
         connectionUpdate(app)
         settingsUpdate(app)
         stageUpdate(app)
         scanningUpdate(app)
         outputSettingsUpdate(app)
         obscureUpdate(app)
         warningsUpdate(app)
         altStageUpdate(app)
      end


      function connectionUpdate(app)
         global master %#ok<*GVMIS>

         %The following section is for coloring the lamps

         if master.RF.initialized
            app.RFLamp.Color = [0 1 0];
         else
            app.RFLamp.Color = [1 0 0];
         end

         if master.stage.initialized
            app.StageLamp.Color = [0 1 0];
         else
            app.StageLamp.Color = [1 0 0];
         end

         if master.PB.initialized
            app.PulseBlasterLamp.Color = [0 1 0];
         else
            app.PulseBlasterLamp.Color = [1 0 0];
         end

         if master.NIDAQ.initialized
            app.DAQLamp.Color = [0 1 0];
         else
            app.DAQLamp.Color = [1 0 0];
         end

         if master.NDYOV.initialized
            app.LaserLamp.Color = [0 1 0];
         else
            app.LaserLamp.Color = [1 0 0];
         end


         aa = (master.NIDAQ.initialized && master.RF.initialized);
         bb = (master.stage.initialized && master.PB.initialized);
         if aa && bb && master.NDYOV.initialized
            app.AllLamp.Color = [0 1 0];
         else
            app.AllLamp.Color = [1 0 0];
         end

         app.BoxAddress.Value = master.gui.boxAddress;

      end


      function settingsUpdate(app)
         global master

         app.DisplayedChannels.Value = master.gui.displayedChannels;

         if master.gui.displayedChannels ~= master.gui.prevDisplayed
            app.seqlayout = tiledlayout(app.PulseSequence,master.gui.displayedChannels,1);
            app.seqlayout.Title.String = 'Pulse Sequence';
            app.seqlayout.XLabel.String = 'Time (ns)';

            for ii = 1:master.gui.displayedChannels
               app.pulsetiles{ii} = nexttile(app.seqlayout,ii);
            end

            cumdur = 0;

            if isfield(master.PB,'sequence')

               for jj = 1:numel(master.PB.sequence)
                  cumdur = cumdur + master.PB.sequence(jj).duration;
               end

               channelx = zeros(1,numel(master.PB.sequence));
               channely= channelx;
               for ii = 1:master.gui.displayedChannels
                  for jj = 1:numel(master.PB.sequence)
                     if ii == 1
                        if jj == 1
                           channelx(1) = 1;
                        else
                           channelx(jj*2-1) = channelx((jj-1)*2);
                        end

                        channelx(jj*2) = channelx(jj*2-1) + master.PB.sequence(jj).duration;
                     end

                     channely(jj*2-1) = str2double(master.PB.sequence(jj).binaryoutput(1+end-ii));
                     channely(jj*2) = channely(jj*2-1) ;

                  end

                  app.pulselines{ii} = plot(app.pulsetiles{ii},channelx,channely);
                  app.pulselines{ii}.Color = master.gui.lineColors(ii);
               end

            end

            if cumdur == 0
               cumdur = 1;
            end
            %Even if no sequence, change properties of tiles
            for ii = 1:master.gui.displayedChannels
               app.pulsetiles{ii}.YTick = [];
               if ii ~= master.gui.displayedChannels
                  app.pulsetiles{ii}.XTick = [];
               else
                  app.pulsetiles{ii}.TickLength = [0 0];
               end
               app.pulsetiles{ii}.XLim = [0 cumdur];
               app.pulsetiles{ii}.YLim = [-.4 1.4];
               if ~mod(ii,2)
                  app.pulsetiles{ii}.YAxisLocation = 'right';
               end
               app.pulsetiles{ii}.YLabel.String = convertStringsToChars(master.gui.channelTitles(ii));
            end
            if master.gui.prevDisplayed > master.gui.displayedChannels
               for ii = 1:(master.gui.prevDisplayed-master.gui.displayedChannels)
                  app.pulsetiles(end) = [];
                  app.pulselines(end) = [];
               end
            end
            master.gui.prevDisplayed = master.gui.displayedChannels;
         end

         app.RFFreq.Value = master.RF.frequency;

         app.Parameter.Value = master.gui.meastype;

         app.FuzzyCounts.Value = ~master.NIDAQ.useClock;

         switch master.gui.itertype
            case 'Time'
               app.IterationsType.SelectedObject = app.TimeButton;
            case 'Number'
               app.IterationsType.SelectedObject = app.NumberButton;
            case 'Perpetual'
               app.IterationsType.SelectedObject = app.PerpetualButton;
         end

         app.IterationsNum.Value = master.gui.maxits;

         if isfield(master.NDYOV,'switch')
            app.LaserSwitch.Value = convertStringsToChars(master.NDYOV.switch);
         end

         if isfield(master.NDYOV,'setpower')
            app.LaserPower.Value = master.NDYOV.setpower;
         end

         if isfield(master.NIDAQ,'confocal')
            if master.NIDAQ.confocal
               app.ConfocalWideField.Value = 'Con';
            else
               app.ConfocalWideField.Value = 'WF';
            end
         end

         app.RFAmp.Value = master.RF.amplitude;

         if isfield(master.RF,'modulationOn')
            app.RFmodulationCheckBox.Value = master.RF.modulationOn;
         end

      end


      function  stageUpdate(app)
         global master

         app.IgnoreSettling.Value = master.stage.ignoreWait;

         app.OptimizationOn.Value = master.gui.optswitch;

         app.OptimizationTiming.Value = master.gui.opttime;


         app.XIncrement.Value = master.gui.xinc;

         app.YIncrement.Value = master.gui.yinc;

         app.ZIncrement.Value = master.gui.zinc;

         if isfield(master.stage,'loc')
            app.XSlider.Value = master.stage.loc(1)+master.stage.loc(4);
            app.XPosition.Value = master.stage.loc(1)+master.stage.loc(4);

            app.YSlider.Value = master.stage.loc(2)+master.stage.loc(5);
            app.YPosition.Value = master.stage.loc(2)+master.stage.loc(5);

            app.ZSlider.Value = master.stage.loc(3)+master.stage.loc(6);
            app.ZPosition.Value = master.stage.loc(3)+master.stage.loc(6);
         end

         app.OptTable.Data = [master.stage.sequence' master.stage.radius' master.stage.steps'];

      end


      function  scanningUpdate(app)
         global master

         app.ScanType.Value = master.gui.scaninstruct.type;


         if isfield(master.gui.scaninstruct,'starts')
            disptext = num2str(master.gui.scaninstruct.starts(1));
            if length(master.gui.scaninstruct.starts)~=1
               for ii = 1:length(master.gui.scaninstruct.starts)-1
                  disptext = [disptext ', ' num2str(master.gui.scaninstruct.starts(ii+1))]; %#ok<AGROW>
               end
            end
            app.StartScan.Value = disptext;
         end

         if isfield(master.gui.scaninstruct,'ends')
            disptext = num2str(master.gui.scaninstruct.ends(1));
            if length(master.gui.scaninstruct.ends)~=1
               for ii = 1:length(master.gui.scaninstruct.ends)-1
                  disptext = [disptext ', ' num2str(master.gui.scaninstruct.ends(ii+1))]; %#ok<AGROW>
               end
            end
            app.EndScan.Value = disptext;
         end

         try
            disptext = num2str(master.gui.scaninstruct.addresses(1));
            if length(master.gui.scaninstruct.addresses)~=1
               for ii = 1:length(master.gui.scaninstruct.addresses)-1
                  disptext = [disptext ', ' num2str(master.gui.scaninstruct.addresses(ii+1))]; %#ok<AGROW>
               end
            end
            app.PulseAddresses.Value = disptext;
         catch
         end


         app.StepSizeField.Value = master.gui.scaninstruct.stepinput;


         app.ScanNum.Value = master.gui.scaninstruct.scannum;

         if strcmp(master.gui.scaninstruct.steptype,'# of points')
            app.NPointsStepSize.SelectedObject = app.NPointsButton;
         else
            app.NPointsStepSize.SelectedObject = app.StepSizeButton;
         end

         if isfield(master.gui,'scan')
            scaninfo = master.gui.scan;
            nscans = length(scaninfo);
            addresses = (1:nscans)';
            scantypes = strings(nscans,1);
            scanstarts = scantypes;
            scanends = scantypes;
            scanstepsize = scantypes;
            scannsteps = zeros(nscans,1);
            for ii = 1:nscans
               scantypes(ii) = scaninfo(ii).type;

               currstart = '';
               currend = currstart;
               currstepsize = currstart;
               for jj = 1:numel(scaninfo(ii).starts)
                  currstart = sprintf('%s%.*g, ',currstart,app.DisplayDigits.Value,scaninfo(ii).starts(jj));
                  currend = sprintf('%s%.*g, ',currend,app.DisplayDigits.Value,scaninfo(ii).ends(jj));
                  currstepsize = sprintf('%s%.*g, ',currstepsize,app.DisplayDigits.Value,scaninfo(ii).stepsize(jj));
               end
               currstart(end-1:end) = [];
               currend(end-1:end) = [];
               currstepsize(end-1:end) = [];
               scanstarts(ii) = convertCharsToStrings(currstart);
               scanends(ii) = convertCharsToStrings(currend);
               scanstepsize(ii) = convertCharsToStrings(currstepsize);

               scannsteps(ii) = scaninfo(ii).nsteps;
            end

            app.ScanTable.Data = [addresses scantypes scanstarts scanends scanstepsize scannsteps];

         else
            app.ScanTable.Data = [];
         end

      end


      function outputSettingsUpdate(app)
         global master

         if isfield(master.gui,'scan')

            scaninfo = master.gui.scan;
            nscans = length(scaninfo);
            addresses = (1:nscans)';
            scantypes = strings(nscans,1);
            scannsteps = zeros(nscans,1);
            chosenvals = scannsteps;

            for ii = 1:nscans
               scantypes(ii) = scaninfo(ii).type;

               scannsteps(ii) = scaninfo(ii).nsteps;

               intermediate = (master.gui.chosensteps(ii)-1)*scaninfo(ii).stepsize(1);
               chosenvals(ii) = convertCharsToStrings(sprintf('%.2g',intermediate + scaninfo(ii).starts(1)));
            end

            chosensteps = master.gui.chosensteps;

            app.DimensionSet.Data = [addresses scantypes scannsteps chosensteps' chosenvals];

         else
            app.DimensionSet.Data = [];
            master.gui.progresslayout = [];
         end


         limstable = ["lower x";"upper x";"default x";"lower y";"upper y";"default y";"lower percentile";"upper percentile"];
         limstable(:,2) = string([master.gui.plotInfo.xlims(1);master.gui.plotInfo.xlims(2);master.gui.plotInfo.xdefault;...
            master.gui.plotInfo.ylims(1);master.gui.plotInfo.ylims(2);master.gui.plotInfo.ydefault;...
            master.gui.plotInfo.percentile(1);master.gui.plotInfo.percentile(2)]);
         if str2double(limstable(3,2))
            limstable(3,2) = "true";
         else
            limstable(3,2) = "false";
         end
         if str2double(limstable(6,2))
            limstable(6,2) = "true";
         else
            limstable(6,2) = "false";
         end
         app.PlotLimitsTable.Data = limstable;

         app.UpdatePeriod.Value = master.gui.updateperiod;

         app.ContrastType.Value = master.gui.contrastType;

         app.XDimension.Value = master.gui.plotInfo.xdim;

         app.YDimension.Value = master.gui.plotInfo.ydim;

         app.InvertXAxis.Value = master.gui.invertx;

         app.PulseAxis.Value = master.gui.plotInfo.axisPulse;

         app.AdditionalxOffset.Value = master.gui.plotInfo.addedxOffset;

         app.Incorporatex.Value = master.gui.plotInfo.incorporatex;

         app.Incorporatey.Value = master.gui.plotInfo.incorporatey;

      end


      function notificationsManualUpdate(app)
         global master
         if master.gui.quickCountsRF
            app.CountssecondRFsettingButtonGroup.SelectedObject = app.QuickCountsRFon;
         else
            app.CountssecondRFsettingButtonGroup.SelectedObject = app.QuickCountsRFoff;
         end

         if isfield(master.gui,'emailaddress')
            app.EmailAddress.Value = master.gui.emailaddress;
         end

         app.EmailIterations.Value = master.gui.emailIts;

         if ~isempty(master.gui.autosavename)
            app.AutoSaveName.Value = master.gui.autosavename;
         end

         app.CompletionEmail.Value = master.gui.completionemail;

         app.Notifications.Value = master.notifications;

         app.EmailIterationsCheckbox.Value = master.gui.itemail;

         app.AutoSaveIterations.Value = master.gui.autosaveits;

         app.ManualOptNum.Value = master.gui.manoptn;

         app.AutoSaveCheckbox.Value = master.gui.autosave;

      end


      function warningsUpdate(app)
         global master

         warningtext = {};
         nn = 1;

         if ~master.stage.initialized || ~master.RF.initialized || ~master.NIDAQ.initialized || ~master.PB.initialized
            warningtext{nn} = 'Not all instruments initialized.';
            nn = nn+1;
         end

         seqPresent = false;
         if isfield(master.PB,'sequence')
            if ~isempty(master.PB.sequence)
               seqPresent = true;
            end
         end
         if ~seqPresent
            warningtext{nn} = 'No pulse sequence selected.';
            nn = nn+1;
         end

         try
            scanstring = strings(1,length(master.gui.scan));
            for ii = 1:length(master.gui.scan)
               scanstring(ii) = convertCharsToStrings(master.gui.scan(ii).type);
            end
            xscan = scanstring == "Stage X";
            yscan = scanstring == "Stage Y";
            zscan = scanstring == "Stage Z";
            rfscan = scanstring == "RF frequency";
            pulsescan = scanstring == "Pulse duration";
            laserscan = scanstring == "Laser power";

            xwrong = any(xscan) && any(master.stage.sequence == "x");
            ywrong = any(yscan) && any(master.stage.sequence == "y");
            zwrong = any(zscan) && any(master.stage.sequence == "z");
            if (xwrong || ywrong || zwrong) && master.gui.optswitch
               badaxes = '';
               if xwrong
                  badaxes = strcat(badaxes,'x, ');
               end
               if ywrong
                  badaxes = strcat(badaxes,'y, ');
               end
               if zwrong
                  badaxes = strcat(badaxes,'z, ');
               end
               badaxes(end) = [];
               warningtext{nn} = sprintf(['Attempting to scan and optimize along stage %s. '...
                  'This will likely interfere with scan results.'],badaxes);
               nn = nn+1;
            end

            xwrong = sum(xscan) > 1;
            ywrong = sum(yscan) > 1;
            zwrong = sum(xscan) > 1;
            if xwrong || ywrong || zwrong
               badaxes = '';
               if xwrong
                  badaxes = strcat(badaxes,'x, ');
               end
               if ywrong
                  badaxes = strcat(badaxes,'y, ');
               end
               if zwrong
                  badaxes = strcat(badaxes,'z, ');
               end
               badaxes(end-1:end) = [];
               warningtext{nn} = sprintf(['Multiple stage %s scans being performed. '...
                  'This will likely interfere with scan results.'],badaxes);
               nn = nn+1;
            end

            if sum(rfscan) > 1
               warningtext{nn} = ['Multiple RF frequency scans being performed. '...
                  'This will likely interfere with scan results.'];
               nn = nn+1;
            end
            
            if sum(laserscan) > 0
               warningtext{nn} = ['Laser scan will be performed. Scan results may be less accurate due to '...
                  'erratic fluctuations as set power changes. Scan will also take at least 1 second per data point.'];
               nn = nn+1;
            end
            
            if sum(laserscan) > 1
               warningtext{nn} = ['Multiple laser power scans being performed. '...
                  'This will likely interfere with scan results.'];
               nn = nn+1;
            end

            if any(pulsescan)
               pulselocs = find(pulsescan);
               alladdresses = [];
               for ii = 1:sum(pulsescan)
                  curraddresses = master.gui.scan(pulselocs(ii)).addresses;
                  alladdresses = [alladdresses curraddresses]; %#ok<AGROW>
               end
               [~,unrepeated] = unique(alladdresses, 'stable');
               repeatedloc = setdiff( 1:numel(alladdresses), unrepeated );
               repeated = alladdresses(repeatedloc);
               if ~isempty(repeated)
                  badaddresses = '';
                  for ii = 1:numel(repeated)
                     badaddresses = sprintf('%s%s, ',badaddresses,num2str(repeated(ii)));
                  end
                  badaddresses(end-1:end) = [];
                  warningtext{nn} = sprintf(['Multiple pulse duration scans at address(es) %s being performed. '...
                     'This will likely interfere with scan results.'],badaddresses);
                  nn = nn+1;
               end
            end
         catch
         end

         if app.IterationsNum.Value > 29 && app.IterationsType.SelectedObject == app.NumberButton
            warningtext{nn} = ['A large number of iterations has been selected.' ...
               ' Are you sure you did not intend a time selection?'];
            nn = nn+1;
         end

         if app.OptReadoutDur.Value < 1e7
            warningtext{nn} = ['Optimization readout duration is very low. ' ...
               'For better results use values above 100 us.'];
            nn = nn+1;
         end

         if app.OptReadoutDur.Value > 1e9
            warningtext{nn} = ['Optimization readout duration is very high. ' ...
               'This may cause experiment to slow down significantly without appreciable gain.'];
            nn = nn+1;
         end

         if app.OpttargetButtonGroup.SelectedObject == app.GaussianPeakButton
            if any(master.stage.steps < 7)
               warningtext{nn} = ['Attempting to model gaussian distribution with fewer than'...
                  ' seven points. This may result in poor model construction.'];
               nn = nn+1;
            end

         end

         if isfield(master.PB,'sequence')
            %Calculate the time of the sequence
            if master.PB.useInterpreter
               [trueSeq] = PBInterpreter;
            else
               trueSeq = master.PB.sequence;
            end
            PBTimeCalculator(trueSeq)

%             %Sequence duration is low
%             if (master.PB.sequenceDuration < 2e8 && master.NIDAQ.useClock)
%                warningtext{nn} = sprintf('Sequence duration is less than 200 ms. This can result in very low signal to noise due to%s', ...
%                   ' the delay between the MatLab read command and actual data acquisition. Consider increasing the total number of loops.');
%                nn = nn+1;
%             end

         end

         if ~isfield(master.gui,'emailaddress') && (master.gui.itemail || master.gui.completionemail)
            warningtext{nn} = 'Attempting to send email but no email address entered.';
         end

         if ~isempty(warningtext)
            app.Warnings.Value = warningtext;
         else
            app.Warnings.Value = 'No warnings present';
         end

         setupDir = dir(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Experiments\Setups'));
         if ~isempty(setupDir)
            setupDir = setupDir(~[setupDir.isdir]);
            [~,idx] = sort([setupDir.datenum],'descend');
            setupDir = setupDir(idx);
            setupDir = struct2cell(setupDir);
            if isempty(setupDir)
               app.PrevSetups.Value = 'No saved setups present at this box address';
            else
               dirNames = @(x) x(1:end-4);
               setupList = squeeze(cellfun(dirNames,setupDir(1,:),'UniformOutput',false));
               setupList = setupList([1,1:end]);
               setupList{1} = 'Saved Setups:';
               app.PrevSetups.Value = setupList;
            end

            seqDir = dir(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Pulse Sequences'));
            seqDir = seqDir(~[seqDir.isdir]);
            [~,idx] = sort([seqDir.datenum],'descend');
            seqDir = seqDir(idx);
            seqDir = struct2cell(seqDir);
            if isempty(seqDir)
               app.PrevSeqs.Value = 'No saved sequences present at this box address';
            else
               dirNames = @(x) x(1:end-4);
               seqList = squeeze(cellfun(dirNames,seqDir(1,:),'UniformOutput',false));
               seqList = seqList([1,1:end]);
               seqList{1} = 'Saved Sequences:';
               app.PrevSeqs.Value = seqList;
            end
         end


      end


      function  obscureUpdate(app)

         global master

         app.NormCounts.Value = master.gui.normCounts;

         app.ComputerEditField.Value = master.comp;

         app.RandomizeScan.Value = master.gui.randomScan;

         app.WFCutoffVoltage.Value = master.NIDAQ.cutoffVoltage;

         if master.stage.opttype == "Raw Value"
            app.OpttargetButtonGroup.SelectedObject = app.RawValueButton;
         else
            app.OpttargetButtonGroup.SelectedObject = app.GaussianPeakButton;
         end

         switch master.gui.optparam

            case 'RF on'
               app.OptimizationParameter.SelectedObject = app.RFonButton;

            case 'RF off'
               app.OptimizationParameter.SelectedObject = app.RFoffButton;

            case 'Contrast'
               app.OptimizationParameter.SelectedObject = app.ContrastButton;
         end

         if isfield(master.gui,'optdur')
            app.OptReadoutDur.Value = master.gui.optdur;
         end

         if isfield(master.stage,'precision')
            app.StagePrecision.Value = master.stage.precision;
         end

         app.LaserEquilibrium.Value = master.NDYOV.waitforequilibrium;

         app.LaserCleanOff.Value = master.NDYOV.offwhencleaned;

         switch master.gui.resetloc

            case 'min'
               app.ResetLocation.SelectedObject = app.ResetMin;

            case 'center'
               app.ResetLocation.SelectedObject = app.ResetCenter;

            case 'max'
               app.ResetLocation.SelectedObject = app.ResetMax;
         end

         app.FinePauseTime.Value = master.stage.finepause * 1000;

         app.CoarsePauseTime.Value = master.stage.coarsepause * 1000;

         if isfield(master.RF,'modulationType')
            app.RFModulationType.Value = master.RF.modulationType;
         end

      end


      function progressUpdate(app)
         global master

         if master.gui.nscans ~= 0

            %Create the tiled layout as well as the tiles and both
            %empty and full patches. also alter settings of these as
            %desired
            if master.gui.makeplot.progressbars
               if isfield(master.gui,'progresslayout')
                  delete(master.gui.progresslayout)
               end
               master.gui.progresslayout = tiledlayout(app.Progress,master.gui.nscans,1);
               for ii = 1:master.gui.nscans
                  switch ii
                     case 2
                        barcolor = 'r';
                     case 3
                        barcolor = 'g';
                     otherwise
                        barcolor = 'b';
                  end
                  app.progresstiles{ii} = nexttile(master.gui.progresslayout,ii);
                  app.progresstiles{ii}.Title.String = sprintf('Scan %d',ii);
                  app.progresstiles{ii}.XTick = [];
                  app.progresstiles{ii}.YTick = [];
                  app.progresstiles{ii}.UserData = 0; %0 steps have been plotted
                  app.emptypatches{ii} = patch(app.progresstiles{ii},[1 0 0 1],[0 0 1 1],[210/255 210/255 210/255]);%empty area
                  app.fullpatches{ii} = patch(app.progresstiles{ii},[0 0 0 0],[0 0 1 1],barcolor);%filled area
               end
               master.gui.makeplot.progressbars = false;
            end

            %Update x data for bars that need updates
            if isfield(master.gui,'odomatrix') && isfield(master.gui,'loopmatrix')
               for ii = 1:master.gui.nscans
                  if app.progresstiles{ii}.UserData ~= master.gui.odomatrix(ii)
                     scanfraction = master.gui.odomatrix(ii) / master.gui.loopmatrix(ii);
                     app.emptypatches{ii}.XData = [1 scanfraction scanfraction 1];
                     app.fullpatches{ii}.XData = [0 scanfraction scanfraction 0];
                     app.progresstiles{ii}.UserData = master.gui.odomatrix(ii);
                  end
               end
            end

         end

         if ~isfield(master.gui,'odoplotted')
            master.gui.odoplotted = ones(1,length(master.gui.odomatrix));
         end

         if (any(master.gui.odoplotted ~= master.gui.odomatrix) && master.gui.step ~= 0) || master.gui.expcomplete || master.gui.step >= prod(master.gui.loopmatrix)

            plotData(app)

            master.gui.odoplotted = master.gui.odomatrix;
         end

         scanInfoText = {};
         nn = 1;

         if isfield(master.gui,'itnum')
            scanInfoText{nn} = sprintf('Iteration: %d',master.gui.itnum);
            nn = nn+1;
         end

         if isfield(master.gui,'referenceVector')
            stepn = master.gui.step;
            if stepn >= 10
               startstep = stepn - 9;
            else
               startstep = 1;
            end
            if master.gui.randomScan
               valcare = master.gui.permutation(startstep:stepn);
            else
               valcare = startstep:stepn;
            end
            if master.NIDAQ.confocal
               if isscalar(master.gui.referenceTimeVector)
                  avg10 = mean(master.gui.referenceVector(valcare) ./ (master.gui.referenceTimeVector*1e-9));
               else
                  avg10 = mean(master.gui.referenceVector(valcare) ./ (master.gui.referenceTimeVector(valcare)*1e-9));
               end

               sigref = 'reference';
               if avg10 == 0
                  if isscalar(master.gui.signalTimeVector)
                     avg10 = mean(master.gui.signalVector(valcare) ./ (master.gui.signalTimeVector*1e-9));
                  else
                     avg10 = mean(master.gui.signalVector(valcare) ./ (master.gui.signalTimeVector(valcare)*1e-9));
                  end
                  sigref = 'signal';
               end
               if avg10 ~= 0 && ~isnan(avg10)
                  avg10 = round(avg10);
                  scanInfoText{nn} = sprintf('Average of last 10 points this iteration: %d counts/s (%s)',avg10,sigref);
               end
            else
               avg10 = mean(master.gui.referenceVector(valcare));
               sigref = 'reference';
               if avg10 == 0
                  avg10 = mean(master.gui.signalVector(valcare));
                  sigref = 'signal';
               end
               if avg10 ~= 0 && ~isnan(avg10)
                  avg10 = round(avg10,3);
                  scanInfoText{nn} = sprintf('Average of last 10 points this iteration: %.3f V (%s) ',avg10,sigref);
               end
            end

         end

         if ~isempty(scanInfoText)
            app.ScanInfo.Value = scanInfoText;
         else
            app.ScanInfo.Value = ' ';
         end

      end


      function plotData(app)
         global master

         if isfield(master.gui,'referenceVector') || isfield(master,'reference')
            try

               xdim = master.gui.plotInfo.xdim;
               ydim = master.gui.plotInfo.ydim;

               for ii = 1:2

                  if ii == 1
                     if isfield(master,'reference')
                        totData = master.gui.totalReference;
                     end
                     if isfield(master.gui,'referenceVector')
                        vecData = master.gui.referenceVector;
                     end
                  else
                     if isfield(master,'reference')
                        totData = master.gui.totalSignal;
                     end
                     vecData = master.gui.signalVector;
                  end

                  if isfield(master,'reference')
                     if isfield(master.gui,'referenceVector') && master.gui.step ~= prod(master.gui.loopmatrix)
                        %Reshape total data back to a vector
                        if master.gui.nscans ~= 1
                           unfurledData = permute(totData,flip(1:master.gui.nscans));
                           unfurledData = reshape(unfurledData,[],1);
                        else
                           unfurledData = totData;
                        end

                        %Add total and vector data accounting for number of
                        %iterations
                        data = unfurledData + vecData;
                        data(1:master.gui.step) = data(1:master.gui.step)./master.gui.itnum;
                        data(master.gui.step+1:end) = data(master.gui.step+1:end)./(master.gui.itnum-1);

                        %Reshape back into proper format
                        if master.gui.nscans ~= 1
                           data = reshape(data,flip(master.gui.loopmatrix));
                           data = permute(data,flip(1:master.gui.nscans));
                        end
                     else %no vector data
                        data = totData;
                     end
                  else %no total data

                     data = vecData;
                     if master.gui.nscans ~= 1
                        data = reshape(data,flip(master.gui.loopmatrix));
                        data = permute(data,flip(1:master.gui.nscans));
                     end
                  end

                  if ii == 1
                     ref = data;
                  else
                     sig = data;
                  end
               end

               switch master.gui.meastype
                  case 'Contrast'

                     switch master.gui.contrastType
                        case 'R-S'
                           data = ref - sig;
                           if master.NIDAQ.confocal
                              if master.gui.normCounts
                                 data = data ./ (master.gui.referenceTime*1e-9);
                                 app.colorbarobject.Label.String = 'counts/s';
                              else
                                 app.colorbarobject.Label.String = 'counts';
                              end
                           else
                              app.colorbarobject.Label.String = 'V';
                           end
                        case 'R-S/R'
                           data = (ref - sig) ./ ref;
                           app.colorbarobject.Label.String = 'cotrast %';
                        case 'R-S/S'
                           data = (ref - sig) ./ sig;
                           app.colorbarobject.Label.String = 'cotrast %';
                        case 'R-S/R+S'
                           data = (ref - sig) ./ (ref + sig);
                           app.colorbarobject.Label.String = 'cotrast %';
                     end

                     if isfield(master,'debugging') && isfield(master.debugging,'baselineContrastSubtraction')
                         data = data - master.debugging.baselineContrastSubtraction;
                     end

                  case 'Signal'
                     data = sig;
                     if master.NIDAQ.confocal
                        if master.gui.normCounts
                           data = data ./ (master.gui.signalTime*1e-9);
                           app.colorbarobject.Label.String = 'counts/s';
                        else
                           app.colorbarobject.Label.String = 'counts';
                        end
                     else
                        app.colorbarobject.Label.String = 'V';
                     end
                  case 'Reference'
                     data = ref;
                     if master.NIDAQ.confocal
                        if master.gui.normCounts
                           data = data ./ (master.gui.referenceTime*1e-9);
                           app.colorbarobject.Label.String = 'counts/s';
                        else
                           app.colorbarobject.Label.String = 'counts';
                        end
                     else
                        app.colorbarobject.Label.String = 'V';
                     end
               end

               if xdim ~= ydim %not a single value, units are relevant
                  if xdim == 0

                     switch master.gui.meastype
                        case 'Contrast'
                           if ~strcmp(master.gui.contrastType,'R-S')
                              master.gui.xunits = ' (relative contrast)';
                           elseif master.gui.normCounts
                              master.gui.xunits = ' (contrast counts/s)';
                           else
                              master.gui.xunits = ' (contrast counts)';
                           end

                        case {'Signal','Reference'}
                           if master.NIDAQ.confocal
                              if master.gui.normCounts
                                 master.gui.xunits = ' (counts/s)';
                              else
                                 master.gui.xunits = ' (counts)';
                              end
                           else
                              master.gui.xunits = ' (V)';
                           end
                     end


                  else
                     switch master.gui.scan(xdim).type
                        case {'Stage X','Stage Y','Stage Z'}
                           master.gui.xunits = ' (μm)';

                        case 'RF frequency'
                           master.gui.xunits = ' (GHz)';

                        case 'Pulse duration'
                           master.gui.xunits = ' (ns)';
                        case 'Laser power'
                           master.gui.xunits = ' (W)';
                     end

                  end

                  if ydim == 0

                     switch master.gui.meastype
                        case 'Contrast'
                           if ~strcmp(master.gui.contrastType,'R-S')
                              master.gui.yunits = ' (relative contrast)';
                           elseif master.gui.normCounts
                              master.gui.yunits = ' (contrast counts/s)';
                           elseif master.NIDAQ.confocal
                              master.gui.yunits = ' (contrast counts)';
                           else
                              master.gui.yunits = ' (contrast V)';
                           end

                        case {'Signal','Reference'}
                           if master.NIDAQ.confocal
                              if master.gui.normCounts
                                 master.gui.yunits = ' (counts/s)';
                              else
                                 master.gui.yunits = ' (counts)';
                              end
                           else
                              master.gui.yunits = ' (V)';
                           end
                     end

                  else
                     switch master.gui.scan(ydim).type
                        case {'Stage X','Stage Y','Stage Z'}
                           master.gui.yunits = ' (μm)';

                        case 'RF frequency'
                           master.gui.yunits = ' (GHz)';

                        case 'Pulse duration'
                           master.gui.yunits = ' (ns)';
                        case 'Laser power'
                           master.gui.xunits = ' (W)';
                     end

                  end
               end

               if ~isfield(master,'reference')
                  if master.gui.nscans ~= 1
                     unfurledData = permute(data,flip(1:master.gui.nscans));
                     unfurledData = reshape(unfurledData,[],1);
                  else
                     unfurledData = data;
                  end
                  if master.gui.randomScan
                     datamin = min(unfurledData(master.gui.permutation(1:master.gui.step)),[],"all");
                     if datamin > 0
                        datamin = datamin*0.9;
                     else
                        datamin = datamin*1.1;
                     end
                     unfurledData(master.gui.permutation(master.gui.step+1:end)) = datamin;
                  else
                     datamin = min(unfurledData(1:master.gui.step),[],"all");
                     datamax = max(unfurledData(1:master.gui.step),[],"all");
%                      if datamin > 0
%                         datamin = datamin*0.9;
%                      else
%                         datamin = datamin*1.1;
%                      end
                     datarange = datamax - datamin;
                     if datarange == 0
                         datarange = datamin * .01;
                     end
                     master.gui.uncollected = datamin - datarange * .1;
                     unfurledData(master.gui.step+1:end) = master.gui.uncollected;
                  end
                  if master.gui.nscans ~= 1
                     data = reshape(unfurledData,flip(master.gui.loopmatrix));
                     data = permute(data,flip(1:master.gui.nscans));
                  else
                     data = unfurledData;
                  end
               end

               if ~isfield(master.gui,'prevuncollected')
                  master.gui.prevuncollected = 0;
               end


               if xdim ~= 0 && ydim == 0

                  %Take only one vector of numbers that is along the x
                  %dimension with all other dimensions set to their
                  %individual value as determined by master.gui.dimselect

                  maxcare = size(data,xdim);
                  vals = 1:maxcare;

                  celldims = cell(1,master.gui.nscans);
                  for ii = 1:master.gui.nscans
                     if ii == xdim
                        currval = vals;
                     else
                        currval = master.gui.chosensteps(ii);
                     end
                     celldims(ii) = {currval};
                  end

                  selecteddata = squeeze(data(celldims{:})); %y axis data

                  if ~master.gui.plotInfo.ydefault
                     if master.gui.plotInfo.resetLims.y == true
                        resetLims(app,'y',selecteddata)
                     end
                  else
                     resetLims(app,'y',selecteddata)
                  end

                  if ~master.gui.plotInfo.xdefault
                     if master.gui.plotInfo.resetLims.x == true
                        resetLims(app,'x',selecteddata)
                     end
                  else
                     resetLims(app,'x',selecteddata)
                  end

                  %x axis data
                  if master.gui.plotInfo.wasLoaded
                      newScan = master.gui.plotInfo.loadedScan;
                  else
                      newScan = master.gui.scan;
                  end
                  if strcmp(newScan(xdim).type,'Pulse duration')
                      orderVal = master.gui.plotInfo.axisPulse;
                  else
                      orderVal = 1;
                  end
                  usedxlims = [newScan(xdim).starts(orderVal) newScan(xdim).ends(orderVal)];
                  usedxlims = usedxlims + master.gui.plotInfo.addedxOffset;
                  if ~master.gui.plotInfo.incorporatex
                      usedxlims = usedxlims - usedxlims(1);
                  end

                  plotaxis = usedxlims(1):master.gui.plotInfo.xstepSize:usedxlims(2);
                  if round(plotaxis(end),10) ~= round(usedxlims(2),10) && length(selecteddata) ~= length(plotaxis)
                    plotaxis(end+1) = usedxlims(2);
                  end
                  master.test.plotaxis = plotaxis;
                    master.test.selected = selecteddata;

                  if master.gui.makeplot.output
                     if isfield(master.gui,'outax')
                        delete(master.gui.outax)
                     end
                     master.gui.outax = axes(app.OutputPanel);

                     app.outputdisplay = plot(master.gui.outax,plotaxis,selecteddata);

                     master.gui.outax.YLimMode = 'manual';
                     master.gui.outax.XLimMode = 'manual';

                     master.gui.outax.YLabel.String = sprintf('%s%s',master.gui.meastype,master.gui.yunits);

                     uniqueStarts = unique([master.gui.scan(xdim).starts;master.gui.scan(xdim).ends],'rows');
                     uniqueStarts = uniqueStarts(:,1);

                     if ~master.gui.plotInfo.incorporatex
                        scanList = squeeze(struct2cell(master.gui.scan));
                        nameList = fieldnames(master.gui.scan);
                        scanList = scanList(strcmp(nameList,'type'),:);
                        anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
                        if anyPulses
                           chosenStart = master.gui.plotInfo.axisPulse;
                           if chosenStart > numel(uniqueStarts)
                              errordlg('x axis pulse label is greater than number of unique pulse durations','Invalid Plotting Pulse Number')
                           end
                        else
                           chosenStart = 1;
                        end

                        master.gui.outax.XLabel.String = sprintf('%s%s - Offset:%g',...
                           master.gui.scan(xdim).type,master.gui.xunits,...
                           master.gui.plotInfo.addedxOffset + uniqueStarts(chosenStart));
                     else
                        master.gui.outax.XLabel.String = sprintf('%s%s',...
                           master.gui.scan(xdim).type,master.gui.xunits);
                     end

                     if master.gui.invertx
                        master.gui.outax.XDir = 'reverse';
                     else
                        master.gui.outax.XDir = 'normal';
                     end

                     master.gui.makeplot.output = false;
                     if isempty(app.outputdisplay(1).UserData)
                        app.outputdisplay(1).UserData = ones(master.gui.nscans,1);
                     end
                  else
                     if master.gui.invertx
                        master.gui.outax.XDir = 'reverse';
                     else
                        master.gui.outax.XDir = 'normal';
                     end

%                      if master.gui.randomScan
                        app.outputdisplay.YData = selecteddata;
%                      else
%                         %If only 1 scan, always update
%                         %If multiple scans, only update when chosen value is
%                         %contained within [oldvalue newvale] inclusive for all
%                         %other scans
%                         checkscans = false;
%                         if xdim ~= 1
%                            if any(app.outputdisplay(1).UserData(1:xdim-1) ~= master.gui.odomatrix(1:xdim-1))
%                               checkscans = true;
%                            end
%                         end
%                         doupdate = false;
%                         if master.gui.nscans == 1
%                            doupdate = true;
%                         elseif master.gui.odomatrix(xdim) ~= app.outputdisplay(1).UserData(xdim) || checkscans
%                            %If it updated on chosenscans, then doupdate
%                            scannum = zeros(1,master.gui.nscans);
%                            for ii = 1:master.gui.nscans
%                               scannum(ii) = str2double(master.gui.scan(ii).scannum);
%                            end
%                            otherscans = find(scannum ~= xdim);
%                            chosenother = master.gui.chosensteps(otherscans);
%                            oldnum = app.outputdisplay(1).UserData(otherscans);
%                            newnum = master.gui.odomatrix(otherscans);
%                            cgreatertrue = oldnum <= chosenother; %chosen value greater than old
%                            clesstrue = chosenother <= newnum;%chosen value less than new
%                            ctrue = clesstrue == cgreatertrue;%greater and less have same value
%                            greatertrue = oldnum > newnum;%completed round
%                            if all(ctrue+greatertrue == 1)
%                               doupdate = true;
%                            end
%                            %There is a case where an extra update occurrs. If
%                            %the number completes a round but the chosen value is
%                            %between the new and old value EXCLUSIVE, no update
%                            %is needed but this will still update. It is in my
%                            %opninion that this is such an unlikely event with
%                            %very little downside of failure that it isn't worth
%                            %programming for
%                            %There may also be some small cases where an update
%                            %is not triggered where it should be but again, I
%                            %don't think it is likely to happen during normal use
%                            %and the result is merely a visual bug, not one
%                            %affecting the data itself
%                         end
% 
%                         aa = app.outputdisplay(1).UserData(xdim)+1 < master.gui.odomatrix(xdim);
%                         bb = master.gui.uncollected == master.gui.prevuncollected;
%                         if doupdate
%                            if aa && bb
%                               app.outputdisplay.YData(app.outputdisplay(1).UserData(xdim)+1:master.gui.odomatrix(xdim))...
%                                  = selecteddata(app.outputdisplay(1).UserData(xdim)+1:master.gui.odomatrix(xdim));
%                               %If new iteration has begun or if the minimum has changed, update all data
%                            else
%                               app.outputdisplay.YData = selecteddata;
%                               master.gui.prevuncollected = master.gui.uncollected;
%                            end
%                         end
%                      end
                     app.outputdisplay(1).UserData = master.gui.odomatrix;%last step plotted
                  end

                  master.gui.outax.XLim = master.gui.plotInfo.xlims;
                  master.gui.outax.YLim = master.gui.plotInfo.ylims;

               elseif xdim == 0 && ydim ~= 0

                  %Take only one vector of numbers that is along the x
                  %dimension with all other dimensions set to their
                  %individual value as determined by master.gui.dimselect

                  maxcare = size(data,ydim);
                  vals = 1:maxcare;

                  celldims = cell(1,master.gui.nscans);
                  for ii = 1:ndims(data)
                     if ii == ydim
                        currval = vals;
                     else
                        currval = master.gui.chosensteps(ii);
                     end
                     celldims(ii) = {currval};
                  end

                  selecteddata = squeeze(data(celldims{:})); %x axis data

                  if ~master.gui.plotInfo.ydefault
                     if master.gui.plotInfo.resetLims.y == true
                        resetLims(app,'y',selecteddata)
                     end
                  else
                     resetLims(app,'y',selecteddata)
                  end

                  if ~master.gui.plotInfo.xdefault
                     if master.gui.plotInfo.resetLims.x == true
                        resetLims(app,'x',selecteddata)
                     end
                  else
                     resetLims(app,'x',selecteddata)
                  end

                  %y axis data
                  usedylims = master.gui.plotInfo.ylims;
%                   if ~master.gui.plotInfo.incorporatey
%                       usedylims = usedylims - master.gui.plotInfo.yoffset;
%                   end

                  plotaxis = usedylims(1):master.gui.plotInfo.ystepSize:usedylims(2);

                  if master.gui.makeplot.output
                     if isfield(master.gui,'outax')
                        delete(master.gui.outax)
                     end
                     master.gui.outax = axes(app.OutputPanel);

                     app.outputdisplay = plot(master.gui.outax,selecteddata,plotaxis);

                     master.gui.outax.XLabel.String = sprintf('%s%s',master.gui.meastype,master.gui.xunits);

                     uniqueStarts = unique([master.gui.scan(ydim).starts;master.gui.scan(ydim).ends],'rows');
                     uniqueStarts = uniqueStarts(:,1);

                     if ~master.gui.plotInfo.incorporatey
                        scanList = squeeze(struct2cell(master.gui.scan));
                        nameList = fieldnames(master.gui.scan);
                        scanList = scanList(strcmp(nameList,'type'),:);
                        anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
                        if anyPulses
                           chosenStart = master.gui.plotInfo.axisPulse;
                           if chosenStart > numel(uniqueStarts)
                              errordlg('y axis pulse label is greater than number of unique pulse durations','Invalid Plotting Pulse Number')
                           end
                        else
                           chosenStart = 1;
                        end

                        master.gui.outax.YLabel.String = sprintf('%s%s - Offset:%g',...
                           master.gui.scan(ydim).type,master.gui.yunits,...
                           master.gui.plotInfo.addedyOffset + uniqueStarts(chosenStart));
                     else
                        master.gui.outax.YLabel.String = sprintf('%s%s',...
                           master.gui.scan(ydim).type,master.gui.yunits);
                     end

                     if master.gui.invertx
                        master.gui.outax.XDir = 'reverse';
                     else
                        master.gui.outax.XDir = 'normal';
                     end

                     master.gui.makeplot.output = false;
                     if isempty(app.outputdisplay(1).UserData)
                        app.outputdisplay(1).UserData = ones(master.gui.nscans,1);
                     end
                  else

                     if master.gui.invertx
                        master.gui.outax.XDir = 'reverse';
                     else
                        master.gui.outax.XDir = 'normal';
                     end

                     if master.gui.randomScan
                        app.outputdisplay.XData = selecteddata -master.gui.plotInfo.xoffset;
                     else
                        %If only 1 scan, always update
                        %If multiple scans, only update when chosen value is
                        %contained within [oldvalue newvale] inclusive for all
                        %other scans
                        checkscans = false;
                        if ydim ~= 1
                           if any(app.outputdisplay(1).UserData(1:ydim-1) ~= master.gui.odomatrix(1:ydim-1))
                              checkscans = true;
                           end
                        end
                        doupdate = false;
                        if master.gui.nscans == 1
                           doupdate = true;
                        elseif master.gui.odomatrix(ydim) ~= app.outputdisplay(1).UserData(ydim) || checkscans
                           %If it updated on chosenscans, then doupdate
                           scannum = zeros(1,master.gui.nscans);
                           for ii = 1:master.gui.nscans
                              scannum(ii) = str2double(master.gui.scan(ii).scannum);
                           end
                           otherscans = find(scannum ~= ydim);
                           chosenother = master.gui.chosensteps(otherscans);
                           oldnum = app.outputdisplay(1).UserData(otherscans);
                           newnum = master.gui.odomatrix(otherscans);
                           cgreatertrue = oldnum <= chosenother; %chosen value greater than old
                           clesstrue = chosenother <= newnum;%chosen value less than new
                           ctrue = clesstrue == cgreatertrue;%greater and less have same value
                           greatertrue = oldnum > newnum;%completed round
                           if all(ctrue+greatertrue == 1)
                              doupdate = true;
                           end
                           %There is a case where an extra update occurrs. If
                           %the number completes a round but the chosen value is
                           %between the new and old value EXCLUSIVE, no update
                           %is needed but this will still update. It is in my
                           %opninion that this is such an unlikely event with
                           %very little downside of failure that it isn't worth
                           %programming for
                           %There may also be some small cases where an update
                           %is not triggered where it should be but again, I
                           %don't think it is likely to happen during normal use
                           %and the result is merely a visual bug, not one
                           %affecting the data itself
                        end

                        aa = app.outputdisplay(1).UserData(ydim)+1 < master.gui.odomatrix(ydim);
                        bb = master.gui.uncollected == master.gui.prevuncollected;
                        if doupdate
                           if aa && bb
                              app.outputdisplay.XData(app.outputdisplay(1).UserData(ydim)+1:master.gui.odomatrix(ydim))...
                                 = selecteddata(app.outputdisplay(1).UserData(ydim)+1:master.gui.odomatrix(ydim));
                              %If new iteration has begun or if the minimum has changed, update all data
                           else
                              app.outputdisplay.XData = selecteddata;
                              master.gui.prevuncollected = master.gui.uncollected;
                           end
                        end
                        app.outputdisplay(1).UserData = master.gui.odomatrix;%last step plotted
                     end
                  end


                  master.gui.outax.XLim = master.gui.plotInfo.xlims;
                  master.gui.outax.YLim = master.gui.plotInfo.ylims;


               elseif (xdim ~= 0 && ydim ~= 0) && ydim ~= xdim

                  maxcare = size(data,xdim);
                  xvals = 1:maxcare;
                  maxcare = size(data,ydim);
                  yvals = 1:maxcare;
                  celldims = cell(1,master.gui.nscans);
                  for ii = 1:ndims(data)
                     if ii == ydim
                        currval = yvals;
                     elseif ii == xdim
                        currval = xvals;
                     else
                        currval = master.gui.chosensteps(ii);
                     end
                     celldims(ii) = {currval};
                  end
                  selecteddata = squeeze(data(celldims{:}));

                  if xdim < ydim
                     selecteddata = permute(selecteddata,[2 1]);
                  end

                  if ~master.gui.plotInfo.ydefault
                     if master.gui.plotInfo.resetLims.y == true
                        resetLims(app,'y',selecteddata)
                     end
                  else
                     resetLims(app,'y',selecteddata)
                  end

                  if ~master.gui.plotInfo.xdefault
                     if master.gui.plotInfo.resetLims.x == true
                        resetLims(app,'x',selecteddata)
                     end
                  else
                     resetLims(app,'x',selecteddata)
                  end

                  resetLims(app,'c',selecteddata)

                  %This function was renamed in R2022a
                  currver = version;
                  yearloc = find(currver == 'R');
                  yearloc = str2double(currver(yearloc+1:yearloc+5));
                  if yearloc > 2021
                     clims(app.colorbaraxes,master.gui.plotInfo.clims)
                  else
                     caxis(app.colorbaraxes,master.gui.plotInfo.clims)
                  end

                  %if ydim < xdim, update prevupdated:current rows else update prevupdated:current columns
                  if ydim < xdim
                     highdim = ydim;
                  else
                     highdim = xdim;
                  end
                  if master.gui.makeplot.output
                     if isfield(master.gui,'outax')
                        delete(master.gui.outax)
                     end
                     master.gui.outax = axes(app.OutputPanel);
                     master.test.selected = selecteddata;

                     app.outputdisplay = imagesc(master.gui.outax,selecteddata);

                     master.gui.outax.CLim = master.gui.plotInfo.clims;

                     app.outputdisplay.YData = [(master.gui.scan(ydim).starts-master.gui.plotInfo.yoffset) (master.gui.scan(ydim).ends-master.gui.plotInfo.yoffset)];
                     master.gui.outax.YLim = master.gui.plotInfo.ylims;

                     uniqueStarts = unique([master.gui.scan(ydim).starts;master.gui.scan(ydim).ends],'rows');
                     uniqueStarts = uniqueStarts(:,1);

                     if ~master.gui.plotInfo.incorporatey
                        scanList = squeeze(struct2cell(master.gui.scan));
                        nameList = fieldnames(master.gui.scan);
                        scanList = scanList(strcmp(nameList,'type'),:);
                        anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
                        if anyPulses
                           chosenStart = master.gui.plotInfo.axisPulse;
                           if chosenStart > numel(uniqueStarts)
                              errordlg('y axis pulse label is greater than number of unique pulse durations','Invalid Plotting Pulse Number')
                           end
                        else
                           chosenStart = 1;
                        end

                        master.gui.outax.YLabel.String = sprintf('%s%s - Offset:%g',...
                           master.gui.scan(ydim).type,master.gui.yunits,...
                           master.gui.plotInfo.addedyOffset + uniqueStarts(chosenStart));
                     else
                        master.gui.outax.YLabel.String = sprintf('%s%s',...
                           master.gui.scan(ydim).type,master.gui.yunits);
                     end

                     uniqueStarts = unique([master.gui.scan(xdim).starts;master.gui.scan(xdim).ends],'rows');
                     uniqueStarts = uniqueStarts(:,1);

                     app.outputdisplay.XData = [(master.gui.scan(xdim).starts-master.gui.plotInfo.xoffset) (master.gui.scan(xdim).ends-master.gui.plotInfo.xoffset)];
                     master.gui.outax.XLim = master.gui.plotInfo.xlims;

                     if ~master.gui.plotInfo.incorporatex
                        scanList = squeeze(struct2cell(master.gui.scan));
                        nameList = fieldnames(master.gui.scan);
                        scanList = scanList(strcmp(nameList,'type'),:);
                        anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
                        if anyPulses
                           chosenStart = master.gui.plotInfo.axisPulse;
                           if chosenStart > numel(uniqueStarts)
                              errordlg('x axis pulse label is greater than number of unique pulse durations','Invalid Plotting Pulse Number')
                           end
                        else
                           chosenStart = 1;
                        end

                        master.gui.outax.XLabel.String = sprintf('%s%s - Offset:%g',...
                           master.gui.scan(xdim).type,master.gui.xunits,...
                           master.gui.plotInfo.addedxOffset + uniqueStarts(chosenStart));
                     else
                        master.gui.outax.XLabel.String = sprintf('%s%s',...
                           master.gui.scan(xdim).type,master.gui.xunits);
                     end

                     master.gui.makeplot.output = false;
                     if isempty(app.outputdisplay(1).UserData) || length(app.outputdisplay(1).UserData) ~= master.gui.nscans
                        app.outputdisplay(1).UserData = ones(master.gui.nscans,1);
                     end
                  else
                     master.gui.outax.CLim = master.gui.plotInfo.clims;
                     if ~isfield(master.gui,'prevuncollected')
                        master.gui.prevuncollected = -.000001;
                     end

                     if master.gui.randomScan
                        app.outputdisplay.CData = selecteddata;
                     else
                        %I am deciding to forgo much of the complicated logic
                        %done for the plots in favor of slight delays in updates
                        %and slight increses in unneeded updates.
                        if app.outputdisplay(1).UserData(highdim) <= master.gui.odomatrix(highdim)...
                              && master.gui.uncollected == master.gui.prevuncollected

                           %only update the rows/columns of the high dim
                           if highdim == ydim
                              app.outputdisplay.CData(app.outputdisplay(1).UserData(highdim):master.gui.odomatrix(highdim),:)...
                                 = selecteddata(app.outputdisplay(1).UserData(highdim):master.gui.odomatrix(highdim),:);
                           else
                               master.test.userdata = app.outputdisplay(1).UserData;
                               master.test.odomatrix = master.gui.odomatrix;
                              app.outputdisplay.CData(:,app.outputdisplay(1).UserData(highdim):master.gui.odomatrix(highdim))...
                                 = selecteddata(:,app.outputdisplay(1).UserData(highdim):master.gui.odomatrix(highdim));
                           end

                        else
                           app.outputdisplay.CData = selecteddata;
                        end
                        master.gui.prevuncollected = master.gui.uncollected;
                        app.outputdisplay(1).UserData = master.gui.odomatrix;
                     end

                  end

               else %ydim and xdim are equal
                  celldims = cell(1,master.gui.nscans);
                  for ii = 1:ndims(data)
                     currval = master.gui.chosensteps(ii);
                     celldims(ii) = {currval};
                  end
                  selecteddata = squeeze(data(celldims{:}));
                  app.SingleOutput = selecteddata;
               end

               master.gui.plotfailed = false;
            catch ME
               if isfield(master.gui,'plotfailed')
                  if ~master.gui.plotfailed
                     warndlg('Plotting attempt failed','Plot Failed')
                  end
               end
               master.gui.plotfailed = true;
               master.gui.ploterror = ME;
            end
         end

      end


      function stepConversion(app)
         global master
         if isfield(master.gui.scaninstruct,'ends') && isfield(master.gui.scaninstruct,'starts')
            aa = master.gui.scaninstruct;
            if strcmp(aa.steptype,'# of points')
               aa.stepsize = (aa.ends-aa.starts)./(aa.stepinput-1);
               aa.nsteps = aa.stepinput;
            elseif strcmp(aa.steptype,'Step size') && length(aa.starts) ~= 1
               aa.steptype = '# of points';
               app.NPointsStepSize.SelectedObject = app.NPointsButton;
               errordlg('Cannot scan by step size if multiple pulse durations are changing','Cannot use step size')
            else
                aa.nsteps = length(aa.starts:aa.stepinput:aa.ends);
               aa.stepsize = aa.stepinput;
            end
            master.gui.scaninstruct = aa;
         end
      end


      function altStageUpdate(app)
         global master
         if isfield(master.stage,'loc')
            app.CoarseX.Value = master.stage.loc(1)/1000;
            app.CoarseY.Value = master.stage.loc(2)/1000;
            app.CoarseZ.Value = master.stage.loc(3)/1000;
            app.FineX.Value = master.stage.loc(4);
            app.FineY.Value = master.stage.loc(5);
            app.FineZ.Value = master.stage.loc(6);
         end

         app.AltStageScan.Value = master.gui.altStageScan;
      end

      function resetLims(~,ax,data)
         global master

         if master.gui.plotInfo.wasLoaded
            newScan = master.gui.plotInfo.loadedScan;
            validData = true;
         elseif isfield(master.gui,'scan')
            newScan = master.gui.scan;
            validData = true;
         else
            validData = false;
         end

         switch ax
            case 'x'
               dim = master.gui.plotInfo.xdim;

               if dim == 0 && validData
                   if master.gui.nscans ~= 1
                       unfurledData = permute(data,flip(1:master.gui.nscans));
                       unfurledData = reshape(unfurledData,[],1);
                   else
                       unfurledData = data;
                   end
                   if master.gui.randomScan
                       datamin = min(unfurledData(master.gui.permutation(1:master.gui.step)),[],"all");
                       if datamin > 0
                           datamin = datamin*0.9;
                       else
                           datamin = datamin*1.1;
                       end
                   else
                       datamin = min(unfurledData(1:master.gui.step),[],"all");
                       if datamin > 0
                           datamin = datamin*0.9;
                       else
                           datamin = datamin*1.1;
                       end
                   end
                   master.gui.plotInfo.xlims(1) = datamin .* 1.05;
                   master.gui.plotInfo.xlims(2) = max(data,[],"all") .* 1.05;      
                  master.gui.plotInfo.xlims(2) = max(data,[],"all");
                  master.gui.plotInfo.xlims = master.gui.plotInfo.xlims + master.gui.plotInfo.addedxOffset;
                  if ~master.gui.plotInfo.incorporatex
                     master.gui.plotInfo.xoffset = master.gui.plotInfo.xlims(1);
                     master.gui.plotInfo.xlims = master.gui.plotInfo.xlims - master.gui.plotInfo.xoffset;
                  end
                  master.gui.plotInfo.xstepAuto = true;

               elseif validData
                  master.gui.plotInfo.xstepAuto = false;
                  if numel(newScan) >= dim

                     if strcmp(newScan(dim).type,'Pulse duration')
                        orderVal = master.gui.plotInfo.axisPulse;
                     else
                        orderVal = 1;
                     end

                     master.gui.plotInfo.xlims(1) = newScan(dim).starts(orderVal);
                     master.gui.plotInfo.xlims(2) = newScan(dim).ends(orderVal);
                     master.gui.plotInfo.xlims = master.gui.plotInfo.xlims + master.gui.plotInfo.addedxOffset;

                     if ~master.gui.plotInfo.incorporatex
                        master.gui.plotInfo.xoffset = master.gui.plotInfo.xlims(1);
                        master.gui.plotInfo.xlims = master.gui.plotInfo.xlims - master.gui.plotInfo.xoffset;
                     end

                     master.gui.plotInfo.xstepSize = newScan(dim).stepsize(orderVal);

                  else
                     %plot error
                     master.gui.plotInfo.xlims = [0 1];
                     master.gui.plotInfo.xstepSize = .1;
                  end
               else
                  master.gui.plotInfo.xstepAuto = false;
                  master.gui.plotInfo.xlims = [0 1];
                  master.gui.plotInfo.xstepSize = .1;
               end


            case 'y'
               dim = master.gui.plotInfo.ydim;

               if dim == 0 && validData
                   if master.gui.nscans ~= 1
                       unfurledData = permute(data,flip(1:master.gui.nscans));
                       unfurledData = reshape(unfurledData,[],1);
                   else
                       unfurledData = data;
                   end
                   master.test.unfurled = unfurledData;
                   if master.gui.itnum == 1
                       if master.gui.randomScan
                           datamin = min(unfurledData(master.gui.permutation(1:master.gui.step)),[],"all");
                           datamax = max(unfurledData(master.gui.permutation(1:master.gui.step)),[],"all");
                       else
                           datamin = min(unfurledData(1:master.gui.step),[],"all");
                           datamax = max(unfurledData(1:master.gui.step),[],"all");
                       end
                   else
                       datamin = min(unfurledData,[],"all");
                       datamax = max(unfurledData,[],"all");
                   end
                   datarange = datamax - datamin;
                   if datarange == 0
                        datarange = datamin * .01;
                   end
                   master.test.datamax = datamax;
                   master.test.datamin = datamin;
                   master.test.datarange = datarange;
                   master.gui.plotInfo.ylims(1) = datamin - (datarange * .12);
                   master.gui.plotInfo.ylims(2) = datamax + (datarange * .1);
                   master.gui.plotInfo.ylims = master.gui.plotInfo.ylims + master.gui.plotInfo.addedyOffset;
                  if ~master.gui.plotInfo.incorporatey
                     master.gui.plotInfo.yoffset = master.gui.plotInfo.ylims(1);
                  end
                  master.gui.plotInfo.ystepAuto = true;

               elseif validData
                  master.gui.plotInfo.ystepAuto = false;
                  if numel(newScan) >= dim

                     if strcmp(newScan(dim).type,'Pulse duration')
                        orderVal = master.gui.plotInfo.axisPulse;
                     else
                        orderVal = 1;
                     end

                     master.gui.plotInfo.ylims(1) = newScan(dim).starts(orderVal);
                     master.gui.plotInfo.ylims(2) = newScan(dim).ends(orderVal);
                     master.gui.plotInfo.ylims = master.gui.plotInfo.ylims + master.gui.plotInfo.addedyOffset;

                     if ~master.gui.plotInfo.incorporatey
                        master.gui.plotInfo.yoffset = master.gui.plotInfo.ylims(1);
                        master.gui.plotInfo.ylims = master.gui.plotInfo.ylims - master.gui.plotInfo.yoffset;
                     end

                     master.gui.plotInfo.ystepSize = newScan(dim).stepsize(orderVal);

                  else
                     %plot error
                     master.gui.plotInfo.ylims = [0 1];
                     master.gui.plotInfo.ystepSize = .1;
                  end
               else
                  master.gui.plotInfo.ystepAuto = true;
                  master.gui.plotInfo.ylims = [0 1];
                  master.gui.plotInfo.ystepSize = .1;
               end

            case 'c'
               if master.gui.plotInfo.ydim ~= 0 && master.gui.plotInfo.xdim ~= 0
                  if validData
                     master.gui.plotInfo.clims(1) = prctile(data,master.gui.plotInfo.percentile(1),"all");
                     master.gui.plotInfo.clims(2) = prctile(data,master.gui.plotInfo.percentile(2),"all");
                  else
                     master.gui.plotInfo.clims = [0 1];
                  end
               else
                  master.gui.plotInfo.clims = [0 1];
               end
         end
      end
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         global master

         %CHANGE THESE VALUES WHEN ADDING/CHANGING CHANNELS
         channelTitles = ["AOM" "Data" "Sig/Ref" "RF" "I" "Q" "7" "8" "9"];
         lineColors = ["g" "b" "r" "k" "m" "c" "g" "b" "r"];
         %End changing values


         master.PB.nchannels = numel(channelTitles);
         master.gui.channelTitles = channelTitles;
         master.gui.lineColors = lineColors;
         
         master.gui.makeplot.output = true;
         master.gui.makeplot.progressbars = true;
         master.expType = "GUI Optimization";

         warning('off','MATLAB:ui:javaframe:PropertyToBeRemoved')

         app.colorbaraxes = axes(app.Colorbar);
         app.colorbaraxes.Visible = false;
         app.colorbarobject = colorbar(app.colorbaraxes);
         lims = [0 1];
         %This function was renamed in R2022a
         currver = version;
         yearloc = find(currver == 'R');
         yearloc = str2double(currver(yearloc+1:yearloc+5));
         if yearloc > 2021
            clims(app.colorbaraxes,lims)
         else
            caxis(app.colorbaraxes,lims)
         end
         app.colorbarobject.Location = 'north';
         app.colorbarobject.Label.String = 'counts';
         app.colorbarobject.Label.FontSize = 8;         

         if ~isfield(master,'notifications'),      master.notifications = true;     end

         if ~isfield(master,'expType'),            master.expType = "GUI Optimization"; end

         if ~isfield(master,'gui'),                master.gui.isrunning = false;    end

         if ~isfield(master.gui,'isrunning'),      master.gui.isrunning = false;    end

         if ~isfield(master.gui,'nscans'),          master.gui.nscans = 0;    end

         if ~isfield(master.gui,'quickCountsRF'),  master.gui.quickCountsRF = false;end

         if ~isfield(master.gui,'optswitch'),      master.gui.optswitch = false;    end

         if ~isfield(master.gui,'opttime'),        master.gui.opttime = 5;          end

         if ~isfield(master.gui,'manoptn'),        master.gui.manoptn = 1;          end

         if ~isfield(master.gui,'altStageScan'),   master.gui.altStageScan = false; end

         if ~isfield(master.gui,'randomScan'),     master.gui.randomScan = false;   end

         if ~isfield(master.gui,'contrastType'),   master.gui.contrastType = 'R-S'; end

         if ~isfield(master.gui,'boxAddress'),     master.gui.boxAddress = 'C:\Users\User\Box'; end

         if ~isfield(master.gui,'nscans'),         master.gui.nscans = 0;           end

         if ~isfield(master.gui,'meastype'),       master.gui.meastype = 'Contrast';end

         if ~isfield(master.gui,'invertx'),        master.gui.invertx = false;      end

         if ~isfield(master.gui,'optdur'),         master.gui.optdur = 1e8;         end

         if ~isfield(master.gui,'optparam'),       master.gui.optparam = "RF off";  end

         if ~isfield(master.gui,'resetloc'),       master.gui.resetloc = "center";  end

         if ~isfield(master.gui,'itertype'),       master.gui.itertype = 'Number';  end

         if ~isfield(master.gui,'itemail'),        master.gui.itemail = false;      end

         if ~isfield(master.gui,'emailIts'),       master.gui.emailIts = 1;      end

         if ~isfield(master.gui,'maxits'),         master.gui.maxits = 1;           end

         if ~isfield(master.gui,'autosaveits'),    master.gui.autosaveits = 1;      end

         if ~isfield(master.gui,'autosavename'),    master.gui.autosavename = [];   end

         if ~isfield(master.gui,'autosave'),       master.gui.autosave = false;     end

         if ~isfield(master.gui,'updateperiod'),   master.gui.updateperiod = 1;     end

         if ~isfield(master.gui,'displaydigits'),  master.gui.displaydigits = 4;    end

         if ~isfield(master.gui,'xinc'),           master.gui.xinc = 0;             end

         if ~isfield(master.gui,'yinc'),           master.gui.yinc = 0;             end

         if ~isfield(master.gui,'zinc'),           master.gui.zinc = 0;             end

         if ~isfield(master.gui,'completionemail'),   master.gui.completionemail = false;       end

         if ~isfield(master.gui,'continue'),       master.gui.continue = false;      end

         if ~isfield(master.gui,'interrupted'),    master.gui.interrupted = false;  end

         if ~isfield(master.gui,'expcomplete'),    master.gui.expcomplete = false;  end

         if ~isfield(master.gui,'updatecounter'),  master.gui.updatecounter = 0;    end

         if ~isfield(master.gui,'normCounts'),     master.gui.normCounts = true;    end

         if ~isfield(master.gui,'plotfailed'),     master.gui.plotfailed = true;    end

         if ~isfield(master.gui,'scaninstruct'),   master.gui.scaninstruct.type = 'Stage X';    end

         if ~isfield(master.gui.scaninstruct,'type'), master.gui.scaninstruct.type = 'Stage X'; end

         if ~isfield(master.gui.scaninstruct,'steptype'),   master.gui.scaninstruct.steptype = '# of points';  end

         if ~isfield(master.gui.scaninstruct,'stepinput'),  master.gui.scaninstruct.stepinput = 1;    end

         if ~isfield(master.gui.scaninstruct,'address'),    master.gui.scaninstruct.scannum = '1';    end

         if ~isfield(master.gui,'plotInfo'),                master.gui.plotInfo.wasLoaded = false;    end

         if ~isfield(master.gui.plotInfo,'wasLoaded'),      master.gui.plotInfo.wasLoaded = false;    end

         if ~isfield(master.gui.plotInfo,'xlims'),          master.gui.plotInfo.xlims = [0 1];  end

         if ~isfield(master.gui.plotInfo,'xdefault'),       master.gui.plotInfo.xdefault  = true;    end

         if ~isfield(master.gui.plotInfo,'ylims'),          master.gui.plotInfo.ylims = [0 1];  end

         if ~isfield(master.gui.plotInfo,'ydefault'),       master.gui.plotInfo.ydefault = true;    end

         if ~isfield(master.gui.plotInfo,'clims'),          master.gui.plotInfo.clims = [0 1];  end
         
         if ~isfield(master.gui.plotInfo,'percentile'),     master.gui.plotInfo.percentile = [0 100];  end

         if ~isfield(master.gui.plotInfo,'xdim'),  master.gui.plotInfo.xdim = 1;    end

         if ~isfield(master.gui.plotInfo,'ydim'),  master.gui.plotInfo.ydim = 0;    end

         if ~isfield(master.gui.plotInfo,'addedxOffset'),  master.gui.plotInfo.addedxOffset = 0;    end
         
         if ~isfield(master.gui.plotInfo,'xoffset'),  master.gui.plotInfo.xoffset = 0;    end
         
         if ~isfield(master.gui.plotInfo,'yoffset'),  master.gui.plotInfo.yoffset = 0;    end

         if ~isfield(master.gui.plotInfo,'addedyOffset'),  master.gui.plotInfo.addedyOffset = 0;    end
         
         if ~isfield(master.gui.plotInfo,'incorporatex'),  master.gui.plotInfo.incorporatex = false;end
         
         if ~isfield(master.gui.plotInfo,'incorporatey'),  master.gui.plotInfo.incorporatey = false;end

         if ~isfield(master.gui.plotInfo,'axisPulse'),  master.gui.plotInfo.axisPulse = 1;    end

         if ~isfield(master.gui.plotInfo,'resetLims'),  master.gui.plotInfo.resetLims.x = false;    end

         if ~isfield(master.gui.plotInfo.resetLims,'x'),  master.gui.plotInfo.resetLims.x = false;    end

         if ~isfield(master.gui.plotInfo.resetLims,'y'),  master.gui.plotInfo.resetLims.y = false;    end

         if ~isfield(master.gui.plotInfo.resetLims,'z'),  master.gui.plotInfo.resetLims.z = false;    end

         if ~isfield(master,'RF'),                 master.RF.initialized = false;   end

         if ~isfield(master.RF,'frequency'),       master.RF.frequency = 2.87;      end

         if ~isfield(master.RF,'amplitude'),       master.RF.amplitude = 10;        end

         if ~isfield(master.RF,'switch'),          master.RF.switch = "on";         end

         if ~isfield(master.RF,'modulationOn'),    master.RF.modulationOn = false;  end

         if ~isfield(master,'PB'),                 master.PB.initialized = false;   end

         if ~isfield(master.PB,'initialized'),     master.PB.initialized = false;   end

         if ~isfield(master.PB,'useInterpreter'),  master.PB.useInterpreter = true; end
         
         if ~isfield(master.gui,'displayedChannels')
            if master.PB.initialized
               master.gui.displayedChannels = master.PB.nchannels;    
            else
               master.gui.displayedChannels = 6;
            end
         end
         
         master.gui.prevDisplayed = 0;

         if ~isfield(master,'stage'),              master.stage.initialized = false;end

         if ~isfield(master,'comp'),         master.comp = "NV";        end

         if ~isfield(master.stage,'precision'),    master.stage.precision = .1;     end

         if ~isfield(master.stage,'coarsepause'),  master.stage.coarsepause = .025;   end

         if ~isfield(master.stage,'finepause'),    master.stage.finepause = .01;    end

         if ~isfield(master.stage,'opttype'),      master.stage.opttype = "Gaussian Peak";      end

         if ~isfield(master.stage,'ignoreWait'),   master.stage.ignoreWait = false;   end
         
         if ~isfield(master.stage,'tolerance'),    master.stage.tolerance = .05;       end

         if ~isfield(master,'NDYOV'),              master.NDYOV.initialized = false;end

         if ~isfield(master.NDYOV,'waitforequilibrium'),    master.NDYOV.waitforequilibrium = true;   end

         if ~isfield(master.NDYOV,'offwhencleaned'),        master.NDYOV.offwhencleaned = true;       end

         if ~isfield(master,'NIDAQ'),              master.NIDAQ.initialized = false;end

         if ~isfield(master.NIDAQ,'counttype'),    master.NIDAQ.counttype(1:2) = "EdgeCount";   end

         if ~isfield(master.NIDAQ,'maxrate'),      master.NIDAQ.maxrate = 1.25e6;   end

         if ~isfield(master.NIDAQ,'useClock'),     master.NIDAQ.useClock = true;    end

         if ~isfield(master.NIDAQ,'cutoffVoltage'),master.NIDAQ.cutoffVoltage = .01;end

         if ~isfield(master.NIDAQ,'channel')
            master.NIDAQ.channel(1) = "ctr1";
            master.NIDAQ.channel(2) = "ctr3";
         end

         app.outputdisplay(1).UserData = ones(master.gui.nscans,1);

         aa = isfield(master.stage,'steps');
         bb = isfield(master.stage,'sequence');
         cc = isfield(master.stage,'radius');

         validOpt = false;
         if all([aa bb cc])
            dd = length(master.stage.steps) == length(master.stage.sequence);
            ee = length(master.stage.steps) == length(master.stage.radius);
            if dd && ee
               validOpt = true;
            end
         end

         if ~validOpt
            if any([aa bb cc])
               uimessage = ['Optimization steps, sequence, and radius not equal. ' ...
                  'Setting options to default'];
               uialert(app.UIFigure,uimessage,'Default settings','Icon','warning')
            end

            master.stage.sequence = ["x" "y" "z"];
            master.stage.radius = [.4 .4 .6];
            master.stage.steps = [11 11 11];

         end

         %This seems to be an issue with the data acquisition toolbox? it
         %corresponds to an error reading off data from a daq object
         warning('off','MATLAB:subscripting:noSubscriptsSpecified')

         appUpdate(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
         global master
         global NIDAQ

         %Note: to ensure the experiment runs as quickly as possible, the
         %time duration of all the code that would run in between
         %measurements was recorded. It was found that this time was
         %typically on the order of microseconds. This obviously doesn't
         %include the optimization sequence as that takes a relatively long
         %time
         noerrors = true;
         if ~master.PB.initialized
            loadinstruments = uiconfirm(app.UIFigure,'Pulse blaster not currently loaded. Would you like to load it or cancel measurement?',...
               'Instruments unloaded','Icon','warning','Options',{'Load PB','Cancel'});
            switch loadinstruments
               case 'Load PB'
                  PBLoadButtonPushed(app)
                  appUpdate(app)
               case 'Cancel'
                  noerrors = false;
            end
         end
         
         if ~master.NIDAQ.initialized
            loadinstruments = uiconfirm(app.UIFigure,'DAQ not currently loaded. Would you like to load it or cancel measurement?',...
               'Instruments unloaded','Icon','warning','Options',{'Load DAQ','Cancel'});
            switch loadinstruments
               case 'Load all'
                  DAQLoadButtonPushed(app)
                  appUpdate(app)
               case 'Cancel'
                  noerrors = false;
            end
         end
         

         if noerrors && master.gui.interrupted
            loadinstruments = uiconfirm(app.UIFigure,'This will resume previous run adding on to old data. Proceed?',...
               'Not Reset','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(loadinstruments,'Cancel')
               noerrors = false;
            end
         end

         if noerrors && ~strcmp(app.Warnings.Value{1},'No warnings present')
            disregard = uiconfirm(app.UIFigure,'Continue despite warnings?','Warnings present','Icon','warning');
            if strcmp(disregard,'Cancel')
               noerrors = false;
            end
         end

         if noerrors
            if isfield(master.PB,'sequence')
               if isempty(master.PB.sequence)
                  errordlg('No pulse sequence selected','No Pulse Sequence')
                  noerrors = false;
               end
            else
               errordlg('No pulse sequence selected','No Pulse Sequence')
               noerrors = false;
            end
         end

         if noerrors && master.gui.nscans == 0
            errordlg('No scan present. Go to scanning tab to create desired experiment','No Scan')
            noerrors = false;
         end

         if noerrors && master.gui.autosave && isempty(master.gui.autosavename)
            errordlg('Autosave on but no autosave name is input. Go to Notifications tab to fix','No Autosave Name')
            noerrors = false;
         end

         if noerrors && (master.gui.completionemail || master.gui.itemail) && isempty(app.EmailAddress.Value)
            errordlg('Email notifications are on but no email address is input. Go to Notifications tab to fix','No Email Address')
            noerrors = false;
         end

         libDur = dir(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library'));
         if noerrors && isempty(libDur) && (master.gui.autosave || master.gui.completionemail || master.gui.itemail)
            errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
            noerrors = false;
         end

         scanList = squeeze(struct2cell(master.gui.scan));
         nameList = fieldnames(master.gui.scan);
         scanList = scanList(strcmp(nameList,'type'),:);
         [X,Y] = ndgrid(1:master.gui.nscans);
         Z = tril(true(master.gui.nscans),-1);
         replicateScans = any(arrayfun(@(x,y)isequal(scanList{x},scanList{y}),X(Z),Y(Z)));

         if noerrors && replicateScans
            proceedExperiment = uiconfirm(app.UIFigure,'Multiple scans with identical types detected. Proceed?',...
               'Replicate Scans','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(proceedExperiment,'Cancel')
               noerrors = false;
            end
         end

         stageScanCheck = @(x) any(strcmp(x,{'Stage X','Stage Y','Stage Z'}));
         stagesPresent = any(cell2mat(cellfun(stageScanCheck,scanList,'UniformOutput',false)));
         if noerrors && stagesPresent && master.gui.optswitch
            proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while attempting stage scan.' ...
               'This is not recommended as the optimization process can affect the scan. Proceed anyways?'],...
               'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(proceedExperiment,'Cancel')
               noerrors = false;
            end
         end
         if stagesPresent && ~master.stage.initialized
            loadinstruments = uiconfirm(app.UIFigure,'Stage not currently loaded. Would you like to load iy or cancel measurement?',...
               'Stage unloaded','Icon','warning','Options',{'Load stage','Cancel'});
            switch loadinstruments
               case 'Load stage'
                  StageLoadButtonPushed(app)
                  appUpdate(app)
               case 'Cancel'
                  noerrors = false;
            end
         end
         
         rfScanCheck = @(x) any(strcmp(x,'RF frequency'));
         rfPresent = any(cell2mat(cellfun(rfScanCheck,scanList,'UniformOutput',false)));
         if rfPresent && ~master.RF.initialized
            loadinstruments = uiconfirm(app.UIFigure,'RF not currently loaded. Would you like to load iy or cancel measurement?',...
               'RF unloaded','Icon','warning','Options',{'Load RF','Cancel'});
            switch loadinstruments
               case 'Load RF'
                  RFLoadPushed(app)
                  appUpdate(app)
               case 'Cancel'
                  noerrors = false;
            end
         end         

         if noerrors && master.stage.ignoreWait && master.gui.optswitch
            proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while ignore wait is also on.' ...
               'This is not recommended as it skews the optimization process. Proceed anyways?'],...
               'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(proceedExperiment,'Cancel')
               noerrors = false;
            end
         end

         if noerrors && master.gui.isrunning
            errordlg('Cannot run experiment while optimization is running','Cannot Run Experiment')
            noerrors = false;
         end

         if noerrors && master.gui.altStageScan
            global fineControl %#ok<TLEV>

            stageLocs = cellfun(@(x) strcmp(x,'Stage X'),scanList);
            if any(stageLocs)
               stageLocs = find(stageLocs);
               if master.gui.scan(stageLocs).starts < master.stage.min(4) || master.gui.scan(stageLocs).ends > master.stage.max(4)
                  errordlg('X scan out of fine bounds while alternate scan mode is on','Out of Bounds')
                  noerrors = false;
               end
            end

            stageLocs = cellfun(@(x) strcmp(x,'Stage Y'),scanList);
            if any(stageLocs)
               stageLocs = find(stageLocs);
               if master.gui.scan(stageLocs).starts < master.stage.min(5) || master.gui.scan(stageLocs).ends > master.stage.max(5)
                  errordlg('Y scan out of fine bounds while alternate scan mode is on','Out of Bounds')
                  noerrors = false;
               end
            end

            stageLocs = cellfun(@(x) strcmp(x,'Stage Z'),scanList);
            if any(stageLocs)
               stageLocs = find(stageLocs);
               if master.gui.scan(stageLocs).starts < master.stage.min(6) || master.gui.scan(stageLocs).ends > master.stage.max(6)
                  errordlg('Z scan out of fine bounds while alternate scan mode is on','Out of Bounds')
                  noerrors = false;
               end
            end

         end


         if noerrors
             
            app.StatusLamp.Color = [0,1,0];
            master.gui.isrunning = true;
            master.gui.plotInfo.wasLoaded = false;
            sigreferror = clock;

            master.gui.loopmatrix = zeros(1,length(master.gui.scan));
            for ii = 1:length(master.gui.scan)
               master.gui.loopmatrix(ii) = master.gui.scan(ii).nsteps;
            end

            if app.IterationsType.SelectedObject == app.TimeButton
               mxtime = clock + (app.IterationsNum.Value*60);
            end
            if ~master.gui.interrupted
               master.gui.itnum = 1;
               anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
               if anyPulses
                  master.gui.signalTimeVector = ones(prod(master.gui.loopmatrix),1);
                  master.gui.referenceTimeVector = ones(prod(master.gui.loopmatrix),1);
               else
                  master.gui.signalTimeVector = 1;
                  master.gui.referenceTimeVector = 1;
               end
               master.gui.plotInfo.xdefault = true;
               master.gui.plotInfo.ydefault = true;
            end
            starttime = clock;

            master.gui.continue = true;

            PBFinalize%Finalizes current pulse sequence

            if master.gui.optswitch
                if NIDAQ.Running
                    turnOnDaq = true;
                    stop(NIDAQ)
                else
                    turnOnDaq = false;
                end
               stageOptimization
               if turnOnDaq
                    start(NIDAQ,'continuous')
               end
            end

            prev_timestamp = clock;
            isdone = false;
            while ~isdone

               if master.gui.randomScan
                  master.gui.permutation = randperm(prod(master.gui.loopmatrix));
                  dimlist = cell(size(master.gui.loopmatrix));
               end

               scanRunning = true;
               if ~master.gui.interrupted %If it is interrupted, pick up where it left off
                  master.gui.step = 0;
                  master.gui.odomatrix = ones(1,master.gui.nscans);
                  master.gui.referenceVector = zeros(prod(master.gui.loopmatrix),1);
                  master.gui.signalVector = zeros(prod(master.gui.loopmatrix),1);
                  scanstring = strings(1,length(master.gui.scan));
                  for ii = 1:length(master.gui.scan)
                     scanstring(ii) = convertCharsToStrings(master.gui.scan(ii).type);
                  end

                  %If not a random scan, set to initial values
                  if ~master.gui.randomScan

                     if any(scanstring == "Stage X")
                        xscanloc = find(scanstring == "Stage X");
                        if master.gui.altStageScan
                           fineControl.MOV('1',master.gui.scan(xscanloc).starts)
                           pause(master.stage.finepause)
                           n = 0;
                           while true
                              if master.stage.ignoreWait
                                 break
                              end
                              if ~fineControl.IsMoving('1')
                                 break
                              end
                              pause(.001)
                              n = n + 1;
                              if n > 1000 %1 second
                                 break
                              end
                           end

                           currPos = fineControl.qPOS ('1');
                           master.stage.loc(4) = currPos;
                           master.stage.locrecord(end+1,:) = master.stage.loc;
                        else
                           stageAbsoluteMove('x',master.gui.scan(xscanloc).starts)
                        end

                     end
                     if any(scanstring == "Stage Y")
                        yscanloc = find(scanstring == "Stage Y");
                        if master.gui.altStageScan
                           fineControl.MOV('2',master.gui.scan(yscanloc).starts)
                           pause(master.stage.finepause)
                           n = 0;
                           while true
                              if master.stage.ignoreWait
                                 break
                              end
                              if ~fineControl.IsMoving('2')
                                 break
                              end
                              pause(.001)
                              n = n + 1;
                              if n > 1000 %1 second
                                 break
                              end
                           end

                           currPos = fineControl.qPOS ('2');
                           master.stage.loc(5) = currPos;
                           master.stage.locrecord(end+1,:) = master.stage.loc;
                        else
                           stageAbsoluteMove('y',master.gui.scan(yscanloc).starts)
                        end
                     end
                     if any(scanstring == "Stage Z")
                        zscanloc = find(scanstring == "Stage Z");
                        if master.gui.altStageScan
                           fineControl.MOV('3',master.gui.scan(zscanloc).starts)
                           pause(master.stage.finepause)
                           n = 0;
                           while true
                              if master.stage.ignoreWait
                                 break
                              end
                              if ~fineControl.IsMoving('3')
                                 break
                              end
                              pause(.001)
                              n = n + 1;
                              if n > 1000 %1 second
                                 break
                              end
                           end

                           currPos = fineControl.qPOS ('3');
                           master.stage.loc(6) = currPos;
                           master.stage.locrecord(end+1,:) = master.stage.loc;
                        else
                           stageAbsoluteMove('z',master.gui.scan(zscanloc).starts)
                        end
                     end
                     if any(scanstring == "RF frequency")
                        rfscanloc = find(scanstring == "RF frequency");
                        master.RF.frequency = master.gui.scan(rfscanloc).starts;
                        RFFrequency
                        pause(1e-6)%allows RF generator to stabilize
                     end
                     if any(scanstring == "Pulse duration")
                        pulsescanloc = find(scanstring == "Pulse duration");
                        for ii = 1:length(pulsescanloc)
                           currscan = pulsescanloc(ii);
                           for jj = 1:length(master.gui.scan(currscan).starts)
                              pulseaddress = master.gui.scan(currscan).addresses(jj);
                              master.PB.sequence(pulseaddress).duration = master.gui.scan(currscan).starts(jj);
                           end
                        end
                        PBFinalize
                     end
                     if any(scanstring == "Laser power")
                        laserscanloc = find(scanstring == "Laser power");
                        master.NDYOV.setpower = master.gui.scan(laserscanloc).starts;
                        NDYOVSetPower
                        pause(1)%allows laser to stabilize
                     end
                  end
               else
                  master.gui.interrupted = false;
               end


               progressUpdate(app)
               prevupdate = clock;


               %Main loop. every iteration, the next data point is taken and the odometer
               %is incremented by 1
               while scanRunning
                  pause(.001)%This is where matlab checks for queued commands

                  timestamp = clock;
                  if etime(timestamp,prev_timestamp) > 60*master.gui.opttime && master.gui.optswitch
                      if NIDAQ.Running
                          turnOnDaq = true;
                          stop(NIDAQ)
                      else
                          turnOnDaq = false;
                      end
                      stageOptimization
                      if turnOnDaq
                          start(NIDAQ,'continuous')
                      end
                     prev_timestamp = clock;
                  end

                  if master.gui.continue
                     master.gui.step = master.gui.step+1;

                     if master.gui.randomScan
                        if (master.gui.randomScan && master.gui.step == prod(master.gui.loopmatrix)+1)
                           scanRunning = false;
                           master.gui.step = master.gui.step-1;
                        else
                           [dimlist{:}] = ind2sub(master.gui.loopmatrix,master.gui.permutation(master.gui.step));

                           for ii = 1:master.gui.nscans
                              currscan = master.gui.scan(ii);
                              switch currscan.type

                                 case 'Stage X'
                                    if master.gui.altStageScan
                                       newLoc = currscan.starts + ((dimlist{ii} - 1)*currscan.stepsize);
                                       fineControl.MOV('1',newLoc)
                                       pause(master.stage.finepause)
                                       n = 0;
                                       while true
                                          if master.stage.ignoreWait
                                             break
                                          end
                                          if ~fineControl.IsMoving('1')
                                             break
                                          end
                                          pause(.001)
                                          n = n + 1;
                                          if n > 1000 %1 second
                                             break
                                          end
                                       end

                                       currPos = fineControl.qPOS ('1');
                                       master.stage.loc(4) = currPos;
                                       master.stage.locrecord(end+1,:) = master.stage.loc;
                                    else
                                       stageAbsoluteMove('x',currscan.starts + ((dimlist{ii} - 1)*currscan.stepsize))
                                    end

                                 case 'Stage Y'
                                    if master.gui.altStageScan
                                       newLoc = currscan.starts + ((dimlist{ii} - 1)*currscan.stepsize);
                                       fineControl.MOV('2',newLoc)
                                       pause(master.stage.finepause)
                                       n = 0;
                                       while true
                                          if master.stage.ignoreWait
                                             break
                                          end
                                          if ~fineControl.IsMoving('2')
                                             break
                                          end
                                          pause(.001)
                                          n = n + 1;
                                          if n > 1000 %1 second
                                             break
                                          end
                                       end

                                       currPos = fineControl.qPOS ('2');
                                       master.stage.loc(5) = currPos;
                                       master.stage.locrecord(end+1,:) = master.stage.loc;
                                    else
                                       stageAbsoluteMove('x',currscan.starts + ((dimlist{ii} - 1)*currscan.stepsize))
                                    end

                                 case 'Stage Z'
                                    if master.gui.altStageScan
                                       newLoc = currscan.starts + ((dimlist{ii} - 1)*currscan.stepsize);
                                       fineControl.MOV('3',newLoc)
                                       pause(master.stage.finepause)
                                       n = 0;
                                       while true
                                          if master.stage.ignoreWait
                                             break
                                          end
                                          if ~fineControl.IsMoving('3')
                                             break
                                          end
                                          pause(.001)
                                          n = n + 1;
                                          if n > 1000 %1 second
                                             break
                                          end
                                       end

                                       currPos = fineControl.qPOS ('3');
                                       master.stage.loc(6) = currPos;
                                       master.stage.locrecord(end+1,:) = master.stage.loc;
                                    else
                                       stageAbsoluteMove('x',currscan.starts + ((dimlist{ii} - 1)*currscan.stepsize))
                                    end

                                 case 'RF frequency'
                                    master.RF.frequency = currscan.starts + ((dimlist{ii}-1)*currscan.stepsize);
                                    RFFrequency

                                 case 'Pulse duration'
                                    for kk = 1:length(currscan.starts)
                                       newdur = currscan.starts(kk) + ((dimlist{ii}-1)*currscan.stepsize(kk));
                                       master.PB.sequence(currscan.addresses(kk)).duration = newdur;
                                    end
                                    PBFinalize

                                 case 'Laser power'
                                    master.NDYOV.setpower = currscan.starts + ((dimlist{ii}-1)*currscan.stepsize);
                                    NDYOVSetPower
                                    pause(1)

                              end
                           end

                           %Resets the counters on the DAQ, runs the pulse
                           %sequence, then reads out the result
                           pause(0.01)
                           [sig,ref] = PBRun;
                           if strcmp(master.comp,'NV2')
                              if isfield(master,'debugging') && isfield(master.debugging,'isXYN') && master.debugging.isXYN
                                 c = (ref - sig)/ref;
                                 if c > .05
                                     master.debugging.sig = sig;
                                     master.debugging.ref = ref;
                                    
                                    fprintf('Contrast of %g found. Stopping experiment\n',c)
                                    master.gui.continue = false;
                                    master.gui.interrupted = true;
                                    master.gui.isrunning = false;
                                    app.StatusLamp.Color = [1.00,0.07,0.65];
                                    datetime
                                    appUpdate(app)
                                 end
                              end
                           end

                           if isnan(sig) || isnan(ref)
                               errortime = clock;
                               if etime(errortime,sigreferror) > 30 || master.gui.step == 1
                                   sigreferror = clock;
                                   errordlg('Data obtained is not a number. Most likely an error in collection or interpretation.','Error in output')
                               end                               
                           end

                           master.gui.signalVector(master.gui.permutation(master.gui.step)) = sig;
                           master.gui.referenceVector(master.gui.permutation(master.gui.step)) = ref;
                           scanList = squeeze(struct2cell(master.gui.scan));
                           nameList = fieldnames(master.gui.scan);
                           scanList = scanList(strcmp(nameList,'type'),:);
                           anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
                           if anyPulses
                              master.gui.signalTimeVector(master.gui.permutation(master.gui.step)) = master.PB.signalDuration;
                              master.gui.referenceTimeVector(master.gui.permutation(master.gui.step)) = master.PB.referenceDuration;
                              if master.gui.nscans ~= 1
                                 trueSigTime = reshape(master.gui.signalTimeVector,flip(master.gui.loopmatrix));
                                 trueRefTime = reshape(master.gui.referenceVector,flip(master.gui.loopmatrix));
                                 trueSigTime = permute(trueSigTime,flip(1:master.gui.nscans));
                                 trueRefTime = permute(trueRefTime,flip(1:master.gui.nscans));
                              else
                                 trueSigTime = master.gui.signalTimeVector;
                                 trueRefTime = master.gui.referenceTimeVector;
                              end
                              master.gui.signalTime = trueSigTime;
                              master.gui.referenceTime = trueRefTime;
                           else
                              master.gui.signalTimeVector = master.PB.signalDuration;
                              master.gui.referenceTimeVector = master.PB.referenceDuration;
                              master.gui.signalTime = master.PB.signalDuration;
                              master.gui.referenceTime = master.PB.referenceDuration;
                           end

                        end

                        %Not a random scan
                     else
                        noturnover = false;
                        nestednum = master.gui.nscans; %Scan begins at most nested loop

                        %Increments lowest digit first (above). If lowest digit hits the limit,
                        %turns lowest digit to 1 and increments next digit. Does this until
                        %upper limit hits maximum then stops the entire loop
                        while ~noturnover
                           if master.gui.step == master.gui.loopmatrix(end)+1 && nestednum == master.gui.nscans
                              mxodo = true;
                           elseif master.gui.step == prod(master.gui.loopmatrix)
                              scanRunning = false;
                              noturnover = true;
                              mxodo = false;
                           else
                              mxodo = master.gui.odomatrix(nestednum) ==  master.gui.loopmatrix(nestednum)+1;
                           end

                           if mxodo  %Is digit max value
                              master.gui.odomatrix(nestednum) = 1;
                              if nestednum ~= 1
                                 master.gui.odomatrix(nestednum-1) = master.gui.odomatrix(nestednum-1)+1;
                              end
                              nestednum = nestednum - 1;
                           else
                              noturnover = true;
                           end
                        end
                        %Moving scan values and data collection
                        if nestednum ~= 0

                           %Change the values of all scans that are at the
                           %current level of nesting or below

                           for ii = 0:(master.gui.nscans-nestednum)
                              currscan = master.gui.scan(end-ii);
                              odonum = master.gui.odomatrix(end-ii);

                              switch currscan.type

                                 case 'Stage X'
                                    if master.gui.altStageScan
                                       newLoc = master.gui.scan(xscanloc).starts + ((odonum - 1)*master.gui.scan(xscanloc).stepsize);
                                       fineControl.MOV('1',newLoc)
                                       pause(master.stage.finepause)
                                       n = 0;
                                       while true
                                          if master.stage.ignoreWait
                                             break
                                          end
                                          if ~fineControl.IsMoving('1')
                                             break
                                          end
                                          pause(.001)
                                          n = n + 1;
                                          if n > 1000 %1 second
                                             break
                                          end
                                       end

                                       currPos = fineControl.qPOS ('1');
                                       master.stage.loc(4) = currPos;
                                       master.stage.locrecord(end+1,:) = master.stage.loc;
                                    else
                                       if odonum == 1
                                          master.stage.doReset = false;
                                          stageAbsoluteMove('x',currscan.starts)
                                          master.stage.doReset = true;
                                          if currscan.ends > currscan.starts
                                             stageFineReset('x','min')
                                          else
                                             stageFineReset('x','max')
                                          end
                                       else
                                          stageRelativeMove('x',currscan.stepsize)
                                       end
                                    end

                                 case 'Stage Y'
                                    if master.gui.altStageScan
                                       newLoc = master.gui.scan(yscanloc).starts + ((odonum - 1)*master.gui.scan(yscanloc).stepsize);
                                       fineControl.MOV('2',newLoc)
                                       pause(master.stage.finepause)
                                       n = 0;
                                       while true
                                          if master.stage.ignoreWait
                                             break
                                          end
                                          if ~fineControl.IsMoving('2')
                                             break
                                          end
                                          pause(.001)
                                          n = n + 1;
                                          if n > 1000 %1 second
                                             break
                                          end
                                       end

                                       currPos = fineControl.qPOS ('2');
                                       master.stage.loc(5) = currPos;
                                       master.stage.locrecord(end+1,:) = master.stage.loc;
                                    else
                                       if odonum == 1
                                          master.stage.doReset = false;
                                          stageAbsoluteMove('y',currscan.starts)
                                          master.stage.doReset = true;
                                          if currscan.ends > currscan.starts
                                             stageFineReset('y','min')
                                          else
                                             stageFineReset('y','max')
                                          end
                                       else
                                          stageRelativeMove('y',currscan.stepsize)
                                       end
                                    end

                                 case 'Stage Z'
                                    if master.gui.altStageScan
                                       newLoc = master.gui.scan(zscanloc).starts + ((odonum - 1)*master.gui.scan(zscanloc).stepsize);
                                       fineControl.MOV('3',newLoc)
                                       pause(master.stage.finepause)
                                       n = 0;
                                       while true
                                          if master.stage.ignoreWait
                                             break
                                          end
                                          if ~fineControl.IsMoving('3')
                                             break
                                          end
                                          pause(.001)
                                          n = n + 1;
                                          if n > 1000 %1 second
                                             break
                                          end
                                       end

                                       currPos = fineControl.qPOS ('3');
                                       master.stage.loc(6) = currPos;
                                       master.stage.locrecord(end+1,:) = master.stage.loc;
                                    else
                                       if odonum == 1
                                          master.stage.doReset = false;
                                          stageAbsoluteMove('z',currscan.starts)
                                          master.stage.doReset = true;
                                          if currscan.ends > currscan.starts
                                             stageFineReset('z','min')
                                          else
                                             stageFineReset('z','max')
                                          end
                                       else
                                          stageRelativeMove('z',currscan.stepsize)
                                       end
                                    end

                                 case 'RF frequency'
                                    master.RF.frequency = currscan.starts + ((odonum-1)*currscan.stepsize);
                                    RFFrequency

                                 case 'Pulse duration'
                                    for kk = 1:length(currscan.starts)
                                       newdur = currscan.starts(kk) + ((odonum-1)*currscan.stepsize(kk));
                                       master.PB.sequence(currscan.addresses(kk)).duration = newdur;
                                    end
                                    PBFinalize
                                    
                                 case 'Laser power'
                                    master.NDYOV.setpower = currscan.starts + ((odonum-1)*currscan.stepsize);
                                    NDYOVSetPower
                                    pause(1)

                              end

                           end

%                            %%
%                            %includes voltage spike workaround - MPB 7/28/2023  
% 
%                            %Resets the counters on the DAQ, runs the pulse
%                            %sequence, then reads out the result
%                            isnotspike = true;
%                            spike_threshold = 0.1; %fraction voltage difference from last data point tolerated
%                            while isnotspike
%                                 pause(0.01)
%                                 [sig,ref] = PBRun;
% 
%                                
%                                if exist('sig_last','var')==1
%                                     sig_diff = abs((sig-sig_last)/sig_last);
%                                     ref_diff = abs((ref-ref_last)/ref_last);
% 
%                                     if (sig_diff<spike_threshold)||(ref_diff<spike_threshold)
%                                         isnotspike = false;
%                                     else
%                                         disp(['There was a spike!' string(datetime)])
% 
%                                     end
% 
%                                end
%                                sig_last = sig;
%                                ref_last = ref;
%                                
%                            end

                           pause(0.01)
                           [sig,ref] = PBRun;

                           %%


                           if isnan(sig) || isnan(ref)
                               errortime = clock;
                               if etime(errortime,sigreferror) > 30 || master.gui.step == 1
                                   sigreferror = clock;
                                   errordlg('Data obtained is not a number. Most likely an error in collection or interpretation.','Error in output')
                               end                               
                           end

                           master.gui.signalVector(master.gui.step) = sig;
                           master.gui.referenceVector(master.gui.step) = ref;
                           scanList = squeeze(struct2cell(master.gui.scan));
                           nameList = fieldnames(master.gui.scan);
                           scanList = scanList(strcmp(nameList,'type'),:);
                           anyPulses = any(cellfun(@(x) strcmp(x,'Pulse duration'),scanList));
                           if anyPulses
                              master.gui.signalTimeVector(master.gui.step) = master.PB.signalDuration;
                              master.gui.referenceTimeVector(master.gui.step) = master.PB.referenceDuration;
                              if master.gui.nscans ~= 1
                                 trueSigTime = reshape(master.gui.signalTimeVector,flip(master.gui.loopmatrix));
                                 trueRefTime = reshape(master.gui.referenceVector,flip(master.gui.loopmatrix));
                                 trueSigTime = permute(trueSigTime,flip(1:master.gui.nscans));
                                 trueRefTime = permute(trueRefTime,flip(1:master.gui.nscans));
                              else
                                 trueSigTime = master.gui.signalTimeVector;
                                 trueRefTime = master.gui.referenceTimeVector;
                              end
                              master.gui.signalTime = trueSigTime;
                              master.gui.referenceTime = trueRefTime;
                           else
                              master.gui.signalTimeVector = master.PB.signalDuration;
                              master.gui.referenceTimeVector = master.PB.referenceDuration;
                              master.gui.signalTime = master.PB.signalDuration;
                              master.gui.referenceTime = master.PB.referenceDuration;
                           end

                        end
                     end


                     currtime = clock;
                     if etime(currtime,prevupdate) > app.UpdatePeriod.Value
                        progressUpdate(app)
                        prevupdate = clock;
                     end

                     if scanRunning
                        master.gui.odomatrix(end) = master.gui.odomatrix(end)+1;
                     end

                  else %If it is told to abort
                     scanRunning = false;
                     isdone = true;
                  end

               end

               %Only save and plot data if sequence was not aborted
               if master.gui.continue
                  %Reshapes data matrix from vector to the shape that would be obtained
                  %naturally from nested for loops
                  if master.gui.nscans ~= 1
                     trueSig = reshape(master.gui.signalVector,flip(master.gui.loopmatrix));
                     trueRef = reshape(master.gui.referenceVector,flip(master.gui.loopmatrix));
                     trueSig = permute(trueSig,flip(1:master.gui.nscans));
                     trueRef = permute(trueRef,flip(1:master.gui.nscans));
                  else
                     trueSig = master.gui.signalVector;
                     trueRef = master.gui.referenceVector;
                  end

                  %Reset vector data
                  master.gui.referenceVector = zeros(prod(master.gui.loopmatrix),1);
                  master.gui.signalVector = zeros(prod(master.gui.loopmatrix),1);


                  if master.gui.itnum == 1
                     master.gui.totalSignal = trueSig;
                     master.gui.totalReference = trueRef;

                  else
                     master.gui.totalSignal = master.gui.totalSignal + trueSig;
                     master.gui.totalReference = master.gui.totalReference + trueRef;
                  end

                  master.signal = master.gui.totalSignal ./ master.gui.itnum;
                  master.reference = master.gui.totalReference ./ master.gui.itnum;


                  numdone = strcmp(master.gui.itertype,"Number") && master.gui.itnum == master.gui.maxits;
                  timedone = strcmp(master.gui.itertype,"Time") && etime(clock,starttime) > master.gui.maxits;
                  if numdone || timedone
                     master.gui.expcomplete = true;
                  end

                  master.gui.itnum = master.gui.itnum + 1;

                  %                   master.gui.makeplot.output = true;
                  progressUpdate(app)
               end


               if master.gui.autosave
                  if ~isempty(master.gui.autosavename)
                     if mod(master.gui.itnum,app.AutoSaveIterations.Value) == 0
                        clear outputs
                        outputs.signal = master.signal;
                        outputs.reference = master.reference;
                        outputs.maxprct = master.gui.highpercentile;
                        outputs.minprct = master.gui.lowpercentile;
                        outputs.signalTime = master.gui.signalTime;
                        outputs.referenceTime = master.gui.referenceTime;
                        outputs.y = app.YDimension.Value;
                        outputs.x = app.XDimension.Value;
                        foldername = strcat(master.gui.boxAddress,...
                           '\Backlund lab shared\data\ExperimentEditor Data\',...
                           master.gui.autosavename);
                        if master.gui.itnum == 1
                           mkdir(foldername)
                        end
                        itname = strcat('\',sprintf('Iteration %d',master.gui.itnum));
                        save(strcat(foldername,itname),"outputs")
                     end
                  end
               end

               if master.gui.itemail
                  if mod(master.gui.itnum,master.gui.emailIts) == 0
                     msgsubject = sprintf('Experiment Iteration %d Complete',master.gui.itnum);
                     if ~master.gui.interrupted
                        currtime = clock;
                        runtime = etime(currtime,starttime);
                        rtsec = floor(mod(runtime,60));
                        rtmin = floor(runtime/60);
                        timetext = sprintf('. Current run time %d minutes %d seconds.',rtmin,rtsec);
                     else
                        timetext = '. Experiment interrupted so no run time calculated.';
                     end
                     if master.gui.nscans ~= 0
                        for ii = 1:length(master.gui.scan)
                           if ii == 1
                              scantext = sprintf('%s',master.gui.scan(ii).type);
                           else
                              %sprintf used instead of strcat to preserve spaces
                              scantext = sprintf('%s, %s',scantext,master.gui.scan(ii).type);
                           end
                        end
                        msgtext = strcat('Scan type(s): ',scantext,timetext);
                     else
                        msgtext = strcat('Single value experiment',scantext,timetext);
                     end

                     SendMessage({master.gui.emailaddress},msgsubject,msgtext);
                  end
               end

               if app.IterationsType.SelectedObject == app.NumberButton
                  if master.gui.expcomplete
                     isdone = true;
                  end
               elseif app.IterationsType.SelectedObject == app.TimeButton
                  currtime = clock;
                  if currtime >= mxtime
                     isdone = true;
                  end
               end

               %                profile viewer

            end

            if master.gui.completionemail
               msgsubject = 'Experiment Scan Complete';
               for ii = 1:length(master.gui.scan)
                  if ii == 1
                     scantext = sprintf('%s',master.gui.scan(ii).type);
                  else
                     %sprintf used instead of strcat to preserve spaces
                     scantext = sprintf('%s, %s',scantext,master.gui.scan(ii).type);
                  end
               end
               if ~master.gui.interrupted
                  currtime = clock;
                  runtime = etime(currtime,starttime);
                  rtsec = floor(mod(runtime,60));
                  rtmin = floor(runtime/60);
                  timetext = sprintf('. Run time %d minutes %d seconds.',rtmin, rtsec);
               else
                  timetext = '. Experiment interrupted so no run time calculated.';
               end
               msgtext = strcat('Scan type(s): ',scantext,timetext);
               SendMessage({master.gui.emailaddress},msgsubject,msgtext);
            end

            if ~master.gui.interrupted
               app.StatusLamp.Color = [0,1,1];
            end

            if NIDAQ.Running
             stop(NIDAQ)
            end

         end


        end

        % Button pushed function: PulseSequenceEditorButton
        function PulseSequenceEditorButtonPushed(app, event)
         run('PulseSequenceEditor.mlapp')
        end

        % Button pushed function: RFUnload
        function RFUnloadButtonPushed(app, event)
         CleanUp('RF')
         connectionUpdate(app)
        end

        % Button pushed function: DAQUnload
        function DAQUnloadButtonPushed(app, event)
         CleanUp('NIDAQ')
         connectionUpdate(app)
        end

        % Button pushed function: PBUnload
        function PBUnloadButtonPushed(app, event)
         CleanUp('PB')
         connectionUpdate(app)
        end

        % Button pushed function: StageUnload
        function StageUnloadButtonPushed(app, event)
         CleanUp('stage')
         connectionUpdate(app)
        end

        % Button pushed function: LaserUnload
        function LaserUnloadButtonPushed(app, event)
         CleanUp('NDYOV')
         connectionUpdate(app)
        end

        % Button pushed function: UnloadAll
        function UnloadAllButtonPushed(app, event)
         CleanUp('all')
         connectionUpdate(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {579, 579};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {476, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RFLoad
        function RFLoadPushed(app, event)
         global master
         RFInitialization
         master.RF.switch = "on";
         RFSwitch
         connectionUpdate(app)
         settingsUpdate(app)
        end

        % Button pushed function: StageLoad
        function StageLoadButtonPushed(app, event)
         stageInitialization
         connectionUpdate(app)
         stageUpdate(app)
        end

        % Button pushed function: PBLoad
        function PBLoadButtonPushed(app, event)
         PBInitialization
         connectionUpdate(app)
        end

        % Button pushed function: DAQLoad
        function DAQLoadButtonPushed(app, event)
         NIDAQInitialization
         connectionUpdate(app)
        end

        % Button pushed function: LaserLoad
        function LaserLoadButtonPushed(app, event)
         NDYOVInitialization
         connectionUpdate(app)
         settingsUpdate(app)
        end

        % Button pushed function: LoadAll
        function LoadAllButtonPushed(app, event)

         RFInitialization
         stageInitialization
         PBInitialization
         NIDAQInitialization
         NDYOVInitialization

         appUpdate(app)

        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            app.XPosition.Value = app.XSlider.Value;
            stageAbsoluteMove('x',app.XPosition.Value)
         end
         stageUpdate(app)
        end

        % Value changed function: XPosition
        function XPositionValueChanged(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            app.XSlider.Value = app.XPosition.Value;
            stageAbsoluteMove('x',app.XPosition.Value)
         end
         stageUpdate(app)
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            app.YPosition.Value = app.YSlider.Value;
            stageAbsoluteMove('x',app.YPosition.Value)
         end
         stageUpdate(app)
        end

        % Value changed function: YPosition
        function YPositionValueChanged(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            app.YSlider.Value = app.YPosition.Value;
            stageAbsoluteMove('y',app.YPosition.Value)
         end
         stageUpdate(app)
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            app.ZPosition.Value = app.ZSlider.Value;
            stageAbsoluteMove('z',app.ZPosition.Value)
         end
         stageUpdate(app)
        end

        % Value changed function: ZPosition
        function ZPositionValueChanged(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            app.ZSlider.Value = app.ZPosition.Value;
            stageAbsoluteMove('z',app.ZPosition.Value)
         end
         stageUpdate(app)
        end

        % Button pushed function: XFineReset
        function XFineResetButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageFineReset('x',master.gui.resetloc)
         end
         stageUpdate(app)
        end

        % Button pushed function: YFineReset
        function YFineResetButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageFineReset('y',master.gui.resetloc)
         end
         stageUpdate(app)
        end

        % Button pushed function: ZFineReset
        function ZFineResetButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageFineReset('z',master.gui.resetloc)
         end
         stageUpdate(app)
        end

        % Button pushed function: XDown
        function XDownButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('x',-master.gui.xinc)
         end
         stageUpdate(app)
        end

        % Button pushed function: XUp
        function XUpButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('x',master.gui.xinc)
         end
         stageUpdate(app)
        end

        % Button pushed function: YDown
        function YDownButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('y',-master.gui.yinc)
         end
         stageUpdate(app)
        end

        % Button pushed function: YUp
        function YUpButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('y',master.gui.yinc)
         end
         stageUpdate(app)
        end

        % Button pushed function: ZDown
        function ZDownButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('z',-master.gui.zinc)
         end
         stageUpdate(app)
        end

        % Button pushed function: ZUp
        function ZUpButtonPushed(app, event)
         global master
         if ~master.stage.initialized
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('z',master.gui.zinc)
         end
         stageUpdate(app)
        end

        % Value changed function: StagePrecision
        function StagePrecisionValueChanged(app, event)
         global master
         master.stage.tolerance = app.StagePrecision.Value;
        end

        % Value changed function: RFFreq
        function RFFreqValueChanged(app, event)
         global master
         if ~master.RF.initialized
            errordlg('RF must be loaded before changing its values','Must load RF')
         else
            master.RF.frequency = app.RFFreq.Value;
            RFFrequency
         end
         settingsUpdate(app)

        end

        % Value changed function: RFAmp
        function RFAmpValueChanged(app, event)
         global master
         if ~master.RF.initialized
            errordlg('RF must be loaded before changing its values','Must load RF')
         else
            master.RF.amplitude = app.RFAmp.Value;
            RFAmplitude
         end
         settingsUpdate(app)
        end

        % Value changed function: OptimizationTiming
        function OptimizationTimingValueChanged(app, event)
         global master
         master.gui.opttime = app.OptimizationTiming.Value;
        end

        % Value changed function: OptimizationOn
        function OptimizationOnValueChanged(app, event)
         global master
         master.gui.optswitch = app.OptimizationOn.Value;
        end

        % Selection changed function: IterationsType
        function IterationsTypeSelectionChanged(app, event)
         global master
         switch app.IterationsType.SelectedObject
            case app.NumberButton
               master.gui.itertype = 'Number';
            case app.TimeButton
               master.gui.itertype = 'Time';
            case app.PerpetualButton
               master.gui.itertype = 'Perpetual';
         end
        end

        % Value changed function: IterationsNum
        function IterationsNumValueChanged(app, event)
         global master
         master.gui.maxits = app.IterationsNum.Value;
        end

        % Button pushed function: LoadpulsesequenceButton
        function LoadpulsesequenceButtonPushed(app, event)
         global master

         if isempty(app.SequenceName.Value)
            uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
         else

            ldname = strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Pulse Sequences\',...
               app.SequenceName.Value);
            loadedvar = load(convertCharsToStrings(ldname));
            master.PB.sequence = loadedvar.recorded_sequence;
            master.PB.totalLoops = loadedvar.nloops;
            if length(master.PB.sequence(1).binaryoutput) ~= master.PB.nchannels
               maxout = 2^(master.PB.nchannels-1);
               reducedPulses = [];
               nn = 1;
               for ii = 1:length(master.PB.sequence)
                  if master.PB.sequence(ii).output > maxout
                     binForm = dec2bin(master.PB.sequence(ii).output);
                     binForm = binForm(end-(master.PB.nchannels-1):end);
                     master.PB.sequence(ii).output = bin2dec(binForm);
                     reducedPulses(nn) = ii; %#ok<AGROW>
                     nn = nn+1;
                  end
                  master.PB.sequence(ii).binaryoutput = PBDec2Bin(master.PB.sequence(ii).output);
               end
               if ~isempty(reducedPulses)
                  if length(reducedPulses) == 1
                     PulseStr = sprintf("%d",reducedPulses);
                  else
                     PulseStr = sprintf("%d and %d other pulses",reducedPulses(1),length(reducedPulses));
                  end
                  errordlg(sprintf("Pulse %s have had their output changed to match the max number of channels",...
                     PulseStr),"Pulse Output Changed")
               end
            end
            uialert(app.UIFigure,'Sequence Loaded!','Load Successful','Icon','success')
         end

         settingsUpdate(app)
         warningsUpdate(app)

        end

        % Button pushed function: AddScanButton
        function AddScanButtonPushed(app, event)
         global master
         aa = master.gui.scaninstruct;

         if ~isfield(aa,'addresses')
            aa.addresses = [];
         end

         noerrors = true;
         nstarts = numel(aa.starts);
         nends = numel(aa.ends);
         if nstarts ~= nends
            errmsg = sprintf('Number of scan starts is not equal to number of scan ends');
            errordlg(errmsg,'Scan Options Not Matched')
            noerrors = false;
         end
         if strcmp(aa.type,'Pulse duration')
            if nstarts  ~= numel(aa.addresses)
               errmsg = sprintf('Number of scan addresses is not equal to number of scan ends');
               errordlg(errmsg,'Scan Options Not Matched')
               noerrors = false;
            end
         end

         if strcmp(aa.steptype,'# of points')
            aa.stepsize = (aa.ends-aa.starts)./(aa.stepinput-1);
            aa.nsteps = aa.stepinput;
         elseif strcmp(aa.steptype,'Step size') && length(aa.starts) ~= 1
            aa.steptype = '# of points';
            app.NPointsStepSize.SelectedObject = app.NPointsButton;
            errordlg('Cannot scan by step size if multiple pulse durations are changing','Cannot use step size')
            noerrors = false;
         else
            aa.nsteps = length(aa.starts:aa.stepinput:aa.ends);
            aa.stepsize = aa.stepinput;
         end

         master.gui.scaninstruct = aa;

         if noerrors
            newval = MultiFieldConversion(master.gui.scaninstruct.scannum);
            if length(newval) == 1
               if strcmp(app.Overwrite.Value,'Insert new')
                  master.gui.scan(newval+1:end+1) = master.gui.scan(newval:end);
                  master.gui.chosensteps(newval+1:end+1) = master.gui.chosensteps(newval:end);
               end

               master.gui.scan(newval) = master.gui.scaninstruct;
               master.gui.chosensteps(newval) = 1;

               master.gui.scaninstruct.scannum = num2str(newval + 1);
               master.gui.nscans = length(master.gui.scan);
               master.gui.odoplotted = ones(1,length(master.gui.scan));
            else
               uialert(app.UIFigure,'Scan number must be a single number for adding to sequence','Failed to add scan','Icon','error')
            end
         end

         scanningUpdate(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: StartScan
        function StartScanValueChanged(app, event)
         global master
         master.gui.scaninstruct.starts = MultiFieldConversion(app.StartScan.Value);
%          stepConversion(app)
        end

        % Value changed function: EndScan
        function EndScanValueChanged(app, event)
         global master
         master.gui.scaninstruct.ends = MultiFieldConversion(app.EndScan.Value);
%          stepConversion(app)
        end

        % Value changed function: PulseAddresses
        function PulseAddressesValueChanged(app, event)
         global master
         newval = MultiFieldConversion(app.PulseAddresses.Value);
         if ~all(floor(newval) == newval)
            isaddress = false;
            if isfield(master.gui,'scaninstruct')
               if isfield(master.gui.scaninstruct,'addresses')
                  oldval = '';
                  for ii = 1:numel(master.gui.scaninstruct.addresses)
                     oldval = sprintf('%s, %d',oldval,master.gui.scaninstruct.addresses(ii));
                  end
                  oldval(1:2) = [];
                  app.PulseAddresses.Value = oldval;
                  isaddress = true;
               end
            end
            if ~isaddress
               app.PulseAddresses.Value = [];
            end
            errordlg('Pulse address cannot be fractional values','Incorrect Pulse Address')
         else
            master.gui.scaninstruct.addresses = newval;
         end
        end

        % Value changed function: ScanType
        function ScanTypeValueChanged(app, event)
         global master
         master.gui.scaninstruct.type = app.ScanType.Value;
        end

        % Selection changed function: NPointsStepSize
        function NPointsStepSizeSelectionChanged(app, event)
         global master
         selectedButton = app.NPointsStepSize.SelectedObject;
         master.gui.scaninstruct.steptype = selectedButton.Text;
%          stepConversion(app)
        end

        % Value changed function: StepSizeField
        function StepSizeFieldValueChanged(app, event)
         global master
         master.gui.scaninstruct.stepinput = app.StepSizeField.Value;
%          stepConversion(app)
        end

        % Button pushed function: RefreshAppButton
        function RefreshAppButtonPushed(app, event)
         appUpdate(app)
        end

        % Button pushed function: SavesetupButton
        function SavesetupButtonPushed(app, event)
         global master

         masterdupe.notifications   =     master.notifications;
         masterdupe.gui.optswitch   =     master.gui.optswitch;
         masterdupe.gui.itertype    =     master.gui.itertype;
         masterdupe.gui.opttime     =     master.gui.opttime;
         masterdupe.gui.optdur      =     master.gui.optdur;
         masterdupe.gui.maxits      =     master.gui.maxits;
         masterdupe.gui.itemail     =     master.gui.itemail;
         masterdupe.gui.autosave    =     master.gui.autosave;
         masterdupe.gui.autosaveits =     master.gui.autosaveits;
         masterdupe.gui.displaydigits  =  master.gui.displaydigits;
         masterdupe.gui.updateperiod=     master.gui.updateperiod;
         masterdupe.gui.plotInfo.xdim    =     master.gui.plotInfo.xdim;
         masterdupe.gui.plotInfo.ydim    =     master.gui.plotInfo.ydim;
         masterdupe.gui.completionemail=  master.gui.completionemail;
         masterdupe.gui.manoptn     =     master.gui.manoptn;
         masterdupe.gui.normCounts  =     master.gui.normCounts;
         masterdupe.gui.resetloc    =     master.gui.resetloc;
         masterdupe.gui.meastype    =     master.gui.meastype;
         masterdupe.gui.contrastType=     master.gui.contrastType;
         masterdupe.gui.emailIts    =     master.gui.emailIts;
         masterdupe.gui.boxAddress  =     master.gui.boxAddress;
         masterdupe.gui.autosavename=     master.gui.autosavename;
         masterdupe.gui.randomScan  =     master.gui.randomScan;
         masterdupe.gui.invertx     =     master.gui.invertx;
         masterdupe.RF.frequency    =     master.RF.frequency;
         masterdupe.RF.amplitude    =     master.RF.amplitude;
         masterdupe.RF.switch       =     master.RF.switch;
         masterdupe.RF.modulationOn =     master.RF.modulationOn;
         masterdupe.RF.modulationType  =  master.RF.modulationType;
         masterdupe.stage.opttype   =     master.stage.opttype;
         masterdupe.stage.precision =     master.stage.precision;
         masterdupe.comp      =     master.comp;
         masterdupe.stage.ignoreWait=     master.stage.ignoreWait;
         masterdupe.NDYOV.waitforequilibrium = master.NDYOV.waitforequilibrium;
         masterdupe.NDYOV.offwhencleaned     = master.NDYOV.offwhencleaned;
         masterdupe.PB.totalLoops   =     master.PB.totalLoops;
         masterdupe.NIDAQ.useClock  =     master.NIDAQ.useClock;
         masterdupe.NIDAQ.confocal  =     master.NIDAQ.confocal;

         if isfield(master.stage,'sequence'),   masterdupe.stage.sequence = master.stage.sequence; end

         if isfield(master.stage,'radius'),     masterdupe.stage.radius = master.stage.radius;     end

         if isfield(master.stage,'steps'),      masterdupe.stage.steps = master.stage.steps;       end

         if isfield(master.PB,'sequence'),      masterdupe.PB.sequence = master.PB.sequence;       end

         if isfield(master.gui,'scan'),         masterdupe.gui.scan = master.gui.scan;             end

         if isfield(master.gui,'nscans'),       masterdupe.gui.nscans = master.gui.nscans;         end

         if isfield(master.gui,'chosensteps'),  masterdupe.gui.chosensteps = master.gui.chosensteps;     end


         if isfield(master.gui,'emailaddress'), masterdupe.gui.emailaddress = master.gui.emailaddress;   end


         svname = strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Experiments\Setups');
         setupDir = dir(svname);
         if isempty(app.SetupSaveName.Value)
            uialert(app.UIFigure,'Save unsuccessful. No file name input','Save Unsuccessful','Icon','error')
         elseif isempty(setupDir)
            errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
         else
            svname = strcat(svname,'\', app.SetupSaveName.Value);
            save(convertCharsToStrings(svname),"masterdupe")
            uialert(app.UIFigure,'Setup Saved!','Save Successful','Icon','success')
         end
         warningsUpdate(app)
        end

        % Value changed function: ComputerEditField
        function ComputerEditFieldValueChanged(app, event)
         global master
         master.comp = app.ComputerEditField.Value;

        end

        % Button pushed function: LoadsetupButton
        function LoadsetupButtonPushed(app, event)
         global master
         ldname = strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Experiments\Setups');
         setupDir = dir(ldname);
         if isempty(app.SetupLoadName.Value)
            uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
         elseif isempty(setupDir)
            errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
         else
            ldname = strcat(ldname,'\',app.SetupLoadName.Value);
            load(convertCharsToStrings(ldname),"masterdupe")

            master.notifications    =     masterdupe.notifications;
            master.gui.optswitch    =     masterdupe.gui.optswitch;
            master.gui.itertype     =     masterdupe.gui.itertype;
            master.gui.opttime      =     masterdupe.gui.opttime;
            master.gui.maxits       =     masterdupe.gui.maxits;
            master.gui.itemail      =     masterdupe.gui.itemail;
            master.gui.autosave     =     masterdupe.gui.autosave;
            master.gui.autosaveits  =     masterdupe.gui.autosaveits;
            master.gui.displaydigits=     masterdupe.gui.displaydigits;
            master.gui.updateperiod =     masterdupe.gui.updateperiod;
            master.gui.plotInfo.xdim     =     masterdupe.gui.plotInfo.xdim;
            master.gui.plotInfo.ydim     =     masterdupe.gui.plotInfo.ydim;
            master.gui.completionemail =  masterdupe.gui.completionemail;
            master.gui.manoptn      =     masterdupe.gui.manoptn;
            master.gui.normCounts   =     masterdupe.gui.normCounts;
            master.gui.resetloc     =     masterdupe.gui.resetloc;
            master.gui.meastype     =     masterdupe.gui.meastype;
            master.gui.contrastType =     masterdupe.gui.contrastType;
            master.gui.emailIts     =     masterdupe.gui.emailIts;
            master.gui.boxAddress   =     masterdupe.gui.boxAddress;
            master.gui.autosavename =     masterdupe.gui.autosavename;
            master.gui.randomScan   =     masterdupe.gui.randomScan;
            master.gui.invertx      =     masterdupe.gui.invertx;
            master.RF.frequency     =     masterdupe.RF.frequency;
            master.RF.amplitude     =     masterdupe.RF.amplitude;
            master.RF.switch        =     masterdupe.RF.switch;
            master.RF.modulationOn  =     masterdupe.RF.modulationOn;
            master.RF.modulationType=     masterdupe.RF.modulationType;
            master.stage.opttype    =     masterdupe.stage.opttype;
            master.stage.precision  =     masterdupe.stage.precision;
            master.stage.ignoreWait =     masterdupe.stage.ignoreWait;
            master.NDYOV.waitforequilibrium  =  masterdupe.NDYOV.waitforequilibrium;
            master.NDYOV.offwhencleaned   =     masterdupe.NDYOV.offwhencleaned;
            master.PB.totalLoops    =     masterdupe.PB.totalLoops;
            master.NIDAQ.useClock   =     masterdupe.NIDAQ.useClock;
            master.NIDAQ.confocal   =     masterdupe.NIDAQ.confocal;


            if isfield(masterdupe.stage,'radius'),    master.stage.radius = masterdupe.stage.radius;     end

            if isfield(masterdupe.stage,'steps'),     master.stage.steps = masterdupe.stage.steps;       end

            if isfield(masterdupe.PB,'sequence'),     master.PB.sequence = masterdupe.PB.sequence;       end

            if isfield(masterdupe.gui,'scan'),        master.gui.scan = masterdupe.gui.scan;             end

            if isfield(masterdupe.gui,'nscans'),      master.gui.nscans = masterdupe.gui.nscans;         end

            if isfield(masterdupe.gui,'chosensteps'), master.gui.chosensteps = masterdupe.gui.chosensteps;     end

            if isfield(masterdupe.gui,'emailaddress'),master.gui.emailaddress = masterdupe.gui.emailaddress;   end

            if isfield(masterdupe.stage,'sequence')
               master.stage.sequence = masterdupe.stage.sequence;
               if length(master.PB.sequence(1).binaryoutput) ~= master.PB.nchannels
                  maxout = 2^(master.PB.nchannels-1);
                  reducedPulses = [];
                  nn = 1;
                  for ii = 1:length(master.PB.sequence)
                     if master.PB.sequence(ii).output > maxout
                        binForm = dec2bin(master.PB.sequence(ii).output);
                        binForm = binForm(end-(master.PB.nchannels-1):end);
                        master.PB.sequence(ii).output = bin2dec(binForm);
                        reducedPulses(nn) = ii; %#ok<AGROW>
                        nn = nn+1;
                     end
                     master.PB.sequence(ii).binaryoutput = PBDec2Bin(master.PB.sequence(ii).output);
                  end
                  if ~isempty(reducedPulses)
                     if length(reducedPulses) == 1
                        PulseStr = sprintf("%d",reducedPulses);
                     else
                        PulseStr = sprintf("%d and %d other pulses",reducedPulses(1),length(reducedPulses));
                     end
                     errordlg(sprintf("Pulse %s have had their output changed to match the max number of channels",...
                        PulseStr),"Pulse Output Changed")
                  end
               end
            end
            
            appUpdate(app)

            if master.RF.initialized
               master.notifications = false;
               RFFrequency
               RFAmplitude
               RFSwitch
               RFModulation
               master.notifications = app.Notifications.Value;
            end

            uialert(app.UIFigure,'Setup Loaded!','Load Successful','Icon','success')

         end

        end

        % Cell edit callback: DimensionSet
        function DimensionSetCellEdit(app, event)
         global master
         indices = event.Indices;
         newData = event.NewData;
         if indices(2) ~= 4
            errordlg('Only the chosen step can be edited in this table','Un-editable feature')
         else
            master.gui.chosensteps(indices(1)) = newData;
         end
         outputSettingsUpdate(app)
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
         global master
         if ~master.gui.expcomplete
            savedData.referenceVector = master.gui.referenceVector;
            savedData.signalVector = master.gui.signalVector;
            savedData.experimentComplete = false;
         else
            savedData.experimentComplete = true;
         end
         if isfield(master,'reference')
            savedData.reference = master.reference;
            savedData.signal = master.signal;
            savedData.completeDataExists = true;
         else
            savedData.completeDataExists = false;
         end

         savedData.loadedScan = master.gui.scan;
         savedData.xdim = master.gui.plotInfo.xdim;
         savedData.ydim = master.gui.plotInfo.ydim;
         savedData.incorporatex = master.gui.plotInfo.incorporatex;
         savedData.incorporatey = master.gui.plotInfo.incorporatey;
         savedData.xoffset = master.gui.plotInfo.xoffset;
         savedData.yoffset = master.gui.plotInfo.yoffset;
         savedData.addedxOffset = master.gui.plotInfo.addedxOffset;
         savedData.addedyOffset = master.gui.plotInfo.addedyOffset;
         savedData.axisPulse = master.gui.plotInfo.axisPulse;
         savedData.contrastType = master.gui.contrastType;
         savedData.normCounts = master.gui.normCounts;
         savedData.meastype = master.gui.meastype;
         savedData.signalTime = master.gui.signalTime;
         savedData.referenceTime = master.gui.referenceTime;

         svname = strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Experiments\Data');
         outputDir = dir(svname);
         if isempty(app.OutputSaveName.Value)
            uialert(app.UIFigure,'Save unsuccessful. No file name input','Save Unsuccessful','Icon','error')
         elseif isempty(outputDir)
            errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
         else
            svname = strcat(svname,'\', app.OutputSaveName.Value);
            save(convertCharsToStrings(svname),"savedData")
            uialert(app.UIFigure,'Output Saved!','Save Successful','Icon','success')
         end
         warningsUpdate(app)
        end

        % Button pushed function: LoadoutputButton
        function LoadoutputButtonPushed(app, event)
         global master

         ldname = strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Experiments\Data');
         outputDir = dir(ldname);
         if isempty(app.OutputLoadName.Value)
            uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
         elseif isempty(outputDir)
            errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
         else
            ldname = strcat(ldname,'\',app.OutputLoadName.Value);
            load(convertCharsToStrings(ldname),"savedData")
            uialert(app.UIFigure,'Output Loaded!','Load Successful','Icon','success')
         end

         if ~savedData.experimentComplete
            master.gui.referenceVector = savedData.referenceVector;
            master.gui.signalVector = savedData.signalVector;
            master.gui.expcomplete = false;
         else
            master.gui.expcomplete = true;
         end

         if savedData.completeDataExists
            master.reference = savedData.reference;
            master.signal = savedData.signal;
         elseif isfield(master,'reference')
            master = rmfield(master,'reference');
            master = rmfield(master,'signal');
         end

         master.gui.plotInfo.wasLoaded = true;
         master.gui.plotInfo.loadedScan = savedData.scan;
         master.gui.plotInfo.xdim = savedData.xdim;
         master.gui.plotInfo.ydim = savedData.ydim;
         master.gui.plotInfo.incorporatex = savedData.incorporatex;
         master.gui.plotInfo.incorporatey = savedData.incorporatey;
         master.gui.plotInfo.xoffset = savedData.xoffset;
         master.gui.plotInfo.yoffset = savedData.yoffset;
         master.gui.plotInfo.addedxOffset = savedData.addedxOffset;
         master.gui.plotInfo.addedyOffset = savedData.addedyOffset;
         master.gui.plotInfo.axisPulse = savedData.axisPulse;
         master.gui.contrastType = savedData.contrastType;
         master.gui.normCounts = savedData.normCounts;
         master.gui.meastype = savedData.meastype;
         master.gui.signalTime = savedData.signalTime;
         master.gui.referenceTime = savedData.referenceTime;

         master.gui.plotInfo.resetLims.x = true;
         master.gui.plotInfo.resetLims.y = true;
         master.gui.plotInfo.resetLims.c = true;

         master.gui.makeplot.output = true;
         plotData(app)
         master.gui.continue = false;
         master.gui.interrupted = true;
         master.gui.isrunning = false;
         app.StatusLamp.Color = [1,0,0];
         appUpdate(app)
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
         global master
         master.gui.continue = false;
         master.gui.interrupted = true;
         master.gui.isrunning = false;
         app.StatusLamp.Color = [1,0,0];
         appUpdate(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
         global master
         if ~master.gui.interrupted && ~master.gui.expcomplete
            doreset = uiconfirm(app.UIFigure,'Attempting to reset when not interrupted and not complete',...
               'Confirm reset',"Options",{'Reset','Cancel'});
            doreset = strcmp(doreset,'Reset');
         else
            doreset = true;
         end
         if doreset
            master.gui.plotInfo.wasLoaded = false;
            master.gui.continue = false;
            master.gui.isrunning = false;
            master.gui.interrupted = false;
            master.gui.expcomplete = false;
            master.gui.makeplot.output = true;
            master.gui.makeplot.progressbars = true;
            app.StatusLamp.Color = [0,0,1];
            if isfield(master,'reference')
               master = rmfield(master,'reference');
            end
            if isfield(master,'signal')
               master = rmfield(master,'signal');
            end
            if isfield(master.gui,'referenceVector')
               master.gui = rmfield(master.gui,'referenceVector');
            end
            if isfield(master.gui,'signalVector')
               master.gui = rmfield(master.gui,'signalVector');
            end
            if isfield(master.gui,'odoplotted')
               master.gui = rmfield(master.gui,'odoplotted');
            end
            try
               app.outputdisplay(1).UserData = ones(master.gui.nscans,1);
            catch
            end
         end
        end

        % Button pushed function: DeleteScanButton
        function DeleteScanButtonPushed(app, event)
         global master
         alladdresses = MultiFieldConversion(master.gui.scaninstruct.scannum);
         alladdresses = sort(alladdresses,'descend');
         validDelete = false;
         if isfield(master.gui,'scan')
            for ii = 1:length(alladdresses)
               del_address = alladdresses(ii);
               if length(master.gui.scan) >= del_address
                  validDelete = true;
                  if numel(master.gui.scan) == 1
                     master.gui = rmfield(master.gui,'scan');
                     master.gui = rmfield(master.gui,'chosensteps');
                  else
                     master.gui.scan(del_address:end-1) = master.gui.scan(del_address+1:end);
                     master.gui.chosensteps(del_address:end-1) = master.gui.chosensteps(del_address+1:end);
                     aa = 0;
                     for jj = 1:length(master.gui.scan)
                        aa = aa+1;
                        master.gui.scan(jj).scannum = aa;
                     end
                     master.gui.scan(end) = [];
                     master.gui.chosensteps(end) = [];
                  end
               end
               scanningUpdate(app)
               outputSettingsUpdate(app)
               if isfield(master.gui,'scan')
                  master.gui.nscans = length(master.gui.scan);
               else
                  master.gui.nscans = 0;
               end
            end
         end
         if ~validDelete
            uimessage = 'Selected pulse address does not exist therefore cannot be deleted';
            uialert(app.UIFigure,uimessage,'Cannot Delete Pulse','Icon','warning')
         end

        end

        % Button pushed function: DeletestepButton
        function DeletestepButtonPushed(app, event)
         global master

         validDelete = false;
         if isfield(master.stage,'sequence')
            alladdresses = MultiFieldConversion(app.OptAddress.Value);
            alladdresses = sort(alladdresses,'descend');
            for ii = 1:length(alladdresses)
               if length(master.stage.sequence) >= alladdresses(ii)
                  validDelete = true;
                  if length(master.stage.sequence) == 1
                     master.stage = rmfield(master.stage,'sequence');
                     master.stage = rmfield(master.stage,'radius');
                     master.stage = rmfield(master.stage,'steps');
                  else
                     master.stage.sequence(alladdresses(ii)) = [];
                     master.stage.steps(alladdresses(ii)) = [];
                     master.stage.radius(alladdresses(ii)) = [];
                  end
               end
               stageUpdate(app)
            end
         end
         if ~validDelete
            uimessage = 'Selected optimization step address does not exist therefore cannot be deleted';
            uialert(app.UIFigure,uimessage,'Cannot Delete Step','Icon','warning')
         end
         stageUpdate(app)

        end

        % Button pushed function: AddstepButton
        function AddstepButtonPushed(app, event)
         global master

         if ~mod(app.OptSteps.Value,2)
            uialert(app.UIFigure,'Number of steps must be odd','Steps must be odd','Icon','warning')
         else

            address = str2double(app.OptAddress.Value);

            if isfield(master.stage,'sequence')
               if address > length(master.stage.sequence) + 1
                  uimessage = ['Attempted  address would leave gaps in optimization sequence. ' ...
                     'Instead, new optimization step will be added to the end of the current sequence'];
                  uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
                  address = length(master.stage.sequence)+1;
               end
            elseif address == 1
            else
               uimessage = ['Attempted  address would leave gaps in optimization sequence. ' ...
                  'Instead, new optimization step will be added to the end of the current sequence'];
               uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
               address = length(master.stage.sequence)+1;
            end

            master.stage.sequence(address) = convertCharsToStrings(app.OptAxis.Value);
            master.stage.radius(address) = app.OptRadius.Value;
            master.stage.steps(address) = app.OptSteps.Value;

            app.OptAddress.Value = num2str(address+1);

            stageUpdate(app)

         end

        end

        % Value changed function: Parameter
        function ParameterValueChanged(app, event)
         global master
         master.gui.meastype = app.Parameter.Value;
         master.gui.makeplot.output = true;
         plotData(app)
        end

        % Callback function
        function UpperPercentileValueChanged(app, event)
         global master
         master.gui.highpercentile = app.UpperPercentile.Value;
         if master.gui.expcomplete
            plotData(app)
         end
         outputSettingsUpdate(app)
        end

        % Value changed function: XDimension
        function XDimensionValueChanged(app, event)
         global master
         master.gui.plotInfo.xdim = app.XDimension.Value;
         master.gui.makeplot.output = true;
         if master.gui.expcomplete
            plotData(app)
         end
         outputSettingsUpdate(app)
        end

        % Value changed function: YDimension
        function YDimensionValueChanged(app, event)
         global master
         master.gui.plotInfo.ydim = app.YDimension.Value;
         master.gui.makeplot.output = true;
         if master.gui.expcomplete
            plotData(app)
         end
         outputSettingsUpdate(app)
        end

        % Callback function
        function LowerPercentileValueChanged(app, event)
         global master
         master.gui.lowpercentile = app.LowerPercentile.Value;
         if master.gui.expcomplete
            plotData(app)
         end
         outputSettingsUpdate(app)
        end

        % Value changed function: Notifications
        function NotificationsValueChanged(app, event)
         global master
         master.notifications = app.Notifications.Value;
        end

        % Value changed function: OptReadoutDur
        function OptReadoutDurValueChanged(app, event)
         global master
         master.gui.optdur = app.OptReadoutDur.Value;
        end

        % Selection changed function: OptimizationParameter
        function OptimizationParameterSelectionChanged(app, event)
         global master
         selectedButton = app.OptimizationParameter.SelectedObject;
         master.gui.optparam = convertCharsToStrings(selectedButton.Text);
        end

        % Selection changed function: OpttargetButtonGroup
        function OpttargetButtonGroupSelectionChanged(app, event)
         global master
         selectedButton = app.OpttargetButtonGroup.SelectedObject;
         master.stage.opttype = convertCharsToStrings(selectedButton.Text);
        end

        % Value changed function: DisplayDigits
        function DisplayDigitsValueChanged(app, event)
         global master
         master.gui.displaydigits = app.DisplayDigits.Value;
         appUpdate(app)
        end

        % Value changed function: EmailIterationsCheckbox
        function EmailIterationsCheckboxValueChanged(app, event)
         global master
         master.gui.itemail = app.EmailIterationsCheckbox.Value;
        end

        % Value changed function: AutoSaveIterations
        function AutoSaveIterationsValueChanged(app, event)
         global master
         master.gui.autosaveits = app.AutoSaveIterations.Value;
        end

        % Value changed function: AutoSaveName
        function AutoSaveNameValueChanged(app, event)
         global master
         master.gui.autosavename = app.AutoSaveName.Value;
        end

        % Value changed function: AutoSaveCheckbox
        function AutoSaveCheckboxValueChanged(app, event)
         global master
         master.gui.autosave = app.AutoSaveCheckbox.Value;
        end

        % Value changed function: EmailAddress
        function EmailAddressValueChanged(app, event)
         global master
         master.gui.emailaddress = app.EmailAddress.Value;
        end

        % Value changed function: UpdatePeriod
        function UpdatePeriodValueChanged(app, event)
         global master
         master.gui.updateperiod = app.UpdatePeriod.Value;
        end

        % Value changed function: XIncrement
        function XIncrementValueChanged(app, event)
         global master
         master.gui.xinc = app.XIncrement.Value;
        end

        % Value changed function: YIncrement
        function YIncrementValueChanged(app, event)
         global master
         master.gui.yinc = app.YIncrement.Value;
        end

        % Value changed function: ZIncrement
        function ZIncrementValueChanged(app, event)
         global master
         master.gui.zinc = app.ZIncrement.Value;
        end

        % Value changed function: ManualOptNum
        function ManualOptNumValueChanged(app, event)
         global master
         master.gui.manoptn = app.ManualOptNum.Value;
        end

        % Button pushed function: BeginManualOpt
        function BeginManualOptPushed(app, event)
         global master
         noerrors = true;
         if ~master.stage.initialized || ~master.NIDAQ.initialized || ~master.PB.initialized
            loadinstruments = uiconfirm(app.UIFigure,'Not all instruments currently loaded. Would you like to load them or cancel measurement?',...
               'Instruments unloaded','Icon','warning','Options',{'Load all','Cancel'});
            switch loadinstruments
               case 'Load all'
                  PBLoadButtonPushed(app)
                  DAQLoadButtonPushed(app)
                  StageLoadButtonPushed(app)
                  appUpdate(app)
               case 'Cancel'
                  noerrors = false;
            end
         end

         if noerrors
            oldClock = master.NIDAQ.useClock;
            master.NIDAQ.useClock = false;
            master.stage.recordOptLoc = true;
            master.stage.optcounter = 0;
            for ii = 1:master.gui.manoptn
               stageOptimization
            end
            master.NIDAQ.useClock = oldClock;
            maxval = max(master.stage.valrecord(end,:));
            xloc = master.stage.optloclog(end,1) + master.stage.optloclog(end,4);
            yloc = master.stage.optloclog(end,2) + master.stage.optloclog(end,5);
            zloc = master.stage.optloclog(end,3) + master.stage.optloclog(end,6);
            app.OptimizationResults.Value = sprintf('Max counts/s: %.1f\nx: %.1f\ny: %.1f\nz: %.1f',maxval,xloc,yloc,zloc);
            uialert(app.UIFigure,'Manual optimization(s) complete','Optimization Complete',"Icon","info")
         end
        end

        % Value changed function: CompletionEmail
        function CompletionEmailValueChanged(app, event)
         global master
         master.gui.completionemail = app.CompletionEmail.Value;
        end

        % Value changed function: ScanNum
        function ScanNumValueChanged(app, event)
         global master
         master.gui.scaninstruct.scannum = app.ScanNum.Value;
        end

        % Value changed function: LaserSwitch
        function LaserSwitchValueChanged(app, event)
         global master
         master.NDYOV.switch = convertCharsToStrings(app.LaserSwitch.Value);
         NDYOVSwitch
         settingsUpdate(app)
        end

        % Value changed function: LaserPower
        function LaserPowerValueChanged(app, event)
         global master
         master.NDYOV.setpower = app.LaserPower.Value;
         NDYOVSetPower
         settingsUpdate(app)
        end

        % Value changed function: LaserEquilibrium
        function LaserEquilibriumValueChanged(app, event)
         global master
         master.NDYOV.waitforequilibrium = app.LaserEquilibrium.Value;
        end

        % Value changed function: LaserCleanOff
        function LaserCleanOffValueChanged(app, event)
         global master
         master.NDYOV.offwhencleaned = app.LaserCleanOff.Value;
        end

        % Selection changed function: ResetLocation
        function ResetLocationSelectionChanged(app, event)
         global master
         switch app.ResetLocation.SelectedObject.Text

            case 'Minimum'
               master.gui.resetloc = 'min';

            case 'Center'
               master.gui.resetloc = 'center';

            case 'Maximum'
               master.gui.resetloc = 'max';

         end
        end

        % Button pushed function: ForceUpdate
        function ForceUpdateButtonPushed(app, event)
         global master
         master.gui.makeplot.output = true;
         plotData(app)
        end

        % Value changed function: CoarsePauseTime
        function CoarsePauseTimeValueChanged(app, event)
         global master
         master.stage.coarsepause = app.CoarsePauseTime.Value /1000;
        end

        % Value changed function: FinePauseTime
        function FinePauseTimeValueChanged(app, event)
         global master
         master.stage.finepause = app.FinePauseTime.Value /1000;
        end

        % Button pushed function: QuickCountsButton
        function QuickCountsButtonPushed(app, event)
         global master
         if isfield(master.PB,'sequence')
            oldseq = master.PB.sequence;
            oldTotalLoops = master.PB.totalLoops;
            oldLoopOn = master.PB.addTotalLoops;
            master.PB = rmfield(master.PB,'sequence');
            master.PB.totalLoops = 1;
            master.PB.addTotalLoops = false;
         end

         oldUseClock = master.NIDAQ.useClock;
         oldNotifications = master.notifications;
         master.NIDAQ.useClock = false;
         master.notifications = false;

         tempseq.duration = 1e8;
         tempseq.direction = "CONTINUE";
         tempseq.contextinfo = 0;
         if master.gui.quickCountsRF
            tempseq.output = 11;
         else
            tempseq.output = 3;
         end
         tempseq.binaryoutput = PBDec2Bin(tempseq.output);

         master.PB.sequence = tempseq;

         PBFinalize

         totalref = 0;
         nruns = 8;
         for ii = 1:nruns
            pause(0.01) 
            [~,ref] = PBRun;
            totalref = totalref + ref;
         end
         totalref = totalref / nruns;
         totalref = totalref * 10;%Counts --> Counts/sec

         app.QuickCountsOutput.Value = num2str(totalref);

         if exist("oldseq","var")
            master.PB.sequence = oldseq;
            master.PB.addTotalLoops = oldLoopOn;
            master.PB.totalLoops = oldTotalLoops;
         else
            master.PB = rmfield(master.PB,'sequence');
         end

         master.NIDAQ.useClock = oldUseClock;
         master.notifications = oldNotifications;
        end

        % Selection changed function: CountssecondRFsettingButtonGroup
        function CountssecondRFsettingButtonGroupSelectionChanged(app, event)
         global master
         rftext = app.CountssecondRFsettingButtonGroup.SelectedObject.Text;
         if strcmp(rftext,"RF on")
            master.gui.quickCountsRF = true;
         else
            master.gui.quickCountsRF = false;
         end

        end

        % Value changed function: NormCounts
        function NormCountsValueChanged(app, event)
         global master
         master.gui.normCounts = app.NormCounts.Value;
         outputSettingsUpdate(app)
         plotData(app)
        end

        % Value changed function: IgnoreSettling
        function IgnoreSettlingValueChanged(app, event)
         global master
         master.stage.ignoreWait = app.IgnoreSettling.Value;
         stageUpdate(app)
        end

        % Value changed function: FuzzyCounts
        function FuzzyCountsValueChanged(app, event)
         global master
         master.NIDAQ.useClock = ~app.FuzzyCounts.Value;
         settingsUpdate(app)
        end

        % Value changed function: EmailIterations
        function EmailIterationsValueChanged(app, event)
         global master
         master.gui.emailIts = app.EmailIterations.Value;
         notificationsManualUpdate(app)
        end

        % Value changed function: BoxAddress
        function BoxAddressValueChanged(app, event)
         global master
         master.gui.boxAddress = app.BoxAddress.Value;
         connectionUpdate(app)
        end

        % Value changed function: ContrastType
        function ContrastTypeValueChanged(app, event)
         global master
         master.gui.contrastType = app.ContrastType.Value;
         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Button pushed function: BeginContinuousOptimizationButton
        function BeginContinuousOptimizationButtonPushed(app, event)
         global master
         noerrors = true;
         if ~master.stage.initialized || ~master.NIDAQ.initialized || ~master.PB.initialized
            loadinstruments = uiconfirm(app.UIFigure,'Not all instruments currently loaded. Would you like to load them or cancel measurement?',...
               'Instruments unloaded','Icon','warning','Options',{'Load all','Cancel'});
            switch loadinstruments
               case 'Load all'
                  PBLoadButtonPushed(app)
                  DAQLoadButtonPushed(app)
                  StageLoadButtonPushed(app)
                  appUpdate(app)
               case 'Cancel'
                  noerrors = false;
            end
         end

         if ~master.gui.isrunning && noerrors
            master.gui.isrunning = true;
            app.StatusLamp.Color = [0.4940 0.1840 0.5560];
            master.stage.recordOptLoc = true;
            oldClock = master.NIDAQ.useClock;
            master.NIDAQ.useClock = false;
            npauses = ceil(master.gui.opttime*60);
            master.gui.continue = true;

            while master.gui.continue

               stageOptimization
               maxval = max(master.stage.valrecord(end,:));
               xloc = master.stage.optloclog(end,1) + master.stage.optloclog(end,4);
               yloc = master.stage.optloclog(end,2) + master.stage.optloclog(end,5);
               zloc = master.stage.optloclog(end,3) + master.stage.optloclog(end,6);
               app.OptimizationResults.Value = sprintf('Max counts/s: %.1f\nx: %.1f\ny: %.1f\nz: %.1f',maxval,xloc,yloc,zloc);

               %Checks for abort or reset every second
               for ii = 1:npauses
                  if ~master.gui.continue
                     break
                  end
                  pause(1)
               end

            end
            master.NIDAQ.useClock = oldClock;
         else
            errordlg('Cannot run optimization while experiment is running','Cannot Optimize')
         end

        end

        % Cell edit callback: OptTable
        function OptTableCellEdit(app, event)
         global master
         indices = event.Indices;
         newData = event.NewData;
         switch indices(2)
            case 1
               switch convertCharsToStrings(app.OptAxis.Value)
                  case {"x","y","z"}
                     master.stage.sequence(indices(1)) = convertCharsToStrings(app.OptAxis.Value);
                  otherwise
                     errordlg('Sequence axis must be x, y, or z','Invalid Axis')
               end

            case 2
               master.stage.radius(indices(1)) = str2double(newData);
            case 3
               newData = str2double(newData);
               if mod(newData,1) == 0 && mod(newData,2) == 1
                  master.stage.steps(indices(1)) = newData;
               else
                  errordlg('Number of steps must be whole and odd','Invalid Step Number')
               end
         end
        end

        % Value changed function: CoarseX
        function CoarseXValueChanged(app, event)
         global coarseXYControl
         global master
         newLoc = -app.CoarseX.Value;%Coarse X & Y are reversed direction compared to fine X & Y
         if newLoc*1000 > master.stage.min(1) && newLoc*1000 < master.stage.max(1)
            coarseXYControl.MOV('1',newLoc)
            pause(master.stage.coarsepause)

            n = 0;
            while true
               if master.stage.ignoreWait
                  break
               end
               if ~coarseXYControl.IsMoving('1')
                  break
               end
               pause(.001)
               n = n + 1;
               if n > 5000 %5 seconds
                  break
               end
            end

            currPos = coarseXYControl.qPOS ('1');
            master.stage.loc(1) = -currPos*1000;
            master.stage.locrecord(end+1,:) = master.stage.loc;
         else
            errordlg('Position outside of coarse X bounds','Outside of Bounds')
         end

         stageUpdate(app)
         altStageUpdate(app)
        end

        % Value changed function: CoarseY
        function CoarseYValueChanged(app, event)
         global coarseXYControl
         global master
         newLoc = -app.CoarseY.Value;%Coarse X & Y are reversed direction compared to fine X & Y
         if newLoc*1000 > master.stage.min(2) && newLoc*1000 < master.stage.max(2)
            coarseXYControl.MOV('2',newLoc)
            pause(master.stage.coarsepause)

            n = 0;
            while true
               if master.stage.ignoreWait
                  break
               end
               if ~coarseXYControl.IsMoving('2')
                  break
               end
               pause(.001)
               n = n + 1;
               if n > 5000 %5 seconds
                  break
               end
            end

            currPos = coarseXYControl.qPOS ('2');
            master.stage.loc(2) = -currPos*1000;
            master.stage.locrecord(end+1,:) = master.stage.loc;
         else
            errordlg('Position outside of coarse Y bounds','Outside of Bounds')
         end

         stageUpdate(app)
         altStageUpdate(app)
        end

        % Value changed function: CoarseZ
        function CoarseZValueChanged(app, event)
         global coarseZControl
         global master
         newLoc = app.CoarseZ.Value;
         if newLoc*1000 > master.stage.min(3) && newLoc*1000 < master.stage.max(3)
            coarseZControl.MOV('1',newLoc)
            pause(master.stage.coarsepause)

            n = 0;
            while true
               if master.stage.ignoreWait
                  break
               end
               if ~coarseZControl.IsMoving('1')
                  break
               end
               pause(.001)
               n = n + 1;
               if n > 5000 %5 seconds
                  break
               end
            end

            currPos = coarseZControl.qPOS ('1');
            master.stage.loc(3) = currPos*1000;
            master.stage.locrecord(end+1,:) = master.stage.loc;
         else
            errordlg('Position outside of coarse Z bounds','Outside of Bounds')
         end

         stageUpdate(app)
         altStageUpdate(app)
        end

        % Value changed function: FineX
        function FineXValueChanged(app, event)
         global fineControl
         global master
         newLoc = app.FineX.Value;
         if newLoc > master.stage.min(4) && newLoc < master.stage.max(4)
            fineControl.MOV('1',newLoc)

            pause(master.stage.finepause)
            n = 0;
            while true
               if master.stage.ignoreWait
                  break
               end
               if ~fineControl.IsMoving('1')
                  break
               end
               pause(.001)
               n = n + 1;
               if n > 1000 %1 second
                  break
               end
            end

            currPos = fineControl.qPOS ('1');
            master.stage.loc(4) = currPos;
            master.stage.locrecord(end+1,:) = master.stage.loc;
         else
            errordlg('Position outside of fine X bounds','Outside of Bounds')
         end

         stageUpdate(app)
         altStageUpdate(app)
        end

        % Value changed function: FineY
        function FineYValueChanged(app, event)
         global fineControl
         global master
         newLoc = app.FineY.Value;
         if newLoc > master.stage.min(5) && newLoc < master.stage.max(5)
            fineControl.MOV('2',newLoc)
            pause(master.stage.finepause)

            n = 0;
            while true
               if master.stage.ignoreWait
                  break
               end
               if ~fineControl.IsMoving('2')
                  break
               end
               pause(.001)
               n = n + 1;
               if n > 1000 %1 second
                  break
               end
            end

            currPos = fineControl.qPOS ('2');
            master.stage.loc(5) = currPos;
            master.stage.locrecord(end+1,:) = master.stage.loc;
         else
            errordlg('Position outside of fine Y bounds','Outside of Bounds')
         end

         stageUpdate(app)
         altStageUpdate(app)
        end

        % Value changed function: FineZ
        function FineZValueChanged(app, event)
         global fineControl
         global master
         newLoc = app.FineZ.Value;
         if newLoc > master.stage.min(6) && newLoc < master.stage.max(6)
            fineControl.MOV('3',newLoc)
            pause(master.stage.finepause)

            n = 0;
            while true
               if master.stage.ignoreWait
                  break
               end
               if ~fineControl.IsMoving('3')
                  break
               end
               pause(.001)
               n = n + 1;
               if n > 1000 %1 second
                  break
               end
            end

            currPos = fineControl.qPOS ('3');
            master.stage.loc(6) = currPos;
            master.stage.locrecord(end+1,:) = master.stage.loc;
         else
            errordlg('Position outside of fine Z bounds','Outside of Bounds')
         end

         stageUpdate(app)
         altStageUpdate(app)
        end

        % Value changed function: AltStageScan
        function AltStageScanValueChanged(app, event)
         global master
         master.gui.altStageScan = app.AltStageScan.Value;
         altStageUpdate(app)
        end

        % Value changed function: RandomizeScan
        function RandomizeScanValueChanged(app, event)
         global master
         master.gui.randomScan = app.RandomizeScan.Value;
         obscureUpdate(app)
        end

        % Value changed function: InvertXAxis
        function InvertXAxisValueChanged(app, event)
         global master
         master.gui.invertx = app.InvertXAxis.Value;
         outputSettingsUpdate(app)
         plotData(app)
        end

        % Value changed function: ConfocalWideField
        function ConfocalWideFieldValueChanged(app, event)
         global master
         if strcmp(app.ConfocalWideField.Value,'Con')
            master.NIDAQ.confocal = true;
         else
            master.NIDAQ.confocal = false;
            master.gui.normCounts = false;
         end
         settingsUpdate(app)
        end

        % Value changed function: RFmodulationCheckBox
        function RFmodulationCheckBoxValueChanged(app, event)
         global master
         master.RF.modulationOn = app.RFmodulationCheckBox.Value;
         RFModulation
         settingsUpdate(app)
        end

        % Value changed function: RFModulationType
        function RFModulationTypeValueChanged(app, event)
         global master
         master.RF.modulationType = app.RFModulationType.Value;
         RFModulation
         obscureUpdate(app)
        end

        % Value changed function: WFCutoffVoltage
        function WFCutoffVoltageValueChanged(app, event)
         global master
         master.NIDAQ.cutoffVoltage = app.WFCutoffVoltage.Value;
         obscureUpdate(app)
        end

        % Cell edit callback: PlotLimitsTable
        function PlotLimitsTableCellEdit(app, event)
         global master
         indices = event.Indices;
         newData = event.NewData;
         switch indices(1)
            case 1
               newData = str2double(newData);
               if newData < master.gui.plotInfo.xlims(2)
               master.gui.plotInfo.xlims(1) = newData;
               master.gui.plotInfo.xdefault = false;
               else
                  errordlg('Lower x limit must be less than upper x limit','Invalid input')
               end
            case 2
               newData = str2double(newData);
               if newData > master.gui.plotInfo.xlims(1)
               master.gui.plotInfo.xlims(2) = newData;
               master.gui.plotInfo.xdefault = false;
               else
                  errordlg('Upper x limit must be more than lower x limit','Invalid input')
               end
            case 3
               if strcmp(newData,'true')
                  master.gui.plotInfo.xdefault = true;
               elseif strcmp(newData,'false')
                  master.gui.plotInfo.xdefault = false;
               else
                  errordlg('Default must be true or false','Invalid input')
               end
            case 4
               newData = str2double(newData);
               if newData < master.gui.plotInfo.ylims(2)
               master.gui.plotInfo.ylims(1) = newData;
               master.gui.plotInfo.ydefault = false;
               else
                  errordlg('Lower y limit must be less than upper y limit','Invalid input')
               end
            case 5
               newData = str2double(newData);
               if newData > master.gui.plotInfo.ylims(1)
               master.gui.plotInfo.ylims(2) = newData;
               master.gui.plotInfo.ydefault = false;
               else
                  errordlg('Upper y limit must be more than lower y limit','Invalid input')
               end
            case 6
               if strcmp(newData,'true')
                  master.gui.plotInfo.ydefault = true;
               elseif strcmp(newData,'false')
                  master.gui.plotInfo.ydefault = false;
               else
                  errordlg('Default must be true or false','Invalid input')
               end
            case 7
               newData = str2double(newData);
               if newData >= 0 && newData <= 100
                  if newData < master.gui.plotInfo.percentile(2)
                     master.gui.plotInfo.percentile(1) = newData;
                  else
                     errordlg('Lower percentile must be less than upper percentile','Invalid input')
                  end
               else
                  errordlg('Percentile must be between 0 and 100','Invalid input')
               end
            case 8
               newData = str2double(newData);
               if newData >= 0 && newData <= 100
                  if newData > master.gui.plotInfo.percentile(1)
                     master.gui.plotInfo.percentile(2) = newData;
                  else
                     errordlg('Upper percentile must be more than lower percentile','Invalid input')
                  end
               else
                  errordlg('Percentile must be between 0 and 100','Invalid input')
               end
         end

         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: PulseAxis
        function PulseAxisValueChanged(app, event)
         global master
         master.gui.plotInfo.axisPulse = app.PulseAxis.Value;
         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: Incorporatex
        function IncorporatexValueChanged(app, event)
         global master
         master.gui.plotInfo.incorporatex = app.Incorporatex.Value;
         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: Incorporatey
        function IncorporateyValueChanged(app, event)
         global master
         master.gui.plotInfo.incorporatey = app.Incorporatey.Value;
         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: AdditionalxOffset
        function AdditionalxOffsetValueChanged(app, event)
         global master
         master.gui.plotInfo.addedxOffset = app.AdditionalxOffset.Value;
         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: AdditionalyOffset
        function AdditionalyOffsetValueChanged(app, event)
         global master
         master.gui.plotInfo.addedyOffset = app.AdditionalyOffset.Value;
         master.gui.makeplot.output = true;
         plotData(app)
         outputSettingsUpdate(app)
        end

        % Value changed function: DisplayedChannels
        function DisplayedChannelsValueChanged(app, event)
         global master
         if master.PB.initialized
            if app.DisplayedChannels.Value <= master.PB.nchannels && app.DisplayedChannels.Value > 0
               master.gui.displayedChannels = app.DisplayedChannels.Value;
            else
               errordlg(sprintf('Displayed channels must be between 1 and %d',master.PB.nchannels),'Invalid Channels Displayed')
            end
         else
            errordlg('Initialize pulse blaster before changing # of displayed channels','Must Initialize PB')
         end
         settingsUpdate(app)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.GridLayout2 = uigridlayout(app.ConnectionTab);
            app.RFLampLabel = uilabel(app.GridLayout2);
            app.RFLampLabel.Text = 'RF';
            app.RFLamp = uilamp(app.GridLayout2);
            app.StageLampLabel = uilabel(app.GridLayout2);
            app.StageLampLabel.Text = 'Stage';
            app.StageLamp = uilamp(app.GridLayout2);
            app.PulseBlasterLampLabel = uilabel(app.GridLayout2);
            app.PulseBlasterLampLabel.Text = 'Pulse Blaster';
            app.PulseBlasterLamp = uilamp(app.GridLayout2);
            app.DAQLampLabel = uilabel(app.GridLayout2);
            app.DAQLampLabel.Text = 'DAQ';
            app.DAQLamp = uilamp(app.GridLayout2);
            app.RFLoad = uibutton(app.GridLayout2, 'push');
            app.RFLoad.ButtonPushedFcn = createCallbackFcn(app, @RFLoadPushed, true);
            app.RFLoad.Text = 'Load';
            app.RFUnload = uibutton(app.GridLayout2, 'push');
            app.RFUnload.ButtonPushedFcn = createCallbackFcn(app, @RFUnloadButtonPushed, true);
            app.RFUnload.Text = 'Unload';
            app.StageLoad = uibutton(app.GridLayout2, 'push');
            app.StageLoad.ButtonPushedFcn = createCallbackFcn(app, @StageLoadButtonPushed, true);
            app.StageLoad.Text = 'Load';
            app.PBLoad = uibutton(app.GridLayout2, 'push');
            app.PBLoad.ButtonPushedFcn = createCallbackFcn(app, @PBLoadButtonPushed, true);
            app.PBLoad.Text = 'Load';
            app.DAQLoad = uibutton(app.GridLayout2, 'push');
            app.DAQLoad.ButtonPushedFcn = createCallbackFcn(app, @DAQLoadButtonPushed, true);
            app.DAQLoad.Text = 'Load';
            app.StageUnload = uibutton(app.GridLayout2, 'push');
            app.StageUnload.ButtonPushedFcn = createCallbackFcn(app, @StageUnloadButtonPushed, true);
            app.StageUnload.Text = 'Unload';
            app.PBUnload = uibutton(app.GridLayout2, 'push');
            app.PBUnload.ButtonPushedFcn = createCallbackFcn(app, @PBUnloadButtonPushed, true);
            app.PBUnload.Text = 'Unload';
            app.DAQUnload = uibutton(app.GridLayout2, 'push');
            app.DAQUnload.ButtonPushedFcn = createCallbackFcn(app, @DAQUnloadButtonPushed, true);
            app.DAQUnload.Text = 'Unload';
            app.LoadAll = uibutton(app.GridLayout2, 'push');
            app.LoadAll.ButtonPushedFcn = createCallbackFcn(app, @LoadAllButtonPushed, true);
            app.LoadAll.Text = 'Load';
            app.UnloadAll = uibutton(app.GridLayout2, 'push');
            app.UnloadAll.ButtonPushedFcn = createCallbackFcn(app, @UnloadAllButtonPushed, true);
            app.UnloadAll.Text = 'Unload';
            app.AllLampLabel = uilabel(app.GridLayout2);
            app.AllLampLabel.Text = 'All';
            app.AllLamp = uilamp(app.GridLayout2);
            app.LocalboxaddressEditFieldLabel = uilabel(app.GridLayout2);
            app.LocalboxaddressEditFieldLabel.Text = 'Local box address';
            app.BoxAddress = uieditfield(app.GridLayout2, 'text');
            app.BoxAddress.ValueChangedFcn = createCallbackFcn(app, @BoxAddressValueChanged, true);
            app.BoxAddress.Value = 'C:\Users\User\';
            app.LaserLoad = uibutton(app.GridLayout2, 'push');
            app.LaserLoad.ButtonPushedFcn = createCallbackFcn(app, @LaserLoadButtonPushed, true);
            app.LaserLoad.Text = 'Load';
            app.LaserUnload = uibutton(app.GridLayout2, 'push');
            app.LaserUnload.ButtonPushedFcn = createCallbackFcn(app, @LaserUnloadButtonPushed, true);
            app.LaserUnload.Text = 'Unload';
            app.LaserLampLabel = uilabel(app.GridLayout2);
            app.LaserLampLabel.Text = 'Laser';
            app.LaserLamp = uilamp(app.GridLayout2);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout4 = uigridlayout(app.GeneralTab);
            app.LoadpulsesequenceButton = uibutton(app.GridLayout4, 'push');
            app.LoadpulsesequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpulsesequenceButtonPushed, true);
            app.LoadpulsesequenceButton.Text = {'Load pulse'; 'sequence'};
            app.IterationsNum = uieditfield(app.GridLayout4, 'numeric');
            app.IterationsNum.Limits = [1 Inf];
            app.IterationsNum.ValueChangedFcn = createCallbackFcn(app, @IterationsNumValueChanged, true);
            app.IterationsNum.Value = 1;
            app.IterationsType = uibuttongroup(app.GridLayout4);
            app.IterationsType.SelectionChangedFcn = createCallbackFcn(app, @IterationsTypeSelectionChanged, true);
            app.IterationsType.Title = 'Iterations';
            app.NumberButton = uitogglebutton(app.IterationsType);
            app.NumberButton.Text = 'Number';
            app.NumberButton.Value = true;
            app.TimeButton = uitogglebutton(app.IterationsType);
            app.TimeButton.Text = 'Time (min)';
            app.PerpetualButton = uitogglebutton(app.IterationsType);
            app.PerpetualButton.Text = 'Perpetual';
            app.SequenceName = uieditfield(app.GridLayout4, 'text');
            app.SequenceName.Placeholder = 'Sequence file name to load';
            app.PulseSequenceEditorButton = uibutton(app.GridLayout4, 'push');
            app.PulseSequenceEditorButton.ButtonPushedFcn = createCallbackFcn(app, @PulseSequenceEditorButtonPushed, true);
            app.PulseSequenceEditorButton.Text = 'Pulse Sequence Editor';
            app.RFFrequencyGHzLabel = uilabel(app.GridLayout4);
            app.RFFrequencyGHzLabel.Text = {'RF Frequency'; '(GHz)'};
            app.RFFreq = uieditfield(app.GridLayout4, 'numeric');
            app.RFFreq.ValueChangedFcn = createCallbackFcn(app, @RFFreqValueChanged, true);
            app.PulseSequence = uipanel(app.GridLayout4);
            app.LaserpowerEditFieldLabel = uilabel(app.GridLayout4);
            app.LaserpowerEditFieldLabel.Text = 'Laser power';
            app.LaserPower = uieditfield(app.GridLayout4, 'numeric');
            app.LaserPower.Limits = [0 8];
            app.LaserPower.ValueChangedFcn = createCallbackFcn(app, @LaserPowerValueChanged, true);
            app.LaserswitchSwitchLabel = uilabel(app.GridLayout4);
            app.LaserswitchSwitchLabel.Text = 'Laser switch';
            app.LaserSwitch = uiswitch(app.GridLayout4, 'slider');
            app.LaserSwitch.Items = {'off', 'on'};
            app.LaserSwitch.ValueChangedFcn = createCallbackFcn(app, @LaserSwitchValueChanged, true);
            app.LaserSwitch.Value = 'off';
            app.FuzzyCounts = uicheckbox(app.GridLayout4);
            app.FuzzyCounts.ValueChangedFcn = createCallbackFcn(app, @FuzzyCountsValueChanged, true);
            app.FuzzyCounts.Text = {'Fuzzy'; 'counts'};
            app.ConfocalWideField = uidropdown(app.GridLayout4);
            app.ConfocalWideField.Items = {'Confocal', 'Wide field'};
            app.ConfocalWideField.ItemsData = {'Con', 'WF'};
            app.ConfocalWideField.ValueChangedFcn = createCallbackFcn(app, @ConfocalWideFieldValueChanged, true);
            app.ConfocalWideField.Value = 'Con';
            app.RFmodulationCheckBox = uicheckbox(app.GridLayout4);
            app.RFmodulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @RFmodulationCheckBoxValueChanged, true);
            app.RFmodulationCheckBox.Text = {'RF'; 'modulation'};
            app.RFAmplitudedBmLabel = uilabel(app.GridLayout4);
            app.RFAmplitudedBmLabel.Text = {'RF Amplitude'; '(dBm)'};
            app.RFAmp = uieditfield(app.GridLayout4, 'numeric');
            app.RFAmp.ValueChangedFcn = createCallbackFcn(app, @RFAmpValueChanged, true);
            app.StageTab = uitab(app.TabGroup);
            app.StageTab.Title = 'Stage';
            app.GridLayout6 = uigridlayout(app.StageTab);
            app.ZUp = uibutton(app.GridLayout6, 'push');
            app.ZUp.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
            app.ZUp.Text = '>';
            app.ZDown = uibutton(app.GridLayout6, 'push');
            app.ZDown.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
            app.ZDown.Text = '<';
            app.ZFineReset = uibutton(app.GridLayout6, 'push');
            app.ZFineReset.ButtonPushedFcn = createCallbackFcn(app, @ZFineResetButtonPushed, true);
            app.ZFineReset.Text = {'Fine axis'; 'reset'};
            app.ZIncrement = uieditfield(app.GridLayout6, 'numeric');
            app.ZIncrement.ValueChangedFcn = createCallbackFcn(app, @ZIncrementValueChanged, true);
            app.ZPosition = uieditfield(app.GridLayout6, 'numeric');
            app.ZPosition.Limits = [1000 25100];
            app.ZPosition.ValueChangedFcn = createCallbackFcn(app, @ZPositionValueChanged, true);
            app.ZPosition.Value = 1000;
            app.ZSlider = uislider(app.GridLayout6);
            app.ZSlider.Limits = [1000 22680];
            app.ZSlider.MajorTicks = [5000 10000 15000 20000];
            app.ZSlider.MajorTickLabels = {'5mm', '10mm', '15mm', '20mm'};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.MinorTicks = [1000 2000 3000 4000 6000 7000 8000 9000 11000 12000 13000 14000 16000 17000 18000 19000 21000 22000 23000 24000];
            app.ZSlider.Value = 12500;
            app.ZaxisSliderLabel_2 = uilabel(app.GridLayout6);
            app.ZaxisSliderLabel_2.Text = 'Z axis';
            app.YUp = uibutton(app.GridLayout6, 'push');
            app.YUp.ButtonPushedFcn = createCallbackFcn(app, @YUpButtonPushed, true);
            app.YUp.Text = '>';
            app.YDown = uibutton(app.GridLayout6, 'push');
            app.YDown.ButtonPushedFcn = createCallbackFcn(app, @YDownButtonPushed, true);
            app.YDown.Text = '<';
            app.YFineReset = uibutton(app.GridLayout6, 'push');
            app.YFineReset.ButtonPushedFcn = createCallbackFcn(app, @YFineResetButtonPushed, true);
            app.YFineReset.Text = {'Fine axis'; 'reset'};
            app.YIncrement = uieditfield(app.GridLayout6, 'numeric');
            app.YIncrement.ValueChangedFcn = createCallbackFcn(app, @YIncrementValueChanged, true);
            app.YPosition = uieditfield(app.GridLayout6, 'numeric');
            app.YPosition.Limits = [-12600 12600];
            app.YPosition.ValueChangedFcn = createCallbackFcn(app, @YPositionValueChanged, true);
            app.YSlider = uislider(app.GridLayout6);
            app.YSlider.Limits = [-9980 10180];
            app.YSlider.MajorTicks = [-9980 -5000 0 5000 10000];
            app.YSlider.MajorTickLabels = {'-10mm', '-5mm', '0mm', '5mm', '10mm'};
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.MinorTicks = [-12000 -11000 -9000 -8000 -7000 -6000 -4000 -3000 -2000 -1000 1000 2000 3000 4000 6000 7000 8000 9000 11000 12000];
            app.YaxisSliderLabel_2 = uilabel(app.GridLayout6);
            app.YaxisSliderLabel_2.Text = 'Y axis';
            app.XUp = uibutton(app.GridLayout6, 'push');
            app.XUp.ButtonPushedFcn = createCallbackFcn(app, @XUpButtonPushed, true);
            app.XUp.Text = '>';
            app.XDown = uibutton(app.GridLayout6, 'push');
            app.XDown.ButtonPushedFcn = createCallbackFcn(app, @XDownButtonPushed, true);
            app.XDown.Text = '<';
            app.XFineReset = uibutton(app.GridLayout6, 'push');
            app.XFineReset.ButtonPushedFcn = createCallbackFcn(app, @XFineResetButtonPushed, true);
            app.XFineReset.Text = {'Fine axis'; 'reset'};
            app.XIncrement = uieditfield(app.GridLayout6, 'numeric');
            app.XIncrement.ValueChangedFcn = createCallbackFcn(app, @XIncrementValueChanged, true);
            app.XPosition = uieditfield(app.GridLayout6, 'numeric');
            app.XPosition.Limits = [-12600 12600];
            app.XPosition.ValueChangedFcn = createCallbackFcn(app, @XPositionValueChanged, true);
            app.XSlider = uislider(app.GridLayout6);
            app.XSlider.Limits = [-9980 10180];
            app.XSlider.MajorTicks = [-9980 -5000 0 5000 10000];
            app.XSlider.MajorTickLabels = {'-10mm', '-5mm', '0mm', '5mm', '10mm'};
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.MinorTicks = [-12000 -11000 -9000 -8000 -7000 -6000 -4000 -3000 -2000 -1000 1000 2000 3000 4000 6000 7000 8000 9000 11000 12000];
            app.XSlider.Value = 5000;
            app.XaxisSliderLabel_2 = uilabel(app.GridLayout6);
            app.XaxisSliderLabel_2.Text = 'X axis';
            app.IncrementLabel = uilabel(app.GridLayout6);
            app.IncrementLabel.Text = 'Increment';
            app.PositionLabel = uilabel(app.GridLayout6);
            app.PositionLabel.Text = 'Position';
            app.TimebetweenoptimizationsminLabel_2 = uilabel(app.GridLayout6);
            app.TimebetweenoptimizationsminLabel_2.Text = {'Time between '; 'optimizations (min)'};
            app.OptimizationTiming = uieditfield(app.GridLayout6, 'numeric');
            app.OptimizationTiming.Limits = [0 Inf];
            app.OptimizationTiming.ValueChangedFcn = createCallbackFcn(app, @OptimizationTimingValueChanged, true);
            app.OptimizationTiming.Value = 5;
            app.OptimizationOn = uicheckbox(app.GridLayout6);
            app.OptimizationOn.ValueChangedFcn = createCallbackFcn(app, @OptimizationOnValueChanged, true);
            app.OptimizationOn.Text = 'Auto-optimization';
            app.OptAxis = uidropdown(app.GridLayout6);
            app.OptAxis.Items = {'x', 'y', 'z'};
            app.OptAxis.Value = 'x';
            app.RadiusEditFieldLabel = uilabel(app.GridLayout6);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.OptRadius = uieditfield(app.GridLayout6, 'numeric');
            app.OptRadius.Limits = [0 Inf];
            app.OptRadius.Value = 0.1;
            app.StepsEditFieldLabel = uilabel(app.GridLayout6);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.OptSteps = uieditfield(app.GridLayout6, 'numeric');
            app.OptSteps.Limits = [3 Inf];
            app.OptSteps.Value = 3;
            app.AddstepButton = uibutton(app.GridLayout6, 'push');
            app.AddstepButton.ButtonPushedFcn = createCallbackFcn(app, @AddstepButtonPushed, true);
            app.AddstepButton.Text = {'Add'; 'step'};
            app.OptTable = uitable(app.GridLayout6);
            app.OptTable.ColumnName = {'Axis'; 'Radius'; 'Steps'};
            app.OptTable.RowName = {};
            app.OptTable.CellEditCallback = createCallbackFcn(app, @OptTableCellEdit, true);
            app.StageHeader = uilabel(app.GridLayout6);
            app.StageHeader.Text = 'All units in μm unless otherwise indicated';
            app.AxisLabel = uilabel(app.GridLayout6);
            app.AxisLabel.Text = 'Axis';
            app.DeletestepButton = uibutton(app.GridLayout6, 'push');
            app.DeletestepButton.ButtonPushedFcn = createCallbackFcn(app, @DeletestepButtonPushed, true);
            app.DeletestepButton.Text = {'Delete'; 'step'};
            app.OptimizationaddressEditFieldLabel = uilabel(app.GridLayout6);
            app.OptimizationaddressEditFieldLabel.Text = 'Optimization address';
            app.OptAddress = uieditfield(app.GridLayout6, 'text');
            app.OptAddress.Value = '1';
            app.IgnoreSettling = uicheckbox(app.GridLayout6);
            app.IgnoreSettling.ValueChangedFcn = createCallbackFcn(app, @IgnoreSettlingValueChanged, true);
            app.IgnoreSettling.Text = 'Ignore stage settling time';
            app.ScanningTab = uitab(app.TabGroup);
            app.ScanningTab.Title = 'Scanning';
            app.GridLayout5 = uigridlayout(app.ScanningTab);
            app.ScantypeDropDownLabel = uilabel(app.GridLayout5);
            app.ScantypeDropDownLabel.Text = 'Scan type';
            app.ScanType = uidropdown(app.GridLayout5);
            app.ScanType.Items = {'Stage X', 'Stage Y', 'Stage Z', 'RF frequency', 'Pulse duration', 'Laser power'};
            app.ScanType.ValueChangedFcn = createCallbackFcn(app, @ScanTypeValueChanged, true);
            app.ScanType.Value = 'Stage X';
            app.StartEditFieldLabel = uilabel(app.GridLayout5);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartScan = uieditfield(app.GridLayout5, 'text');
            app.StartScan.ValueChangedFcn = createCallbackFcn(app, @StartScanValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.GridLayout5);
            app.EndEditFieldLabel.Text = 'End';
            app.EndScan = uieditfield(app.GridLayout5, 'text');
            app.EndScan.ValueChangedFcn = createCallbackFcn(app, @EndScanValueChanged, true);
            app.PulseaddressesifapplicableLabel = uilabel(app.GridLayout5);
            app.PulseaddressesifapplicableLabel.Text = {'Pulse address(es)'; 'if applicable'};
            app.PulseAddresses = uieditfield(app.GridLayout5, 'text');
            app.PulseAddresses.ValueChangedFcn = createCallbackFcn(app, @PulseAddressesValueChanged, true);
            app.NPointsStepSize = uibuttongroup(app.GridLayout5);
            app.NPointsStepSize.SelectionChangedFcn = createCallbackFcn(app, @NPointsStepSizeSelectionChanged, true);
            app.NPointsButton = uitogglebutton(app.NPointsStepSize);
            app.NPointsButton.Text = '# of points';
            app.NPointsButton.Value = true;
            app.StepSizeButton = uitogglebutton(app.NPointsStepSize);
            app.StepSizeButton.Text = 'Step size';
            app.StepSizeField = uieditfield(app.GridLayout5, 'numeric');
            app.StepSizeField.ValueChangedFcn = createCallbackFcn(app, @StepSizeFieldValueChanged, true);
            app.StepSizeField.Value = 1;
            app.ScanTable = uitable(app.GridLayout5);
            app.ScanTable.ColumnName = {'Order'; 'Type'; 'Start'; 'End'; 'Steps size'; 'Total steps'};
            app.ScanTable.RowName = {};
            app.AddScanButton = uibutton(app.GridLayout5, 'push');
            app.AddScanButton.ButtonPushedFcn = createCallbackFcn(app, @AddScanButtonPushed, true);
            app.AddScanButton.Text = 'Add Scan';
            app.DeleteScanButton = uibutton(app.GridLayout5, 'push');
            app.DeleteScanButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanButtonPushed, true);
            app.DeleteScanButton.Text = 'Delete Scan';
            app.Overwrite = uidropdown(app.GridLayout5);
            app.Overwrite.Items = {'Overwrite', 'Insert new'};
            app.Overwrite.Value = 'Overwrite';
            app.ScannumberLabel = uilabel(app.GridLayout5);
            app.ScannumberLabel.Text = 'Scan number';
            app.ScanNum = uieditfield(app.GridLayout5, 'text');
            app.ScanNum.ValueChangedFcn = createCallbackFcn(app, @ScanNumValueChanged, true);
            app.ScanNum.Value = '1';
            app.OutputSettingsTab = uitab(app.TabGroup);
            app.OutputSettingsTab.Title = 'Output Settings';
            app.GridLayout7 = uigridlayout(app.OutputSettingsTab);
            app.XaxisdimensionLabel = uilabel(app.GridLayout7);
            app.XaxisdimensionLabel.Text = 'X axis dimension';
            app.XDimension = uieditfield(app.GridLayout7, 'numeric');
            app.XDimension.Limits = [0 Inf];
            app.XDimension.ValueChangedFcn = createCallbackFcn(app, @XDimensionValueChanged, true);
            app.XDimension.Value = 1;
            app.YaxisdimensionLabel = uilabel(app.GridLayout7);
            app.YaxisdimensionLabel.Text = 'Y axis dimension';
            app.YDimension = uieditfield(app.GridLayout7, 'numeric');
            app.YDimension.Limits = [0 Inf];
            app.YDimension.ValueChangedFcn = createCallbackFcn(app, @YDimensionValueChanged, true);
            app.Progress = uipanel(app.GridLayout7);
            app.Progress.Title = 'Scan progress';
            app.UpdateperiodsLabel = uilabel(app.GridLayout7);
            app.UpdateperiodsLabel.Text = {'Update'; 'period (s)'};
            app.UpdatePeriod = uieditfield(app.GridLayout7, 'numeric');
            app.UpdatePeriod.Limits = [0 Inf];
            app.UpdatePeriod.ValueChangedFcn = createCallbackFcn(app, @UpdatePeriodValueChanged, true);
            app.Parameter = uidropdown(app.GridLayout7);
            app.Parameter.Items = {'Contrast', 'Signal', 'Reference'};
            app.Parameter.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Parameter.Value = 'Contrast';
            app.MeasurementparameterLabel = uilabel(app.GridLayout7);
            app.MeasurementparameterLabel.Text = {'Measurement'; 'parameter'};
            app.ContrasttypeDropDownLabel = uilabel(app.GridLayout7);
            app.ContrasttypeDropDownLabel.Text = 'Contrast type';
            app.ContrastType = uidropdown(app.GridLayout7);
            app.ContrastType.Items = {'Ref - Sig', '(Ref - Sig)/Ref', '(Ref - Sig)/Sig', '(Ref - Sig)/(Ref + Sig)'};
            app.ContrastType.ItemsData = {'R-S', 'R-S/R', 'R-S/S', 'R-S/R+S'};
            app.ContrastType.ValueChangedFcn = createCallbackFcn(app, @ContrastTypeValueChanged, true);
            app.ContrastType.Value = 'R-S';
            app.NormCounts = uicheckbox(app.GridLayout7);
            app.NormCounts.ValueChangedFcn = createCallbackFcn(app, @NormCountsValueChanged, true);
            app.NormCounts.Text = {'Convert to'; 'counts/s'};
            app.InvertXAxis = uicheckbox(app.GridLayout7);
            app.InvertXAxis.ValueChangedFcn = createCallbackFcn(app, @InvertXAxisValueChanged, true);
            app.InvertXAxis.Text = 'Invert x axis';
            app.PlotLimitsTable = uitable(app.GridLayout7);
            app.PlotLimitsTable.ColumnName = {'Limit'; 'Value'};
            app.PlotLimitsTable.RowName = {};
            app.PlotLimitsTable.ColumnEditable = [false true];
            app.PlotLimitsTable.CellEditCallback = createCallbackFcn(app, @PlotLimitsTableCellEdit, true);
            app.PulsenumberforplottingLabel = uilabel(app.GridLayout7);
            app.PulsenumberforplottingLabel.Text = {'Pulse number'; 'for plotting'};
            app.PulseAxis = uieditfield(app.GridLayout7, 'numeric');
            app.PulseAxis.ValueChangedFcn = createCallbackFcn(app, @PulseAxisValueChanged, true);
            app.Incorporatex = uicheckbox(app.GridLayout7);
            app.Incorporatex.ValueChangedFcn = createCallbackFcn(app, @IncorporatexValueChanged, true);
            app.Incorporatex.Text = {'Incorporate'; 'x offset'};
            app.Incorporatey = uicheckbox(app.GridLayout7);
            app.Incorporatey.ValueChangedFcn = createCallbackFcn(app, @IncorporateyValueChanged, true);
            app.Incorporatey.Text = {'Incorporate'; 'y offset'};
            app.AdditionalxoffsetEditFieldLabel = uilabel(app.GridLayout7);
            app.AdditionalxoffsetEditFieldLabel.Text = 'Additional x offset';
            app.AdditionalxOffset = uieditfield(app.GridLayout7, 'numeric');
            app.AdditionalxOffset.ValueChangedFcn = createCallbackFcn(app, @AdditionalxOffsetValueChanged, true);
            app.AdditionalyoffsetEditFieldLabel = uilabel(app.GridLayout7);
            app.AdditionalyoffsetEditFieldLabel.Text = 'Additional y offset';
            app.AdditionalyOffset = uieditfield(app.GridLayout7, 'numeric');
            app.AdditionalyOffset.ValueChangedFcn = createCallbackFcn(app, @AdditionalyOffsetValueChanged, true);
            app.NotificationsManualOperationTab = uitab(app.TabGroup);
            app.NotificationsManualOperationTab.Title = 'Notifications & Manual Operation';
            app.GridLayout10 = uigridlayout(app.NotificationsManualOperationTab);
            app.BeginManualOpt = uibutton(app.GridLayout10, 'push');
            app.BeginManualOpt.ButtonPushedFcn = createCallbackFcn(app, @BeginManualOptPushed, true);
            app.BeginManualOpt.Text = 'Begin Optimization(s)';
            app.NumberofmanualoptimizationsLabel = uilabel(app.GridLayout10);
            app.NumberofmanualoptimizationsLabel.Text = {'Number of manual'; 'optimizations'};
            app.ManualOptNum = uieditfield(app.GridLayout10, 'numeric');
            app.ManualOptNum.Limits = [1 Inf];
            app.ManualOptNum.ValueChangedFcn = createCallbackFcn(app, @ManualOptNumValueChanged, true);
            app.ManualOptNum.Value = 1;
            app.Notifications = uicheckbox(app.GridLayout10);
            app.Notifications.ValueChangedFcn = createCallbackFcn(app, @NotificationsValueChanged, true);
            app.Notifications.Text = {'Command'; 'window'; 'notifications'};
            app.Notifications.Value = true;
            app.CompletionEmail = uicheckbox(app.GridLayout10);
            app.CompletionEmail.ValueChangedFcn = createCallbackFcn(app, @CompletionEmailValueChanged, true);
            app.CompletionEmail.Text = {'Send email'; 'upon'; 'completion'};
            app.EmailIterationsCheckbox = uicheckbox(app.GridLayout10);
            app.EmailIterationsCheckbox.ValueChangedFcn = createCallbackFcn(app, @EmailIterationsCheckboxValueChanged, true);
            app.EmailIterationsCheckbox.Text = {'Send email'; 'every _'; 'iterations'};
            app.AutoSaveIterations = uieditfield(app.GridLayout10, 'numeric');
            app.AutoSaveIterations.Limits = [1 Inf];
            app.AutoSaveIterations.ValueChangedFcn = createCallbackFcn(app, @AutoSaveIterationsValueChanged, true);
            app.AutoSaveIterations.Value = 1;
            app.AutoSaveName = uieditfield(app.GridLayout10, 'text');
            app.AutoSaveName.ValueChangedFcn = createCallbackFcn(app, @AutoSaveNameValueChanged, true);
            app.AutoSaveName.Placeholder = 'Auto save folder name';
            app.AutoSaveCheckbox = uicheckbox(app.GridLayout10);
            app.AutoSaveCheckbox.ValueChangedFcn = createCallbackFcn(app, @AutoSaveCheckboxValueChanged, true);
            app.AutoSaveCheckbox.Text = {'Auto save'; 'every _ '; 'iterations'};
            app.ForceUpdate = uibutton(app.GridLayout10, 'push');
            app.ForceUpdate.ButtonPushedFcn = createCallbackFcn(app, @ForceUpdateButtonPushed, true);
            app.ForceUpdate.Text = 'Force Plot Update';
            app.EmailaddresstosendcompletionnotificationLabel = uilabel(app.GridLayout10);
            app.EmailaddresstosendcompletionnotificationLabel.Text = {'Email address to send'; 'completion notification'};
            app.EmailAddress = uieditfield(app.GridLayout10, 'text');
            app.EmailAddress.ValueChangedFcn = createCallbackFcn(app, @EmailAddressValueChanged, true);
            app.EmailAddress.Placeholder = 'YourEmail@gmail.com';
            app.RefreshAppButton = uibutton(app.GridLayout10, 'push');
            app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
            app.RefreshAppButton.Text = 'Refresh App';
            app.QuickCountsButton = uibutton(app.GridLayout10, 'push');
            app.QuickCountsButton.ButtonPushedFcn = createCallbackFcn(app, @QuickCountsButtonPushed, true);
            app.QuickCountsButton.Text = {'Obtain Quick'; 'counts/second'};
            app.QuickCountsOutput = uitextarea(app.GridLayout10);
            app.CountssecondRFsettingButtonGroup = uibuttongroup(app.GridLayout10);
            app.CountssecondRFsettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CountssecondRFsettingButtonGroupSelectionChanged, true);
            app.CountssecondRFsettingButtonGroup.Title = 'Counts/second RF setting';
            app.QuickCountsRFoff = uitogglebutton(app.CountssecondRFsettingButtonGroup);
            app.QuickCountsRFoff.Text = 'RF off';
            app.QuickCountsRFoff.Value = true;
            app.QuickCountsRFon = uitogglebutton(app.CountssecondRFsettingButtonGroup);
            app.QuickCountsRFon.Text = 'RF on';
            app.OptimizationresultsLabel = uilabel(app.GridLayout10);
            app.OptimizationresultsLabel.Text = {'Optimization'; 'results'};
            app.OptimizationResults = uitextarea(app.GridLayout10);
            app.EmailIterations = uieditfield(app.GridLayout10, 'numeric');
            app.EmailIterations.ValueChangedFcn = createCallbackFcn(app, @EmailIterationsValueChanged, true);
            app.BeginContinuousOptimizationButton = uibutton(app.GridLayout10, 'push');
            app.BeginContinuousOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginContinuousOptimizationButtonPushed, true);
            app.BeginContinuousOptimizationButton.Text = 'Begin Continuous Optimization';
            app.DisplayedpulsechannelsSpinnerLabel = uilabel(app.GridLayout10);
            app.DisplayedpulsechannelsSpinnerLabel.Text = {'Displayed'; 'pulse channels'};
            app.DisplayedChannels = uispinner(app.GridLayout10);
            app.DisplayedChannels.ValueChangedFcn = createCallbackFcn(app, @DisplayedChannelsValueChanged, true);
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.GridLayout9 = uigridlayout(app.InformationTab);
            app.Warnings = uitextarea(app.GridLayout9);
            app.Warnings.Editable = 'off';
            app.PrevSetups = uitextarea(app.GridLayout9);
            app.PrevSetups.Editable = 'off';
            app.PrevSeqs = uitextarea(app.GridLayout9);
            app.PrevSeqs.Editable = 'off';
            app.ObscureSettingsTab = uitab(app.TabGroup);
            app.ObscureSettingsTab.Title = 'Obscure Settings';
            app.GridLayout8 = uigridlayout(app.ObscureSettingsTab);
            app.OpttargetButtonGroup = uibuttongroup(app.GridLayout8);
            app.OpttargetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OpttargetButtonGroupSelectionChanged, true);
            app.OpttargetButtonGroup.Title = 'Opt target';
            app.RawValueButton = uitogglebutton(app.OpttargetButtonGroup);
            app.RawValueButton.Text = 'Raw Value';
            app.RawValueButton.Value = true;
            app.GaussianPeakButton = uitogglebutton(app.OpttargetButtonGroup);
            app.GaussianPeakButton.Text = 'Gaussian Peak';
            app.DurationofcountreadoutforoptimizationnsLabel = uilabel(app.GridLayout8);
            app.DurationofcountreadoutforoptimizationnsLabel.Text = {'Duration of count'; 'readout for'; 'optimization (ns)'};
            app.OptReadoutDur = uieditfield(app.GridLayout8, 'numeric');
            app.OptReadoutDur.Limits = [1 Inf];
            app.OptReadoutDur.ValueChangedFcn = createCallbackFcn(app, @OptReadoutDurValueChanged, true);
            app.OptReadoutDur.Value = 1000000;
            app.OptimizationParameter = uibuttongroup(app.GridLayout8);
            app.OptimizationParameter.SelectionChangedFcn = createCallbackFcn(app, @OptimizationParameterSelectionChanged, true);
            app.OptimizationParameter.Title = 'Opt settings';
            app.RFoffButton = uitogglebutton(app.OptimizationParameter);
            app.RFoffButton.Text = 'RF off';
            app.RFoffButton.Value = true;
            app.RFonButton = uitogglebutton(app.OptimizationParameter);
            app.RFonButton.Text = 'RF on';
            app.ContrastButton = uitogglebutton(app.OptimizationParameter);
            app.ContrastButton.Text = 'Contrast';
            app.StagetolerancemLabel = uilabel(app.GridLayout8);
            app.StagetolerancemLabel.Text = {'Stage'; 'tolerance (μm)'};
            app.StagePrecision = uieditfield(app.GridLayout8, 'numeric');
            app.StagePrecision.Limits = [0 Inf];
            app.StagePrecision.ValueChangedFcn = createCallbackFcn(app, @StagePrecisionValueChanged, true);
            app.StagePrecision.Value = 0.01;
            app.NumberofdigitsdisplayedLabel = uilabel(app.GridLayout8);
            app.NumberofdigitsdisplayedLabel.Text = {'Number of'; 'digits displayed'};
            app.DisplayDigits = uieditfield(app.GridLayout8, 'numeric');
            app.DisplayDigits.Limits = [1 10];
            app.DisplayDigits.ValueChangedFcn = createCallbackFcn(app, @DisplayDigitsValueChanged, true);
            app.DisplayDigits.Value = 4;
            app.LaserEquilibrium = uicheckbox(app.GridLayout8);
            app.LaserEquilibrium.ValueChangedFcn = createCallbackFcn(app, @LaserEquilibriumValueChanged, true);
            app.LaserEquilibrium.Text = {'Wait until laser'; 'is equilibrated'};
            app.LaserCleanOff = uicheckbox(app.GridLayout8);
            app.LaserCleanOff.ValueChangedFcn = createCallbackFcn(app, @LaserCleanOffValueChanged, true);
            app.LaserCleanOff.Text = {'Turn laser off'; 'when unloaded'};
            app.ResetLocation = uibuttongroup(app.GridLayout8);
            app.ResetLocation.SelectionChangedFcn = createCallbackFcn(app, @ResetLocationSelectionChanged, true);
            app.ResetLocation.Title = 'Fine reset loc';
            app.ResetMin = uitogglebutton(app.ResetLocation);
            app.ResetMin.Text = 'Minimum';
            app.ResetCenter = uitogglebutton(app.ResetLocation);
            app.ResetCenter.Text = 'Center';
            app.ResetCenter.Value = true;
            app.ResetMax = uitogglebutton(app.ResetLocation);
            app.ResetMax.Text = 'Maximum';
            app.PausedurationforcoarsestagemovementsmsLabel = uilabel(app.GridLayout8);
            app.PausedurationforcoarsestagemovementsmsLabel.Text = {'Pause duration'; 'for coarse stage'; 'movements (ms)'};
            app.CoarsePauseTime = uieditfield(app.GridLayout8, 'numeric');
            app.CoarsePauseTime.ValueChangedFcn = createCallbackFcn(app, @CoarsePauseTimeValueChanged, true);
            app.PausedurationforfinestagemovementsmsLabel = uilabel(app.GridLayout8);
            app.PausedurationforfinestagemovementsmsLabel.Text = {'Pause duration'; 'for fine stage'; 'movements (ms)'};
            app.FinePauseTime = uieditfield(app.GridLayout8, 'numeric');
            app.FinePauseTime.ValueChangedFcn = createCallbackFcn(app, @FinePauseTimeValueChanged, true);
            app.ComputerEditFieldLabel = uilabel(app.GridLayout8);
            app.ComputerEditFieldLabel.Text = 'Computer';
            app.ComputerEditField = uieditfield(app.GridLayout8, 'text');
            app.ComputerEditField.ValueChangedFcn = createCallbackFcn(app, @ComputerEditFieldValueChanged, true);
            app.ComputerEditField.Value = 'NV';
            app.RandomizeScan = uicheckbox(app.GridLayout8);
            app.RandomizeScan.ValueChangedFcn = createCallbackFcn(app, @RandomizeScanValueChanged, true);
            app.RandomizeScan.Text = {'Randomize'; 'scan'};
            app.RFmodulationtypeDropDownLabel = uilabel(app.GridLayout8);
            app.RFmodulationtypeDropDownLabel.Text = {'RF modulation'; 'type'};
            app.RFModulationType = uidropdown(app.GridLayout8);
            app.RFModulationType.Items = {'I/Q', 'Amplitude'};
            app.RFModulationType.ValueChangedFcn = createCallbackFcn(app, @RFModulationTypeValueChanged, true);
            app.RFModulationType.Value = 'I/Q';
            app.WidefieldvoltagecutoffVLabel = uilabel(app.GridLayout8);
            app.WidefieldvoltagecutoffVLabel.Text = {'Wide field'; 'voltage cutoff (V)'};
            app.WFCutoffVoltage = uieditfield(app.GridLayout8, 'numeric');
            app.WFCutoffVoltage.ValueChangedFcn = createCallbackFcn(app, @WFCutoffVoltageValueChanged, true);
            app.WFCutoffVoltage.Value = 0.01;
            app.DimensionSet = uitable(app.GridLayout8);
            app.DimensionSet.ColumnName = {'Order'; 'Type'; 'Number of steps'; 'Chosen step'; 'Chosen value'};
            app.DimensionSet.RowName = {};
            app.DimensionSet.ColumnEditable = [false false false true false];
            app.DimensionSet.CellEditCallback = createCallbackFcn(app, @DimensionSetCellEdit, true);
            app.StagealtTab = uitab(app.TabGroup);
            app.StagealtTab.Title = 'Stage (alt)';
            app.GridLayout11 = uigridlayout(app.StagealtTab);
            app.AltStageScan = uicheckbox(app.GridLayout11);
            app.AltStageScan.ValueChangedFcn = createCallbackFcn(app, @AltStageScanValueChanged, true);
            app.AltStageScan.Text = 'Use alternate stage system for scans';
            app.CoarseX = uieditfield(app.GridLayout11, 'numeric');
            app.CoarseX.ValueChangedFcn = createCallbackFcn(app, @CoarseXValueChanged, true);
            app.CoarsemmLabel = uilabel(app.GridLayout11);
            app.CoarsemmLabel.Text = 'Coarse (mm)';
            app.FinemLabel = uilabel(app.GridLayout11);
            app.FinemLabel.Text = 'Fine (μm)';
            app.XLabel = uilabel(app.GridLayout11);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout11);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout11);
            app.ZLabel.Text = 'Z';
            app.FineX = uieditfield(app.GridLayout11, 'numeric');
            app.FineX.ValueChangedFcn = createCallbackFcn(app, @FineXValueChanged, true);
            app.CoarseY = uieditfield(app.GridLayout11, 'numeric');
            app.CoarseY.ValueChangedFcn = createCallbackFcn(app, @CoarseYValueChanged, true);
            app.FineY = uieditfield(app.GridLayout11, 'numeric');
            app.FineY.ValueChangedFcn = createCallbackFcn(app, @FineYValueChanged, true);
            app.CoarseZ = uieditfield(app.GridLayout11, 'numeric');
            app.CoarseZ.ValueChangedFcn = createCallbackFcn(app, @CoarseZValueChanged, true);
            app.FineZ = uieditfield(app.GridLayout11, 'numeric');
            app.FineZ.ValueChangedFcn = createCallbackFcn(app, @FineZValueChanged, true);
            app.Label = uilabel(app.GridLayout11);
            app.Label.Text = {'Warning: If using the alternate stage system for scans, only the fine stage will be'; 'available. Additionally, you must manually move the coarse stage to where you'; 'want it to be when the scan begins such that the fine stage has enough room to'; 'complete the scan. If there is not enough room for the scan to complete, you will be'; 'given an error popup upon pressing the start button.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SavesetupButton = uibutton(app.GridLayout3, 'push');
            app.SavesetupButton.ButtonPushedFcn = createCallbackFcn(app, @SavesetupButtonPushed, true);
            app.SavesetupButton.Text = 'Save setup';
            app.SaveOutputButton = uibutton(app.GridLayout3, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = 'Save Output';
            app.LoadsetupButton = uibutton(app.GridLayout3, 'push');
            app.LoadsetupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsetupButtonPushed, true);
            app.LoadsetupButton.Text = 'Load setup';
            app.SetupLoadName = uieditfield(app.GridLayout3, 'text');
            app.SetupLoadName.Placeholder = 'Setup load name';
            app.AbortButton = uibutton(app.GridLayout3, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.SetupSaveName = uieditfield(app.GridLayout3, 'text');
            app.SetupSaveName.Placeholder = 'Setup save name';
            app.OutputSaveName = uieditfield(app.GridLayout3, 'text');
            app.OutputSaveName.Placeholder = 'Output save name';
            app.OutputLoadName = uieditfield(app.GridLayout3, 'text');
            app.OutputLoadName.Placeholder = 'Output load name';
            app.LoadoutputButton = uibutton(app.GridLayout3, 'push');
            app.LoadoutputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoutputButtonPushed, true);
            app.LoadoutputButton.Text = 'Load output';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatusLamp = uilamp(app.GridLayout3);
            app.OutputPanel = uipanel(app.GridLayout3);
            app.Colorbar = uipanel(app.GridLayout3);
            app.ScanInfo = uitextarea(app.GridLayout3);
            app.ScanInfo.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExperimentEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end