classdef ExperimentEditorBeta < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                       matlab.ui.Figure
      GridLayout                     matlab.ui.container.GridLayout
      LeftPanel                      matlab.ui.container.Panel
      TabGroup                       matlab.ui.container.TabGroup
      ConnectionTab                  matlab.ui.container.Tab
      GridLayout2                    matlab.ui.container.GridLayout
      LaserLamp                      matlab.ui.control.Lamp
      LaserLampLabel                 matlab.ui.control.Label
      LaserUnload                    matlab.ui.control.Button
      LaserLoad                      matlab.ui.control.Button
      EmailAddress                   matlab.ui.control.EditField
      EmailaddresstosendcompletionnotificationLabel  matlab.ui.control.Label
      ComputerEditField              matlab.ui.control.EditField
      ComputerEditFieldLabel         matlab.ui.control.Label
      BoxAddress                     matlab.ui.control.EditField
      LocalboxaddressEditFieldLabel  matlab.ui.control.Label
      AllLamp                        matlab.ui.control.Lamp
      AllLampLabel                   matlab.ui.control.Label
      UnloadAll                      matlab.ui.control.Button
      LoadAll                        matlab.ui.control.Button
      DAQUnload                      matlab.ui.control.Button
      PBUnload                       matlab.ui.control.Button
      StageUnload                    matlab.ui.control.Button
      DAQLoad                        matlab.ui.control.Button
      PBLoad                         matlab.ui.control.Button
      StageLoad                      matlab.ui.control.Button
      RFUnload                       matlab.ui.control.Button
      RFLoad                         matlab.ui.control.Button
      DAQLamp                        matlab.ui.control.Lamp
      DAQLampLabel                   matlab.ui.control.Label
      PulseBlasterLamp               matlab.ui.control.Lamp
      PulseBlasterLampLabel          matlab.ui.control.Label
      StageLamp                      matlab.ui.control.Lamp
      StageLampLabel                 matlab.ui.control.Label
      RFLamp                         matlab.ui.control.Lamp
      RFLampLabel                    matlab.ui.control.Label
      GeneralTab                     matlab.ui.container.Tab
      GridLayout4                    matlab.ui.container.GridLayout
      LaserSwitch                    matlab.ui.control.Switch
      LaserswitchSwitchLabel         matlab.ui.control.Label
      LaserPower                     matlab.ui.control.NumericEditField
      LaserpowerEditFieldLabel       matlab.ui.control.Label
      Parameter                      matlab.ui.control.DropDown
      MeasurementparameterLabel      matlab.ui.control.Label
      PulseSequence                  matlab.ui.container.Panel
      RFAmp                          matlab.ui.control.NumericEditField
      RFAmplitudedBmLabel            matlab.ui.control.Label
      RFFreq                         matlab.ui.control.NumericEditField
      RFFrequencyGHzLabel            matlab.ui.control.Label
      PulseSequenceEditorButton      matlab.ui.control.Button
      SequenceName                   matlab.ui.control.EditField
      RFOn                           matlab.ui.control.CheckBox
      IterationsType                 matlab.ui.container.ButtonGroup
      TimeminButton                  matlab.ui.control.ToggleButton
      NumberButton                   matlab.ui.control.ToggleButton
      IterationsNum                  matlab.ui.control.NumericEditField
      LoadpulsesequenceButton        matlab.ui.control.Button
      StageTab                       matlab.ui.container.Tab
      GridLayout6                    matlab.ui.container.GridLayout
      OptAddress                     matlab.ui.control.EditField
      OptimizationaddressEditFieldLabel  matlab.ui.control.Label
      DeletestepButton               matlab.ui.control.Button
      AxisLabel                      matlab.ui.control.Label
      StageHeader                    matlab.ui.control.Label
      OptTable                       matlab.ui.control.Table
      AddstepButton                  matlab.ui.control.Button
      OptSteps                       matlab.ui.control.NumericEditField
      StepsEditFieldLabel            matlab.ui.control.Label
      OptRadius                      matlab.ui.control.NumericEditField
      RadiusEditFieldLabel           matlab.ui.control.Label
      OptAxis                        matlab.ui.control.DropDown
      OptimizationOn                 matlab.ui.control.CheckBox
      OptimizationTiming             matlab.ui.control.NumericEditField
      TimebetweenoptimizationsminLabel_2  matlab.ui.control.Label
      PositionLabel                  matlab.ui.control.Label
      IncrementLabel                 matlab.ui.control.Label
      XaxisSliderLabel_2             matlab.ui.control.Label
      XSlider                        matlab.ui.control.Slider
      XPosition                      matlab.ui.control.NumericEditField
      XIncrement                     matlab.ui.control.NumericEditField
      XFineReset                     matlab.ui.control.Button
      XDown                          matlab.ui.control.Button
      XUp                            matlab.ui.control.Button
      YaxisSliderLabel_2             matlab.ui.control.Label
      YSlider                        matlab.ui.control.Slider
      YPosition                      matlab.ui.control.NumericEditField
      YIncrement                     matlab.ui.control.NumericEditField
      YFineReset                     matlab.ui.control.Button
      YDown                          matlab.ui.control.Button
      YUp                            matlab.ui.control.Button
      ZaxisSliderLabel_2             matlab.ui.control.Label
      ZSlider                        matlab.ui.control.Slider
      ZPosition                      matlab.ui.control.NumericEditField
      ZIncrement                     matlab.ui.control.NumericEditField
      ZFineReset                     matlab.ui.control.Button
      ZDown                          matlab.ui.control.Button
      ZUp                            matlab.ui.control.Button
      ScanningTab                    matlab.ui.container.Tab
      GridLayout5                    matlab.ui.container.GridLayout
      ScanNum                        matlab.ui.control.EditField
      ScannumberLabel                matlab.ui.control.Label
      Overwrite                      matlab.ui.control.DropDown
      DeleteScanButton               matlab.ui.control.Button
      AddScanButton                  matlab.ui.control.Button
      ScanTable                      matlab.ui.control.Table
      StepSizeField                  matlab.ui.control.NumericEditField
      NPointsStepSize                matlab.ui.container.ButtonGroup
      StepSizeButton                 matlab.ui.control.ToggleButton
      NPointsButton                  matlab.ui.control.ToggleButton
      PulseAddresses                 matlab.ui.control.EditField
      PulseaddressesifapplicableLabel  matlab.ui.control.Label
      EndScan                        matlab.ui.control.EditField
      EndEditFieldLabel              matlab.ui.control.Label
      StartScan                      matlab.ui.control.EditField
      StartEditFieldLabel            matlab.ui.control.Label
      ScanType                       matlab.ui.control.DropDown
      ScantypeDropDownLabel          matlab.ui.control.Label
      OutputSettingsTab              matlab.ui.container.Tab
      GridLayout7                    matlab.ui.container.GridLayout
      CurrentIteration               matlab.ui.control.TextArea
      CurrentiterationTextAreaLabel  matlab.ui.control.Label
      UpdatePeriod                   matlab.ui.control.NumericEditField
      UpdateperiodsLabel             matlab.ui.control.Label
      LowerPercentile                matlab.ui.control.NumericEditField
      LowerpercentilelimitLabel      matlab.ui.control.Label
      UpperPercentile                matlab.ui.control.NumericEditField
      UpperpercentilelimitLabel      matlab.ui.control.Label
      OtherscandimensionschosensliceLabel  matlab.ui.control.Label
      DimensionSet                   matlab.ui.control.Table
      Progress                       matlab.ui.container.Panel
      YDimension                     matlab.ui.control.NumericEditField
      YaxisdimensionLabel            matlab.ui.control.Label
      XDimension                     matlab.ui.control.NumericEditField
      XaxisdimensionLabel            matlab.ui.control.Label
      WarningsTab                    matlab.ui.container.Tab
      GridLayout9                    matlab.ui.container.GridLayout
      Warnings                       matlab.ui.control.TextArea
      ObscureSettingsTab             matlab.ui.container.Tab
      GridLayout8                    matlab.ui.container.GridLayout
      LaserCleanOff                  matlab.ui.control.CheckBox
      LaserEquilibrium               matlab.ui.control.CheckBox
      CompletionEmail                matlab.ui.control.CheckBox
      Notifications                  matlab.ui.control.CheckBox
      ManualOptNum                   matlab.ui.control.NumericEditField
      NumberofoptimizationsLabel     matlab.ui.control.Label
      BeginOptimizationsButton       matlab.ui.control.Button
      IterationEmail                 matlab.ui.control.CheckBox
      AutoSaveIterations             matlab.ui.control.NumericEditField
      Autosaveevery_iterationsLabel  matlab.ui.control.Label
      AutoSaveName                   matlab.ui.control.EditField
      AutosaveCheckBox               matlab.ui.control.CheckBox
      DisplayDigits                  matlab.ui.control.NumericEditField
      NumberofdigitsdisplayedLabel   matlab.ui.control.Label
      StagePrecision                 matlab.ui.control.NumericEditField
      StageprecisionmLabel           matlab.ui.control.Label
      OptimizationparametersettingsButtonGroup  matlab.ui.container.ButtonGroup
      ContrastButton                 matlab.ui.control.ToggleButton
      RFonButton                     matlab.ui.control.ToggleButton
      RFoffButton                    matlab.ui.control.ToggleButton
      OptReadoutDur                  matlab.ui.control.NumericEditField
      DurationofcountreadoutforoptimizationnsLabel  matlab.ui.control.Label
      OptimizationtargetButtonGroup  matlab.ui.container.ButtonGroup
      GaussianPeakButton             matlab.ui.control.ToggleButton
      RawValueButton                 matlab.ui.control.ToggleButton
      RightPanel                     matlab.ui.container.Panel
      GridLayout3                    matlab.ui.container.GridLayout
      OutputPanel                    matlab.ui.container.Panel
      StatusLamp                     matlab.ui.control.Lamp
      ResetButton                    matlab.ui.control.Button
      LoadoutputButton               matlab.ui.control.Button
      OutputLoadName                 matlab.ui.control.EditField
      OutputSaveName                 matlab.ui.control.EditField
      SetupSaveName                  matlab.ui.control.EditField
      AbortButton                    matlab.ui.control.Button
      SetupLoadName                  matlab.ui.control.EditField
      LoadsetupButton                matlab.ui.control.Button
      SaveOutputButton               matlab.ui.control.Button
      RefreshAppButton               matlab.ui.control.Button
      SavesetupButton                matlab.ui.control.Button
      StartButton                    matlab.ui.control.Button
      SingleOutput                   matlab.ui.control.TextArea
      SinglevalueoutputLabel         matlab.ui.control.Label
   end

   % Properties that correspond to apps with auto-reflow
   properties (Access = private)
      onePanelWidth = 576;
   end

% ExperimentEditor v0.4.1 4/12/22


    properties (Access = private)
        progresslayout
        progresstiles
        emptypatches
        fullpatches
        outputdisplay
        pulsetiles
        seqlayout
        pulselines
        
    end

    methods (Access = private)

        function  appUpdate(app)
            connectionUpdate(app)
            settingsUpdate(app)
            stageUpdate(app)
            scanningUpdate(app)
            outputSettingsUpdate(app)
            obscureUpdate(app)
            warningsUpdate(app)
        end


        function connectionUpdate(app)
            global master %#ok<*GVMIS>

            %The following section is for coloring the lamps
            %Using try/catch here bypasses potential errors of fields not existing
            try
                if master.RFgen.initialized
                    app.RFLamp.Color = [0 1 0];
                else
                    app.RFLamp.Color = [1 0 0];
                end
            catch
                app.RFLamp.Color = [1 0 0];
                master.RFgen.initialized = 0;
            end

            try
                if master.stage.initialized
                    app.StageLamp.Color = [0 1 0];
                else
                    app.StageLamp.Color = [1 0 0];
                end
            catch
                app.StageLamp.Color = [1 0 0];
                master.stage.initialized = 0;
            end

            try
                if master.PB.initialized
                    app.PulseBlasterLamp.Color = [0 1 0];
                else
                    app.PulseBlasterLamp.Color = [1 0 0];
                end
            catch
                app.PulseBlasterLamp.Color = [1 0 0];
                master.PB.initialized = 0;
            end

            try
                if master.NIDAQ.initialized
                    app.DAQLamp.Color = [0 1 0];
                else
                    app.DAQLamp.Color = [1 0 0];
                end
            catch
                app.DAQLamp.Color = [1 0 0];
                master.NIDAQ.initialized = 0;
            end

            try
                if master.NDYOV.initialized
                    app.LaserLamp.Color = [0 1 0];
                else
                    app.LaserLamp.Color = [1 0 0];
                end
            catch
                app.LaserLamp.Color = [1 0 0];
                master.NDYOV.initialized = 0;
            end

            aa = (master.NIDAQ.initialized && master.RFgen.initialized);
            bb = (master.stage.initialized && master.PB.initialized);
            if aa && bb && master.NDYOV.initialized
                app.AllLamp.Color = [0 1 0];
            else
                app.AllLamp.Color = [1 0 0];
            end

            try
                app.ComputerEditField.Value = master.stage.comp;
            catch
            end

            try
                app.EmailAddress.Value = master.gui.emailaddress;
            catch
            end

        end


        function settingsUpdate(app)
            global master

            cumdur = 0;
            for ii = 1:master.PB.nchannels
               if ~isvalid(app.pulsetiles{ii})
                  app.pulsetiles{ii} = nexttile(app.seqlayout,ii);
               end
            end
            
            if isfield(master.PB,'sequence')
               
               for jj = 1:numel(master.PB.sequence)
                  cumdur = cumdur + master.PB.sequence(jj).duration;
               end

               channelx = zeros(1,numel(master.PB.sequence));
               channely= channelx;
               for ii = 1:master.PB.nchannels
                  for jj = 1:numel(master.PB.sequence)
                     if ii == 1
                        if jj == 1
                           channelx(1) = 1;
                        else
                           channelx(jj*2-1) = channelx((jj-1)*2);
                        end

                        channelx(jj*2) = channelx(jj*2-1) + master.PB.sequence(jj).duration;
                     end

                     channely(jj*2-1) = str2double(master.PB.sequence(jj).binaryoutput(1+end-ii));
                     channely(jj*2) = channely(jj*2-1) ;

                  end
                  
                  app.pulselines{ii} = plot(app.pulsetiles{ii},channelx,channely);
                  app.pulselines{ii}.Color = master.gui.PSE.lineColors(ii);
               end


            end
            if cumdur == 0
               cumdur = 1;
            end
            %Even if no sequence, change properties of tiles
               for ii = 1:master.PB.nchannels
                  app.pulsetiles{ii}.YTick = [];
                  if ii ~= master.PB.nchannels
                     app.pulsetiles{ii}.XTick = [];
                  else
                     app.pulsetiles{ii}.TickLength = [0 0];
                  end
                  app.pulsetiles{ii}.XLim = [0 cumdur];
                  app.pulsetiles{ii}.YLim = [-.4 1.4];
                  if ~mod(ii,2)
                     app.pulsetiles{ii}.YAxisLocation = 'right';
                  end
                  app.pulsetiles{ii}.YLabel.String = convertStringsToChars(master.gui.PSE.channelTitles(ii));
               end

            try
                app.RFFreq.Value = master.RFgen.freq;
            catch
            end

            try
                app.RFAmp.Value = master.RFgen.amp;
            catch
            end

            try
                if master.RFgen.switch == "on"
                    app.RFOn.Value = true;
                else
                    app.RFOn.Value = false;
                end
            catch
            end

            try
                if strcmp(master.gui.itertype,'Time')
                    app.IterationsType.SelectedObject = app.TimeminButton;
                elseif strcmp(master.gui.itertype,'Number')
                    app.IterationsType.SelectedObject = app.NumberButton;
                end
            catch
            end

            try
                app.IterationsNum.Value = master.gui.maxits;
            catch
            end

            try
                app.LaserSwitch.Value = convertStringsToChars(master.NDYOV.switch);
            catch
            end

            try
                app.LaserPower.Value = master.NDYOV.setpower;
            catch
            end

        end


        function  stageUpdate(app)
            global master

            try
                app.OptimizationOn.Value = master.gui.optswitch;
            catch
            end

            try
                app.OptimizationTiming.Value = master.gui.opttime;
            catch
            end

            try
                app.XIncrement.Value = master.gui.xinc;
            catch
            end

            try
                app.YIncrement.Value = master.gui.yinc;
            catch
            end

            try
                app.ZIncrement.Value = master.gui.zinc;
            catch
            end

            try
                app.XSlider.Value = master.stage.loc(1)+master.stage.loc(4);
                app.XPosition.Value = master.stage.loc(1)+master.stage.loc(4);

                app.YSlider.Value = master.stage.loc(2)+master.stage.loc(5);
                app.YPosition.Value = master.stage.loc(2)+master.stage.loc(5);

                app.ZSlider.Value = master.stage.loc(3)+master.stage.loc(6);
                app.ZPosition.Value = master.stage.loc(3)+master.stage.loc(6);
            catch
            end

            try
                app.OptTable.Data = [master.stage.sequence' master.stage.radius' master.stage.steps'];
            catch
                app.OptTable.Data = [];
            end
        end


        function  scanningUpdate(app)
            global master
            try
                app.ScanType.Value = master.gui.scaninstruct.type;
            catch
            end

            try
                disptext = num2str(master.gui.scaninstruct.starts(1));
                if length(master.gui.scaninstruct.starts)~=1
                    for ii = 1:length(master.gui.scaninstruct.starts)-1
                        disptext = [disptext ', ' num2str(master.gui.scaninstruct.starts(ii+1))]; %#ok<AGROW>
                    end
                end
                app.StartScan.Value = disptext;
            catch
            end

            try
                disptext = num2str(master.gui.scaninstruct.ends(1));
                if length(master.gui.scaninstruct.ends)~=1
                    for ii = 1:length(master.gui.scaninstruct.ends)-1
                        disptext = [disptext ', ' num2str(master.gui.scaninstruct.ends(ii+1))]; %#ok<AGROW>
                    end
                end
                app.EndScan.Value = disptext;
            catch
            end

            try
                disptext = num2str(master.gui.scaninstruct.addresses(1));
                if length(master.gui.scaninstruct.addresses)~=1
                    for ii = 1:length(master.gui.scaninstruct.addresses)-1
                        disptext = [disptext ', ' num2str(master.gui.scaninstruct.addresses(ii+1))]; %#ok<AGROW>
                    end
                end
                app.PulseAddresses.Value = disptext;
            catch
            end

            try
                app.StepSizeField.Value = master.gui.scaninstruct.stepinput;
            catch
            end

            try
                app.ScanNum.Value = master.gui.scaninstruct.scannum;
            catch
            end

            try
                if strcmp(master.gui.scaninstruct.steptype,'# of points')
                    app.NPointsStepSize.SelectedObject = app.NPointsButton;
                else
                    app.NPointsStepSize.SelectedObject = app.StepSizeButton;
                end
            catch
            end

            try
                scaninfo = master.gui.scan;
                nscans = length(scaninfo);
                addresses = (1:nscans)';
                scantypes = strings(nscans,1);
                scanstarts = scantypes;
                scanends = scantypes;
                scanstepsize = scantypes;
                scannsteps = zeros(nscans,1);
                for ii = 1:nscans
                    scantypes(ii) = scaninfo(ii).type;

                    currstart = '';
                    currend = currstart;
                    currstepsize = currstart;
                    for jj = 1:numel(scaninfo(ii).starts)
                        currstart = sprintf('%s%.*g, ',currstart,app.DisplayDigits.Value,scaninfo(ii).starts(jj));
                        currend = sprintf('%s%.*g, ',currend,app.DisplayDigits.Value,scaninfo(ii).ends(jj));
                        currstepsize = sprintf('%s%.*g, ',currstepsize,app.DisplayDigits.Value,scaninfo(ii).stepsize(jj));
                    end
                    currstart(end-1:end) = [];
                    currend(end-1:end) = [];
                    currstepsize(end-1:end) = [];
                    scanstarts(ii) = convertCharsToStrings(currstart);
                    scanends(ii) = convertCharsToStrings(currend);
                    scanstepsize(ii) = convertCharsToStrings(currstepsize);

                    scannsteps(ii) = scaninfo(ii).nsteps;
                end

                app.ScanTable.Data = [addresses scantypes scanstarts scanends scanstepsize scannsteps];

            catch
                app.ScanTable.Data = [];
            end

        end


        function outputSettingsUpdate(app)
            global master

            try

                scaninfo = master.gui.scan;
                nscans = length(scaninfo);
                addresses = (1:nscans)';
                scantypes = strings(nscans,1);
                scannsteps = zeros(nscans,1);
                chosenvals = scannsteps;


                for ii = 1:nscans
                    scantypes(ii) = scaninfo(ii).type;

                    scannsteps(ii) = scaninfo(ii).nsteps;

                    intermediate = (master.gui.chosensteps(ii)-1)*scaninfo(ii).stepsize(1);
                    chosenvals(ii) = convertCharsToStrings(sprintf('%.2g',intermediate + scaninfo(ii).starts(1)));
                end

                chosensteps = master.gui.chosensteps;

                app.DimensionSet.Data = [addresses scantypes scannsteps chosensteps' chosenvals];


            catch
                app.DimensionSet.Data = [];
                master.gui.progresslayout = [];
            end

            try
                app.UpdatePeriod.Value = master.gui.updateperiod;
            catch
            end

            try
                app.XDimension.Value = master.gui.plotxdim;
            catch
            end

            try
                app.YDimension.Value = master.gui.plotydim;
            catch
            end

            try
                app.UpperPercentile.Value = master.gui.highpercentile;
            catch
            end

            try
                app.LowerPercentile.Value = master.gui.lowpercentile;
            catch
            end

        end


        function progressUpdate(app)
            global master
            
            if master.gui.nscans ~= 0
                   
                   %Create the tiled layout as well as the tiles and both
                   %empty and full patches. also alter settings of these as
                   %desired
                   if master.gui.makeplot.progressbars
                       if isfield(master.gui,'progresslayout')
                           delete(master.gui.progresslayout)
                       end
                      master.gui.progresslayout = tiledlayout(app.Progress,master.gui.nscans,1);
                      for ii = 1:master.gui.nscans
                         switch ii
                            case 2
                               barcolor = 'r';
                            case 3
                               barcolor = 'g';
                            otherwise
                               barcolor = 'b';
                         end
                         app.progresstiles{ii} = nexttile(master.gui.progresslayout,ii);
                         app.progresstiles{ii}.Title.String = sprintf('Scan %d',ii);
                         app.progresstiles{ii}.XTick = [];
                         app.progresstiles{ii}.YTick = [];
                         app.progresstiles{ii}.UserData = 0; %0 steps have been plotted
                         app.emptypatches{ii} = patch(app.progresstiles{ii},[1 0 0 1],[0 0 1 1],[210/255 210/255 210/255]);%empty area
                         app.fullpatches{ii} = patch(app.progresstiles{ii},[0 0 0 0],[0 0 1 1],barcolor);%filled area
                      end
                      master.gui.makeplot.progressbars = false;
                   end
                   
                   %Update x data for bars that need updates
                   if isfield(master.gui,'odomatrix') && isfield(master.gui,'loopmatrix')
                      for ii = 1:master.gui.nscans
                         if app.progresstiles{ii}.UserData ~= master.gui.odomatrix(ii)
                            scanfraction = master.gui.odomatrix(ii) / master.gui.loopmatrix(ii);
                            app.emptypatches{ii}.XData = [1 scanfraction scanfraction 1];
                            app.fullpatches{ii}.XData = [0 scanfraction scanfraction 0];
                            app.progresstiles{ii}.UserData = master.gui.odomatrix(ii);
                         end
                      end
                   end
                   
            end
            
            if ~isfield(master.gui,'odoplotted')
                master.gui.odoplotted = ones(1,length(master.gui.odomatrix));
            end
            
            if any(master.gui.odoplotted ~= master.gui.odomatrix)
               
               if master.gui.step ~= 0
                  middledata = master.gui.vecdata;
                  master.gui.uncollected = min(middledata(1:master.gui.step),[],"all")*.9;
                  middledata(master.gui.step+1:end) = master.gui.uncollected;

                  if isfield(master,'output')
                     if master.gui.expcomplete
                        updatedata = master.output;
                     else
                        if master.gui.nscans ~= 1
                           unfurledoutput = permute(master.output,flip(1:master.gui.nscans));
                           unfurledoutput = reshape(unfurledoutput,[],1);
                        else
                           unfurledoutput = master.output;
                        end

                        updatedata = (unfurledoutput + middledata)./master.gui.itnum;
                        updatedata(master.gui.step+1:end) = unfurledoutput(master.gui.step+1:end)./(master.gui.itnum-1);
                        if master.gui.nscans ~= 1
                           updatedata = reshape(updatedata,flip(master.gui.loopmatrix));
                           updatedata = permute(updatedata,flip(1:master.gui.nscans));
                        end
                     end
                  else
                     updatedata = middledata;
                     if master.gui.nscans ~= 1
                        updatedata = reshape(updatedata,flip(master.gui.loopmatrix));
                        updatedata = permute(updatedata,flip(1:master.gui.nscans));
                     end
                  end

                  plotData(app,updatedata)

               end
               
               master.gui.odoplotted = master.gui.odomatrix;
            end

        end


        function  dataout = dataCollection(~,scannum)
            global master
            global NIDAQ

            currscan = master.gui.scan(scannum);
            stepnum = master.gui.odomatrix(scannum);
            allones = find(master.gui.odomatrix == 1);
            lowerresets = allones > scannum;
            lowerresets = allones(lowerresets);

            for ii = 1:length(lowerresets)
                aa = lowerresets(ii);
                if strcmp(master.gui.scan(aa).type,'Stage X') ||...
                      strcmp(master.gui.scan(aa).type,'Stage Y') || strcmp(master.gui.scan(aa).type,'Stage Z')

                    if strcmp(master.gui.scan(aa).type,'Stage X')
                        master.stage.ax = "x";
                    elseif strcmp(master.gui.scan(aa).type,'Stage Y')
                        master.stage.ax = "y";
                    else
                        master.stage.ax = "z";
                    end

                    master.stage.atarget = master.gui.scan(aa).starts;
                    PIStageAbsoluteMove()

                elseif strcmp(master.gui.scan(aa).type,'RF frequency')

                    master.RFgen.freq = master.gui.scan(aa).starts + ((stepnum-1)*master.gui.scan(aa).stepsize);
                    RFFrequency()

                elseif strcmp(master.gui.scan(aa).type,'Pulse duration')

                    for kk = 1:length(master.gui.scan(aa).starts)
                        newdur = master.gui.scan(aa).starts(kk) + ((stepnum-1)*master.gui.scan(aa).stepsize(kk));
                        master.PB.scan(master.gui.scan(aa).addresses(kk)).duration = newdur;
                    end
                    PBFinalize()

                end

            end

            if strcmp(currscan.type,'Stage X') || strcmp(currscan.type,'Stage Y') || strcmp(currscan.type,'Stage Z')

                if strcmp(currscan.type,'Stage X')
                    master.stage.ax = "x";
                elseif strcmp(currscan.type,'Stage Y')
                    master.stage.ax = "y";
                else
                    master.stage.ax = "z";
                end
                master.stage.target = currscan.stepsize;

                if stepnum == 1
                    master.stage.atarget = currscan.starts;
                    PIStageAbsoluteMove()
                else
                    PIStageRelativeMove()
                end

            elseif strcmp(currscan.type,'RF frequency')

                master.RFgen.freq = currscan.starts + ((stepnum-1)*currscan.stepsize);
                RFFrequency()

            elseif strcmp(currscan.type,'Pulse duration')

                for kk = 1:length(currscan.starts)
                    newdur = currscan.starts(kk) + ((stepnum-1)*currscan.stepsize(kk));
                    master.PB.scan(currscan.addresses(kk)).duration = newdur;
                end
                PBFinalize()

            end

            %This seems to be an issue with the data acquisition toolbox?
            warning('off','MATLAB:subscripting:noSubscriptsSpecified')

            resetcounters(NIDAQ)
            PBRun()
            daqout = read(NIDAQ);

            if strcmp(master.gui.meastype,'Contrast')
                dataout = daqout.Dev1_ctr3-daqout.Dev1_ctr1;
            elseif strcmp(master.gui.meastype,'Channel 1 counts')
                dataout = daqout.Dev1_ctr1;
            elseif strcmp(master.gui.meastype,'Channel 3 counts')
                dataout = daqout.Dev1_ctr3;
            end

        end


        function plotData(app,data)
            global master

            xdim = master.gui.plotxdim;
            ydim = master.gui.plotydim;
            
            if ~isfield(master.gui,'prevuncollected')
                master.gui.prevuncollected = 0;
            end


            if xdim ~= 0 && ydim == 0


                %Take only one vector of numbers that is along the x
                %dimension with all other dimensions set to their
                %individual value as determined by master.gui.dimselect

                maxcare = size(data,xdim);
                vals = 1:maxcare;

                celldims = cell(1,master.gui.nscans);
                for ii = 1:master.gui.nscans
                    if ii == xdim
                        currval = vals;
                    else
                        currval = master.gui.chosensteps(ii);
                    end
                    celldims(ii) = {currval};
                end

                selecteddata = squeeze(data(celldims{:}));
                if master.gui.lowpercentile < 0
                    xdiff = prctile(selecteddata,abs(master.gui.lowpercentile),"all")-prctile(selecteddata,0,"all");
                    lims(1) = prctile(selecteddata,0,"all") - xdiff;
                else
                    lims(1) = prctile(selecteddata,master.gui.lowpercentile,"all");
                end
                if master.gui.lowpercentile > 100
                    xdiff = prctile(selecteddata,200-master.gui.highpercentile,"all")-prctile(selecteddata,100,"all");
                    lims(2) = prctile(selecteddata,100,"all") - xdiff;
                else
                    lims(2) = prctile(selecteddata,master.gui.highpercentile,"all");
                end

                if lims(1) == lims(2)
                    lims(1) = lims(1) - .01;
                    lims(2) = lims(2) + .01;
                end
                
                if master.gui.makeplot.output
                    if isfield(master.gui,'outax')
                        delete(master.gui.outax)
                    end
                    master.gui.outax = axes(app.OutputPanel);
                   master.gui.outax.YLimMode = 'manual';
                   master.gui.outax.YLim = lims;
                   master.gui.outax.YLabel.String = master.gui.meastype;
                   master.gui.outax.XLabel.String = master.gui.scan(xdim).type;                   
                   app.outputdisplay = plot(master.gui.outax,...
                      linspace(master.gui.scan(xdim).starts(1),master.gui.scan(xdim).ends(1),master.gui.scan(xdim).nsteps),selecteddata);                   
                   
                   master.gui.makeplot.output = false;
                else
                   master.gui.outax.YLim = lims;
                   
                   %If only 1 scan, always update
                   %If multiple scans, only update when chosen value is
                   %contained within [oldvalue newvale] inclusive for all
                   %other scans
                   if xdim ~= 1
                      if any(app.outputdisplay.UserData(1:xdim-1) ~= master.gui.odomatrix(1:xdim-1))
                         checkscans = true;
                      end
                   end
                   doupdate = false;
                   if master.gui.nscans == 1
                      doupdate = true;                      
                   elseif master.gui.odomatrix(xdim) ~= app.outputdisplay.UserData(xdim) || checkscans
                      %If it updated on chosenscans, then doupdate
                      scannum = zeros(1,master.gui.nscans);
                      for ii = 1:master.gui.nscans
                          scannum(ii) = str2double(master.gui.scan(ii).scannum);
                      end
                      otherscans = find(scannum ~= xdim);
                      chosenother = master.gui.chosensteps(otherscans);
                      oldnum = app.outputdisplay.UserData(otherscans);
                      newnum = master.gui.odomatrix(otherscans);
                      cgreatertrue = oldnum <= chosenother; %chosen value greater than old
                      clesstrue = chosenother <= newnum;%chosen value less than new
                      ctrue = clesstrue == cgreatertrue;%greater and less have same value
                      greatertrue = oldnum > newnum;%completed round
                      if all(ctrue+greatertrue == 1) 
                         doupdate = true;
                      end
                      %There is a case where an extra update occurrs. If
                      %the number completes a round but the chosen value is
                      %between the new and old value EXCLUSIVE, no update
                      %is needed but this will still update. It is in my
                      %opninion that this is such an unlikely event with
                      %very little downside of failure that it isn't worth
                      %programming for
                      %There may also be some small cases where an update
                      %is not triggered where it should be but again, I
                      %don't think it is likely to happen during normal use
                      %and the result is merely a visual bug, not one
                      %affecting the data itself
                   end                 
                   
                   
                   aa = app.outputdisplay.UserData(xdim)+1 < master.gui.odomatrix(xdim);
                   bb = master.gui.uncollected == master.gui.prevuncollected;
                   if doupdate
                      if aa && bb
                         app.outputdisplay.YData(app.outputdisplay.UserData(xdim)+1:master.gui.odomatrix(xdim))...
                            = selecteddata(app.outputdisplay.UserData(xdim)+1:master.gui.odomatrix(xdim));
                         %If new iteration has begun or if the minimum has changed, update all data
                      else
                         app.outputdisplay.YData = selecteddata;
                         master.gui.prevuncollected = master.gui.uncollected;
                      end
                   end
                end
                app.outputdisplay.UserData = master.gui.odomatrix;%last step plotted

            elseif xdim == 0 && ydim ~= 0

                %Take only one vector of numbers that is along the x
                %dimension with all other dimensions set to their
                %individual value as determined by master.gui.dimselect

                maxcare = size(data,ydim);
                vals = 1:maxcare;

                celldims = cell(1,master.gui.nscans);
                for ii = 1:ndims(data)
                    if ii == ydim
                        currval = vals;
                    else
                        currval = master.gui.chosensteps(ii);
                    end
                    celldims(ii) = {currval};
                end

                selecteddata = squeeze(data(celldims{:}));
                if master.gui.lowpercentile < 0
                    xdiff = prctile(selecteddata,abs(master.gui.lowpercentile),"all")-prctile(selecteddata,0,"all");
                    lims(1) = prctile(selecteddata,0,"all") - xdiff;
                else
                    lims(1) = prctile(selecteddata,master.gui.lowpercentile,"all");
                end
                if master.gui.lowpercentile > 100
                    xdiff = prctile(selecteddata,200-master.gui.highpercentile,"all")-prctile(selecteddata,100,"all");
                    lims(2) = prctile(selecteddata,100,"all") - xdiff;
                else
                    lims(2) = prctile(selecteddata,master.gui.highpercentile,"all");
                end

                if lims(1) == lims(2)
                    lims(1) = lims(1) - .01;
                    lims(2) = lims(2) + .01;
                end
                if master.gui.updatecounter == 4
                    cla(master.gui.outax,'reset')
                end

                if master.gui.makeplot.output
                    if isfield(master.gui,'outax')
                        delete(master.gui.outax)
                    end
                    master.gui.outax = axes(app.OutputPanel);
                   master.gui.outax.XLimMode = 'manual';
                   master.gui.outax.XLim = lims;
                   master.gui.outax.XLabel.String = master.gui.meastype;
                   master.gui.outax.YLabel.String = master.gui.scan(ydim).type;
                   app.outputdisplay = plot(master.gui.outax,selecteddata,... 
                      linspace(master.gui.scan(ydim).starts(1),master.gui.scan(ydim).ends(1),master.gui.scan(ydim).nsteps));                   
                   
                   master.gui.makeplot.output = false;
                else
                   master.gui.outax.XLim = lims;
                   
                   %If only 1 scan, always update
                   %If multiple scans, only update when chosen value is
                   %contained within [oldvalue newvale] inclusive for all
                   %other scans
                   if ydim ~= 1
                      if any(app.outputdisplay.UserData(1:ydim-1) ~= master.gui.odomatrix(1:ydim-1))
                         checkscans = true;
                      end
                   end
                   doupdate = false;
                   if master.gui.nscans == 1
                      doupdate = true;                      
                   elseif master.gui.odomatrix(ydim) ~= app.outputdisplay.UserData(ydim) || checkscans
                      %If it updated on chosenscans, then doupdate
                      scannum = zeros(1,master.gui.nscans);
                      for ii = 1:master.gui.nscans
                          scannum(ii) = str2double(master.gui.scan(ii).scannum);
                      end
                      otherscans = find(scannum ~= ydim);
                      chosenother = master.gui.chosensteps(otherscans);
                      oldnum = app.outputdisplay.UserData(otherscans);
                      newnum = master.gui.odomatrix(otherscans);
                      cgreatertrue = oldnum <= chosenother; %chosen value greater than old
                      clesstrue = chosenother <= newnum;%chosen value less than new
                      ctrue = clesstrue == cgreatertrue;%greater and less have same value
                      greatertrue = oldnum > newnum;%completed round
                      if all(ctrue+greatertrue == 1) 
                         doupdate = true;
                      end
                      %There is a case where an extra update occurrs. If
                      %the number completes a round but the chosen value is
                      %between the new and old value EXCLUSIVE, no update
                      %is needed but this will still update. It is in my
                      %opninion that this is such an unlikely event with
                      %very little downside of failure that it isn't worth
                      %programming for
                      %There may also be some small cases where an update
                      %is not triggered where it should be but again, I
                      %don't think it is likely to happen during normal use
                      %and the result is merely a visual bug, not one
                      %affecting the data itself
                   end  
                   
                   aa = app.outputdisplay.UserData(ydim)+1 < master.gui.odomatrix(ydim);
                   bb = master.gui.uncollected == master.gui.prevuncollected;
                   if doupdate
                      if aa && bb
                         app.outputdisplay.XData(app.outputdisplay.UserData(ydim)+1:master.gui.odomatrix(ydim))...
                            = selecteddata(app.outputdisplay.UserData(ydim)+1:master.gui.odomatrix(ydim));
                         %If new iteration has begun or if the minimum has changed, update all data
                      else
                         app.outputdisplay.XData = selecteddata;
                         master.gui.prevuncollected = master.gui.uncollected;
                      end
                   end
                end
                   
                app.outputdisplay.UserData = master.gui.odomatrix;%last step plotted

            elseif (xdim ~= 0 && ydim ~= 0) && ydim ~= xdim
                
                maxcare = size(data,xdim);
                xvals = 1:maxcare;
                maxcare = size(data,ydim);
                yvals = 1:maxcare;
                celldims = cell(1,master.gui.nscans);
                for ii = 1:ndims(data)
                    if ii == ydim
                        currval = yvals;
                    elseif ii == xdim
                        currval = xvals;
                    else
                        currval = master.gui.chosensteps(ii);
                    end
                    celldims(ii) = {currval};
                end
                selecteddata = squeeze(data(celldims{:}));
                if master.gui.lowpercentile < 0
                    xdiff = prctile(selecteddata,abs(master.gui.lowpercentile),"all")-prctile(selecteddata,0,"all");
                    lims(1) = prctile(selecteddata,0,"all") - xdiff;
                else
                    lims(1) = prctile(selecteddata,master.gui.lowpercentile,"all");
                end
                if master.gui.lowpercentile > 100
                    xdiff = prctile(selecteddata,200-master.gui.highpercentile,"all")-prctile(selecteddata,100,"all");
                    lims(2) = prctile(selecteddata,100,"all") - xdiff;
                else
                    lims(2) = prctile(selecteddata,master.gui.highpercentile,"all");
                end

                if xdim < ydim
                    selecteddata = permute(selecteddata,[2 1]);
                end

                if lims(1) == lims(2)
                    lims(1) = lims(1) - .01;
                    lims(2) = lims(2) + .01;
                end
                
                %if ydim < xdim, update prevupdated:current rows else update prevupdated:current columns
                if ydim < xdim
                   highdim = ydim;
                else
                   highdim = xdim;
                end
                if master.gui.makeplot.output
                    if isfield(master.gui,'outax')
                        delete(master.gui.outax)
                    end
                   master.gui.outax = axes(app.OutputPanel);                   
                   app.outputdisplay = imagesc(master.gui.outax,selecteddata);   
%                    master.test = app.outputdisplay;
                   master.gui.outax.CLim = lims;                   
                   app.outputdisplay.YData = [master.gui.scan(ydim).starts master.gui.scan(ydim).ends];
                   master.gui.outax.YLim = [master.gui.scan(ydim).starts-master.gui.scan(ydim).stepsize master.gui.scan(ydim).ends+master.gui.scan(ydim).stepsize];
                   master.gui.outax.XLim = [master.gui.scan(xdim).starts-master.gui.scan(xdim).stepsize master.gui.scan(xdim).ends+master.gui.scan(xdim).stepsize];
                   app.outputdisplay.XData = [master.gui.scan(xdim).starts master.gui.scan(xdim).ends];
                   master.gui.outax.YLabel.String = master.gui.scan(ydim).type;
                   master.gui.outax.XLabel.String = master.gui.scan(xdim).type;
                   master.gui.makeplot.output = false;
                else
                   master.gui.outax.CLim = lims;
                   if isfield(master.gui,'prevuncollected')
                      master.gui.prevuncollected = -.000001;
                   end
                   
                   %I am deciding to forgo much of the complicated logic
                   %done for the plots in favor of slight delays in updates
                   %and slight increses in unneeded updates.
                   if app.outputdisplay.UserData(highdim) <= master.gui.odomatrix(highdim)...
                         && master.gui.uncollected == master.gui.prevuncollected
                      
                      %only update the rows/columns of the high dim
                      if highdim == ydim
                         app.outputdisplay.CData(app.outputdisplay.UserData(highdim):master.gui.odomatrix(highdim),:)...
                         = selecteddata(app.outputdisplay.UserData(highdim):master.gui.odomatrix(highdim),:);
                      else
                         app.outputdisplay.CData(:,app.outputdisplay.UserData(highdim):master.gui.odomatrix(highdim))...
                         = selecteddata(:,app.outputdisplay.UserData(highdim):master.gui.odomatrix(highdim));
                      end                      

                   else
                      app.outputdisplay.CData = selecteddata;                      
                   end
                   master.gui.prevuncollected = master.gui.uncollected;
                   
                end
                   app.outputdisplay.UserData = master.gui.odomatrix;

            else %ydim and xdim are equal
                celldims = cell(1,master.gui.nscans);
                for ii = 1:ndims(data)
                    currval = master.gui.chosensteps(ii);
                    celldims(ii) = {currval};
                end
                selecteddata = squeeze(data(celldims{:}));
                app.SingleOutput = selecteddata;
            end
            

        end


        function pulseUpdate(app,ntile,name,color)

            %Ignore the errors in this function. they do not cause problems
            %but I cannot get rid of them. quite frankly I don't know why
            %they are here
            global master
            aa = master.PB;
            seq = nexttile(app.pulsetiles,ntile);
            plot(seq,aa.xdur,aa.yseqs(:,end+1-ntile),'Color',color)
            set(seq,'YTick',[])
            if ntile ~= master.PB.nchannels
                set(seq,'XTick',[])
            else
                set(seq,'TickLength',[0 0])
            end
            ylabel(seq,name)
            if ~mod(ntile,2)
                set(seq,'YAxisLocation','right')
            end
            ylim(seq,[-.2 1.7])
            xlim(seq,[0,aa.xdur(end)])

        end


        function stepConversion(app)
            global master
            try
                aa = master.gui.scaninstruct;
                if strcmp(aa.steptype,'# of points')
                    aa.stepsize = (aa.ends-aa.starts)./(aa.stepinput-1);
                    aa.nsteps = aa.stepinput;
                elseif strcmp(aa.steptype,'Step size') && length(aa.starts) ~= 1
                    aa.steptype = '# of points';
                    app.NPointsStepSize.SelectedObject = app.NPointsButton;
                    errordlg('Cannot scan by step size if multiple pulse durations are changing','Cannot use step size')
                else
                    aa.nsteps = 1+ceil(((aa.ends-aa.starts) ./ aa.stepinput));
                    aa.stepsize = aa.stepinput;
                end
                master.gui.scaninstruct = aa;
            catch
            end
        end

        function  obscureUpdate(app)

            global master

            try
                if master.stage.opttype == "Raw Value"
                    app.OptimizationtargetButtonGroup.SelectedObject = app.RawValueButton;
                elseif master.stage.opttype == "Gaussian Peak"
                    app.OptimizationtargetButtonGroup.SelectedObject = app.GaussianPeakButton;
                end
            catch
            end

            try
                if master.stage.optparam == "RF on"
                    app.OptimizationparametersettingsButtonGroup.SelectedObject = app.RFonButton;
                elseif master.optparam == "RF on"
                    app.OptimizationparametersettingsButtonGroup.SelectedObject = app.RFoffButton;
                elseif master.optparam == "Contrast"
                    app.OptimizationparametersettingsButtonGroup.SelectedObject = app.ContrastButton;
                end
            catch
            end

            try
                app.OptReadoutDur.Value = master.gui.optdur;
            catch
            end

            try
                app.StagePrecision.Value = master.stage.precision;
            catch
            end

            try
                app.IterationEmail.Value = master.gui.itemail;
            catch
            end

            app.AutoSaveIterations.Value = master.gui.autosaveits;

            app.LaserEquilibrium.Value = master.NDYOV.waitforequilibrium;

            app.LaserCleanOff.Value = master.NDYOV.offwhencleaned;

            try
                app.AutoSaveName.Value = master.gui.autosavename;
            catch
            end

            app.CompletionEmail.Value = master.gui.completionemail;

            app.Notifications.Value = master.notifications;

        end

        function warningsUpdate(app)
            global master

            warningtext = {};
            nn = 1;

            if ~master.stage.initialized || ~master.RFgen.initialized || ~master.NIDAQ.initialized || ~master.PB.initialized
                warningtext{nn} = 'Not all instruments initialized.';
                nn = nn+1;
            end

            iscorrect = false;
            if isfield(master.PB,'sequence')
                if ~isempty(master.PB.sequence)
                    iscorrect = true;
                end
            end
            if ~iscorrect
                warningtext{nn} = 'No pulse sequence selected.';
                nn = nn+1;
            end

            try
                scanstring = strings(1,length(master.gui.scan));
                for ii = 1:length(master.gui.scan)
                    scanstring(ii) = convertCharsToStrings(master.gui.scan(ii).type);
                end
                xscan = scanstring == "Stage X";
                yscan = scanstring == "Stage Y";
                zscan = scanstring == "Stage Z";
                rfscan = scanstring == "RF frequency";
                pulsescan = scanstring == "Pulse duration";

                xwrong = any(xscan) && any(master.stage.sequence == "x");
                ywrong = any(yscan) && any(master.stage.sequence == "y");
                zwrong = any(zscan) && any(master.stage.sequence == "z");
                if (xwrong || ywrong || zwrong) && master.gui.optswitch
                    badaxes = '';
                    if xwrong
                        badaxes = strcat(badaxes,'x, ');
                    end
                    if ywrong
                        badaxes = strcat(badaxes,'y, ');
                    end
                    if zwrong
                        badaxes = strcat(badaxes,'z, ');
                    end
                    badaxes(end) = [];
                    warningtext{nn} = sprintf(['Attempting to scan and optimize along stage %s. '...
                        'This will likely interfere with scan results.'],badaxes);
                    nn = nn+1;
                end

                xwrong = sum(xscan) > 1;
                ywrong = sum(yscan) > 1;
                zwrong = sum(xscan) > 1;
                if xwrong || ywrong || zwrong
                    badaxes = '';
                    if xwrong
                        badaxes = strcat(badaxes,'x, ');
                    end
                    if ywrong
                        badaxes = strcat(badaxes,'y, ');
                    end
                    if zwrong
                        badaxes = strcat(badaxes,'z, ');
                    end
                    badaxes(end-1:end) = [];
                    warningtext{nn} = sprintf(['Multiple stage %s scans being performed. '...
                        'This will likely interfere with scan results.'],badaxes);
                    nn = nn+1;
                end

                if sum(rfscan) > 1
                    warningtext{nn} = ['Multiple RF frequency scans being performed. '...
                        'This will likely interfere with scan results.'];
                    nn = nn+1;
                end

                if any(pulsescan)
                    pulselocs = find(pulsescan);
                    alladdresses = [];
                    for ii = 1:sum(pulsescan)
                        curraddresses = master.gui.scan(pulselocs(ii)).addresses;
                        alladdresses = [alladdresses curraddresses]; %#ok<AGROW>
                    end
                    [~,unrepeated] = unique(alladdresses, 'stable');
                    repeatedloc = setdiff( 1:numel(alladdresses), unrepeated );
                    repeated = alladdresses(repeatedloc);
                    if ~isempty(repeated)
                        badaddresses = '';
                        for ii = 1:numel(repeated)
                            badaddresses = sprintf('%s%s, ',badaddresses,num2str(repeated(ii)));
                        end
                        badaddresses(end-1:end) = [];
                        warningtext{nn} = sprintf(['Multiple pulse duration scans at address(es) %s being performed. '...
                            'This will likely interfere with scan results.'],badaddresses);
                        nn = nn+1;
                    end
                end
            catch
            end

            if app.IterationsNum.Value > 29 && app.IterationsType.SelectedObject == app.TimeminButton
                warningtext{nn} = ['A large number of iterations has been selected.' ...
                    ' Are you sure you did not intend a time selection?'];
                nn = nn+1;
            end

            if app.IterationsNum.Value > 29 && app.IterationsType.SelectedObject == app.TimeminButton
                warningtext{nn} = ['A large number of iterations has been selected.' ...
                    ' Are you sure you did not intend a time selection?'];
                nn = nn+1;
            end

            if app.OptReadoutDur.Value < 1e4
                warningtext{nn} = ['Optimization readout duration is very low. ' ...
                    'For better results use values above 10 μs.'];
                nn = nn+1;
            end

            if app.OptReadoutDur.Value > 10e6
                warningtext{nn} = ['Optimization readout duration is very high. ' ...
                    'This may cause experiment to slow down significantly without appreciable gain.'];
                nn = nn+1;
            end

            if app.OptimizationtargetButtonGroup.SelectedObject == app.GaussianPeakButton
                if any(master.stage.steps < 7)
                    warningtext{nn} = ['Attempting to model gaussian distribution with fewer than'...
                        ' seven points. This may result in poor model construction.'];
                end
                nn = nn+1;
            end

            if ~isfield(master.gui,'emailaddress') && (master.gui.itemail || master.gui.completionemail)
                warningtext{nn} = 'Attempting to send email but no email address entered.';
            end

            if ~isempty(warningtext)
                app.Warnings.Value = warningtext;
            else
                app.Warnings.Value = 'No warnings present';
            end

        end
    end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
            global master
            
            %CHANGE THESE VALUES WHEN ADDING/CHANGING CHANNELS
            channelTitles = ["AOM" "SPC Switch" "DAQ Chnl Switch" "RF" "I" "Q"];
            lineColors = ["g" "b" "r" "k" "m" "c"];
            %End changing values


            master.PB.nchannels = numel(channelTitles);
            master.gui.channelTitles = channelTitles;
            master.gui.lineColors = lineColors;
            app.outputdisplay.UserData = 0;
            master.gui.makeplot.output = true;
            master.gui.makeplot.progressbars = true;
            master.exptype = "GUI Optimization";
            
            app.seqlayout = tiledlayout(app.PulseSequence,master.PB.nchannels,1);
            app.seqlayout.Title.String = 'Pulse Sequence';
            app.seqlayout.XLabel.String = 'Time (ns)';

            for ii = 1:master.PB.nchannels
               app.pulsetiles{ii} = nexttile(app.seqlayout,ii);
            end

            if ~isfield(master,'notifications')
                master.notifications = true;
            end

            if ~isfield(master,'gui')
                master.gui.optswitch = false;
            end

            if ~isfield(master.gui,'optswitch')
                master.gui.optswitch = false;
            end

            if ~isfield(master.gui,'opttime')
                master.gui.opttime = 5;
            end

            if ~isfield(master.gui,'manoptn')
                master.gui.manoptn = 1;
            end

            if ~isfield(master.gui,'nscans')
                master.gui.nscans = 0;
            end

            if ~isfield(master.gui,'meastype')
                master.gui.meastype = 'Contrast';
            end

            if ~isfield(master.gui,'optdur')
                master.gui.optdur = 1e6;
            end

            if ~isfield(master.gui,'optparam')
                master.gui.optparam = "RF off";
            end

            if ~isfield(master.gui,'itertype')
                master.gui.itertype = 'Number';
            end

            if ~isfield(master.gui,'itemail')
                master.gui.itemail = false;
            end

            if ~isfield(master.gui,'maxits')
                master.gui.maxits = 1;
            end

            if ~isfield(master.gui,'autosaveits')
                master.gui.autosaveits = 1;
            end

            if ~isfield(master.gui,'autosave')
                master.gui.autosave = true;
            end

            if ~isfield(master.gui,'plotxdim')
                master.gui.plotxdim = 1;
            end

            if ~isfield(master.gui,'plotydim')
                master.gui.plotydim = 0;
            end

            if ~isfield(master.gui,'updateperiod')
                master.gui.updateperiod = 1;
            end

            if ~isfield(master.gui,'displaydigits')
                master.gui.displaydigits = 4;
            end

            if ~isfield(master.gui,'xinc')
                master.gui.xinc = 0;
            end

            if ~isfield(master.gui,'yinc')
                master.gui.yinc = 0;
            end

            if ~isfield(master.gui,'zinc')
                master.gui.zinc = 0;
            end

            if ~isfield(master.gui,'highpercentile')
                master.gui.highpercentile = 100;
            end

            if ~isfield(master.gui,'lowpercentile')
                master.gui.lowpercentile = 0;
            end

            if ~isfield(master.gui,'completionemail')
                master.gui.completionemail = false;
            end

            if ~isfield(master.gui,'continue')
                master.gui.continue = true;
            end

            if ~isfield(master.gui,'interrupted')
                master.gui.interrupted = false;
            end

            if ~isfield(master.gui,'expcomplete')
                master.gui.expcomplete = false;
            end

            if ~isfield(master.gui,'updatecounter')
                master.gui.updatecounter = 0;
            end

            if ~isfield(master.gui,'scaninstruct')
                master.gui.scaninstruct.type = 'Stage X';
            end

            if ~isfield(master.gui.scaninstruct,'type')
                master.gui.scaninstruct.type = 'Stage X';
            end

            if ~isfield(master.gui.scaninstruct,'steptype')
                master.gui.scaninstruct.steptype = '# of points';
            end

            if ~isfield(master.gui.scaninstruct,'stepinput')
                master.gui.scaninstruct.stepinput = 1;
            end

            if ~isfield(master.gui.scaninstruct,'address')
                master.gui.scaninstruct.scannum = '1';
            end

            if ~isfield(master,'RFgen')
                master.RFgen.initialized = 0;
            end

            if ~isfield(master.RFgen,'initialized')
                master.RFgen.initialized = 0;
            end

            if ~isfield(master.RFgen,'freq')
                master.RFgen.freq = 2.87;
            end

            if ~isfield(master.RFgen,'amp')
                master.RFgen.amp = 10;
            end

            if ~isfield(master.RFgen,'ampunit')
                master.RFgen.ampunit = "dbm";
            end

            if ~isfield(master.RFgen,'switch')
                master.RFgen.switch = "off";
            end

            if ~isfield(master,'stage')
                master.stage.precision = .01;
            end

            if ~isfield(master.stage,'precision')
                master.stage.precision = .1;
            end

            if ~isfield(master.stage,'opttype')
                master.stage.opttype = "Gaussian Peak";
            end

            if isfield(master,'NDYOV')
                if ~isfield(master.NDYOV,'waitforequilibrium')
                    master.NDYOV.waitforequilibrium = true;
                end
            else
                master.NDYOV.waitforequilibrium = true;
            end

            if ~isfield(master.NDYOV,'offwhencleaned')
                master.NDYOV.offwhencleaned = true;
            end

            aa = isfield(master.stage,'steps');
            bb = isfield(master.stage,'sequence');
            cc = isfield(master.stage,'radius');
            iscorrect = false;
            if all([aa bb cc])
                dd = length(master.stage.steps) == length(master.stage.sequence);
                ee = length(master.stage.steps) == length(master.stage.radius);
                if dd && ee
                    iscorrect = true;
                end
            end
            if ~iscorrect
                if any([aa bb cc])
                    uimessage = ['Optimization steps, sequence, and radius not equal. ' ...
                        'Setting options to default'];
                    uialert(app.UIFigure,uimessage,'Default settings','Icon','warning')
                end

                master.stage.sequence = ["x" "y" "z"];
                master.stage.radius = [1 1 1];
                master.stage.steps = [11 11 11];

            end

            if ~isfield(master.gui,'comp')
                master.stage.comp = "NV";
            end


            appUpdate(app)
      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
            global master
            global NIDAQ

            %Note: to ensure the experiment runs as quickly as possible, the
            %time duration of all the code that would run in between
            %measurements was recorded. It was found that this time was
            %typically on the order of microseconds. This obviously doesn't
            %include the optimization sequence as that takes a relatively long
            %time
            noerrors = true;
            if ~master.stage.initialized || ~master.RFgen.initialized || ~master.NIDAQ.initialized || ~master.PB.initialized
                loadinstruments = uiconfirm('Not all instruments currently loaded. Would you like to load them or cancel measurement?',...
                    'Instruments unloaded','Icon','warning','Options',{'Load all','Cancel'});
                switch loadinstruments
                    case 'Load all'
                        LoadAllButtonPushed(app)
                        appUpdate(app)
                    case 'Cancel'
                        noerrors = false;
                end
            end

            if noerrors
                if ~strcmp(app.Warnings.Value,'No warnings present')
                    disregard = uiconfirm('Continue despite warnings?','Warnings present','Icon','warning');
                    if strcmp(disregard,'Cancel')
                        noerrors = false;
                    end
                end
            end
            if noerrors
                if isfield(master.PB,'sequence')
                    if isempty(master.PB.sequence)
                        errordlg('No pulse sequence selected','No Pulse Sequence')
                        noerrors = false;
                    end
                else
                    errordlg('No pulse sequence selected','No Pulse Sequence')
                    noerrors = false;
                end
            end

            if noerrors

                app.StatusLamp.Color = [0,1,0];

                if app.IterationsType.SelectedObject == app.TimeminButton
                    mxtime = clock + (app.IterationsNum.Value*60);
                end
                if master.gui.interrupted
                    %Decrements iteration number to compensate for increment at
                    %the beginning of the loop
                    master.gui.itnum = master.gui.itnum - 1;
                else
                    master.gui.itnum = 0;
                    starttime = clock;
                end

                master.gui.continue = true;

                PBFinalize()%Finalizes current pulse sequence

                if master.gui.optswitch
                    PIStageOptimization()
                end

                isdone = false;
                while ~isdone
                    
                    if master.gui.nscans ~= 0
                        scanstring = strings(1,length(master.gui.scan));
                        for ii = 1:length(master.gui.scan)
                            scanstring(ii) = convertCharsToStrings(master.gui.scan(ii).type);
                        end

                        if any(scanstring == "Stage X")
                            xscanloc = find(scanstring == "Stage X");
                            master.stage.ax = "x";
                            master.stage.atarget = master.gui.scan(xscanloc).starts;
                            PIStageAbsoluteMove()
                        end
                        if any(scanstring == "Stage Y")
                            yscanloc = find(scanstring == "Stage Y");
                            master.stage.ax = "y";
                            master.stage.atarget = master.gui.scan(yscanloc).starts;
                            PIStageAbsoluteMove()
                        end
                        if any(scanstring == "Stage Z")
                            zscanloc = find(scanstring == "Stage Z");
                            master.stage.ax = "z";
                            master.stage.atarget = master.gui.scan(zscanloc).starts;
                            PIStageAbsoluteMove()
                        end
                        if any(scanstring == "RF frequency")
                            rfscanloc = find(scanstring == "RF frequency");
                            master.RFgen.freq = master.gui.scan(rfscanloc).starts;
                            RFFrequency()
                            pause(1e-6)%allows RF generator to stabilize
                        end
                        if any(scanstring == "Pulse duration")
                            pulsescanloc = find(scanstring == "Pulse duration");
                            for ii = 1:length(pulsescanloc)
                                currscan = pulsescanloc(ii);
                                for jj = 1:length(master.gui.scan(currscan).starts)
                                    pulseaddress = master.gui.scan(currscan).addresses(jj);
                                    master.PB.sequence(pulseaddress).duration = master.gui.scan(currscan).starts(jj);
                                end
                            end
                        end

                    end

                    master.gui.itnum = master.gui.itnum + 1;
                    app.CurrentIteration.Value = num2str(master.gui.itnum);

                    if ~isfield(master.gui,'scan') %No scan, single value only
                        resetcounters(NIDAQ)
                        PBRun()
                        daqout = read(NIDAQ);
                        if strcmp(master.gui.meastype,'Contrast')
                            master.output = daqout.Dev1_ctr3-daqout.Dev1_ctr1;
                        elseif strcmp(master.gui.meastype,'Channel 1 counts')
                            master.output = daqout.Dev1_ctr1;
                        elseif strcmp(master.gui.meastype,'Channel 3 counts')
                            master.output = daqout.Dev1_ctr3;
                        end

                        app.SingleOutput.Value = num2str(master.output);

                    else %Odometer scan loop

                        master.gui.loopmatrix = zeros(1,length(master.gui.scan));
                        for ii = 1:length(master.gui.scan)
                            master.gui.loopmatrix(ii) = master.gui.scan(ii).nsteps;
                        end

                        iscorrect = false;
                        if ~master.gui.interrupted %If it is interrupted, pick up where it left off
                            master.gui.step = 0;
                            master.gui.odomatrix = ones(1,master.gui.nscans);
                            master.gui.vecdata = zeros(prod(master.gui.loopmatrix),1);
                        end

                        prev_timestamp = clock;
                        progressUpdate(app)
                        prevupdate = clock;

                        %Main loop. every iteration, the next data point is taken and the odometer
                        %is incremented by 1
                        while ~iscorrect
                            pause(0)%This is where matlab checks for queued commands

                            timestamp = clock;
                            if etime(timestamp,prev_timestamp) > 60*master.gui.opttime && master.gui.optswitch
                                PIStageOptimization()
                                prev_timestamp = clock;
                            end

                            if master.gui.continue
                                master.gui.step = master.gui.step+1;
                                noturnover = false;
                                revdigit = master.gui.nscans;

                                %Increments lowest digit first (above). If lowest digit hits the limit,
                                %turns lowest digit to 1 and increments next digit. Does this until
                                %upper limit hits maximum then stops the entire loop
                                while ~noturnover
                                    if master.gui.step == master.gui.loopmatrix(end)+1 && revdigit == master.gui.nscans
                                        mxodo = true;
                                    elseif master.gui.step == prod(master.gui.loopmatrix)
                                        iscorrect = true;
                                        noturnover = true;
                                        mxodo = false;
                                    else
                                        mxodo = master.gui.odomatrix(revdigit) ==  master.gui.loopmatrix(revdigit)+1;
                                    end

                                    if mxodo  %Is digit max value
                                        master.gui.odomatrix(revdigit) = 1;
                                        if revdigit ~= 1
                                            master.gui.odomatrix(revdigit-1) = master.gui.odomatrix(revdigit-1)+1;
                                        end
                                        revdigit = revdigit - 1;
                                    else
                                        noturnover = true;
                                    end
                                end

                                %both digits change at the same time so y never
                                %rests
                                if revdigit ~= 0
                                    master.gui.vecdata(master.gui.step) = dataCollection(app,revdigit);
                                end

                                currtime = clock;
                                if etime(currtime,prevupdate) > app.UpdatePeriod.Value
                                    progressUpdate(app)
                                    prevupdate = clock;
                                end

                                if ~iscorrect
                                    master.gui.odomatrix(end) = master.gui.odomatrix(end)+1;
                                end

                            else %If it is told to abort
                                iscorrect = true;
                                isdone = true;
                            end

                        end

                        %Only save and plot data if sequence was not aborted
                        if master.gui.continue
                            %Reshapes data matrix from vector to the shape that would be obtained
                            %naturally from nested for loops
                            if master.gui.nscans ~= 1
                                truedata = reshape(master.gui.vecdata,flip(master.gui.loopmatrix));
                                truedata = permute(truedata,flip(1:master.gui.nscans));
                            else
                                truedata = master.gui.vecdata;
                            end

                            if master.gui.itnum == 1
                                master.output = truedata;
                                if master.gui.itnum == master.gui.maxits
                                    master.gui.expcomplete = true;
                                end
                            else
                                master.output = master.output + truedata;
                                if master.gui.itnum == master.gui.maxits
                                    master.gui.expcomplete = true;
                                    master.output = master.output ./ master.gui.itnum;
                                end
                            end

                            progressUpdate(app)
                        end

                    end


                    if master.gui.autosave
                        if mod(master.gui.itnum,app.AutoSaveIterations.Value) == 1
                            clear outputs
                            outputs.output = master.output;
                            outputs.maxprct = master.gui.highpercentile;
                            outputs.minprct = master.gui.lowpercentile;
                            outputs.y = app.YDimension.Value;
                            outputs.x = app.XDimension.Value;
                            foldername = strcat(app.BoxAddress.Value,...
                                'Box\Backlund lab shared\data\ExperimentEditor Data\',...
                                master.gui.autosavename);
                            if master.gui.itnum == 1
                                mkdir(foldername)
                            end
                            itname = strcat('\',sprintf('Iteration %d',master.gui.itnum));
                            save(strcat(foldername,itname),"outputs")
                        end
                    end

                    if master.gui.itemail
                        if master.gui.completionemail
                            msgsubject = sprintf('Experiment Iteration %d Complete',master.gui.itnum);
                            if ~master.gui.interrupted
                                currtime = clock;
                                runtime = etime(currtime,starttime);
                                rtsec = floor(mod(runtime,60));
                                rtmin = floor(runtime/60);
                                timetext = sprintf('. Current run time %d minutes %d seconds.',rtmin,rtsec);
                            else
                                timetext = '. Experiment interrupted so no run time calculated.';
                            end
                            if master.gui.nscans ~= 0
                                for ii = 1:length(master.gui.scan)
                                    if ii == 1
                                        scantext = sprintf('%s',master.gui.scan(ii).type);
                                    else
                                        %sprintf used instead of strcat to preserve spaces
                                        scantext = sprintf('%s, %s',scantext,master.gui.scan(ii).type);
                                    end
                                end
                                msgtext = strcat('Scan type(s): ',scantext,timetext);
                            else
                                msgtext = strcat('Single value experiment',scantext,timetext);
                            end

                            send_msg({master.gui.emailaddress},msgsubject,msgtext);
                        end
                    end

                    if app.IterationsType.SelectedObject == app.NumberButton
                        if master.gui.expcomplete
                            isdone = true;
                        end
                    else
                        currtime = clock;
                        if currtime >= mxtime
                            isdone = true;
                        end
                    end

                end

                if master.gui.completionemail
                    msgsubject = 'Experiment Scan Complete';
                    for ii = 1:length(master.gui.scan)
                        if ii == 1
                            scantext = sprintf('%s',master.gui.scan(ii).type);
                        else
                            %sprintf used instead of strcat to preserve spaces
                            scantext = sprintf('%s, %s',scantext,master.gui.scan(ii).type);
                        end
                    end
                    if ~master.gui.interrupted
                        currtime = clock;
                        runtime = etime(currtime,starttime);
                        rtsec = floor(mod(runtime,60));
                        rtmin = floor(runtime/60);
                        timetext = sprintf('. Run time %d minutes %d seconds.',rtmin, rtsec);
                    else
                        timetext = '. Experiment interrupted so no run time calculated.';
                    end
                    msgtext = strcat('Scan type(s): ',scantext,timetext);
                    send_msg({master.gui.emailaddress},msgsubject,msgtext);
                end

                if ~master.gui.interrupted
                    app.StatusLamp.Color = [0,1,1];
                end

            end


      end

      % Button pushed function: PulseSequenceEditorButton
      function PulseSequenceEditorButtonPushed(app, event)
            run('PulseSequenceEditor.mlapp')
      end

      % Button pushed function: RFUnload
      function RFUnloadButtonPushed(app, event)
            global master
            master.cleanonly = "RFgen";
            CleanUp()
            connectionUpdate(app)
      end

      % Button pushed function: DAQUnload
      function DAQUnloadButtonPushed(app, event)
            global master
            master.cleanonly = "NIDAQ";
            CleanUp()
            connectionUpdate(app)
      end

      % Button pushed function: PBUnload
      function PBUnloadButtonPushed(app, event)
            global master
            master.cleanonly = "PB";
            CleanUp()
            connectionUpdate(app)
      end

      % Button pushed function: StageUnload
      function StageUnloadButtonPushed(app, event)
            global master
            master.cleanonly = "stage";
            CleanUp()
            connectionUpdate(app)
      end

      % Button pushed function: LaserUnload
      function LaserUnloadButtonPushed(app, event)
            global master
            master.cleanonly = "NDYOV";
            CleanUp()
            connectionUpdate(app)
      end

      % Button pushed function: UnloadAll
      function UnloadAllButtonPushed(app, event)
            global master
            master.cleanonly = "all";
            CleanUp()
            connectionUpdate(app)
      end

      % Changes arrangement of the app based on UIFigure width
      function updateAppLayout(app, event)
         currentFigureWidth = app.UIFigure.Position(3);
         if(currentFigureWidth <= app.onePanelWidth)
             % Change to a 2x1 grid
             app.GridLayout.RowHeight = {579, 579};
             app.GridLayout.ColumnWidth = {'1x'};
             app.RightPanel.Layout.Row = 2;
             app.RightPanel.Layout.Column = 1;
         else
             % Change to a 1x2 grid
             app.GridLayout.RowHeight = {'1x'};
             app.GridLayout.ColumnWidth = {476, '1x'};
             app.RightPanel.Layout.Row = 1;
             app.RightPanel.Layout.Column = 2;
         end
      end

      % Button pushed function: RFLoad
      function RFLoadPushed(app, event)
            global master

            if ~isfield(master,'RFgen')
                master.RFgen.initialized = 0;
            end
            if ~isfield(master.RFgen,'freq')
                master.RFgen.freq = 2.87;
            end
            if ~isfield(master.RFgen,'switch')
                master.RFgen.switch = "on";
            end
            if ~isfield(master.RFgen,'amp')
                master.RFgen.amp = 5;
            end
            if ~isfield(master.RFgen,'ampunit')
                master.RFgen.ampunit = "dbm";
            end

            RFInitialization()

            connectionUpdate(app)
            settingsUpdate(app)
      end

      % Button pushed function: StageLoad
      function StageLoadButtonPushed(app, event)
            global master

            if ~isfield(master,'stage')
                master.stage.initialized = 0;
            end
            if ~isfield(master.stage,'comp')
                master.stage.comp = "NV";
            end

            PIStageInitialization()

            connectionUpdate(app)
            stageUpdate(app)
      end

      % Button pushed function: PBLoad
      function PBLoadButtonPushed(app, event)
            global master

            if ~isfield(master,'PB')
                master.PB.initialized = 0;
            end

            PBInitialization()

            connectionUpdate(app)
      end

      % Button pushed function: DAQLoad
      function DAQLoadButtonPushed(app, event)
            global master

            if ~isfield(master,'NIDAQ')
                master.NIDAQ.initialized = 0;
            end
            if ~isfield(master.NIDAQ,'channel')
                master.NIDAQ.channel(1) = "ctr1";
                master.NIDAQ.channel(2) = "ctr3";
            end
            if ~isfield(master.NIDAQ,'counttype')
                master.NIDAQ.counttype(1:2) = "EdgeCount";
            end
            if ~isfield(master.NIDAQ,'maxrate')
                master.NIDAQ.maxrate = 1.25e6;
            end

            NIDAQInitialization()

            connectionUpdate(app)
      end

      % Button pushed function: LaserLoad
      function LaserLoadButtonPushed(app, event)
            NDYOVInitialization
            connectionUpdate(app)
            settingsUpdate(app)
      end

      % Button pushed function: LoadAll
      function LoadAllButtonPushed(app, event)
            global master

            try
                if ~isfield(master,'RFgen')
                    master.RFgen.initialized = 0;
                end
                if ~isfield(master.RFgen,'freq')
                    master.RFgen.freq = 2.87;
                end
                if ~isfield(master.RFgen,'switch')
                    master.RFgen.switch = "on";
                end
                if ~isfield(master.RFgen,'amp')
                    master.RFgen.amp = 5;
                end
                if ~isfield(master.RFgen,'ampunit')
                    master.RFgen.ampunit = "dbm";
                end

                RFInitialization
            catch
                errordlg('RF initialization failed','RF initialization failed')
            end

            try
                if ~isfield(master,'stage')
                    master.stage.initialized = 0;
                end
                if ~isfield(master.stage,'comp')
                    master.stage.comp = "NV";
                end

                PIStageInitialization()
            catch
                errordlg('PI stage initialization failed','Stage initialization failed')
            end

            try
                if ~isfield(master,'PB')
                    master.PB.initialized = 0;
                end

                PBInitialization()
            catch
                errordlg('Pulse blaster initialization failed','PB initialization failed')
            end

            try
                if ~isfield(master,'NIDAQ')
                    master.NIDAQ.initialized = 0;
                end
                if ~isfield(master.NIDAQ,'channel')
                    master.NIDAQ.channel(1) = "ctr1";
                    master.NIDAQ.channel(2) = "ctr3";
                end
                if ~isfield(master.NIDAQ,'counttype')
                    master.NIDAQ.counttype(1:2) = "EdgeCount";
                end
                if ~isfield(master.NIDAQ,'maxrate')
                    master.NIDAQ.maxrate = 1.25e6;
                end

                NIDAQInitialization()
            catch
                errordlg('DAQ initialization failed','DAQ initialization failed')
            end

            try
                NDYOVInitialization
            catch
                errordlg('Nd:YOV laser initialization failed','Laser initialization failed')
            end

            appUpdate(app)

      end

      % Value changed function: XSlider
      function XSliderValueChanged(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                app.XPosition.Value = app.XSlider.Value;
                master.stage.ax = "x";
                master.stage.atarget = app.XSlider.Value;
                PIStageAbsoluteMove()
            end
            stageUpdate(app)
      end

      % Value changed function: XPosition
      function XPositionValueChanged(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                try
                    app.XSlider.Value = app.XPosition.Value;
                    master.stage.ax = "x";
                    master.stage.atarget = app.XPosition.Value;
                    PIStageAbsoluteMove()
                catch ME
                    fprintf('X position value not within limits')
                    rethrow(ME)
                end
            end
            stageUpdate(app)
      end

      % Value changed function: YSlider
      function YSliderValueChanged(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                app.YPosition.Value = app.YSlider.Value;
                master.stage.ax = "y";
                master.stage.atarget = app.YSlider.Value;
                PIStageAbsoluteMove()
            end
            stageUpdate(app)
      end

      % Value changed function: YPosition
      function YPositionValueChanged(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                app.YSlider.Value = app.YPosition.Value;
                master.stage.ax = "y";
                master.stage.atarget = app.YPosition.Value;
                PIStageAbsoluteMove()
            end
            stageUpdate(app)
      end

      % Value changed function: ZSlider
      function ZSliderValueChanged(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                app.ZPosition.Value = app.ZSlider.Value;
                master.stage.ax = "z";
                master.stage.atarget = app.ZSlider.Value;
                PIStageAbsoluteMove()
            end
            stageUpdate(app)
      end

      % Value changed function: ZPosition
      function ZPositionValueChanged(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                app.ZSlider.Value = app.ZPosition.Value;
                master.stage.ax = "z";
                master.stage.atarget = app.ZPosition.Value;
                PIStageAbsoluteMove()
            end
            stageUpdate(app)
      end

      % Button pushed function: XFineReset
      function XFineResetButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.ax = "x";
                PIStageFineReset()
            end
            stageUpdate(app)
      end

      % Button pushed function: YFineReset
      function YFineResetButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.ax = "y";
                PIStageFineReset()
            end
            stageUpdate(app)
      end

      % Button pushed function: ZFineReset
      function ZFineResetButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.ax = "z";
                PIStageFineReset()
            end
            stageUpdate(app)
      end

      % Button pushed function: XDown
      function XDownButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.target = -master.gui.xinc;
                master.stage.ax = "x";
                PIStageRelativeMove()
            end
            stageUpdate(app)
      end

      % Button pushed function: XUp
      function XUpButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.target = master.gui.xinc;
                master.stage.ax = "x";
                PIStageRelativeMove()
            end
            stageUpdate(app)
      end

      % Button pushed function: YDown
      function YDownButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.target = -master.gui.yinc;
                master.stage.ax = "y";
                PIStageRelativeMove()
            end
            stageUpdate(app)
      end

      % Button pushed function: YUp
      function YUpButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.target = master.gui.yinc;
                master.stage.ax = "y";
                PIStageRelativeMove()
            end
            stageUpdate(app)
      end

      % Button pushed function: ZDown
      function ZDownButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.target = -master.gui.zinc;
                master.stage.ax = "z";
                PIStageRelativeMove()
            end
            stageUpdate(app)
      end

      % Button pushed function: ZUp
      function ZUpButtonPushed(app, event)
            global master
            if ~master.stage.initialized
                errordlg('PI stage must be loaded before attempting to move it','Must load stage')
            else
                master.stage.target = master.gui.zinc;
                master.stage.ax = "z";
                PIStageRelativeMove()
            end
            stageUpdate(app)
      end

      % Value changed function: StagePrecision
      function StagePrecisionValueChanged(app, event)
            global master
            master.stage.precision = app.StagePrecision.Value;
      end

      % Value changed function: RFFreq
      function RFFreqValueChanged(app, event)
            global master
            if ~master.RFgen.initialized
                errordlg('RF must be loaded before changing its values','Must load RF')
            else
                master.RFgen.frequency = app.RFFreq.Value;
                RFFrequency()
            end
            settingsUpdate(app)

      end

      % Value changed function: RFAmp
      function RFAmpValueChanged(app, event)
            global master
            if ~master.RFgen.initialized
                errordlg('RF must be loaded before changing its values','Must load RF')
            else
                master.RFgen.ampunit = "dbm";
                master.RFgen.amp = app.RFAmp.Value;
                RFAmplitude()
            end
            settingsUpdate(app)
      end

      % Value changed function: RFOn
      function RFOnValueChanged(app, event)
            global master
            if ~master.RFgen.initialized
                errordlg('RF must be loaded before changing its values','Must load RF')
            else
                if app.RFOn.Value
                    master.RFgen.switch = "on";
                else
                    master.RFgen.switch = "off";
                end
                RFSwitch()
            end
            settingsUpdate(app)
      end

      % Value changed function: OptimizationTiming
      function OptimizationTimingValueChanged(app, event)
            global master
            master.gui.opttime = app.OptimizationTiming.Value;
      end

      % Value changed function: OptimizationOn
      function OptimizationOnValueChanged(app, event)
            global master
            master.gui.optswitch = app.OptimizationOn.Value;
      end

      % Selection changed function: IterationsType
      function IterationsTypeSelectionChanged(app, event)
            global master
            buttonob = app.IterationsType.SelectedObject;
            master.gui.itertype = buttonob.Text;
      end

      % Value changed function: IterationsNum
      function IterationsNumValueChanged(app, event)
            global master
            master.gui.maxits = app.IterationsNum.Value;
      end

      % Button pushed function: LoadpulsesequenceButton
      function LoadpulsesequenceButtonPushed(app, event)
            global master

            if isempty(app.SequenceName.Value)
                uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
            else

                ldname = strcat(app.BoxAddress.Value,'Box\Backlund lab shared\code\Function Library\Pulse Sequences\',...
                    app.SequenceName.Value);
                loadedvar = load(convertCharsToStrings(ldname));
                master.PB.sequence = loadedvar.recorded_sequence;
                uialert(app.UIFigure,'Sequence Loaded!','Load Successful','Icon','success')
            end

            settingsUpdate(app)
            warningsUpdate(app)

      end

      % Button pushed function: AddScanButton
      function AddScanButtonPushed(app, event)
            global master

            if ~isfield(master.gui.scaninstruct,'addresses')
                master.gui.scaninstruct.addresses = [];
            end

            newval = MultiFieldConversion(master.gui.scaninstruct.scannum);
            if length(newval) == 1
                if strcmp(app.Overwrite.Value,'Insert new')
                    master.gui.scan(newval+1:end+1) = master.gui.scan(newval:end);
                end

                master.gui.scan(newval) = master.gui.scaninstruct;
                master.gui.chosensteps(newval) = 1;

                master.gui.scaninstruct.scannum = num2str(newval + 1);
                master.gui.nscans = length(master.gui.scan);
                master.gui.odoplotted = ones(1,length(master.gui.scan));
            else
                uialert(app.UIFigure,'Scan number must be a single number for adding to sequence','Failed to add scan','Icon','error')
            end

            scanningUpdate(app)
            outputSettingsUpdate(app)
      end

      % Value changed function: StartScan
      function StartScanValueChanged(app, event)
            global master
            master.gui.scaninstruct.starts = MultiFieldConversion(app.StartScan.Value);
            stepConversion(app)
      end

      % Value changed function: EndScan
      function EndScanValueChanged(app, event)
            global master
            master.gui.scaninstruct.ends = MultiFieldConversion(app.EndScan.Value);
            stepConversion(app)
      end

      % Value changed function: PulseAddresses
      function PulseAddressesValueChanged(app, event)
            global master
            master.gui.scaninstruct.addresses = MultiFieldConversion(app.PulseAddresses.Value);
      end

      % Value changed function: ScanType
      function ScanTypeValueChanged(app, event)
            global master
            master.gui.scaninstruct.type = app.ScanType.Value;
      end

      % Selection changed function: NPointsStepSize
      function NPointsStepSizeSelectionChanged(app, event)
            global master
            selectedButton = app.NPointsStepSize.SelectedObject;
            master.gui.scaninstruct.steptype = selectedButton.Text;
            stepConversion(app)
      end

      % Value changed function: StepSizeField
      function StepSizeFieldValueChanged(app, event)
            global master
            master.gui.scaninstruct.stepinput = app.StepSizeField.Value;
            stepConversion(app)
      end

      % Button pushed function: RefreshAppButton
      function RefreshAppButtonPushed(app, event)
            appUpdate(app)
      end

      % Button pushed function: SavesetupButton
      function SavesetupButtonPushed(app, event)
            global master

            masterdupe.notifications = master.notifications;
            masterdupe.gui.optswitch = master.gui.optswitch;
            masterdupe.gui.itertype = master.gui.itertype;
            masterdupe.gui.opttime = master.gui.opttime;
            masterdupe.gui.optdur = master.gui.optdur;
            masterdupe.gui.maxits = master.gui.maxits;
            masterdupe.gui.itemail = master.gui.itemail;
            masterdupe.gui.autosave = master.gui.autosave;
            masterdupe.gui.autosaveits = master.gui.autosaveits;
            masterdupe.gui.displaydigits = master.gui.displaydigits;
            masterdupe.gui.updateperiod = master.gui.updateperiod;
            masterdupe.gui.plotxdim = master.gui.plotxdim;
            masterdupe.gui.plotydim = master.gui.plotydim;
            masterdupe.gui.completionemail = master.gui.completionemail;
            masterdupe.gui.manoptn = master.gui.manoptn;
            masterdupe.RFgen.freq = master.RFgen.freq;
            masterdupe.RFgen.amp = master.RFgen.amp;
            masterdupe.RFgen.ampunit = master.RFgen.ampunit;
            masterdupe.RFgen.freq = master.RFgen.freq;
            masterdupe.RFgen.switch = master.RFgen.switch;
            masterdupe.stage.precision = master.stage.precision;
            masterdupe.stage.comp = master.stage.comp;
            masterdupe.NDYOV.waitforequilibrium = master.NDYOV.waitforequilibrium;
            masterdupe.NDYOV.offwhencleaned = master.NDYOV.offwhencleaned;

            if isfield(master.stage,'sequence')
                masterdupe.stage.sequence = master.stage.sequence;
            end

            if isfield(master.stage,'radius')
                masterdupe.stage.radius = master.stage.radius;
            end

            if isfield(master.stage,'steps')
                masterdupe.stage.steps = master.stage.steps;
            end

            if isfield(master.gui,'scan')
                masterdupe.gui.scan = master.gui.scan;
            end

            if isfield(master.gui,'nscans')
                masterdupe.gui.nscans = master.gui.nscans;
            end

            if isfield(master.gui,'chosensteps')
                masterdupe.gui.chosensteps = master.gui.chosensteps;
            end

            if isfield(master.gui,'autosavename')
                masterdupe.gui.autosavename = master.gui.autosavename;
            end

            if isfield(master.gui,'emailaddress')
                masterdupe.gui.emailaddress = master.gui.emailaddress;
            end

            if isfield(master.PB,'sequence')
                masterdupe.PB.sequence = master.PB.sequence;
            end


            if isempty(app.SetupSaveName.Value)
                uialert(app.UIFigure,'Save unsuccessful. No file name input','Save Unsuccessful','Icon','error')
            else
                svname = strcat(app.BoxAddress.Value,'Box\Backlund lab shared\code\Function Library\Experiments\Setups\',...
                    app.SetupSaveName.Value);
                save(convertCharsToStrings(svname),"masterdupe")
                uialert(app.UIFigure,'Setup Saved!','Save Successful','Icon','success')
            end
      end

      % Value changed function: ComputerEditField
      function ComputerEditFieldValueChanged(app, event)
            global master
            master.stage.comp = app.ComputerEditField.Value;

      end

      % Button pushed function: LoadsetupButton
      function LoadsetupButtonPushed(app, event)
            global master

            if isempty(app.SetupLoadName.Value)
                uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
            else
                ldname = strcat(app.BoxAddress.Value,'Box\Backlund lab shared\code\Function Library\Experiments\Setups\',...
                    app.SetupLoadName.Value);
                load(convertCharsToStrings(ldname),"masterdupe")
                uialert(app.UIFigure,'Setup Loaded!','Load Successful','Icon','success')


                master.notifications = masterdupe.notifications;
                master.gui.optswitch = masterdupe.gui.optswitch;
                master.gui.itertype = masterdupe.gui.itertype;
                master.gui.opttime = masterdupe.gui.opttime;
                master.gui.maxits = masterdupe.gui.maxits;
                master.gui.itemail = masterdupe.gui.itemail;
                master.gui.autosave = masterdupe.gui.autosave;
                master.gui.autosaveits = masterdupe.gui.autosaveits;
                master.gui.displaydigits = masterdupe.gui.displaydigits;
                master.gui.updateperiod = masterdupe.gui.updateperiod;
                master.gui.plotxdim = masterdupe.gui.plotxdim;
                master.gui.plotydim = masterdupe.gui.plotydim;
                master.gui.completionemail = masterdupe.gui.completionemail;
                master.gui.manoptn = masterdupe.gui.manoptn;
                master.RFgen.freq = masterdupe.RFgen.freq;
                master.RFgen.amp = masterdupe.RFgen.amp;
                master.RFgen.ampunit = masterdupe.RFgen.ampunit;
                master.RFgen.freq = masterdupe.RFgen.freq;
                master.RFgen.switch = masterdupe.RFgen.switch;
                master.stage.precision = masterdupe.stage.precision;
                master.stage.comp = masterdupe.stage.comp;
                master.NDYOV.waitforequilibrium = masterdupe.NDYOV.waitforequilibrium;
                master.NDYOV.offwhencleaned = masterdupe.NDYOV.offwhencleaned;

                if isfield(masterdupe.stage,'sequence')
                    master.stage.sequence = masterdupe.stage.sequence;
                end

                if isfield(masterdupe.stage,'radius')
                    master.stage.radius = masterdupe.stage.radius;
                end

                if isfield(masterdupe.stage,'steps')
                    master.stage.steps = masterdupe.stage.steps;
                end

                if isfield(masterdupe.gui,'scan')
                    master.gui.scan = masterdupe.gui.scan;
                end

                if isfield(masterdupe.gui,'nscans')
                    master.gui.nscans = masterdupe.gui.nscans;
                end

                if isfield(masterdupe.gui,'chosensteps')
                    master.gui.chosensteps = masterdupe.gui.chosensteps;
                end

                if isfield(masterdupe.gui,'autosavename')
                    master.gui.autosavename = masterdupe.gui.autosavename;
                end

                if isfield(masterdupe.gui,'emailaddress')
                    master.gui.emailaddress = masterdupe.gui.emailaddress;
                end

                if isfield(masterdupe.PB,'sequence')
                    master.PB.sequence = masterdupe.PB.sequence;
                end

                appUpdate(app)
                if master.RFgen.initialized
                    RFFrequency()
                    RFAmplitude()
                    RFSwitch()
                end
            end

      end

      % Cell edit callback: DimensionSet
      function DimensionSetCellEdit(app, event)
            global master
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) ~= 4
                errordlg('Only the chosen step can be edited in this table','Un-editable feature')
            else
                master.gui.chosensteps(indices(1)) = newData;
            end
            outputSettingsUpdate(app)
      end

      % Button pushed function: SaveOutputButton
      function SaveOutputButtonPushed(app, event)
            global master
            outputs.output = master.output;
            outputs.maxprct = app.UpperPercentile.Value;
            outputs.minprct = app.LowerPercentile.Value;
            outputs.y = app.YDimension.Value;
            outputs.x = app.XDimension.Value;
            if isempty(app.OutputSaveName.Value)
                uialert(app.UIFigure,'Save unsuccessful. No file name input','Save Unsuccessful','Icon','error')
            else
                svname = strcat(app.BoxAddress.Value,'Box\Backlund lab shared\data\ExperimentEditor Data\',...
                    app.OutputSaveName.Value);
                save(convertCharsToStrings(svname),"outputs")
                uialert(app.UIFigure,'Output Saved!','Save Successful','Icon','success')
            end
      end

      % Button pushed function: LoadoutputButton
      function LoadoutputButtonPushed(app, event)
            global master
            if isempty(app.OutputLoadName.Value)
                uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
            else
                ldname = strcat(app.BoxAddress.Value,'Box\Backlund lab shared\data\ExperimentEditor Data\',...
                    app.OutputLoadName.Value);
                load(convertCharsToStrings(ldname),"outputs")
                uialert(app.UIFigure,'Output Loaded!','Load Successful','Icon','success')
            end
            master.output = outputs.output;
            app.UpperPercentile.Value = outputs.maxprct;
            app.LowerPercentile.Value = outputs.minprct;
            app.YDimension.Value = outputs.y;
            app.XDimension.Value = outputs.x;
            master.gui.expcomplete = true;
      end

      % Button pushed function: AbortButton
      function AbortButtonPushed(app, event)
            global master
            master.gui.continue = false;
            master.gui.interrupted = true;
            app.StatusLamp.Color = [1,0,0];
      end

      % Button pushed function: ResetButton
      function ResetButtonPushed(app, event)
            global master
            if ~master.gui.interrupted && ~master.gui.expcomplete
               doreset = uiconfirm(app.UIFigure,'Attempting to reset when not interrupted and not complete',...
                  'Confirm reset',"Options",{'Reset','Cancel'});
               doreset = strcmp(doreset,'Reset');
            else
               doreset = true;
            end
            if doreset
               master.gui.interrupted = false;
               master.gui.expcomplete = false;
               master.gui.makeplot.output = true;
               master.gui.makeplot.progressbars = true;
               if ~islogical(app.outputdisplay)
                  app.outputdisplay.UserData =0;
               end
               app.StatusLamp.Color = [0,0,1];
               if isfield(master,'output')
                  master = rmfield(master,'output');
               end
               if isfield(master.gui,'vecdata')
                  master.gui = rmfield(master.gui,'vecdata');
               end
               if isfield(master.gui,'odoplotted')
                  master.gui = rmfield(master.gui,'odoplotted');
               end
            end
      end

      % Button pushed function: DeleteScanButton
      function DeleteScanButtonPushed(app, event)
            global master
            alladdresses = MultiFieldConversion(master.gui.scaninstruct.scannum);
            alladdresses = sort(alladdresses,'descend');
            iscorrect = false;
            if isfield(master.gui,'scan')
                for ii = 1:length(alladdresses)
                    del_address = alladdresses(ii);
                    if length(master.gui.scan) >= del_address
                        iscorrect = true;
                        if numel(master.gui.scan) == 1
                            master.gui = rmfield(master.gui,'scan');
                            master.gui = rmfield(master.gui,'chosensteps');
                        else
                            master.gui.scan(del_address:end-1) = master.gui.scan(del_address+1:end);
                            master.gui.chosensteps(del_address:end-1) = master.gui.chosensteps(del_address+1:end);
                            aa = 0;
                            for jj = 1:length(master.gui.scan)
                                aa = aa+1;
                                master.gui.scan(jj).scannum = aa;
                            end
                            master.gui.scan(end) = [];
                            master.gui.chosensteps(end) = [];
                        end
                    end
                    scanningUpdate(app)
                    outputSettingsUpdate(app)
                    master.gui.nscans = length(master.gui.scan);
                end
            end
            if ~iscorrect
                uimessage = 'Selected pulse address does not exist therefore cannot be deleted';
                uialert(app.UIFigure,uimessage,'Cannot Delete Pulse','Icon','warning')
            end


      end

      % Button pushed function: DeletestepButton
      function DeletestepButtonPushed(app, event)
            global master

            iscorrect = false;
            if isfield(master.stage,'sequence')
                alladdresses = MultiFieldConversion(app.OptAddress.Value);
                alladdresses = sort(alladdresses,'descend');
                for ii = 1:length(alladdresses)
                    if length(master.stage.sequence) >= alladdresses(ii)
                        iscorrect = true;
                        if length(master.stage.sequence) == 1
                            master.stage = rmfield(master.stage,'sequence');
                            master.stage = rmfield(master.stage,'radius');
                            master.stage = rmfield(master.stage,'steps');
                        else
                            master.stage.sequence(alladdresses(ii)) = [];
                            master.stage.steps(alladdresses(ii)) = [];
                            master.stage.radius(alladdresses(ii)) = [];
                        end
                    end
                    stageUpdate(app)
                end
            end
            if ~iscorrect
                uimessage = 'Selected optimization step address does not exist therefore cannot be deleted';
                uialert(app.UIFigure,uimessage,'Cannot Delete Step','Icon','warning')
            end
            stageUpdate(app)

      end

      % Button pushed function: AddstepButton
      function AddstepButtonPushed(app, event)
            global master

            if ~mod(app.OptSteps.Value,2)
                uialert(app.UIFigure,'Number of steps must be odd','Steps must be odd','Icon','warning')
            else

                address = app.OptAddress.Value;

                if isfield(master.stage,'sequence')
                    if address > length(master.stage.sequence) + 1
                        uimessage = ['Attempted  address would leave gaps in optimization sequence. ' ...
                            'Instead, new optimization step will be added to the end of the current sequence'];
                        uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
                        address = length(master.stage.sequence)+1;
                    end
                elseif address == 1
                else
                    uimessage = ['Attempted  address would leave gaps in optimization sequence. ' ...
                        'Instead, new optimization step will be added to the end of the current sequence'];
                    uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
                    address = length(master.stage.sequence)+1;
                end


                master.stage.sequence(address) = convertCharsToStrings(app.OptAxis.Value);
                master.stage.radius(address) = app.OptRadius.Value;
                master.stage.steps(address) = app.OptSteps.Value;

                app.OptAddress.Value = address+1;

                stageUpdate(app)

            end


      end

      % Value changed function: Parameter
      function ParameterValueChanged(app, event)
            global master
            master.gui.meastype = app.Parameter.Value;
      end

      % Value changed function: UpperPercentile
      function UpperPercentileValueChanged(app, event)
            global master
            master.gui.highpercentile = app.UpperPercentile.Value;
            if master.gui.expcomplete
                plotData(app,master.output)
            end
            outputSettingsUpdate(app)
      end

      % Value changed function: XDimension
      function XDimensionValueChanged(app, event)
            global master
            master.gui.plotxdim = app.XDimension.Value;
            master.gui.makeplot.output = true;
            if master.gui.expcomplete
                plotData(app,master.output)
            end
            outputSettingsUpdate(app)
      end

      % Value changed function: YDimension
      function YDimensionValueChanged(app, event)
            global master
            master.gui.plotydim = app.YDimension.Value;
            master.gui.makeplot.output = true;
            if master.gui.expcomplete
                plotData(app,master.output)
            end
            outputSettingsUpdate(app)
      end

      % Value changed function: LowerPercentile
      function LowerPercentileValueChanged(app, event)
            global master
            master.gui.lowpercentile = app.LowerPercentile.Value;
            if master.gui.expcomplete
                plotData(app,master.output)
            end
            outputSettingsUpdate(app)
      end

      % Value changed function: Notifications
      function NotificationsValueChanged(app, event)
            global master
            master.notifications = app.Notifications.Value;
            obscureUpdate(app)
      end

      % Value changed function: OptReadoutDur
      function OptReadoutDurValueChanged(app, event)
            global master
            master.gui.optdur = app.OptReadoutDur.Value;
      end

      % Selection changed function: 
      % OptimizationparametersettingsButtonGroup
      function OptimizationparametersettingsButtonGroupSelectionChanged(app, event)
            global master
            selectedButton = app.OptimizationparametersettingsButtonGroup.SelectedObject;
            master.gui.optparam = convertCharsToStrings(selectedButton.Text);
      end

      % Selection changed function: OptimizationtargetButtonGroup
      function OptimizationtargetButtonGroupSelectionChanged(app, event)
            global master
            selectedButton = app.OptimizationtargetButtonGroup.SelectedObject;
            master.stage.opttype = convertCharsToStrings(selectedButton.Text);
      end

      % Value changed function: DisplayDigits
      function DisplayDigitsValueChanged(app, event)
            global master
            master.gui.displaydigits = app.DisplayDigits.Value;
            appUpdate(app)
      end

      % Value changed function: IterationEmail
      function IterationEmailValueChanged(app, event)
            global master
            master.gui.itemail = app.IterationEmail.Value;
            obscureUpdate(app)
      end

      % Value changed function: AutoSaveIterations
      function AutoSaveIterationsValueChanged(app, event)
            global master
            master.gui.autosaveits = app.AutoSaveIterations.Value;
            obscureUpdate(app)
      end

      % Value changed function: AutoSaveName
      function AutoSaveNameValueChanged(app, event)
            global master
            master.gui.autosavename = app.AutoSaveName.Value;
            obscureUpdate(app)
      end

      % Value changed function: AutosaveCheckBox
      function AutosaveCheckBoxValueChanged(app, event)
            global master
            master.gui.autosave = app.AutosaveCheckBox.Value;
            obscureUpdate(app)
      end

      % Value changed function: EmailAddress
      function EmailAddressValueChanged(app, event)
            global master
            master.gui.emailaddress = app.EmailAddress.Value;
            connectionUpdate(app)
      end

      % Value changed function: UpdatePeriod
      function UpdatePeriodValueChanged(app, event)
            global master
            master.gui.updateperiod = app.UpdatePeriod.Value;
            outputSettingsUpdate(app)
      end

      % Value changed function: XIncrement
      function XIncrementValueChanged(app, event)
            global master
            master.gui.xinc = app.XIncrement.Value;
      end

      % Value changed function: YIncrement
      function YIncrementValueChanged(app, event)
            global master
            master.gui.yinc = app.YIncrement.Value;
      end

      % Value changed function: ZIncrement
      function ZIncrementValueChanged(app, event)
            global master
            master.gui.zinc = app.ZIncrement.Value;
      end

      % Value changed function: ManualOptNum
      function ManualOptNumValueChanged(app, event)
            global master
            master.gui.manoptn = app.ManualOptNum.Value;
      end

      % Button pushed function: BeginOptimizationsButton
      function BeginOptimizationsButtonPushed(app, event)
            global master
            noerrors = true;
            if ~master.stage.initialized || ~master.NIDAQ.initialized || ~master.PB.initialized
                loadinstruments = uiconfirm('Not all instruments currently loaded. Would you like to load them or cancel measurement?',...
                    'Instruments unloaded','Icon','warning','Options',{'Load all','Cancel'});
                switch loadinstruments
                    case 'Load all'
                        PBLoadButtonPushed(app)
                        DAQLoadButtonPushed(app)
                        StageLoadButtonPushed(app)
                        appUpdate(app)
                    case 'Cancel'
                        noerrors = false;
                end
            end

            if noerrors
                for ii = 1:master.gui.manoptn
                    PIStageOptimization()
                end
            end
      end

      % Value changed function: CompletionEmail
      function CompletionEmailValueChanged(app, event)
            global master
            master.gui.completionemail = app.CompletionEmail.Value;
      end

      % Value changed function: ScanNum
      function ScanNumValueChanged(app, event)
            global master
            master.gui.scaninstruct.scannum = app.ScanNum.Value;
      end

      % Value changed function: LaserSwitch
      function LaserSwitchValueChanged(app, event)
            global master
            master.NDYOV.switch = convertCharsToStrings(app.LaserSwitch.Value);
            NDYOVSwitch
            settingsUpdate(app)
      end

      % Value changed function: LaserPower
      function LaserPowerValueChanged(app, event)
            global master
            master.NDYOV.setpower = app.LaserPower.Value;
            NDYOVSetPower
            settingsUpdate(app)
      end

      % Value changed function: LaserEquilibrium
      function LaserEquilibriumValueChanged(app, event)
            global master
            master.NDYOV.waitforequilibrium = app.LaserEquilibrium.Value;
      end

      % Value changed function: LaserCleanOff
      function LaserCleanOffValueChanged(app, event)
            global master
            master.NDYOV.offwhencleaned = app.LaserCleanOff.Value;
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
         app.GridLayout = uigridlayout(app.UIFigure);
         app.LeftPanel = uipanel(app.GridLayout);
         app.TabGroup = uitabgroup(app.LeftPanel);
         app.ConnectionTab = uitab(app.TabGroup);
         app.ConnectionTab.Title = 'Connection';
         app.GridLayout2 = uigridlayout(app.ConnectionTab);
         app.RFLampLabel = uilabel(app.GridLayout2);
         app.RFLampLabel.Text = 'RF';
         app.RFLamp = uilamp(app.GridLayout2);
         app.StageLampLabel = uilabel(app.GridLayout2);
         app.StageLampLabel.Text = 'Stage';
         app.StageLamp = uilamp(app.GridLayout2);
         app.PulseBlasterLampLabel = uilabel(app.GridLayout2);
         app.PulseBlasterLampLabel.Text = 'Pulse Blaster';
         app.PulseBlasterLamp = uilamp(app.GridLayout2);
         app.DAQLampLabel = uilabel(app.GridLayout2);
         app.DAQLampLabel.Text = 'DAQ';
         app.DAQLamp = uilamp(app.GridLayout2);
         app.RFLoad = uibutton(app.GridLayout2, 'push');
         app.RFLoad.ButtonPushedFcn = createCallbackFcn(app, @RFLoadPushed, true);
         app.RFLoad.Text = 'Load';
         app.RFUnload = uibutton(app.GridLayout2, 'push');
         app.RFUnload.ButtonPushedFcn = createCallbackFcn(app, @RFUnloadButtonPushed, true);
         app.RFUnload.Text = 'Unload';
         app.StageLoad = uibutton(app.GridLayout2, 'push');
         app.StageLoad.ButtonPushedFcn = createCallbackFcn(app, @StageLoadButtonPushed, true);
         app.StageLoad.Text = 'Load';
         app.PBLoad = uibutton(app.GridLayout2, 'push');
         app.PBLoad.ButtonPushedFcn = createCallbackFcn(app, @PBLoadButtonPushed, true);
         app.PBLoad.Text = 'Load';
         app.DAQLoad = uibutton(app.GridLayout2, 'push');
         app.DAQLoad.ButtonPushedFcn = createCallbackFcn(app, @DAQLoadButtonPushed, true);
         app.DAQLoad.Text = 'Load';
         app.StageUnload = uibutton(app.GridLayout2, 'push');
         app.StageUnload.ButtonPushedFcn = createCallbackFcn(app, @StageUnloadButtonPushed, true);
         app.StageUnload.Text = 'Unload';
         app.PBUnload = uibutton(app.GridLayout2, 'push');
         app.PBUnload.ButtonPushedFcn = createCallbackFcn(app, @PBUnloadButtonPushed, true);
         app.PBUnload.Text = 'Unload';
         app.DAQUnload = uibutton(app.GridLayout2, 'push');
         app.DAQUnload.ButtonPushedFcn = createCallbackFcn(app, @DAQUnloadButtonPushed, true);
         app.DAQUnload.Text = 'Unload';
         app.LoadAll = uibutton(app.GridLayout2, 'push');
         app.LoadAll.ButtonPushedFcn = createCallbackFcn(app, @LoadAllButtonPushed, true);
         app.LoadAll.Text = 'Load';
         app.UnloadAll = uibutton(app.GridLayout2, 'push');
         app.UnloadAll.ButtonPushedFcn = createCallbackFcn(app, @UnloadAllButtonPushed, true);
         app.UnloadAll.Text = 'Unload';
         app.AllLampLabel = uilabel(app.GridLayout2);
         app.AllLampLabel.Text = 'All';
         app.AllLamp = uilamp(app.GridLayout2);
         app.LocalboxaddressEditFieldLabel = uilabel(app.GridLayout2);
         app.LocalboxaddressEditFieldLabel.Text = 'Local box address';
         app.BoxAddress = uieditfield(app.GridLayout2, 'text');
         app.BoxAddress.Value = 'C:\Users\User\';
         app.ComputerEditFieldLabel = uilabel(app.GridLayout2);
         app.ComputerEditFieldLabel.Text = 'Computer';
         app.ComputerEditField = uieditfield(app.GridLayout2, 'text');
         app.ComputerEditField.ValueChangedFcn = createCallbackFcn(app, @ComputerEditFieldValueChanged, true);
         app.ComputerEditField.Value = 'NV';
         app.EmailaddresstosendcompletionnotificationLabel = uilabel(app.GridLayout2);
         app.EmailaddresstosendcompletionnotificationLabel.Text = {'Email address to send'; 'completion notification'};
         app.EmailAddress = uieditfield(app.GridLayout2, 'text');
         app.EmailAddress.ValueChangedFcn = createCallbackFcn(app, @EmailAddressValueChanged, true);
         app.EmailAddress.Placeholder = 'YourEmail@gmail.com';
         app.LaserLoad = uibutton(app.GridLayout2, 'push');
         app.LaserLoad.ButtonPushedFcn = createCallbackFcn(app, @LaserLoadButtonPushed, true);
         app.LaserLoad.Text = 'Load';
         app.LaserUnload = uibutton(app.GridLayout2, 'push');
         app.LaserUnload.ButtonPushedFcn = createCallbackFcn(app, @LaserUnloadButtonPushed, true);
         app.LaserUnload.Text = 'Unload';
         app.LaserLampLabel = uilabel(app.GridLayout2);
         app.LaserLampLabel.Text = 'Laser';
         app.LaserLamp = uilamp(app.GridLayout2);
         app.GeneralTab = uitab(app.TabGroup);
         app.GeneralTab.Title = 'General';
         app.GridLayout4 = uigridlayout(app.GeneralTab);
         app.LoadpulsesequenceButton = uibutton(app.GridLayout4, 'push');
         app.LoadpulsesequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpulsesequenceButtonPushed, true);
         app.LoadpulsesequenceButton.Text = {'Load pulse'; 'sequence'};
         app.IterationsNum = uieditfield(app.GridLayout4, 'numeric');
         app.IterationsNum.Limits = [1 Inf];
         app.IterationsNum.ValueChangedFcn = createCallbackFcn(app, @IterationsNumValueChanged, true);
         app.IterationsNum.Value = 1;
         app.IterationsType = uibuttongroup(app.GridLayout4);
         app.IterationsType.SelectionChangedFcn = createCallbackFcn(app, @IterationsTypeSelectionChanged, true);
         app.IterationsType.Title = 'Iterations';
         app.NumberButton = uitogglebutton(app.IterationsType);
         app.NumberButton.Text = 'Number';
         app.NumberButton.Value = true;
         app.TimeminButton = uitogglebutton(app.IterationsType);
         app.TimeminButton.Text = 'Time (min)';
         app.RFOn = uicheckbox(app.GridLayout4);
         app.RFOn.ValueChangedFcn = createCallbackFcn(app, @RFOnValueChanged, true);
         app.RFOn.Text = 'RF on';
         app.RFOn.Value = true;
         app.SequenceName = uieditfield(app.GridLayout4, 'text');
         app.SequenceName.Placeholder = 'Sequence file name to load';
         app.PulseSequenceEditorButton = uibutton(app.GridLayout4, 'push');
         app.PulseSequenceEditorButton.ButtonPushedFcn = createCallbackFcn(app, @PulseSequenceEditorButtonPushed, true);
         app.PulseSequenceEditorButton.Text = 'Pulse Sequence Editor';
         app.RFFrequencyGHzLabel = uilabel(app.GridLayout4);
         app.RFFrequencyGHzLabel.Text = {'RF Frequency'; '(GHz)'};
         app.RFFreq = uieditfield(app.GridLayout4, 'numeric');
         app.RFFreq.ValueChangedFcn = createCallbackFcn(app, @RFFreqValueChanged, true);
         app.RFAmplitudedBmLabel = uilabel(app.GridLayout4);
         app.RFAmplitudedBmLabel.Text = {'RF Amplitude'; '(dBm)'};
         app.RFAmp = uieditfield(app.GridLayout4, 'numeric');
         app.RFAmp.ValueChangedFcn = createCallbackFcn(app, @RFAmpValueChanged, true);
         app.PulseSequence = uipanel(app.GridLayout4);
         app.MeasurementparameterLabel = uilabel(app.GridLayout4);
         app.MeasurementparameterLabel.Text = 'Measurement parameter';
         app.Parameter = uidropdown(app.GridLayout4);
         app.Parameter.Items = {'Contrast', 'Channel 1 counts', 'Channel 3 counts'};
         app.Parameter.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
         app.Parameter.Value = 'Contrast';
         app.LaserpowerEditFieldLabel = uilabel(app.GridLayout4);
         app.LaserpowerEditFieldLabel.Text = 'Laser power';
         app.LaserPower = uieditfield(app.GridLayout4, 'numeric');
         app.LaserPower.Limits = [0 8];
         app.LaserPower.ValueChangedFcn = createCallbackFcn(app, @LaserPowerValueChanged, true);
         app.LaserswitchSwitchLabel = uilabel(app.GridLayout4);
         app.LaserswitchSwitchLabel.Text = 'Laser switch';
         app.LaserSwitch = uiswitch(app.GridLayout4, 'slider');
         app.LaserSwitch.Items = {'off', 'on'};
         app.LaserSwitch.ValueChangedFcn = createCallbackFcn(app, @LaserSwitchValueChanged, true);
         app.LaserSwitch.Value = 'off';
         app.StageTab = uitab(app.TabGroup);
         app.StageTab.Title = 'Stage';
         app.GridLayout6 = uigridlayout(app.StageTab);
         app.ZUp = uibutton(app.GridLayout6, 'push');
         app.ZUp.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
         app.ZUp.Text = '>';
         app.ZDown = uibutton(app.GridLayout6, 'push');
         app.ZDown.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
         app.ZDown.Text = '<';
         app.ZFineReset = uibutton(app.GridLayout6, 'push');
         app.ZFineReset.ButtonPushedFcn = createCallbackFcn(app, @ZFineResetButtonPushed, true);
         app.ZFineReset.Text = {'Fine axis'; 'reset'};
         app.ZIncrement = uieditfield(app.GridLayout6, 'numeric');
         app.ZIncrement.ValueChangedFcn = createCallbackFcn(app, @ZIncrementValueChanged, true);
         app.ZPosition = uieditfield(app.GridLayout6, 'numeric');
         app.ZPosition.Limits = [-100 25100];
         app.ZPosition.ValueChangedFcn = createCallbackFcn(app, @ZPositionValueChanged, true);
         app.ZSlider = uislider(app.GridLayout6);
         app.ZSlider.Limits = [2520 22680];
         app.ZSlider.MajorTicks = [5000 10000 15000 20000];
         app.ZSlider.MajorTickLabels = {'5mm', '10mm', '15mm', '20mm'};
         app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
         app.ZSlider.MinorTicks = [1000 2000 3000 4000 6000 7000 8000 9000 11000 12000 13000 14000 16000 17000 18000 19000 21000 22000 23000 24000];
         app.ZSlider.Value = 12500;
         app.ZaxisSliderLabel_2 = uilabel(app.GridLayout6);
         app.ZaxisSliderLabel_2.Text = 'Z axis';
         app.YUp = uibutton(app.GridLayout6, 'push');
         app.YUp.ButtonPushedFcn = createCallbackFcn(app, @YUpButtonPushed, true);
         app.YUp.Text = '>';
         app.YDown = uibutton(app.GridLayout6, 'push');
         app.YDown.ButtonPushedFcn = createCallbackFcn(app, @YDownButtonPushed, true);
         app.YDown.Text = '<';
         app.YFineReset = uibutton(app.GridLayout6, 'push');
         app.YFineReset.ButtonPushedFcn = createCallbackFcn(app, @YFineResetButtonPushed, true);
         app.YFineReset.Text = {'Fine axis'; 'reset'};
         app.YIncrement = uieditfield(app.GridLayout6, 'numeric');
         app.YIncrement.ValueChangedFcn = createCallbackFcn(app, @YIncrementValueChanged, true);
         app.YPosition = uieditfield(app.GridLayout6, 'numeric');
         app.YPosition.Limits = [-12600 12600];
         app.YPosition.ValueChangedFcn = createCallbackFcn(app, @YPositionValueChanged, true);
         app.YSlider = uislider(app.GridLayout6);
         app.YSlider.Limits = [-9980 10180];
         app.YSlider.MajorTicks = [-9980 -5000 0 5000 10000];
         app.YSlider.MajorTickLabels = {'-10mm', '-5mm', '0mm', '5mm', '10mm'};
         app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
         app.YSlider.MinorTicks = [-12000 -11000 -9000 -8000 -7000 -6000 -4000 -3000 -2000 -1000 1000 2000 3000 4000 6000 7000 8000 9000 11000 12000];
         app.YaxisSliderLabel_2 = uilabel(app.GridLayout6);
         app.YaxisSliderLabel_2.Text = 'Y axis';
         app.XUp = uibutton(app.GridLayout6, 'push');
         app.XUp.ButtonPushedFcn = createCallbackFcn(app, @XUpButtonPushed, true);
         app.XUp.Text = '>';
         app.XDown = uibutton(app.GridLayout6, 'push');
         app.XDown.ButtonPushedFcn = createCallbackFcn(app, @XDownButtonPushed, true);
         app.XDown.Text = '<';
         app.XFineReset = uibutton(app.GridLayout6, 'push');
         app.XFineReset.ButtonPushedFcn = createCallbackFcn(app, @XFineResetButtonPushed, true);
         app.XFineReset.Text = {'Fine axis'; 'reset'};
         app.XIncrement = uieditfield(app.GridLayout6, 'numeric');
         app.XIncrement.ValueChangedFcn = createCallbackFcn(app, @XIncrementValueChanged, true);
         app.XPosition = uieditfield(app.GridLayout6, 'numeric');
         app.XPosition.Limits = [-12600 12600];
         app.XPosition.ValueChangedFcn = createCallbackFcn(app, @XPositionValueChanged, true);
         app.XSlider = uislider(app.GridLayout6);
         app.XSlider.Limits = [-9980 10180];
         app.XSlider.MajorTicks = [-9980 -5000 0 5000 10000];
         app.XSlider.MajorTickLabels = {'-10mm', '-5mm', '0mm', '5mm', '10mm'};
         app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
         app.XSlider.MinorTicks = [-12000 -11000 -9000 -8000 -7000 -6000 -4000 -3000 -2000 -1000 1000 2000 3000 4000 6000 7000 8000 9000 11000 12000];
         app.XSlider.Value = 5000;
         app.XaxisSliderLabel_2 = uilabel(app.GridLayout6);
         app.XaxisSliderLabel_2.Text = 'X axis';
         app.IncrementLabel = uilabel(app.GridLayout6);
         app.IncrementLabel.Text = 'Increment';
         app.PositionLabel = uilabel(app.GridLayout6);
         app.PositionLabel.Text = 'Position';
         app.TimebetweenoptimizationsminLabel_2 = uilabel(app.GridLayout6);
         app.TimebetweenoptimizationsminLabel_2.Text = {'Time between '; 'optimizations (min)'};
         app.OptimizationTiming = uieditfield(app.GridLayout6, 'numeric');
         app.OptimizationTiming.Limits = [0 Inf];
         app.OptimizationTiming.ValueChangedFcn = createCallbackFcn(app, @OptimizationTimingValueChanged, true);
         app.OptimizationTiming.Value = 5;
         app.OptimizationOn = uicheckbox(app.GridLayout6);
         app.OptimizationOn.ValueChangedFcn = createCallbackFcn(app, @OptimizationOnValueChanged, true);
         app.OptimizationOn.Text = 'Auto-optimization';
         app.OptAxis = uidropdown(app.GridLayout6);
         app.OptAxis.Items = {'x', 'y', 'z'};
         app.OptAxis.Value = 'x';
         app.RadiusEditFieldLabel = uilabel(app.GridLayout6);
         app.RadiusEditFieldLabel.Text = 'Radius';
         app.OptRadius = uieditfield(app.GridLayout6, 'numeric');
         app.OptRadius.Limits = [0 Inf];
         app.OptRadius.Value = 0.1;
         app.StepsEditFieldLabel = uilabel(app.GridLayout6);
         app.StepsEditFieldLabel.Text = 'Steps';
         app.OptSteps = uieditfield(app.GridLayout6, 'numeric');
         app.OptSteps.Limits = [3 Inf];
         app.OptSteps.Value = 3;
         app.AddstepButton = uibutton(app.GridLayout6, 'push');
         app.AddstepButton.ButtonPushedFcn = createCallbackFcn(app, @AddstepButtonPushed, true);
         app.AddstepButton.Text = {'Add'; 'step'};
         app.OptTable = uitable(app.GridLayout6);
         app.OptTable.ColumnName = {'Axis'; 'Radius'; 'Steps'};
         app.OptTable.RowName = {};
         app.StageHeader = uilabel(app.GridLayout6);
         app.StageHeader.Text = 'All units in μm unless otherwise indicated';
         app.AxisLabel = uilabel(app.GridLayout6);
         app.AxisLabel.Text = 'Axis';
         app.DeletestepButton = uibutton(app.GridLayout6, 'push');
         app.DeletestepButton.ButtonPushedFcn = createCallbackFcn(app, @DeletestepButtonPushed, true);
         app.DeletestepButton.Text = {'Delete'; 'step'};
         app.OptimizationaddressEditFieldLabel = uilabel(app.GridLayout6);
         app.OptimizationaddressEditFieldLabel.Text = 'Optimization address';
         app.OptAddress = uieditfield(app.GridLayout6, 'text');
         app.OptAddress.Value = '1';
         app.ScanningTab = uitab(app.TabGroup);
         app.ScanningTab.Title = 'Scanning';
         app.GridLayout5 = uigridlayout(app.ScanningTab);
         app.ScantypeDropDownLabel = uilabel(app.GridLayout5);
         app.ScantypeDropDownLabel.Text = 'Scan type';
         app.ScanType = uidropdown(app.GridLayout5);
         app.ScanType.Items = {'Stage X', 'Stage Y', 'Stage Z', 'RF frequency', 'Pulse duration'};
         app.ScanType.ValueChangedFcn = createCallbackFcn(app, @ScanTypeValueChanged, true);
         app.ScanType.Value = 'Stage X';
         app.StartEditFieldLabel = uilabel(app.GridLayout5);
         app.StartEditFieldLabel.Text = 'Start';
         app.StartScan = uieditfield(app.GridLayout5, 'text');
         app.StartScan.ValueChangedFcn = createCallbackFcn(app, @StartScanValueChanged, true);
         app.EndEditFieldLabel = uilabel(app.GridLayout5);
         app.EndEditFieldLabel.Text = 'End';
         app.EndScan = uieditfield(app.GridLayout5, 'text');
         app.EndScan.ValueChangedFcn = createCallbackFcn(app, @EndScanValueChanged, true);
         app.PulseaddressesifapplicableLabel = uilabel(app.GridLayout5);
         app.PulseaddressesifapplicableLabel.Text = {'Pulse address(es)'; 'if applicable'};
         app.PulseAddresses = uieditfield(app.GridLayout5, 'text');
         app.PulseAddresses.ValueChangedFcn = createCallbackFcn(app, @PulseAddressesValueChanged, true);
         app.NPointsStepSize = uibuttongroup(app.GridLayout5);
         app.NPointsStepSize.SelectionChangedFcn = createCallbackFcn(app, @NPointsStepSizeSelectionChanged, true);
         app.NPointsButton = uitogglebutton(app.NPointsStepSize);
         app.NPointsButton.Text = '# of points';
         app.NPointsButton.Value = true;
         app.StepSizeButton = uitogglebutton(app.NPointsStepSize);
         app.StepSizeButton.Text = 'Step size';
         app.StepSizeField = uieditfield(app.GridLayout5, 'numeric');
         app.StepSizeField.ValueChangedFcn = createCallbackFcn(app, @StepSizeFieldValueChanged, true);
         app.StepSizeField.Value = 1;
         app.ScanTable = uitable(app.GridLayout5);
         app.ScanTable.ColumnName = {'Order'; 'Type'; 'Start'; 'End'; 'Steps size'; 'Total steps'};
         app.ScanTable.RowName = {};
         app.AddScanButton = uibutton(app.GridLayout5, 'push');
         app.AddScanButton.ButtonPushedFcn = createCallbackFcn(app, @AddScanButtonPushed, true);
         app.AddScanButton.Text = 'Add Scan';
         app.DeleteScanButton = uibutton(app.GridLayout5, 'push');
         app.DeleteScanButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanButtonPushed, true);
         app.DeleteScanButton.Text = 'Delete Scan';
         app.Overwrite = uidropdown(app.GridLayout5);
         app.Overwrite.Items = {'Overwrite', 'Insert new'};
         app.Overwrite.Value = 'Overwrite';
         app.ScannumberLabel = uilabel(app.GridLayout5);
         app.ScannumberLabel.Text = 'Scan number';
         app.ScanNum = uieditfield(app.GridLayout5, 'text');
         app.ScanNum.ValueChangedFcn = createCallbackFcn(app, @ScanNumValueChanged, true);
         app.ScanNum.Value = '1';
         app.OutputSettingsTab = uitab(app.TabGroup);
         app.OutputSettingsTab.Title = 'Output Settings';
         app.GridLayout7 = uigridlayout(app.OutputSettingsTab);
         app.XaxisdimensionLabel = uilabel(app.GridLayout7);
         app.XaxisdimensionLabel.Text = 'X axis dimension';
         app.XDimension = uieditfield(app.GridLayout7, 'numeric');
         app.XDimension.Limits = [0 Inf];
         app.XDimension.ValueChangedFcn = createCallbackFcn(app, @XDimensionValueChanged, true);
         app.XDimension.Value = 1;
         app.YaxisdimensionLabel = uilabel(app.GridLayout7);
         app.YaxisdimensionLabel.Text = 'Y axis dimension';
         app.YDimension = uieditfield(app.GridLayout7, 'numeric');
         app.YDimension.Limits = [0 Inf];
         app.YDimension.ValueChangedFcn = createCallbackFcn(app, @YDimensionValueChanged, true);
         app.Progress = uipanel(app.GridLayout7);
         app.Progress.Title = 'Scan progress';
         app.DimensionSet = uitable(app.GridLayout7);
         app.DimensionSet.ColumnName = {'Order'; 'Type'; 'Number of steps'; 'Chosen step'; 'Chosen value'};
         app.DimensionSet.RowName = {};
         app.DimensionSet.ColumnEditable = true;
         app.DimensionSet.CellEditCallback = createCallbackFcn(app, @DimensionSetCellEdit, true);
         app.OtherscandimensionschosensliceLabel = uilabel(app.GridLayout7);
         app.OtherscandimensionschosensliceLabel.Text = 'Other scan dimensions chosen slice';
         app.UpperpercentilelimitLabel = uilabel(app.GridLayout7);
         app.UpperpercentilelimitLabel.Text = {'Upper percentile'; 'limit'};
         app.UpperPercentile = uieditfield(app.GridLayout7, 'numeric');
         app.UpperPercentile.Limits = [0 110];
         app.UpperPercentile.ValueChangedFcn = createCallbackFcn(app, @UpperPercentileValueChanged, true);
         app.UpperPercentile.Value = 100;
         app.LowerpercentilelimitLabel = uilabel(app.GridLayout7);
         app.LowerpercentilelimitLabel.Text = {'Lower percentile'; 'limit'};
         app.LowerPercentile = uieditfield(app.GridLayout7, 'numeric');
         app.LowerPercentile.Limits = [-10 100];
         app.LowerPercentile.ValueChangedFcn = createCallbackFcn(app, @LowerPercentileValueChanged, true);
         app.UpdateperiodsLabel = uilabel(app.GridLayout7);
         app.UpdateperiodsLabel.Text = {'Update'; 'period (s)'};
         app.UpdatePeriod = uieditfield(app.GridLayout7, 'numeric');
         app.UpdatePeriod.Limits = [0 Inf];
         app.UpdatePeriod.ValueChangedFcn = createCallbackFcn(app, @UpdatePeriodValueChanged, true);
         app.CurrentiterationTextAreaLabel = uilabel(app.GridLayout7);
         app.CurrentiterationTextAreaLabel.Text = 'Current iteration';
         app.CurrentIteration = uitextarea(app.GridLayout7);
         app.CurrentIteration.Editable = 'off';
         app.CurrentIteration.Placeholder = '1';
         app.WarningsTab = uitab(app.TabGroup);
         app.WarningsTab.Title = 'Warnings';
         app.GridLayout9 = uigridlayout(app.WarningsTab);
         app.Warnings = uitextarea(app.GridLayout9);
         app.ObscureSettingsTab = uitab(app.TabGroup);
         app.ObscureSettingsTab.Title = 'Obscure Settings';
         app.GridLayout8 = uigridlayout(app.ObscureSettingsTab);
         app.OptimizationtargetButtonGroup = uibuttongroup(app.GridLayout8);
         app.OptimizationtargetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptimizationtargetButtonGroupSelectionChanged, true);
         app.OptimizationtargetButtonGroup.Title = 'Optimization target';
         app.RawValueButton = uitogglebutton(app.OptimizationtargetButtonGroup);
         app.RawValueButton.Text = 'Raw Value';
         app.RawValueButton.Value = true;
         app.GaussianPeakButton = uitogglebutton(app.OptimizationtargetButtonGroup);
         app.GaussianPeakButton.Text = 'Gaussian Peak';
         app.DurationofcountreadoutforoptimizationnsLabel = uilabel(app.GridLayout8);
         app.DurationofcountreadoutforoptimizationnsLabel.Text = {'Duration of count'; 'readout for'; 'optimization (ns)'};
         app.OptReadoutDur = uieditfield(app.GridLayout8, 'numeric');
         app.OptReadoutDur.Limits = [1 Inf];
         app.OptReadoutDur.ValueChangedFcn = createCallbackFcn(app, @OptReadoutDurValueChanged, true);
         app.OptReadoutDur.Value = 1000000;
         app.OptimizationparametersettingsButtonGroup = uibuttongroup(app.GridLayout8);
         app.OptimizationparametersettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptimizationparametersettingsButtonGroupSelectionChanged, true);
         app.OptimizationparametersettingsButtonGroup.Title = 'Optimization parameter settings';
         app.RFoffButton = uitogglebutton(app.OptimizationparametersettingsButtonGroup);
         app.RFoffButton.Text = 'RF off';
         app.RFoffButton.Value = true;
         app.RFonButton = uitogglebutton(app.OptimizationparametersettingsButtonGroup);
         app.RFonButton.Text = 'RF on';
         app.ContrastButton = uitogglebutton(app.OptimizationparametersettingsButtonGroup);
         app.ContrastButton.Text = 'Contrast';
         app.StageprecisionmLabel = uilabel(app.GridLayout8);
         app.StageprecisionmLabel.Text = {'Stage'; 'precision (μm)'};
         app.StagePrecision = uieditfield(app.GridLayout8, 'numeric');
         app.StagePrecision.Limits = [0 Inf];
         app.StagePrecision.ValueChangedFcn = createCallbackFcn(app, @StagePrecisionValueChanged, true);
         app.StagePrecision.Value = 0.01;
         app.NumberofdigitsdisplayedLabel = uilabel(app.GridLayout8);
         app.NumberofdigitsdisplayedLabel.Text = {'Number of'; 'digits displayed'};
         app.DisplayDigits = uieditfield(app.GridLayout8, 'numeric');
         app.DisplayDigits.Limits = [1 10];
         app.DisplayDigits.ValueChangedFcn = createCallbackFcn(app, @DisplayDigitsValueChanged, true);
         app.DisplayDigits.Value = 4;
         app.AutosaveCheckBox = uicheckbox(app.GridLayout8);
         app.AutosaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBoxValueChanged, true);
         app.AutosaveCheckBox.Text = 'Auto save';
         app.AutoSaveName = uieditfield(app.GridLayout8, 'text');
         app.AutoSaveName.ValueChangedFcn = createCallbackFcn(app, @AutoSaveNameValueChanged, true);
         app.AutoSaveName.Placeholder = 'Auto save folder name';
         app.Autosaveevery_iterationsLabel = uilabel(app.GridLayout8);
         app.Autosaveevery_iterationsLabel.Text = {'Auto save'; 'every _ iterations'};
         app.AutoSaveIterations = uieditfield(app.GridLayout8, 'numeric');
         app.AutoSaveIterations.Limits = [1 Inf];
         app.AutoSaveIterations.ValueChangedFcn = createCallbackFcn(app, @AutoSaveIterationsValueChanged, true);
         app.AutoSaveIterations.Value = 1;
         app.IterationEmail = uicheckbox(app.GridLayout8);
         app.IterationEmail.ValueChangedFcn = createCallbackFcn(app, @IterationEmailValueChanged, true);
         app.IterationEmail.Text = {'Send email every'; 'iteration completion'};
         app.BeginOptimizationsButton = uibutton(app.GridLayout8, 'push');
         app.BeginOptimizationsButton.ButtonPushedFcn = createCallbackFcn(app, @BeginOptimizationsButtonPushed, true);
         app.BeginOptimizationsButton.Text = 'Begin Optimization(s)';
         app.NumberofoptimizationsLabel = uilabel(app.GridLayout8);
         app.NumberofoptimizationsLabel.Text = {'Number of '; 'optimizations'};
         app.ManualOptNum = uieditfield(app.GridLayout8, 'numeric');
         app.ManualOptNum.Limits = [1 Inf];
         app.ManualOptNum.ValueChangedFcn = createCallbackFcn(app, @ManualOptNumValueChanged, true);
         app.ManualOptNum.Value = 1;
         app.Notifications = uicheckbox(app.GridLayout8);
         app.Notifications.ValueChangedFcn = createCallbackFcn(app, @NotificationsValueChanged, true);
         app.Notifications.Text = {'Command window'; 'notifications'};
         app.Notifications.Value = true;
         app.CompletionEmail = uicheckbox(app.GridLayout8);
         app.CompletionEmail.ValueChangedFcn = createCallbackFcn(app, @CompletionEmailValueChanged, true);
         app.CompletionEmail.Text = 'Send email upon completion';
         app.LaserEquilibrium = uicheckbox(app.GridLayout8);
         app.LaserEquilibrium.ValueChangedFcn = createCallbackFcn(app, @LaserEquilibriumValueChanged, true);
         app.LaserEquilibrium.Text = {'Wait until laser'; 'is equilibrated'};
         app.LaserCleanOff = uicheckbox(app.GridLayout8);
         app.LaserCleanOff.ValueChangedFcn = createCallbackFcn(app, @LaserCleanOffValueChanged, true);
         app.LaserCleanOff.Text = {'Turn laser off'; 'when unloaded'};
         app.RightPanel = uipanel(app.GridLayout);
         app.GridLayout3 = uigridlayout(app.RightPanel);
         app.SinglevalueoutputLabel = uilabel(app.GridLayout3);
         app.SinglevalueoutputLabel.Text = {'Single value'; 'output'};
         app.SingleOutput = uitextarea(app.GridLayout3);
         app.SingleOutput.Editable = 'off';
         app.StartButton = uibutton(app.GridLayout3, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.SavesetupButton = uibutton(app.GridLayout3, 'push');
         app.SavesetupButton.ButtonPushedFcn = createCallbackFcn(app, @SavesetupButtonPushed, true);
         app.SavesetupButton.Text = 'Save setup';
         app.RefreshAppButton = uibutton(app.GridLayout3, 'push');
         app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
         app.RefreshAppButton.Text = 'Refresh App';
         app.SaveOutputButton = uibutton(app.GridLayout3, 'push');
         app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
         app.SaveOutputButton.Text = 'Save Output';
         app.LoadsetupButton = uibutton(app.GridLayout3, 'push');
         app.LoadsetupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsetupButtonPushed, true);
         app.LoadsetupButton.Text = 'Load setup';
         app.SetupLoadName = uieditfield(app.GridLayout3, 'text');
         app.SetupLoadName.Placeholder = 'Setup load name';
         app.AbortButton = uibutton(app.GridLayout3, 'push');
         app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
         app.AbortButton.Text = 'Abort';
         app.SetupSaveName = uieditfield(app.GridLayout3, 'text');
         app.SetupSaveName.Placeholder = 'Setup save name';
         app.OutputSaveName = uieditfield(app.GridLayout3, 'text');
         app.OutputSaveName.Placeholder = 'Output save name';
         app.OutputLoadName = uieditfield(app.GridLayout3, 'text');
         app.OutputLoadName.Placeholder = 'Output load name';
         app.LoadoutputButton = uibutton(app.GridLayout3, 'push');
         app.LoadoutputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoutputButtonPushed, true);
         app.LoadoutputButton.Text = 'Load output';
         app.ResetButton = uibutton(app.GridLayout3, 'push');
         app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
         app.ResetButton.Text = 'Reset';
         app.StatusLamp = uilamp(app.GridLayout3);
         app.OutputPanel = uipanel(app.GridLayout3);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = ExperimentEditorBeta

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end