classdef PulseSequenceEditorBeta < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                      matlab.ui.Figure
      GridLayout                    matlab.ui.container.GridLayout
      LeftPanel                     matlab.ui.container.Panel
      TabGroup                      matlab.ui.container.TabGroup
      OverviewTab                   matlab.ui.container.Tab
      GridLayout4                   matlab.ui.container.GridLayout
      DeleteAddress                 matlab.ui.control.EditField
      AddressesEditFieldLabel       matlab.ui.control.Label
      LineLabels                    matlab.ui.control.Switch
      PlotLineLabelsSwitchLabel     matlab.ui.control.Label
      DeleteAllButton               matlab.ui.control.Button
      LocalboxaddressLabel          matlab.ui.control.Label
      BoxAddress                    matlab.ui.control.TextArea
      LoadName                      matlab.ui.control.EditField
      DeleteStepButton              matlab.ui.control.Button
      SequenceTable                 matlab.ui.control.Table
      ChannelInfo                   matlab.ui.control.TextArea
      LoadSequenceButton            matlab.ui.control.Button
      NewStepTab                    matlab.ui.container.Tab
      GridLayout3                   matlab.ui.container.GridLayout
      Warnings                      matlab.ui.control.TextArea
      InstructionAddressEditFieldLabel  matlab.ui.control.Label
      Address                       matlab.ui.control.NumericEditField
      DurationnsEditFieldLabel      matlab.ui.control.Label
      Duration                      matlab.ui.control.NumericEditField
      InstructionTypeDropDownLabel  matlab.ui.control.Label
      InstructionType               matlab.ui.control.DropDown
      Channels                      matlab.ui.control.ListBox
      Binary                        matlab.ui.control.TextArea
      AddtoSequenceButton           matlab.ui.control.Button
      Overwrite                     matlab.ui.control.DropDown
      ContextNum                    matlab.ui.control.NumericEditField
      ContextName                   matlab.ui.control.TextArea
      CopySegmentsTab               matlab.ui.container.Tab
      GridLayout5                   matlab.ui.container.GridLayout
      SegmentVisual                 matlab.ui.container.Panel
      PasteSegmentButton            matlab.ui.control.Button
      PasteAddress                  matlab.ui.control.NumericEditField
      PasteaddressEditFieldLabel    matlab.ui.control.Label
      EndSeg                        matlab.ui.control.NumericEditField
      EndofsegmentaddressLabel      matlab.ui.control.Label
      StartSeg                      matlab.ui.control.NumericEditField
      StartofsegmentaddressLabel    matlab.ui.control.Label
      SequenceTable2                matlab.ui.control.Table
      RightPanel                    matlab.ui.container.Panel
      GridLayout2                   matlab.ui.container.GridLayout
      RefreshButton                 matlab.ui.control.Button
      SaveName                      matlab.ui.control.TextArea
      Visuals                       matlab.ui.container.Panel
      SaveButton                    matlab.ui.control.Button
      RunButton                     matlab.ui.control.Button
      CompileButton                 matlab.ui.control.Button
   end

   % Properties that correspond to apps with auto-reflow
   properties (Access = private)
      onePanelWidth = 576;
   end

   %Pulse Sequence Editor v1.2 4/12/22

   %Things needed to edit when adding/changing output channels:
   %channelTitles and lineColors within startup function (~line 400)
   %(optional) change default value within PBAddSequence function

   %This does not handle non-time based instructions very well. for
   %example: loop does not show a loop on the interface and the duration
   %selected for it is actually shown rather than just being ignored. it
   %is therefore recommended to manually keep track of where a loop is

   properties (Access = private)
      seqlayout
      segmentlayout
      pulselines
      pulsetiles
      segmenttiles
      segmentlines
      stoplabels
      branchlabels
      looplabels
      endlooplabels
   end

   methods (Access = private)

      function PBguiUpdate(app)

         global master %#ok<*GVMIS>
         iscorrect = false;

         if isfield(master.PB,'sequence')
            aa = master.PB.sequence;
            durations = zeros(length(aa),1);
            addresses = durations;
            directions = string(durations);
            channels = string(durations);
            elapseddur = 1;

            master.gui.PSE.loops = [];
            master.gui.PSE.loopvals = [];
            master.gui.PSE.loopaddresses = [];
            master.gui.PSE.endloops = [];
            master.gui.PSE.endrefs = [];
            master.gui.PSE.branches = [];
            master.gui.PSE.branchrefs = [];
            master.gui.PSE.stops = [];

            for ii = 1:length(aa)
               durations(ii) = aa(ii).duration;
               addresses(ii) = aa(ii).address;
               directions(ii) = aa(ii).direction;
               cchannels = PBguiBin(app,aa(ii).output);
               channels(ii) = convertCharsToStrings(cchannels);

               if strcmp(directions(ii),'LOOP')
                  master.gui.PSE.loops(end+1) = elapseddur;
                  master.gui.PSE.loopvals(end+1) = aa(ii).contextinfo;
                  master.gui.PSE.loopaddresses(end+1) = aa(ii).address;
               end
               if strcmp(directions(ii),'END_LOOP')
                  master.gui.PSE.endloops(end+1) = elapseddur;
                  master.gui.PSE.endrefs(end+1) = aa(ii).contextinfo;
               end
               if strcmp(directions(ii),'BRANCH')
                  master.gui.PSE.branches(end+1) = elapseddur;
                  master.gui.PSE.branchrefs(end+1) = aa(ii).contextinfo;
               end
               if strcmp(directions(ii),'STOP')
                  master.gui.PSE.stops(end+1) = elapseddur;
               end

               elapseddur = elapseddur + aa(ii).duration;
               master.gui.PSE.cdurationend(ii) = elapseddur;

            end

            app.SequenceTable.Data = [addresses directions channels durations];
            app.SequenceTable2.Data = app.SequenceTable.Data;

            kk = 1;
            warningtext = {};
            if ~isempty(master.gui.PSE.loopaddresses)
               openloops = ismember(master.gui.PSE.loopaddresses,master.gui.PSE.endrefs);
               for mm = 1:length(openloops)
                  if ~openloops(mm)
                     warningtext{kk} = sprintf('Loop %d is missing an end loop',mm); %#ok<AGROW>
                     kk = kk+1;
                  end
               end
            end
            if ~isempty(master.gui.PSE.loopaddresses)
               extracloses = ismember(master.gui.PSE.endrefs,master.gui.PSE.loopaddresses);
               for mm = 1:length(extracloses)
                  if ~extracloses(mm)
                     warningtext{kk} = sprintf('End loop %d is missing a beginning',mm); %#ok<AGROW>
                     kk = kk+1;
                  end
               end
            end
            if ~isempty(master.gui.PSE.stops) && ~strcmp(master.PB.sequence(end).direction,'STOP')
               warningtext{kk} = sprintf('Extra stop pulse present within sequence');
               kk = kk+1;
            end
            if length(master.PB.sequence) >= 2
               seclast = master.PB.sequence(end-1);
               if strcmp(seclast.direction,'CONTINUE')
                  if seclast.output == 0
                     if seclast.duration >= 20
                     else
                        warningtext{kk} = convertStringsToChars("Second to last instruction's duration must be at least 20");
                        kk = kk+1;
                     end
                  else
                     warningtext{kk} = convertStringsToChars("Second to last instruction's channel output must be 0");
                     kk = kk+1;
                  end
               else
                  warningtext{kk} = convertStringsToChars("Second to last instruction must be 'CONTINUE'");
                  kk = kk+1;
               end
            end
            if ~strcmp(master.PB.sequence(end).direction,'STOP')
               warningtext{kk} = sprintf('No stop pulse at the end of the sequence');
            end
            if ~isempty(warningtext)
               app.Warnings.Value = warningtext;
            else
               app.Warnings.Value = 'No warnings present';
            end

            iscorrect = true;

         end
         PBguiPlotUpdate(app)
         PBguiSegmentUpdate(app)
         if ~iscorrect
            for ii = 1:master.PB.nchannels
               try
               delete(app.pulsetiles{ii})
               delete(app.segmenttiles{ii})
               catch
               end
            end
            app.SequenceTable.Data = [];
         end

         app.StartSeg.Value = master.gui.PSE.begseg;
         app.EndSeg.Value = master.gui.PSE.endseg;

      end

      function binaryOutput = PBguiBin(app,sumOfChannels) %#ok<INUSL>
         %Can be used sequentially with PBguiChnlSum function to turn a
         %cell array of values into a binary sequence with nchannels digits
         global master
         aa = master.PB.nchannels;
         valbin = dec2bin(sumOfChannels);
         lvalbin = length(valbin);
         if lvalbin ~= aa
            storedval = valbin;
            valbin(1:aa-lvalbin) = '0';
            valbin(1+aa-lvalbin:aa) = storedval;
         end
         binaryOutput = valbin;
      end


      function  PBguiPlotUpdate(app)
         global master
         cumdur = 0;
         if isfield(master.PB,'sequence')
            for jj = 1:numel(master.PB.sequence)
               cumdur = cumdur + master.PB.sequence(jj).duration;
            end

            channelx = zeros(1,numel(master.PB.sequence));
            channely= channelx;
            for ii = 1:master.PB.nchannels
               for jj = 1:numel(master.PB.sequence)
                  if ii == 1
                     if jj == 1
                        channelx(1) = 1;
                     else
                        channelx(jj*2-1) = channelx((jj-1)*2);
                     end

                     channelx(jj*2) = channelx(jj*2-1) + master.PB.sequence(jj).duration;
                  end

                  channely(jj*2-1) = str2double(master.PB.sequence(jj).binaryoutput(1+end-ii));
                  channely(jj*2) = channely(jj*2-1) ;

               end
               if ~isvalid(app.pulsetiles{1})
                  PBguiGeneratePlotAxes(app)
               end
               app.pulselines{ii} = plot(app.pulsetiles{ii},channelx,channely);
               app.pulselines{ii}.Color = master.gui.PSE.lineColors(ii);
            end

            aa = master.gui.PSE;
            %If line labels are on
            if strcmp(app.LineLabels.Value,'1')
               if ~isempty(aa.loops)
                  for ii = 1:length(aa.loops)
                     app.looplabels{ii} = xline(seq,aa.loops(ii),'-k',sprintf('Loop %d, %d',ii,aa.loopvals(ii)));
                     app.looplabels{ii}.LabelHorizontalAlignment = 'left';
                     app.looplabels{ii}.LabelOrientation = 'horizontal';
                  end
               end

               if ~isempty(aa.endloops)
                  for ii = 1:length(aa.endloops)
                     loopref = find(aa.loopaddresses == aa.endrefs(ii));
                     app.endlooplabels{ii} = xline(seq,aa.endloops(ii),'-b',sprintf('End loop %d',loopref));
                     app.endlooplabels{ii}.LabelHorizontalAlignment = 'left';
                     app.endlooplabels{ii}.LabelOrientation = 'horizontal';
                  end
               end

               if ~isempty(aa.branches)
                  for ii = 1:length(aa.branches)
                     app.branchlabels{ii} = xline(seq,aa.branches(ii),'g',sprintf('Branch to %d',aa.branchrefs(ii)));
                     app.branchlabels{ii}.LabelHorizontalAlignment = 'left';
                     app.branchlabels{ii}.LabelOrientation = 'horizontal';
                  end
               end

               if ~isempty(aa.stops)
                  for ii = 1:length(aa.stops)
                     app.stoplabels{ii} = xline(seq,aa.stops(ii),'r','Stop');
                     app.stoplabels{ii}.LabelHorizontalAlignment = 'left';
                     app.stoplabels{ii}.LabelOrientation = 'horizontal';
                  end
               end
            end
            

         end
         if cumdur == 0
            cumdur = 1;
         end

         %Even if no sequence, change properties of tiles
         for ii = 1:master.PB.nchannels
            app.pulsetiles{ii}.YTick = [];
            if ii ~= master.PB.nchannels
               app.pulsetiles{ii}.XTick = [];
            else
               app.pulsetiles{ii}.TickLength = [0 0];
            end
            app.pulsetiles{ii}.XLim = [0 cumdur];
            app.pulsetiles{ii}.YLim = [-.4 1.4];
            if ~mod(ii,2)
               app.pulsetiles{ii}.YAxisLocation = 'right';
            end
            app.pulsetiles{ii}.YLabel.String = convertStringsToChars(master.gui.PSE.channelTitles(ii));
         end

      end
      
      function PBguiSegmentUpdate(app)
         global master
         
         begseg = master.gui.PSE.begseg;
         endseg = master.gui.PSE.endseg;
         cumdur = 0;

         if isfield(master.PB,'sequence')
            if numel(master.PB.sequence) >= endseg %is end of segment within sequence

               segseq = master.PB.sequence(begseg:endseg);               

               for jj = 1:numel(segseq)
                  cumdur = cumdur + segseq(jj).duration;
               end

               channelx = zeros(1,numel(segseq));
               channely= channelx;
               for ii = 1:master.PB.nchannels
                  for jj = 1:numel(segseq)
                     if ii == 1
                        if jj == 1
                           channelx(1) = 1;
                        else
                           channelx(jj*2-1) = channelx((jj-1)*2);
                        end

                        channelx(jj*2) = channelx(jj*2-1) + segseq(jj).duration;
                     end

                     channely(jj*2-1) = str2double(segseq(jj).binaryoutput(1+end-ii));
                     channely(jj*2) = channely(jj*2-1) ;

                  end
                  if ~isvalid(app.pulsetiles{1})
                     PBguiGeneratePlotAxes(app)
                  end
                  
                  app.segmentlines{ii} = plot(app.segmenttiles{ii},channelx,channely);
                  app.segmentlines{ii}.Color = master.gui.PSE.lineColors(ii);                  
                  
               end
            end
         end
         
         if cumdur == 0
            cumdur = 1;
         end
         
         for ii = 1:master.PB.nchannels
            app.segmenttiles{ii}.YTick = [];
            if ii ~= master.PB.nchannels
               app.segmenttiles{ii}.XTick = [];
            else
               app.segmenttiles{ii}.TickLength = [0 0];
            end
            app.segmenttiles{ii}.XLim = [0 cumdur];
            app.segmenttiles{ii}.YLim = [-.4 1.4];
            if ~mod(ii,2)
               app.segmenttiles{ii}.YAxisLocation = 'right';
            end
            app.segmenttiles{ii}.YLabel.String = convertStringsToChars(master.gui.PSE.channelTitles(ii));
         end

      end
      
      function PBguiGeneratePlotAxes(app)
         global master
         for ii = 1:master.PB.nchannels
            app.pulsetiles{ii} = nexttile(app.seqlayout,ii);
            app.segmenttiles{ii} = nexttile(app.segmentlayout,ii);
         end        
      end
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, varargin)

         global master

         %CHANGE THESE VALUES WHEN ADDING/CHANGING CHANNELS
         channelTitles = ["AOM" "SPC Switch" "DAQ Chnl Switch" "RF" "I" "Q"];
         lineColors = ["g" "b" "r" "k" "m" "c"];
         %End changing values


         master.PB.nchannels = numel(channelTitles);
         master.gui.PSE.lineColors = lineColors;
         master.gui.PSE.channelTitles = channelTitles;

         app.seqlayout = tiledlayout(app.Visuals,master.PB.nchannels,1);
         app.seqlayout.Title.String = 'Pulse Sequence';
         app.seqlayout.XLabel.String = 'Time (ns)';

         if ~isfield(master.gui.PSE,'begseg')
            master.gui.PSE.begseg = 1;
         end
         if ~isfield(master.gui.PSE,'endseg')
            master.gui.PSE.endseg = 1;
         end
         app.segmentlayout = tiledlayout(app.SegmentVisual,master.PB.nchannels,1);
         app.segmentlayout.Title.String = 'Segment Sequence';         
         app.segmentlayout.XLabel.String = 'Time (ns)';

         chnlinfo = cell(master.PB.nchannels,1);
         chnlinfo{1} = 'None';
         chnlnum = zeros(master.PB.nchannels,1);
         for ii = 1:master.PB.nchannels
            chnlinfo{1+ii} = sprintf('%s: bit %d',channelTitles(ii),ii);
            chnlnum(ii+1) = 2^(ii-1);
         end
         
         app.ChannelInfo.Value = chnlinfo(2:end);
         app.Channels.Items = chnlinfo;
         app.Channels.ItemsData = chnlnum;
         app.Binary.Value = PBguiBin(app,0);
         PBguiGeneratePlotAxes(app)
         PBguiUpdate(app)      

      end

      % Value changed function: Channels
      function ChannelsValueChanged(app, event)
         app.Binary.Value = PBguiBin(app,sum(app.Channels.Value));
      end

      % Value changed function: InstructionType
      function InstructionTypeValueChanged(app, event)
         value = char(app.InstructionType.Value);
         if strcmp(value,'CONTINUE') || strcmp(value,'STOP') || strcmp(value,'RTS') || strcmp(value,'WAIT')
            app.ContextName.Value = 'N/A';
         elseif strcmp(value,'LOOP')
            app.ContextName.Value = 'Number of loops';
         elseif strcmp(value,'LONG_DELAY')
            app.ContextName.Value = 'Multiplier of delay (>=2)';
         elseif strcmp(value,'END_LOOP') || strcmp(value,'BRANCH') || strcmp(value,'JSR')
            app.ContextName.Value = 'Reference instruction';
         else
            app.ContextName.Value = value;
         end
      end

      % Button pushed function: AddtoSequenceButton
      function AddtoSequenceButtonPushed(app, event)
         global master

         master.PB.command.output = sum(app.Channels.Value);
         master.PB.command.direction = char(app.InstructionType.Value);
         master.PB.command.duration = app.Duration.Value;
         master.PB.command.contextinfo = app.ContextNum.Value;
         insseq = app.Overwrite.Value;
         des_address = app.Address.Value;

         if isfield(master.PB,'sequence')
            if app.Address.Value > length(master.PB.sequence) + 1
               uimessage = ['Attempted sequence address would leave gaps in sequence. ' ...
                  'Instead, new pulse will be added to the end of the current sequence'];
               uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
               insseq = 'Overwrite';
               des_address = length(master.PB.sequence)+1;
            end
         elseif des_address ~= 1
            uimessage = ['Attempted sequence address would leave gaps in sequence. ' ...
               'Instead, new pulse will be added to the end of the current sequence'];
            uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
            insseq = 'Overwrite';
            des_address = 1;
         end

         if ~strcmp(char(insseq),'Overwrite') && isfield(master.PB,'sequence')
            ilseq = length(master.PB.sequence);
            flseq = ilseq+1;
            if (flseq) ~= des_address %if they are equal, new command just goes to the end
               master.PB.sequence(des_address+1:end+1) = master.PB.sequence(des_address:end);
            end

            aa = 0;
            for ii = 1:length(master.PB.sequence)
               aa = aa+1;
               master.PB.sequence(ii).address = aa;
            end
         end

         master.PB.command.address = des_address;

         PBAddSequence()

         app.Address.Value = app.Address.Value + 1;

         PBguiUpdate(app)


      end

      % Button pushed function: CompileButton
      function CompileButtonPushed(app, event)
         PBFinalize()
      end

      % Button pushed function: RunButton
      function RunButtonPushed(app, event)
         PBRun()
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)
         global master

         if isempty(app.SaveName.Value)
            uialert(app.UIFigure,'Save unsuccessful. No file name input','Save Unsuccessful','Icon','error')
         else

            recorded_sequence = rmfield(master, setdiff(fieldnames(master), {'PB'}));
            recorded_sequence = rmfield(recorded_sequence.PB, setdiff(fieldnames(recorded_sequence.PB), {'sequence'}));
            recorded_sequence = recorded_sequence.sequence;
            svname = strcat(strcat(app.BoxAddress.Value,'Box\Backlund lab shared\code\Pulse Sequences\'),app.SaveName.Value);
            save(convertCharsToStrings(svname),"recorded_sequence")
            uialert(app.UIFigure,'Sequence Saved!','Save Successful','Icon','success')
         end
      end

      % Button pushed function: LoadSequenceButton
      function LoadSequenceButtonPushed(app, event)
         global master

         if isempty(app.LoadName.Value)
            uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
         else

            ldname = strcat(strcat(app.BoxAddress.Value,'Box\Backlund lab shared\code\Pulse Sequences\'),app.LoadName.Value);
            loadedvar = load(convertCharsToStrings(ldname));
            master.PB.sequence = loadedvar.recorded_sequence;
            uialert(app.UIFigure,'Sequence Loaded!','Load Successful','Icon','success')
            PBguiUpdate(app)
         end
      end

      % Button pushed function: DeleteStepButton
      function DeleteStepButtonPushed(app, event)
         global master

         alladdresses = MultiFieldConversion(app.DeleteAddress.Value);
         alladdresses = sort(alladdresses,'descend');
         iscorrect = false;
         if isfield(master,'PB')
            if isfield(master.PB,'sequence')
               for ii = 1:length(alladdresses)
                  del_address = alladdresses(ii);
                  if length(master.PB.sequence) >= del_address
                     iscorrect = true;
                     if length(master.PB.sequence) == 1
                        master.PB = rmfield(master.PB,'sequence');
                     else
                        master.PB.sequence(del_address:end-1) = master.PB.sequence(del_address+1:end);
                        aa = 0;
                        for jj = 1:length(master.PB.sequence)
                           aa = aa+1;
                           master.PB.sequence(jj).address = aa;
                        end
                        master.PB.sequence(end) = [];
                     end
                  end
                  PBguiUpdate(app)
               end
            end
         end
         if ~iscorrect
            uimessage = 'Selected pulse address does not exist therefore cannot be deleted';
            uialert(app.UIFigure,uimessage,'Cannot Delete Pulse','Icon','warning')
         end
      end

      % Button pushed function: DeleteAllButton
      function DeleteAllButtonPushed(app, event)
         global master
         iscorrect = uiconfirm(app.UIFigure,'Are you sure you want to delete all pulses?','Delete All','Icon','warning');
         if isfield(master,'PB')
            if isfield(master.PB,'sequence')
               switch iscorrect
                  case 'OK'
                     master.PB = rmfield(master.PB,'sequence');
                     PBguiUpdate(app)
               end
            end
         end

      end

      % Cell edit callback: SequenceTable
      function SequenceTableCellEdit(app, event)
         global master
         indices = event.Indices;
         newData = event.NewData;
         switch indices(2)
            case 1
               uimessage = 'Cannot change address via this table. Please use Delete and Overwrite features';
               uialert(app.UIFigure,uimessage,'Cannot Change Address','Icon','warning')
            case 2
               validdata = newData == "STOP" || newData == "CONTINUE" || newData == "LOOP" ...
                  || newData == "END_LOOP" || newData == "BRANCH" || newData == "RTS" || ...
                  newData == "WAIT" || newData == "LONG_DELAY" || newData == "JSR";
               if validdata
                  master.PB.sequence(indices(1)).direction = convertStringsToChars(newData);
               else
                  uimessage = 'Instruction input was invalid. Please reference possible instructions';
                  uialert(app.UIFigure,uimessage,'Invalid Instruction','Icon','warning')
               end
            case 3
               master.PB.sequence(indices(1)).output = bin2dec(newData);
            case 4
               master.PB.sequence(indices(1)).duration = str2double(newData);
         end
         PBguiUpdate(app)
      end

      % Value changed function: LineLabels
      function LineLabelsValueChanged(app, event)
         PBguiUpdate(app)
      end

      % Button pushed function: RefreshButton
      function RefreshButtonPushed(app, event)
         PBguiUpdate(app)
      end

      % Value changed function: EndSeg
      function EndSegValueChanged(app, event)
         global master
         try
            if app.EndSeg.Value > length(master.PB.sequence)
               uimessage = 'Ending address must be a valid address';
               uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
            end
         catch
            uimessage = 'Ending address must be a valid address';
            uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
         end
         if app.EndSeg.Value >= master.gui.PSE.begseg
            master.gui.PSE.endseg = app.EndSeg.Value;
         else
            uimessage = 'Ending address must be greater than beginning address';
            uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
         end
         PBguiUpdate(app)
      end

      % Value changed function: StartSeg
      function StartSegValueChanged(app, event)
         global master
         try
            if app.StartSeg.Value > length(master.PB.sequence)
               uimessage = 'Starting address must be a valid address';
               uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
            end
         catch
            uimessage = 'Starting address must be a valid address';
            uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
         end
         if app.StartSeg.Value <= master.gui.PSE.endseg
            master.gui.PSE.begseg = app.StartSeg.Value;
         else
            uimessage = 'Ending address must be greater than beginning address';
            uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
         end
         PBguiUpdate(app)
      end

      % Button pushed function: PasteSegmentButton
      function PasteSegmentButtonPushed(app, event)
         global master

         if isfield(master.PB,'sequence')
            if app.PasteAddress.Value > length(master.PB.sequence) + 1
               uimessage = ['Attempted sequence address would leave gaps in sequence. ' ...
                  'Instead, new pulse will be added to the end of the current sequence'];
               uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
               des_address = length(master.PB.sequence)+1;
            else
               des_address = app.PasteAddress.Value;
            end

            nseg = 1 + master.gui.PSE.endseg - master.gui.PSE.begseg;
            seg = master.PB.sequence(master.gui.PSE.begseg:master.gui.PSE.endseg);
            master.PB.sequence(des_address+nseg:end+nseg) = master.PB.sequence(des_address:end);
            master.PB.sequence(des_address:des_address+nseg-1) = seg;

            if des_address <= master.gui.PSE.begseg
               master.gui.PSE.begseg  = master.gui.PSE.begseg  + nseg;
               master.gui.PSE.endseg = master.gui.PSE.endseg + nseg;
            end
            for ii = 1:numel(master.PB.sequence)
               master.PB.sequence(ii).address = ii;
            end

            PBguiUpdate(app)

         else
            uimessage = 'To paste a sequence segment, you must first have a sequence segment to copy';
            uialert(app.UIFigure,uimessage,'Cannot Paste','Icon','error')
         end


      end

      % Changes arrangement of the app based on UIFigure width
      function updateAppLayout(app, event)
         currentFigureWidth = app.UIFigure.Position(3);
         if(currentFigureWidth <= app.onePanelWidth)
             % Change to a 2x1 grid
             app.GridLayout.RowHeight = {480, 480};
             app.GridLayout.ColumnWidth = {'1x'};
             app.RightPanel.Layout.Row = 2;
             app.RightPanel.Layout.Column = 1;
         else
             % Change to a 1x2 grid
             app.GridLayout.RowHeight = {'1x'};
             app.GridLayout.ColumnWidth = {372, '1x'};
             app.RightPanel.Layout.Row = 1;
             app.RightPanel.Layout.Column = 2;
         end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
         app.GridLayout = uigridlayout(app.UIFigure);
         app.LeftPanel = uipanel(app.GridLayout);
         app.TabGroup = uitabgroup(app.LeftPanel);
         app.OverviewTab = uitab(app.TabGroup);
         app.OverviewTab.Title = 'Overview';
         app.GridLayout4 = uigridlayout(app.OverviewTab);
         app.LoadSequenceButton = uibutton(app.GridLayout4, 'push');
         app.LoadSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSequenceButtonPushed, true);
         app.LoadSequenceButton.Text = 'Load Sequence';
         app.ChannelInfo = uitextarea(app.GridLayout4);
         app.ChannelInfo.Editable = 'off';
         app.SequenceTable = uitable(app.GridLayout4);
         app.SequenceTable.ColumnName = {'Step #'; 'Instruction'; 'Channels'; 'Duration (ns)'};
         app.SequenceTable.RowName = {};
         app.SequenceTable.ColumnEditable = true;
         app.SequenceTable.CellEditCallback = createCallbackFcn(app, @SequenceTableCellEdit, true);
         app.DeleteStepButton = uibutton(app.GridLayout4, 'push');
         app.DeleteStepButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStepButtonPushed, true);
         app.DeleteStepButton.Text = 'Delete Step';
         app.LoadName = uieditfield(app.GridLayout4, 'text');
         app.LoadName.Placeholder = 'File name to load';
         app.BoxAddress = uitextarea(app.GridLayout4);
         app.BoxAddress.Value = {'C:\Users\User\'};
         app.LocalboxaddressLabel = uilabel(app.GridLayout4);
         app.LocalboxaddressLabel.Text = 'Local box address';
         app.DeleteAllButton = uibutton(app.GridLayout4, 'push');
         app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
         app.DeleteAllButton.Text = 'Delete All';
         app.PlotLineLabelsSwitchLabel = uilabel(app.GridLayout4);
         app.PlotLineLabelsSwitchLabel.Text = 'Plot Line Labels';
         app.LineLabels = uiswitch(app.GridLayout4, 'slider');
         app.LineLabels.ItemsData = {'0', '1'};
         app.LineLabels.ValueChangedFcn = createCallbackFcn(app, @LineLabelsValueChanged, true);
         app.LineLabels.Value = '1';
         app.AddressesEditFieldLabel = uilabel(app.GridLayout4);
         app.AddressesEditFieldLabel.Text = 'Address(es)';
         app.DeleteAddress = uieditfield(app.GridLayout4, 'text');
         app.NewStepTab = uitab(app.TabGroup);
         app.NewStepTab.Title = 'New Step';
         app.GridLayout3 = uigridlayout(app.NewStepTab);
         app.ContextName = uitextarea(app.GridLayout3);
         app.ContextName.Editable = 'off';
         app.ContextName.Placeholder = 'N/A';
         app.ContextNum = uieditfield(app.GridLayout3, 'numeric');
         app.Overwrite = uidropdown(app.GridLayout3);
         app.Overwrite.Items = {'Overwrite', 'Insert new'};
         app.Overwrite.Value = 'Overwrite';
         app.AddtoSequenceButton = uibutton(app.GridLayout3, 'push');
         app.AddtoSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoSequenceButtonPushed, true);
         app.AddtoSequenceButton.Text = 'Add to Sequence';
         app.Binary = uitextarea(app.GridLayout3);
         app.Channels = uilistbox(app.GridLayout3);
         app.Channels.Items = {};
         app.Channels.Multiselect = 'on';
         app.Channels.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
         app.Channels.Value = {};
         app.InstructionType = uidropdown(app.GridLayout3);
         app.InstructionType.Items = {'Continue', 'Loop', 'End loop', 'Branch', 'Wait', 'Long delay', 'RTS', 'JSR', 'Stop', ''};
         app.InstructionType.ItemsData = {'CONTINUE', 'LOOP', 'END_LOOP', 'BRANCH', 'WAIT', 'LONG_DELAY', 'RTS', 'JSR', 'STOP'};
         app.InstructionType.ValueChangedFcn = createCallbackFcn(app, @InstructionTypeValueChanged, true);
         app.InstructionType.Value = 'CONTINUE';
         app.InstructionTypeDropDownLabel = uilabel(app.GridLayout3);
         app.InstructionTypeDropDownLabel.Text = 'Instruction Type';
         app.Duration = uieditfield(app.GridLayout3, 'numeric');
         app.Duration.Limits = [2 Inf];
         app.Duration.Value = 100;
         app.DurationnsEditFieldLabel = uilabel(app.GridLayout3);
         app.DurationnsEditFieldLabel.Text = 'Duration (ns)';
         app.Address = uieditfield(app.GridLayout3, 'numeric');
         app.Address.Limits = [1 Inf];
         app.Address.Value = 1;
         app.InstructionAddressEditFieldLabel = uilabel(app.GridLayout3);
         app.InstructionAddressEditFieldLabel.Text = 'Instruction Address';
         app.Warnings = uitextarea(app.GridLayout3);
         app.Warnings.Editable = 'off';
         app.CopySegmentsTab = uitab(app.TabGroup);
         app.CopySegmentsTab.Title = 'Copy Segments';
         app.GridLayout5 = uigridlayout(app.CopySegmentsTab);
         app.SequenceTable2 = uitable(app.GridLayout5);
         app.SequenceTable2.ColumnName = {'Step #'; 'Instruction'; 'Channels'; 'Duration (ns)'};
         app.SequenceTable2.RowName = {};
         app.SequenceTable2.ColumnEditable = true;
         app.StartofsegmentaddressLabel = uilabel(app.GridLayout5);
         app.StartofsegmentaddressLabel.Text = {'Start of segment'; 'address'};
         app.StartSeg = uieditfield(app.GridLayout5, 'numeric');
         app.StartSeg.Limits = [1 Inf];
         app.StartSeg.ValueChangedFcn = createCallbackFcn(app, @StartSegValueChanged, true);
         app.StartSeg.Value = 1;
         app.EndofsegmentaddressLabel = uilabel(app.GridLayout5);
         app.EndofsegmentaddressLabel.Text = {'End of segment'; 'address'};
         app.EndSeg = uieditfield(app.GridLayout5, 'numeric');
         app.EndSeg.Limits = [1 Inf];
         app.EndSeg.ValueChangedFcn = createCallbackFcn(app, @EndSegValueChanged, true);
         app.EndSeg.Value = 1;
         app.PasteaddressEditFieldLabel = uilabel(app.GridLayout5);
         app.PasteaddressEditFieldLabel.Text = 'Paste address';
         app.PasteAddress = uieditfield(app.GridLayout5, 'numeric');
         app.PasteAddress.Limits = [1 Inf];
         app.PasteAddress.Value = 1;
         app.PasteSegmentButton = uibutton(app.GridLayout5, 'push');
         app.PasteSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @PasteSegmentButtonPushed, true);
         app.PasteSegmentButton.Text = 'Paste Segment';
         app.SegmentVisual = uipanel(app.GridLayout5);
         app.RightPanel = uipanel(app.GridLayout);
         app.GridLayout2 = uigridlayout(app.RightPanel);
         app.CompileButton = uibutton(app.GridLayout2, 'push');
         app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
         app.CompileButton.Text = 'Compile';
         app.RunButton = uibutton(app.GridLayout2, 'push');
         app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
         app.RunButton.Text = 'Run';
         app.SaveButton = uibutton(app.GridLayout2, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.Visuals = uipanel(app.GridLayout2);
         app.SaveName = uitextarea(app.GridLayout2);
         app.SaveName.Placeholder = 'File name to save as';
         app.RefreshButton = uibutton(app.GridLayout2, 'push');
         app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
         app.RefreshButton.Text = 'Refresh';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = PulseSequenceEditorBeta(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end