classdef PulseSequenceEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        OverviewTab                   matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        RunButton                     matlab.ui.control.Button
        CompileButton                 matlab.ui.control.Button
        NTotalLoops                   matlab.ui.control.NumericEditField
        NumberoftotalloopsLabel       matlab.ui.control.Label
        AddTotalLoops                 matlab.ui.control.CheckBox
        DeleteAddress                 matlab.ui.control.EditField
        AddressesEditFieldLabel       matlab.ui.control.Label
        LineLabels                    matlab.ui.control.Switch
        PlotlinelabelsLabel           matlab.ui.control.Label
        DeleteAllButton               matlab.ui.control.Button
        LocalboxaddressLabel          matlab.ui.control.Label
        BoxAddress                    matlab.ui.control.TextArea
        DeletePulseButton             matlab.ui.control.Button
        SequenceTable                 matlab.ui.control.Table
        ChannelInfo                   matlab.ui.control.TextArea
        NewStepTab                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        UserdescriptioneditableLabel  matlab.ui.control.Label
        WarningsLabel                 matlab.ui.control.Label
        UserDescription               matlab.ui.control.TextArea
        Warnings                      matlab.ui.control.TextArea
        InstructionaddressLabel       matlab.ui.control.Label
        Address                       matlab.ui.control.NumericEditField
        DurationnsEditFieldLabel      matlab.ui.control.Label
        Duration                      matlab.ui.control.NumericEditField
        InstructiontypeLabel          matlab.ui.control.Label
        InstructionType               matlab.ui.control.DropDown
        Channels                      matlab.ui.control.ListBox
        Binary                        matlab.ui.control.TextArea
        AddtoSequenceButton           matlab.ui.control.Button
        Overwrite                     matlab.ui.control.DropDown
        ContextNum                    matlab.ui.control.NumericEditField
        ContextName                   matlab.ui.control.TextArea
        CopySegmentsTab               matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        SegmentVisual                 matlab.ui.container.Panel
        PasteSegmentButton            matlab.ui.control.Button
        PasteAddress                  matlab.ui.control.NumericEditField
        PasteaddressEditFieldLabel    matlab.ui.control.Label
        EndSeg                        matlab.ui.control.NumericEditField
        EndofsegmentaddressLabel      matlab.ui.control.Label
        StartSeg                      matlab.ui.control.NumericEditField
        StartofsegmentaddressLabel    matlab.ui.control.Label
        SequenceTable2                matlab.ui.control.Table
        TemplatesTab                  matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        ConfocalWideField             matlab.ui.control.DropDown
        LoadTemplate                  matlab.ui.control.Button
        TemplateSelection             matlab.ui.control.DropDown
        TemplateselectionLabel        matlab.ui.control.Label
        TemplateInputs                matlab.ui.control.Table
        SavedSequencesTab             matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        SavedSequencesDisplay         matlab.ui.control.TextArea
        LoadSequenceButton            matlab.ui.control.Button
        LoadName                      matlab.ui.control.EditField
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        DisplayedChannels             matlab.ui.control.Spinner
        channelsSpinnerLabel          matlab.ui.control.Label
        timeendEditField              matlab.ui.control.NumericEditField
        timeendEditFieldLabel         matlab.ui.control.Label
        timestartEditField            matlab.ui.control.NumericEditField
        timestartEditFieldLabel       matlab.ui.control.Label
        RefreshButton                 matlab.ui.control.Button
        SaveName                      matlab.ui.control.TextArea
        Visuals                       matlab.ui.container.Panel
        SaveButton                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

   %Pulse Sequence Editor v1.6 9/8/22

   %Things needed to edit when adding/changing output channels:
   %channelTitles and lineColors within startup function (~line 700)
   %change default nchannels within PBInitialization function

   properties (Access = private)
      seqlayout
      segmentlayout
      pulselines
      pulsetiles
      segmenttiles
      segmentlines
      stoplabels
      branchlabels
      endlooplabels
   end

   methods (Access = private)

      function appUpdate(app)

         global master %#ok<*GVMIS>
         try

         if isfield(master.PB,'sequence')

            seq = master.PB.sequence;%Abbreviation

            %Creates list for character or string inputs in the sequnece
            listMaker = @(x) convertCharsToStrings(x);
            cellSeq = squeeze(struct2cell(seq));
            
            nameList = fieldnames(seq);
            directionList = cellfun(listMaker,cellSeq(strcmp(nameList,'direction'),:));
            binOutList = cellfun(listMaker,cellSeq(strcmp(nameList,'binaryoutput'),:));
            if ismember({'description'},nameList)
               desFun = @(x) ischar(x);
               blankFun = @(x) '';
               descriptionCell = cellSeq(strcmp(nameList,'description'),:);
               descriptionChars = cellfun(desFun,descriptionCell);
               descriptionCell(~descriptionChars) = cellfun(blankFun,descriptionCell(~descriptionChars),'UniformOutput',false);
               descriptionList = cellfun(listMaker,descriptionCell);
            else
               descriptionList = strings(1,numel(binOutList));
            end

%             if length(fieldnames(seq)) == 6
%             descriptionList = cellfun(listMaker,cellSeq(5,:));
%             binOutList = cellfun(listMaker,cellSeq(6,:));
%             else
%                 binOutList = cellfun(listMaker,cellSeq(5,:));
%                 descriptionList = strings(1,numel(binOutList));
%             end


            %Updates overview table
            app.SequenceTable.Data = [(1:numel(seq))' directionList' binOutList' [seq.duration]' [seq.contextinfo]' descriptionList'];

            if isfield(master.gui.PSE,'loops')
               master.gui.PSE = rmfield(master.gui.PSE,'loops');
            end
            if isfield(master.gui.PSE,'loopvals')
               master.gui.PSE = rmfield(master.gui.PSE,'loopvals');
            end
            if isfield(master.gui.PSE,'loopaddresses')
               master.gui.PSE = rmfield(master.gui.PSE,'loopaddresses');
            end
            if isfield(master.gui.PSE,'endloops')
               master.gui.PSE = rmfield(master.gui.PSE,'endloops');
            end
            if isfield(master.gui.PSE,'endrefs')
               master.gui.PSE = rmfield(master.gui.PSE,'endrefs');
            end
            if isfield(master.gui.PSE,'branches')
               master.gui.PSE = rmfield(master.gui.PSE,'branches');
            end
            if isfield(master.gui.PSE,'branchrefs')
               master.gui.PSE = rmfield(master.gui.PSE,'branchrefs');
            end
            if isfield(master.gui.PSE,'stops')
               master.gui.PSE = rmfield(master.gui.PSE,'stops');
            end
               
            %Creates list of relevant directions and corresponding info
            elapseddur = 1;
            for ii = 1:length(seq)
               
               switch(seq(ii).direction)
                  case 'LOOP'
                     if ~isfield(master.gui.PSE,'loops')
                        master.gui.PSE.loops = elapseddur;
                        master.gui.PSE.loopvals = seq(ii).contextinfo;
                        master.gui.PSE.loopaddresses = ii;
                     else
                        master.gui.PSE.loops(end+1) = elapseddur;
                        master.gui.PSE.loopvals(end+1) = seq(ii).contextinfo;
                        master.gui.PSE.loopaddresses(end+1) = ii;
                     end

                  case 'END_LOOP'
                     if ~isfield(master.gui.PSE,'endloops')
                        master.gui.PSE.endloops = elapseddur;
                        master.gui.PSE.endrefs = seq(ii).contextinfo;
                     else
                        master.gui.PSE.endloops(end+1) = elapseddur;
                        master.gui.PSE.endrefs(end+1) = seq(ii).contextinfo;
                     end

                  case 'BRANCH'
                     if ~isfield(master.gui.PSE,'branches')
                        master.gui.PSE.branches = elapseddur;
                        master.gui.PSE.branchrefs = seq(ii).contextinfo;
                     else
                        master.gui.PSE.branches(end+1) = elapseddur;
                        master.gui.PSE.branchrefs(end+1) = seq(ii).contextinfo;
                     end

                  case 'STOP'
                     if ~isfield(master.gui.PSE,'stops')
                        master.gui.PSE.stops = elapseddur;
                     else
                        master.gui.PSE.stops(end+1) = elapseddur;
                     end
               end

               elapseddur = elapseddur + seq(ii).duration;

            end

            %The following section updates the warning tab with whatever
            %warnings are present
            pse = master.gui.PSE;
            kk = 1;
            warningtext = {};

            %Loop is missing an end loop
            if isfield(pse,'loopaddresses')
               if isfield(pse,'endrefs')
                  openloops = ismember(pse.loopaddresses,pse.endrefs);
               else
                  openloops = zeros(numel(pse.loopaddresses),1);
               end
               for mm = 1:numel(openloops)
                  if ~openloops(mm)
                     warningtext{kk} = sprintf('Loop %d is missing an end loop',mm); %#ok<AGROW>
                     kk = kk+1;
                  end
               end
            end

            %End loop is missing a begin loop
            if isfield(pse,'endrefs')
               if isfield(pse,'loopaddresses')
                  extracloses = ismember(pse.endrefs,pse.loopaddresses);
               else
                  extracloses = zeros(numel(pse.endrefs),1);
               end
               for mm = 1:length(extracloses)
                  if ~extracloses(mm)
                     warningtext{kk} = sprintf('End loop %d is missing a beginning',mm); %#ok<AGROW>
                     kk = kk+1;
                  end
               end
            end

            %Stop command is present
            if isfield(pse,'stops')
               warningtext{kk} = sprintf('Stop pulse present within sequence. If you are using PBInterpreter (default)%s',...
                  ' this is unnecessary and will result in an error');
               kk = kk+1;
            end

            %Multiple stop commands are present
            if isfield(pse,'stops') && ~strcmp(seq(end).direction,'STOP')
               warningtext{kk} = sprintf('Extra stop pulse present within sequence');
               kk = kk+1;
            end


            if isfield(master.PB,'sequence')
               %Calculate the time of the sequence
               if master.PB.useInterpreter && isfield(master,'NIDAQ')
                  [trueSeq] = PBInterpreter;
               else
                  trueSeq = master.PB.sequence;
               end
               PBTimeCalculator(trueSeq)
               
               %Sequence duration is low
               if master.PB.sequenceDuration < 2e8
                  warningtext{kk} = sprintf('Sequence duration is less than 200 ms. This can result in very low signal to noise due to%s', ...
                     ' the delay between the MatLab read command and actual data acquisition. Consider increasing the total number of loops.');
               end
            end

            %If any warnings are present, display them; otherwise print no
            %warnings present
            if ~isempty(warningtext)
               app.Warnings.Value = warningtext;
            else
               app.Warnings.Value = 'No warnings present';
            end

         else %No sequence present
            app.SequenceTable.Data = [];
            app.Warnings.Value = 'No warnings present';
         end
         
         if master.gui.PSE.doZoomRefresh
            master.gui.PSE.startZoom = 0;
            if isfield(master.PB,'sequence')
               cellSeq = squeeze(struct2cell(master.PB.sequence));
                nameList = fieldnames(master.PB.sequence);
                cumdur = sum(cell2mat(cellSeq(strcmp(nameList,'duration'),:)));
               master.gui.PSE.endZoom = cumdur;                 
            else
               master.gui.PSE.endZoom = 1;
            end
            master.gui.PSE.doZoomRefresh = false;
         end
         
         %Displays current start and end zoom values
         app.timestartEditField.Value = master.gui.PSE.startZoom;
         app.timeendEditField.Value = master.gui.PSE.endZoom;

         %Updates the main pulse diagram
         plotUpdate(app)

         %Updates the copy-paste tab
         segmentUpdate(app)
         
         %Updates the template tab
         templateUpdate(app)

         %Changes checkbox and number of loops to stored value
         app.AddTotalLoops.Value = master.PB.addTotalLoops;
         app.NTotalLoops.Value = master.PB.totalLoops;
         
         %Updates box address to match global value
         app.BoxAddress.Value = master.gui.boxAddress;

         %Loads the saved sequences at the current box address
         seqDir = dir(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Pulse Sequences'));
         if ~isempty(seqDir)
            seqDir = seqDir(~[seqDir.isdir]);
            [~,idx] = sort([seqDir.datenum],'descend');
            seqDir = seqDir(idx);
            seqDir = struct2cell(seqDir);
            if isempty(seqDir)
               app.SavedSequencesDisplay.Value = 'No saved sequences present at this box address';
            else
               dirNames = @(x) x(1:end-4);
               seqList = squeeze(cellfun(dirNames,seqDir(1,:),'UniformOutput',false));
               seqList = seqList([1,1:end]);
               seqList{1} = 'Saved Sequences:';
               app.SavedSequencesDisplay.Value = seqList;
            end
         end
         catch ME
            master.gui.PSE.lasterror = ME;
            rethrow(ME)
         end

      end


      function  plotUpdate(app)
         %Put into separate function from appUpdate just for readability
         global master

         %Suppresses warning that would be generated by changing the
         %x and y data sequentially
         warning('off','MATLAB:gui:array:InvalidArrayShape')

         if isfield(master.PB,'sequence')
            seq = master.PB.sequence;%Abbreviation

            app.DisplayedChannels.Value = master.gui.PSE.displayedChannels;

            if master.gui.PSE.prevChannels ~= master.gui.PSE.displayedChannels
               
               app.seqlayout = tiledlayout(app.Visuals,master.gui.PSE.displayedChannels,1);
               app.seqlayout.Title.String = 'Pulse Sequence';
               app.seqlayout.XLabel.String = 'Time (ns)';
               
               app.segmentlayout = tiledlayout(app.SegmentVisual,master.gui.PSE.displayedChannels,1);
               app.segmentlayout.Title.String = 'Segment Sequence';
               app.segmentlayout.XLabel.String = 'Time (ns)';
         
               for ii = 1:master.gui.PSE.displayedChannels
                  %Generate lines for each channel with the desired
                  %specifications, the data can be edited later
                  app.pulsetiles{ii} = nexttile(app.seqlayout,ii);
                  app.pulselines{ii} = plot(app.pulsetiles{ii},0,0);

                  app.pulsetiles{ii}.YTick = [];%Removes all y ticks

                  %Removes x ticks for all channels except the last one
                  if ii ~= master.gui.PSE.displayedChannels
                     app.pulsetiles{ii}.XTick = [];
                  else
                     app.pulsetiles{ii}.TickLength = [0 0];
                  end

                  %Alter limits to fit data
                  app.pulsetiles{ii}.XLim = [0 1];
                  app.pulsetiles{ii}.YLim = [-.4 1.4];

                  %Alternate which side the label goes on
                  if ~mod(ii,2)
                     app.pulsetiles{ii}.YAxisLocation = 'right';
                  end

                  %Add name and color based on main inputs
                  app.pulsetiles{ii}.YLabel.String = convertStringsToChars(master.gui.PSE.channelTitles(ii));
                  app.pulselines{ii}.Color = master.gui.PSE.lineColors(ii);

                  %Repeat for segmenttiles
                  app.segmenttiles{ii} = nexttile(app.segmentlayout,ii);
                  app.segmentlines{ii} = plot(app.segmenttiles{ii},0,0);

                  app.segmenttiles{ii}.YTick = [];

                  if ii ~= master.gui.PSE.displayedChannels
                     app.segmenttiles{ii}.XTick = [];
                  else
                     app.segmenttiles{ii}.TickLength = [0 0];
                  end

                  app.segmenttiles{ii}.XLim = [0 1];
                  app.segmenttiles{ii}.YLim = [-.4 1.4];

                  if ~mod(ii,2)
                     app.segmenttiles{ii}.YAxisLocation = 'right';
                  end

                  app.segmenttiles{ii}.YLabel.String = convertStringsToChars(master.gui.PSE.channelTitles(ii));
                  app.segmentlines{ii}.Color = master.gui.PSE.lineColors(ii);
                  master.test.numpulselines = ii;
               end
               if master.gui.PSE.prevChannels > master.gui.PSE.displayedChannels
                  for ii = 1:(master.gui.PSE.prevChannels-master.gui.PSE.displayedChannels)
                     app.pulsetiles(end) = [];
                     app.pulselines(end) = [];
                     app.segmenttiles(end) = [];
                     app.segmentlines(end) = [];
                  end
               end
               master.gui.PSE.prevChannels = master.gui.PSE.displayedChannels;
               
            end


            %Create the x (time) values for every pulse. There are 2 point
            %for every pulse, the beginning and the end, which are
            %separated by the duration of that pulse. Is not unique to each
            %channel
            channelx = zeros(1,numel(seq));
            for jj = 1:numel(seq)
               %If it is the first point, x is 1, otherwise x is
               %dependent on the previous set of points
               if jj == 1
                  channelx(1) = 1;
               else
                  channelx(jj*2-1) = channelx((jj-1)*2);
               end

               channelx(jj*2) = channelx(jj*2-1) + seq(jj).duration;
            end

            %Creation of the y values then plotting the sequence. Is unique
            %to each channel
            channely = zeros(1,numel(seq));
            for ii = 1:master.gui.PSE.displayedChannels

               %Gives the y value either a 0 or 1 depending on its binary
               %output
               for jj = 1:numel(seq)
                  channely(jj*2-1) = str2double(seq(jj).binaryoutput(1+end-ii));
                  channely(jj*2) = channely(jj*2-1);
               end               
               
               %Alter data of plots
               app.pulselines{ii}.XData = channelx;
               app.pulselines{ii}.YData = channely;
               
               %Alter limits to fit data
               app.pulsetiles{ii}.XLim = [master.gui.PSE.startZoom master.gui.PSE.endZoom];

            end

            %Delete the old labels if they exist
            for jj = 1:master.gui.PSE.displayedChannels
               if isfield(master.gui.PSE,'looplabels')
                  for ii = 1:size(master.gui.PSE.looplabels,1)
                     delete(master.gui.PSE.looplabels{ii,jj});
                  end
               end
               if isfield(master.gui.PSE,'endlooplabels')
                  for ii = 1:size(master.gui.PSE.endlooplabels,1)
                     delete(master.gui.PSE.endlooplabels{ii,jj})
                  end
               end
               if isfield(master.gui.PSE,'branchlabels')
                  for ii = 1:size(master.gui.PSE.branchlabels,1)
                     delete(master.gui.PSE.branchlabels{ii,jj})
                  end
               end
               if isfield(master.gui.PSE,'stoplabels')
                  for ii = 1:size(master.gui.PSE.stoplabels,1)
                     delete(master.gui.PSE.stoplabels{ii,jj})
                  end
               end
            end
            
            pse = master.gui.PSE;%Abbreviation
            %If line labels are on, create vertical lines with horizontal
            %description labels for each relevant direction type
            if strcmp(app.LineLabels.Value,'1')

               for jj = 1:master.gui.PSE.displayedChannels
                  if isfield(pse,'loops')
                     for ii = 1:length(pse.loops)
                        master.gui.PSE.looplabels{ii,jj} = xline(app.pulsetiles{jj},pse.loops(ii),'-k',sprintf('Loop %d, %d',ii,pse.loopvals(ii)));
                        master.gui.PSE.looplabels{ii,jj}.LabelHorizontalAlignment = 'left';
                        master.gui.PSE.looplabels{ii,jj}.LabelOrientation = 'horizontal';
                     end
                  end

                  if isfield(pse,'endloops')
                     for ii = 1:length(pse.endloops)
                        if isfield(master.gui.PSE,'loopaddresses')
                           loopref = find(pse.loopaddresses == pse.endrefs(ii));
                        else
                           loopref = [];
                        end
                        master.gui.PSE.endlooplabels{ii,jj} = xline(app.pulsetiles{jj},pse.endloops(ii),'-b',sprintf('End loop %d',loopref));
                        master.gui.PSE.endlooplabels{ii,jj}.LabelHorizontalAlignment = 'left';
                        master.gui.PSE.endlooplabels{ii,jj}.LabelOrientation = 'horizontal';
                        
                     end
                  end

                  if isfield(pse,'branches')
                     for ii = 1:length(pse.branches)
                        master.gui.PSE.branchlabels{ii,jj} = xline(app.pulsetiles{jj},pse.branches(ii),'g',sprintf('Branch to %d',pse.branchrefs(ii)));
                        master.gui.PSE.branchlabels{ii,jj}.LabelHorizontalAlignment = 'left';
                        master.gui.PSE.branchlabels{ii,jj}.LabelOrientation = 'horizontal';
                     end
                  end

                  if isfield(pse,'stops')
                     for ii = 1:length(pse.stops)
                        master.gui.PSE.stoplabels{ii,jj} = xline(app.pulsetiles{jj},pse.stops(ii),'r','Stop');
                        master.gui.PSE.stoplabels{ii,jj}.LabelHorizontalAlignment = 'left';
                        master.gui.PSE.stoplabels{ii,jj}.LabelOrientation = 'horizontal';
                     end
                  end

               end

            end

         else %No sequence at all, remove data and set x limits to [0 1]
            for ii = 1:master.PB.nchannels
               app.pulselines{ii}.XData = 0;
               app.pulselines{ii}.YData = 0;
            end
            app.pulsetiles{ii}.XLim = [0 1];
         end

      end

      function segmentUpdate(app)
         global master

         begseg = master.gui.PSE.begseg;%Abbreviation
         endseg = master.gui.PSE.endseg;

         %Changes app values to match saved values
         app.StartSeg.Value = begseg;
         app.EndSeg.Value = endseg;
         
         %Suppresses warning that would be generated by changing the
         %x and y data sequentially
         warning('off','MATLAB:gui:array:InvalidArrayShape')

         if isfield(master.PB,'sequence')
            if numel(master.PB.sequence) >= endseg %is end of segment within sequence

               segseq = master.PB.sequence(begseg:endseg);%Abbreviation for current section of sequence

               %Find total duration of section
               cellSeq = squeeze(struct2cell(segseq));
               nameList = fieldnames(segseq);
               cumdur = sum(cell2mat(cellSeq(strcmp(nameList,'duration'),:)));

               %Create the x (time) values for every pulse. There are 2 point
               %for every pulse, the beginning and the end, which are
               %separated by the duration of that pulse. Is not unique to each
               %channel
               channelx = zeros(1,numel(segseq));
               for jj = 1:numel(segseq)
                  %If it is the first point, x is 1, otherwise x is
                  %dependent on the previous set of points
                  if jj == 1
                     channelx(1) = 1;
                  else
                     channelx(jj*2-1) = channelx((jj-1)*2);
                  end

                  channelx(jj*2) = channelx(jj*2-1) + segseq(jj).duration;
               end

               %Creation of the y values then plotting the sequence. Is unique
               %to each channel
               channely = zeros(1,numel(segseq));
               for ii = 1:master.gui.PSE.displayedChannels

                  %Gives the y value either a 0 or 1 depending on its binary
                  %output
                  for jj = 1:numel(segseq)
                     channely(jj*2-1) = str2double(segseq(jj).binaryoutput(1+end-ii));
                     channely(jj*2) = channely(jj*2-1);
                  end
                  
                  %Alter data of plots
                  app.segmentlines{ii}.XData = channelx;
                  app.segmentlines{ii}.YData = channely;

                  %Alter limits to fit data
                  app.segmenttiles{ii}.XLim = [0 cumdur+1];

               end

            end

            %Update the copy-paste overview based on a section of the main
            %overview
            app.SequenceTable2.Data = app.SequenceTable.Data(master.gui.PSE.begseg:master.gui.PSE.endseg,:);

         else %No sequence at all, remove data, reset table, and set x limits to [0 1]
            app.SequenceTable2.Data = [];
            for ii = 1:master.PB.nchannels
               app.segmentlines{ii}.XData = 0;
               app.segmentlines{ii}.YData = 0;
            end
            app.segmenttiles{ii}.XLim = [0 1];
         end

      end


      function templateUpdate(app)
                  
         global master
         %Check the functions present in the Sequence Templates folder
         seqDir = dir(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Sequence Templates'));
         if ~isempty(seqDir)            
            seqDir = seqDir(~[seqDir.isdir]);
            [~,idx] = sort([seqDir.datenum],'descend');
            seqDir = seqDir(idx);
            seqDir = struct2cell(seqDir);
            if isempty(seqDir)
               app.TemplateSelection.Items = {'No templates loaded'};
               app.TemplateSelection.ItemsData = {'N/A'};
            else
               dirNames = @(x) x(1:end-2);
               master.gui.PSE.templatesList = squeeze(cellfun(dirNames,seqDir(1,:),'UniformOutput',false));
               app.TemplateSelection.Items = master.gui.PSE.templatesList;
               app.TemplateSelection.ItemsData = master.gui.PSE.templatesList;
               if isfield(master.gui.PSE,'template')
                  if ~ismember(master.gui.PSE.template,master.gui.PSE.templatesList)
                     master.gui.PSE.template = master.gui.PSE.templatesList{1};
                  end
               else
                  master.gui.PSE.template = master.gui.PSE.templatesList{1};
               end               
               app.TemplateSelection.Value = master.gui.PSE.template;
            end
            feval(master.gui.PSE.template,'inputs request')
         
            makeZeros = true;
            if isfield(master.gui.PSE,'templateInputs')
               if numel(master.gui.PSE.templateInputs) == numel(master.gui.PSE.inputNames)
                  makeZeros = false;
               end
            end
            if makeZeros
               master.gui.PSE.templateInputs = num2cell(string(zeros(1,numel(master.gui.PSE.inputNames))));
            end
            if numel(master.gui.PSE.inputNames) ~= numel(master.gui.PSE.templateInputs)
               master.gui.PSE.templateInputs(numel(master.gui.PSE.inputNames)+1:end) = [];
            end
            app.TemplateInputs.Data = [master.gui.PSE.inputNames' master.gui.PSE.templateInputs'];
            
         else
            app.TemplateSelection.Items = {'Check box address'};
            app.TemplateSelection.ItemsData = {'N/A'};
         end 
         
      end
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

         global master

         %CHANGE THESE VALUES WHEN ADDING/CHANGING CHANNELS
         channelTitles = ["AOM" "Data" "Sig/Ref" "RF" "I" "Q" "7" "8" "9"];
         lineColors = ["g" "b" "r" "k" "m" "c" "g" "b" "r"];
         
         %End changing values


         master.PB.nchannels = numel(channelTitles);
         master.gui.PSE.lineColors = lineColors;
         master.gui.PSE.channelTitles = channelTitles;

         InitializationCheck('PB')      

         if ~isfield(master.gui.PSE,'begseg')
            master.gui.PSE.begseg = 1;
         end
         if ~isfield(master.gui.PSE,'endseg')
            master.gui.PSE.endseg = 1;
         end
         
         if ~isfield(master.gui,'boxAddress')
            master.gui.boxAddress = 'C:\Users\User\Box';
         end
         
         if ~isfield(master.gui.PSE,'startZoom')
            master.gui.PSE.startZoom = 0;
         end
         
         if ~isfield(master.gui.PSE,'displayedChannels')
            master.gui.PSE.displayedChannels = master.PB.nchannels;
         end
         
         if ~isfield(master,'NIDAQ')
            master.NIDAQ.initialized = false;
         end
         
         if ~isfield(master.NIDAQ,'confocal')
            master.NIDAQ.confocal = true;
         end

         if ~isfield(master.gui.PSE,'endZoom')
            if isfield(master.PB,'sequence')
               %Calculate the time of the sequence
               cellSeq = squeeze(struct2cell(master.PB.sequence));
                nameList = fieldnames(master.PB.sequence);
                cumdur = sum(cell2mat(cellSeq(strcmp(nameList,'duration'),:)));
               master.gui.PSE.endZoom = cumdur;
            else
               master.gui.PSE.endZoom = 1;
            end
         end
         
         master.gui.PSE.doZoomRefresh = false;
   

         chnlinfo = cell(master.PB.nchannels,1);
         chnlinfo{1} = 'None';
         chnlnum = zeros(master.PB.nchannels,1);
         for ii = 1:master.PB.nchannels
            chnlinfo{1+ii} = sprintf('%s: bit %d',channelTitles(ii),ii);
            chnlnum(ii+1) = 2^(ii-1);
         end
         
         master.gui.PSE.prevChannels = 0;

         %Update various fields and options based on the inputs provided
         app.ChannelInfo.Value = chnlinfo(2:end);
         app.Channels.Items = chnlinfo;
         app.Channels.ItemsData = chnlnum;
         app.Channels.Value = chnlnum(1);
         app.Binary.Value = PBDec2Bin(0);
         
         %Perform overall app update
         appUpdate(app)

        end

        % Value changed function: Channels
        function ChannelsValueChanged(app, event)
         %Changes the numerical representation of which channels are
         %active. Purely cosmetic
         app.Binary.Value = PBDec2Bin(sum(app.Channels.Value));
        end

        % Value changed function: InstructionType
        function InstructionTypeValueChanged(app, event)
         %Change label for contextinfo based on chosen instruction type
         switch char(app.InstructionType.Value)
            case 'LOOP'
               app.ContextName.Value = 'Number of loops';
            case 'LONG_DELAY'
               app.ContextName.Value = 'Multiplier of delay (>=2)';
            case 'END_LOOP'
               app.ContextName.Value = 'Reference instruction';
            otherwise
               app.ContextName.Value = 'N/A';
         end
        end

        % Button pushed function: AddtoSequenceButton
        function AddtoSequenceButtonPushed(app, event)
         %Add/Overwrite the current instruction to the sequence
         
         global master
         
         %Load current values into .command struct
         master.PB.command.output = sum(app.Channels.Value);
         master.PB.command.direction = char(app.InstructionType.Value);
         master.PB.command.duration = app.Duration.Value;
         master.PB.command.contextinfo = app.ContextNum.Value;
         useDes = app.UserDescription.Value;
         for ii = 1:numel(useDes)
            if ii == 1
               totDes = useDes{1};
            else
               totDes(end+1:end+2) = '  ';
               if numel(useDes{ii}) ~= 0
                  totDes(end+1:end+numel(useDes{ii})) = useDes{ii};
               end
            end
         end
         master.PB.command.description = totDes;

         
         %Abbreviations for insert/overwrite and desired address
         insOver = app.Overwrite.Value;
         desAddress = app.Address.Value;

         if isfield(master.PB,'sequence')
            %If address is longer than the sequence +1, change the address
            %to be the sequence +1 and give warning
            if desAddress > numel(master.PB.sequence) + 1
               uimessage = ['Attempted sequence address would leave gaps in sequence. ' ...
                  'Instead, new pulse will be added to the end of the current sequence'];
               uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
               insOver = 'Overwrite';
               desAddress = numel(master.PB.sequence)+1;
            end
         elseif desAddress ~= 1
            %If desired address isn't 1 while the sequence doesn't exist,
            %change address to 1 and give warning
            uimessage = ['Attempted sequence address would leave gaps in sequence. ' ...
               'New pulse will become the first pulse in the sequence'];
            uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
            desAddress = 1;
         end

         %If insert new is chosen and there is a sequence, move sequence
         %after the address down 1
         if strcmp(char(insOver),'Insert new') && isfield(master.PB,'sequence')
            if (numel(master.PB.sequence) + 1) ~= desAddress %if they are equal, new command just goes to the end
               master.PB.sequence(desAddress+1:end+1) = master.PB.sequence(desAddress:end);
            end
         end

         %Add current pulse to the end of the sequence
         PBAddSequence

         %If it is not in the correct address, move it to the right one and
         %delete it from the end
         if desAddress ~= numel(master.PB.sequence)
            master.PB.sequence(desAddress) = master.PB.sequence(end);
            master.PB.sequence(end) = [];
         end

         %Increment address value for convenience
         app.Address.Value = app.Address.Value + 1;

         master.gui.PSE.doZoomRefresh = true;
         appUpdate(app)

        end

        % Button pushed function: CompileButton
        function CompileButtonPushed(app, event)
         PBFinalize
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
         [~] = PBRun;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         %Save the current sequence to box where it can be loaded later
         global master

         if isempty(app.SaveName.Value)
            uialert(app.UIFigure,'Save unsuccessful. No file name input','Save Unsuccessful','Icon','error')
         else

            recorded_sequence = master.PB.sequence;
            nloops = master.PB.totalLoops;
            svname = strcat(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Pulse Sequences\'),app.SaveName.Value);
            save(convertCharsToStrings(svname),"recorded_sequence","nloops")
            uialert(app.UIFigure,'Sequence Saved!','Save Successful','Icon','success')
         end
        end

        % Button pushed function: LoadSequenceButton
        function LoadSequenceButtonPushed(app, event)
         %Pulls previously saved sequence from box
         global master

         if isempty(app.LoadName.Value)
            uialert(app.UIFigure,'Load unsuccessful. No file name input','Load Unsuccessful','Icon','error')
         else

            ldname = strcat(strcat(master.gui.boxAddress,'\Backlund lab shared\code\Function Library\Pulse Sequences\'),app.LoadName.Value);
            loadedvar = load(convertCharsToStrings(ldname));
            master.PB.sequence = loadedvar.recorded_sequence;
            if ~isfield(master.PB.sequence,'description')
               master.PB.sequence(1).description = '';
            end
            master.PB.totalLoops = loadedvar.nloops;
            master.gui.PSE.doZoomRefresh = true;
            if length(master.PB.sequence(1).binaryoutput) ~= master.PB.nchannels
               maxout = 2^(master.PB.nchannels-1);
               reducedPulses = [];
               nn = 1;
               for ii = 1:length(master.PB.sequence)
                  if master.PB.sequence(ii).output > maxout                              
                     binForm = dec2bin(master.PB.sequence(ii).output);
                     binForm = binForm(end-(master.PB.nchannels-1):end);
                     master.PB.sequence(ii).output = bin2dec(binForm);
                     reducedPulses(nn) = ii; %#ok<AGROW> 
                     nn = nn+1;
                  end
                  master.PB.sequence(ii).binaryoutput = PBDec2Bin(master.PB.sequence(ii).output);
               end
               if ~isempty(reducedPulses)
                  if length(reducedPulses) == 1
                     PulseStr = sprintf("%d",reducedPulses);
                  else
                     PulseStr = sprintf("%d and %d other pulses",reducedPulses(1),length(reducedPulses));
                  end
                  errordlg(sprintf("Pulse %s have had their output changed to match the max number of channels",...
                     PulseStr),"Pulse Output Changed")
               end
            end
            appUpdate(app)
            uialert(app.UIFigure,'Sequence Loaded!','Load Successful','Icon','success')            
         end
        end

        % Button pushed function: DeletePulseButton
        function DeletePulseButtonPushed(app, event)
         %Deletes one or multiple pulses in the sequence
         global master

         %Turns input into descending array of addresses to be deleted
         allAddresses = MultiFieldConversion(app.DeleteAddress.Value);
         allAddresses = sort(allAddresses,'descend');
         
         if isfield(master.PB,'sequence')

            %Sort address based on their validity
            isPos = allAddresses > 0;
            isInt = mod(allAddresses,1) == 0;
            isLess = allAddresses <= numel(master.PB.sequence);
            validAddresses = allAddresses(all([isPos;isInt;isLess],1));
            invalidAddresses = allAddresses(~ismember(allAddresses,validAddresses));

            %Deletes valid addresses
            master.PB.sequence(validAddresses) = [];

            %If invalid addresses are present, give warning
            if ~isempty(invalidAddresses)
               ialist = '';
               for ii = 1:numel(invalidAddresses)
                  ialist = sprintf('%s, %.3f',ialist,invalidAddresses(ii));
               end
               ialist(end-1:end) = [];
               uimessage = sprintf('Address(es) %s does not exist therefore cannot be deleted',ialist);
               uialert(app.UIFigure,uimessage,'Cannot Delete Pulse','Icon','warning')
            end
            
            %If this was the last pulse of the sequence, remove .sequence
            if isempty(master.PB.sequence)
               master.PB = rmfield(master.PB,'sequence');
               master.gui.PSE.endseg = 1;
               master.gui.PSE.begseg = 1;
            else
               if master.gui.PSE.endseg > numel(master.PB.sequence)
                  master.gui.PSE.endseg = numel(master.PB.sequence);
               end

               if master.gui.PSE.begseg > numel(master.PB.sequence)
                  master.gui.PSE.begseg = numel(master.PB.sequence);
               end
            end
         else
            uimessage = sprintf('Address(es) cannot be deleted as there is no pulse sequence');
            uialert(app.UIFigure,uimessage,'Cannot Delete Pulse','Icon','warning')
         end
         
         master.gui.PSE.doZoomRefresh = true;
         appUpdate(app)
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
         %Deletes all pulses after asking for confirmation
         global master
         
         if isfield(master.PB,'sequence')
            delAll = uiconfirm(app.UIFigure,'Are you sure you want to delete all pulses?','Delete All','Icon','warning');
            if strcmp(delAll,'OK')
               master.PB = rmfield(master.PB,'sequence');
               master.gui.PSE.begseg = 1;
               master.gui.PSE.endseg = 1;
               master.gui.PSE.doZoomRefresh = true;
               appUpdate(app)
            end
         else
            uimessage = sprintf('Address(es) cannot be deleted as there is no pulse sequence');
            uialert(app.UIFigure,uimessage,'Cannot Delete Pulse','Icon','warning')
         end

        end

        % Cell edit callback: SequenceTable
        function SequenceTableCellEdit(app, event)
         %Edits the pulse sequence based on changes to the overview table
         global master
         
         %indices are the row/column where the change happens. newData is
         %what the element is changed into
         indices = event.Indices;
         newData = event.NewData;
         
         switch indices(2) %Column of change
            case 1 %Address
               newData = str2double(newData);
               
               %If the location value is valid, move the selected pulse to
               %the chosen value and move all pulses including and after
               %the chosen value down by 1
               if mod(newData,1) == 0 && newData <= numel(master.PB.sequence) && newData >= 1
                  master.PB.command = master.PB.sequence(indices(1));
                  master.PB.sequence(indices(1)) = [];
                  shifted = master.PB.sequence(newData:end);
                  master.PB.sequence(newData:end) = [];
                  PBAddSequence
                  master.PB.sequence(end+1:end+numel(shifted)) = shifted;
               else
                  uimessage = sprintf('Invalid location value. Location must be an integer between 1 and %d',numel(master.PB.sequence));
                  uialert(app.UIFigure,uimessage,'Invalid Location','Icon','warning')
               end
               master.gui.PSE.doZoomRefresh = true;
            case 2 %Direction
               %If direction is valid, change it
               if any(strcmp(newData,{'STOP','CONTINUE','LOOP','END_LOOP','BRANCH','RTS','WAIT','LONG_DELAY','JSR'}))
                  master.PB.sequence(indices(1)).direction = convertStringsToChars(newData);
               else
                  uimessage = 'Instruction input was invalid. Please reference possible instructions';
                  uialert(app.UIFigure,uimessage,'Invalid Instruction','Icon','warning')
               end
               
            case 3 %Binary output
               %Alter output and binary output
               master.PB.sequence(indices(1)).output = bin2dec(newData);
               master.PB.sequence(indices(1)).binaryoutput = PBDec2Bin(bin2dec(newData));
               
            case 4 %Duration
               master.PB.sequence(indices(1)).duration = str2double(newData);
               master.gui.PSE.doZoomRefresh = true;
            case 5 %Context info
               master.PB.sequence(indices(1)).contextinfo = str2double(newData);
            case 6 %User Description
               master.PB.sequence(indices(1)).description = convertStringsToChars(newData);
         end
         
         appUpdate(app)
        end

        % Value changed function: LineLabels
        function LineLabelsValueChanged(app, event)
         appUpdate(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
         global master
         master.gui.PSE.doZoomRefresh = true;
         appUpdate(app)
        end

        % Value changed function: EndSeg
        function EndSegValueChanged(app, event)
         %Changes ending value of copy-paste segment
         global master
         
         if isfield(master.PB,'sequence')
            if app.EndSeg.Value <= numel(master.PB.sequence)               
               if app.EndSeg.Value >= master.gui.PSE.begseg
                  %Valid ending address is entered as .endseg
                  master.gui.PSE.endseg = app.EndSeg.Value;
               else %Beginning address is higher than ending address
                  uimessage = 'Ending address must be greater than beginning address';
                  uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
               end
            else %Higher address than number of pulses
               uimessage = 'Ending address must be a valid address';
               uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
            end
         else %No sequence
            uimessage = 'Sequence not present, no addresses are valid';
            uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
         end
         
         segmentUpdate(app)
        end

        % Value changed function: StartSeg
        function StartSegValueChanged(app, event)
         %Changes starting value of copy-paste segment
         global master
         
         if isfield(master.PB,'sequence')
            if app.StartSeg.Value <= numel(master.PB.sequence)               
               if app.StartSeg.Value <= master.gui.PSE.endseg
                  %Valid starting address is entered .begseg
                  master.gui.PSE.begseg = app.StartSeg.Value;
               else %Beginning address is higher than ending address
                  uimessage = 'Beginning address must be less than ending address';
                  uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
               end
            else %Higher address than number of pulses
               uimessage = 'Beginning address must be a valid address';
               uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
            end
         else %No sequence
            uimessage = 'Sequence not present, no addresses are valid';
            uialert(app.UIFigure,uimessage,'Invalid Address','Icon','error')
         end

         segmentUpdate(app)
        end

        % Button pushed function: PasteSegmentButton
        function PasteSegmentButtonPushed(app, event)
         %Pastes the selected segment into chosen address
         global master

         if isfield(master.PB,'sequence')
            if app.PasteAddress.Value > length(master.PB.sequence) + 1
               uimessage = ['Attempted sequence address would leave gaps in sequence. ' ...
                  'Instead, new pulse will be added to the end of the current sequence'];
               uialert(app.UIFigure,uimessage,'Address Changed','Icon','warning')
               desAddress = length(master.PB.sequence)+1;
            else
               desAddress = app.PasteAddress.Value;
            end

            %Selects chosen sequence and adds it to the target location.
            %All pulses after the target location are moved to compensate
            nseg = 1 + master.gui.PSE.endseg - master.gui.PSE.begseg;
            seg = master.PB.sequence(master.gui.PSE.begseg:master.gui.PSE.endseg);
            master.PB.sequence(desAddress+nseg:end+nseg) = master.PB.sequence(desAddress:end);
            master.PB.sequence(desAddress:desAddress+nseg-1) = seg;

            %Changes the currently selected addresses to keep the same
            %section selected
            if desAddress <= master.gui.PSE.begseg
               master.gui.PSE.begseg  = master.gui.PSE.begseg  + nseg;
               master.gui.PSE.endseg = master.gui.PSE.endseg + nseg;
            end

            appUpdate(app)

         else
            uimessage = 'To paste a sequence segment, you must first have a sequence segment to copy';
            uialert(app.UIFigure,uimessage,'Cannot Paste','Icon','error')
         end

        end

        % Value changed function: AddTotalLoops
        function AddTotalLoopsValueChanged(app, event)
         %Changes whether total loops will be added in PBInterpreter
         global master
         master.PB.addTotalLoops = app.AddTotalLoops.Value;
         appUpdate(app)
        end

        % Value changed function: NTotalLoops
        function NTotalLoopsValueChanged(app, event)
         %Changes # of total loops that will be added in PBInterpreter
         global master
         master.PB.totalLoops = app.NTotalLoops.Value;
         appUpdate(app)
        end

        % Callback function
        function TemplateInputCreatorCellEdit(app, event)

        end

        % Value changed function: BoxAddress
        function BoxAddressValueChanged(app, event)
         global master
         master.gui.boxAddress = app.BoxAddress.Value{1};
         appUpdate(app)
        end

        % Button pushed function: LoadTemplate
        function LoadTemplatePushed(app, event)
         global master
         feval(master.gui.PSE.template,master.gui.PSE.templateInputs)
         master.gui.PSE.doZoomRefresh = true;
         uialert(app.UIFigure,'Template Loaded!','Load Successful','Icon','success')   
         appUpdate(app)
        end

        % Value changed function: TemplateSelection
        function TemplateSelectionValueChanged(app, event)
         global master
         master.gui.PSE.template = app.TemplateSelection.Value;
         master.gui.PSE = rmfield(master.gui.PSE,'templateInputs');
         templateUpdate(app)
        end

        % Cell edit callback: TemplateInputs
        function TemplateInputsCellEdit(app, event)
         global master
         master.gui.PSE.templateInputs{event.Indices(1)} = event.NewData;
         templateUpdate(app)
        end

        % Value changed function: timestartEditField
        function timestartEditFieldValueChanged(app, event)
         global master
         newval = app.timestartEditField.Value;
         if isfield(master.PB,'sequence')
            if newval >= 0 && newval < master.gui.PSE.endZoom
               master.gui.PSE.startZoom = newval;
               master.gui.PSE.doZoomRefresh = false;
            else
               errordlg('Starting time value is not within limits. It must be between 0 and the ending time','Invalid start time')
            end
         else
            master.gui.PSE.doZoomRefresh = true;
            errordlg('No sequence present','No sequence')
         end
         appUpdate(app)
        end

        % Value changed function: timeendEditField
        function timeendEditFieldValueChanged(app, event)
         global master
         newval = app.timeendEditField.Value;
         if isfield(master.PB,'sequence')
            %Calculate the time of the sequence
            cellSeq = squeeze(struct2cell(master.PB.sequence));
                nameList = fieldnames(master.PB.sequence);
                cumdur = sum(cell2mat(cellSeq(strcmp(nameList,'duration'),:)));
            if newval > master.gui.PSE.startZoom && newval <= cumdur
               master.gui.PSE.endZoom = newval;
               master.gui.PSE.doZoomRefresh = false;
            else
               errordlg('Ending time value is not within limits. It must be between starting time and the total sequence duration ','Invalid end time')
            end
         else
            master.gui.PSE.doZoomRefresh = true;
            errordlg('No sequence present','No sequence')
         end
         appUpdate(app)
        end

        % Value changed function: ConfocalWideField
        function ConfocalWideFieldValueChanged(app, event)
         global master
         if strcmp(app.ConfocalWideField.Value,'Confocal')
            master.NIDAQ.confocal = true;
         else
            master.NIDAQ.confocal = false;
            master.gui.normCounts = false;
         end
         appUpdate(app)
        end

        % Value changed function: DisplayedChannels
        function DisplayedChannelsValueChanged(app, event)
         global master
         if app.DisplayedChannels.Value <= master.PB.nchannels && app.DisplayedChannels.Value > 0
            master.gui.PSE.displayedChannels = app.DisplayedChannels.Value;
         else
            errordlg(sprintf("Number of displayed channels must be between 1 and %d",master.PB.nchannels),"Invalid Displayed Channels")
         end
         appUpdate(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {372, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.GridLayout4 = uigridlayout(app.OverviewTab);
            app.ChannelInfo = uitextarea(app.GridLayout4);
            app.ChannelInfo.Editable = 'off';
            app.SequenceTable = uitable(app.GridLayout4);
            app.SequenceTable.ColumnName = {'#'; 'Instruction'; 'Channels'; 'Duration (ns)'; 'Context info'; 'User description'};
            app.SequenceTable.RowName = {};
            app.SequenceTable.ColumnEditable = true;
            app.SequenceTable.CellEditCallback = createCallbackFcn(app, @SequenceTableCellEdit, true);
            app.DeletePulseButton = uibutton(app.GridLayout4, 'push');
            app.DeletePulseButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePulseButtonPushed, true);
            app.DeletePulseButton.Text = 'Delete Pulse';
            app.BoxAddress = uitextarea(app.GridLayout4);
            app.BoxAddress.ValueChangedFcn = createCallbackFcn(app, @BoxAddressValueChanged, true);
            app.BoxAddress.Value = {'C:\Users\User\Box'};
            app.LocalboxaddressLabel = uilabel(app.GridLayout4);
            app.LocalboxaddressLabel.Text = 'Local box address';
            app.DeleteAllButton = uibutton(app.GridLayout4, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = 'Delete All';
            app.PlotlinelabelsLabel = uilabel(app.GridLayout4);
            app.PlotlinelabelsLabel.Text = {'Plot line'; 'labels'};
            app.LineLabels = uiswitch(app.GridLayout4, 'slider');
            app.LineLabels.ItemsData = {'0', '1'};
            app.LineLabels.ValueChangedFcn = createCallbackFcn(app, @LineLabelsValueChanged, true);
            app.LineLabels.Value = '1';
            app.AddressesEditFieldLabel = uilabel(app.GridLayout4);
            app.AddressesEditFieldLabel.Text = 'Address(es)';
            app.DeleteAddress = uieditfield(app.GridLayout4, 'text');
            app.AddTotalLoops = uicheckbox(app.GridLayout4);
            app.AddTotalLoops.ValueChangedFcn = createCallbackFcn(app, @AddTotalLoopsValueChanged, true);
            app.AddTotalLoops.Text = {'Add'; 'total'; 'loop'};
            app.NumberoftotalloopsLabel = uilabel(app.GridLayout4);
            app.NumberoftotalloopsLabel.Text = {'Number of'; 'total loops'};
            app.NTotalLoops = uieditfield(app.GridLayout4, 'numeric');
            app.NTotalLoops.ValueChangedFcn = createCallbackFcn(app, @NTotalLoopsValueChanged, true);
            app.CompileButton = uibutton(app.GridLayout4, 'push');
            app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
            app.CompileButton.Text = 'Compile';
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NewStepTab = uitab(app.TabGroup);
            app.NewStepTab.Title = 'New Step';
            app.GridLayout3 = uigridlayout(app.NewStepTab);
            app.ContextName = uitextarea(app.GridLayout3);
            app.ContextName.Editable = 'off';
            app.ContextName.Placeholder = 'N/A';
            app.ContextNum = uieditfield(app.GridLayout3, 'numeric');
            app.Overwrite = uidropdown(app.GridLayout3);
            app.Overwrite.Items = {'Overwrite', 'Insert new'};
            app.Overwrite.Value = 'Overwrite';
            app.AddtoSequenceButton = uibutton(app.GridLayout3, 'push');
            app.AddtoSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoSequenceButtonPushed, true);
            app.AddtoSequenceButton.Text = 'Add to Sequence';
            app.Binary = uitextarea(app.GridLayout3);
            app.Binary.Editable = 'off';
            app.Channels = uilistbox(app.GridLayout3);
            app.Channels.Items = {};
            app.Channels.Multiselect = 'on';
            app.Channels.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Channels.Value = {};
            app.InstructionType = uidropdown(app.GridLayout3);
            app.InstructionType.Items = {'Continue', 'Loop', 'End loop', 'Branch', 'Wait', 'Long delay', 'RTS', 'JSR', 'Stop'};
            app.InstructionType.ItemsData = {'CONTINUE', 'LOOP', 'END_LOOP', 'BRANCH', 'WAIT', 'LONG_DELAY', 'RTS', 'JSR', 'STOP'};
            app.InstructionType.ValueChangedFcn = createCallbackFcn(app, @InstructionTypeValueChanged, true);
            app.InstructionType.Value = 'CONTINUE';
            app.InstructiontypeLabel = uilabel(app.GridLayout3);
            app.InstructiontypeLabel.Text = 'Instruction type';
            app.Duration = uieditfield(app.GridLayout3, 'numeric');
            app.Duration.Limits = [2 Inf];
            app.Duration.Value = 100;
            app.DurationnsEditFieldLabel = uilabel(app.GridLayout3);
            app.DurationnsEditFieldLabel.Text = 'Duration (ns)';
            app.Address = uieditfield(app.GridLayout3, 'numeric');
            app.Address.Limits = [1 Inf];
            app.Address.Value = 1;
            app.InstructionaddressLabel = uilabel(app.GridLayout3);
            app.InstructionaddressLabel.Text = 'Instruction address';
            app.Warnings = uitextarea(app.GridLayout3);
            app.Warnings.Editable = 'off';
            app.UserDescription = uitextarea(app.GridLayout3);
            app.WarningsLabel = uilabel(app.GridLayout3);
            app.WarningsLabel.Text = 'Warnings';
            app.UserdescriptioneditableLabel = uilabel(app.GridLayout3);
            app.UserdescriptioneditableLabel.Text = 'User description (editable)';
            app.CopySegmentsTab = uitab(app.TabGroup);
            app.CopySegmentsTab.Title = 'Copy Segments';
            app.GridLayout5 = uigridlayout(app.CopySegmentsTab);
            app.SequenceTable2 = uitable(app.GridLayout5);
            app.SequenceTable2.ColumnName = {'#'; 'Instruction'; 'Channels'; 'Duration (ns)'; 'Context info'};
            app.SequenceTable2.RowName = {};
            app.StartofsegmentaddressLabel = uilabel(app.GridLayout5);
            app.StartofsegmentaddressLabel.Text = {'Start of segment'; 'address'};
            app.StartSeg = uieditfield(app.GridLayout5, 'numeric');
            app.StartSeg.Limits = [1 Inf];
            app.StartSeg.ValueChangedFcn = createCallbackFcn(app, @StartSegValueChanged, true);
            app.StartSeg.Value = 1;
            app.EndofsegmentaddressLabel = uilabel(app.GridLayout5);
            app.EndofsegmentaddressLabel.Text = {'End of segment'; 'address'};
            app.EndSeg = uieditfield(app.GridLayout5, 'numeric');
            app.EndSeg.Limits = [1 Inf];
            app.EndSeg.ValueChangedFcn = createCallbackFcn(app, @EndSegValueChanged, true);
            app.EndSeg.Value = 1;
            app.PasteaddressEditFieldLabel = uilabel(app.GridLayout5);
            app.PasteaddressEditFieldLabel.Text = 'Paste address';
            app.PasteAddress = uieditfield(app.GridLayout5, 'numeric');
            app.PasteAddress.Limits = [1 Inf];
            app.PasteAddress.Value = 1;
            app.PasteSegmentButton = uibutton(app.GridLayout5, 'push');
            app.PasteSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @PasteSegmentButtonPushed, true);
            app.PasteSegmentButton.Text = 'Paste Segment';
            app.SegmentVisual = uipanel(app.GridLayout5);
            app.TemplatesTab = uitab(app.TabGroup);
            app.TemplatesTab.Title = 'Templates';
            app.GridLayout6 = uigridlayout(app.TemplatesTab);
            app.TemplateInputs = uitable(app.GridLayout6);
            app.TemplateInputs.ColumnName = {'Input name'; 'Input value'};
            app.TemplateInputs.RowName = {};
            app.TemplateInputs.ColumnEditable = [false true];
            app.TemplateInputs.CellEditCallback = createCallbackFcn(app, @TemplateInputsCellEdit, true);
            app.TemplateselectionLabel = uilabel(app.GridLayout6);
            app.TemplateselectionLabel.Text = 'Template selection';
            app.TemplateSelection = uidropdown(app.GridLayout6);
            app.TemplateSelection.ValueChangedFcn = createCallbackFcn(app, @TemplateSelectionValueChanged, true);
            app.LoadTemplate = uibutton(app.GridLayout6, 'push');
            app.LoadTemplate.ButtonPushedFcn = createCallbackFcn(app, @LoadTemplatePushed, true);
            app.LoadTemplate.Text = 'Load template';
            app.ConfocalWideField = uidropdown(app.GridLayout6);
            app.ConfocalWideField.Items = {'Confocal', 'Wide Field'};
            app.ConfocalWideField.ValueChangedFcn = createCallbackFcn(app, @ConfocalWideFieldValueChanged, true);
            app.ConfocalWideField.Value = 'Confocal';
            app.SavedSequencesTab = uitab(app.TabGroup);
            app.SavedSequencesTab.Title = 'Saved Sequences';
            app.GridLayout7 = uigridlayout(app.SavedSequencesTab);
            app.LoadName = uieditfield(app.GridLayout7, 'text');
            app.LoadName.Placeholder = 'File name to load';
            app.LoadSequenceButton = uibutton(app.GridLayout7, 'push');
            app.LoadSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSequenceButtonPushed, true);
            app.LoadSequenceButton.Text = 'Load Sequence';
            app.SavedSequencesDisplay = uitextarea(app.GridLayout7);
            app.SavedSequencesDisplay.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Visuals = uipanel(app.GridLayout2);
            app.SaveName = uitextarea(app.GridLayout2);
            app.SaveName.Placeholder = 'File name to save as';
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.timestartEditFieldLabel = uilabel(app.GridLayout2);
            app.timestartEditFieldLabel.Text = 'time start';
            app.timestartEditField = uieditfield(app.GridLayout2, 'numeric');
            app.timestartEditField.ValueChangedFcn = createCallbackFcn(app, @timestartEditFieldValueChanged, true);
            app.timeendEditFieldLabel = uilabel(app.GridLayout2);
            app.timeendEditFieldLabel.Text = 'time end';
            app.timeendEditField = uieditfield(app.GridLayout2, 'numeric');
            app.timeendEditField.ValueChangedFcn = createCallbackFcn(app, @timeendEditFieldValueChanged, true);
            app.channelsSpinnerLabel = uilabel(app.GridLayout2);
            app.channelsSpinnerLabel.Text = 'channels';
            app.DisplayedChannels = uispinner(app.GridLayout2);
            app.DisplayedChannels.ValueChangedFcn = createCallbackFcn(app, @DisplayedChannelsValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PulseSequenceEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end