classdef NV_ExperimentEditor < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                        matlab.ui.Figure
      RightPanel                      matlab.ui.container.Panel
      Panel                           matlab.ui.container.Panel
      GridLayout7                     matlab.ui.container.GridLayout
      scanTime                        matlab.ui.control.Label
      ScreenshotButton                matlab.ui.control.Button
      AbortButton                     matlab.ui.control.Button
      StatusLamp                      matlab.ui.control.Lamp
      ColorBarPanel                   matlab.ui.container.Panel
      ColorbarAxes                    matlab.ui.control.UIAxes
      ResetButton                     matlab.ui.control.Button
      StartButton                     matlab.ui.control.Button
      OutputPanel                     matlab.ui.container.Panel
      TwoDAxes                        matlab.ui.control.UIAxes
      PulseSequenceAxes               matlab.ui.control.UIAxes
      OneDAxes                        matlab.ui.control.UIAxes
      LeftPanel                       matlab.ui.container.Panel
      TabGroup                        matlab.ui.container.TabGroup
      ConnectionsTab                  matlab.ui.container.Tab
      GridLayout2                     matlab.ui.container.GridLayout
      WindfreakRFLoad                 matlab.ui.control.Button
      WindfreakRFLamp                 matlab.ui.control.Lamp
      WindfreakRFUnload               matlab.ui.control.Button
      WindfreakRFConfig               matlab.ui.control.EditField
      WindfreakRFLabel                matlab.ui.control.Label
      DAQConfig                       matlab.ui.control.EditField
      SRSRFConfig                     matlab.ui.control.EditField
      SproutLaserConfig               matlab.ui.control.EditField
      PulseBlasterConfig              matlab.ui.control.EditField
      StageConfig                     matlab.ui.control.EditField
      ConfigFileNameLabel             matlab.ui.control.Label
      DAQLabel                        matlab.ui.control.Label
      SRSRFLabel                      matlab.ui.control.Label
      SproutLaserLabel                matlab.ui.control.Label
      PulseBlasterLabel               matlab.ui.control.Label
      StageLabel                      matlab.ui.control.Label
      SproutLaserLamp                 matlab.ui.control.Lamp
      SproutLaserUnload               matlab.ui.control.Button
      SproutLaserLoad                 matlab.ui.control.Button
      DAQUnload                       matlab.ui.control.Button
      DAQLoad                         matlab.ui.control.Button
      SRSRFUnload                     matlab.ui.control.Button
      PulseBlasterUnload              matlab.ui.control.Button
      DAQLamp                         matlab.ui.control.Lamp
      SRSRFLamp                       matlab.ui.control.Lamp
      PulseBlasterLamp                matlab.ui.control.Lamp
      SRSRFLoad                       matlab.ui.control.Button
      PulseBlasterLoad                matlab.ui.control.Button
      StageLamp                       matlab.ui.control.Lamp
      StageUnload                     matlab.ui.control.Button
      StageLoad                       matlab.ui.control.Button
      SettingsTab                     matlab.ui.container.Tab
      GridLayout4                     matlab.ui.container.GridLayout
      DataType                        matlab.ui.control.DropDown
      DataTypeLabel                   matlab.ui.control.Label
      SRSModWaveform                  matlab.ui.control.DropDown
      windfreakModWaveform            matlab.ui.control.DropDown
      ModulationLabel_2               matlab.ui.control.Label
      AmplitudedBmLabel               matlab.ui.control.Label
      WindfreakRFsettingsLabel        matlab.ui.control.Label
      SRSRFsettingsLabel              matlab.ui.control.Label
      windfreakRFModulationDropDown   matlab.ui.control.DropDown
      ModulationLabel                 matlab.ui.control.Label
      windfreakRFAmplitude            matlab.ui.control.NumericEditField
      windfreakRFFrequency            matlab.ui.control.NumericEditField
      RFFrequencyGHzLabel_2           matlab.ui.control.Label
      NumberOfPointsForContCollection  matlab.ui.control.NumericEditField
      NumberofpointsforcontinuouscollectionLabel  matlab.ui.control.Label
      SRS_RFModulationDropDown        matlab.ui.control.DropDown
      OffWhenDeleted                  matlab.ui.control.CheckBox
      WaitForLaserEquilibrium         matlab.ui.control.CheckBox
      DifferentiatesignalandreferenceCheckBox  matlab.ui.control.CheckBox
      SRS_RFAmplitude                 matlab.ui.control.NumericEditField
      SRS_RFFrequency                 matlab.ui.control.NumericEditField
      CopyappobjecttoworkspaceButton  matlab.ui.control.Button
      CollectdatacontinuouslyButton   matlab.ui.control.Button
      RefreshLaserPowerReading        matlab.ui.control.Button
      LaserPowerReading               matlab.ui.control.NumericEditField
      PowerreadingmWLabel             matlab.ui.control.Label
      SetpowermWLabel                 matlab.ui.control.Label
      RefreshAppButton                matlab.ui.control.Button
      ToggleLaser                     matlab.ui.control.Switch
      LaserLabel_2                    matlab.ui.control.Label
      SetLaserPower                   matlab.ui.control.NumericEditField
      PositionTab                     matlab.ui.container.Tab
      GridLayout3                     matlab.ui.container.GridLayout
      OptimizationInsertorReplace     matlab.ui.control.DropDown
      OptimizationPercentage          matlab.ui.control.NumericEditField
      TimebetweenoptimizationsminLabel_2  matlab.ui.control.Label
      OptimizationPercentageCheck     matlab.ui.control.CheckBox
      OptimizationTimer               matlab.ui.control.CheckBox
      OptimizationparameterDropDown   matlab.ui.control.DropDown
      OptimizationparameterLabel      matlab.ui.control.Label
      ZFineReset                      matlab.ui.control.Button
      YFineReset                      matlab.ui.control.Button
      XFineReset                      matlab.ui.control.Button
      ResetFineAxisLabel              matlab.ui.control.Label
      StageSettlingTime               matlab.ui.control.NumericEditField
      PausedurationforfinestagemovementsmsLabel  matlab.ui.control.Label
      OptimizationmethodDropDown      matlab.ui.control.DropDown
      OptimizationmethodDropDownLabel  matlab.ui.control.Label
      Numberofmanualoptimizations     matlab.ui.control.NumericEditField
      NumberofmanualoptimizationsLabel  matlab.ui.control.Label
      BeginmanualoptimizationButton   matlab.ui.control.Button
      OptimizationInterval            matlab.ui.control.NumericEditField
      TimebetweenoptimizationsminLabel  matlab.ui.control.Label
      StageAutoOptimization           matlab.ui.control.CheckBox
      DeleteOptimizationStep          matlab.ui.control.Button
      AddOptimizationStep             matlab.ui.control.Button
      StageOptimizationInstructions   matlab.ui.control.Table
      StageAddress                    matlab.ui.control.NumericEditField
      AddressEditField_2Label         matlab.ui.control.Label
      ZIncrement                      matlab.ui.control.NumericEditField
      ZPosition                       matlab.ui.control.NumericEditField
      ZUp                             matlab.ui.control.Button
      YUp                             matlab.ui.control.Button
      ZDown                           matlab.ui.control.Button
      YDown                           matlab.ui.control.Button
      MovepositiveLabel               matlab.ui.control.Label
      MovenegativeLabel               matlab.ui.control.Label
      StagezLabel                     matlab.ui.control.Label
      StageyLabel                     matlab.ui.control.Label
      StagexLabel                     matlab.ui.control.Label
      AxisLabel                       matlab.ui.control.Label
      StageTolerance                  matlab.ui.control.NumericEditField
      StagetolerancemLabel            matlab.ui.control.Label
      YPosition                       matlab.ui.control.NumericEditField
      YIncrement                      matlab.ui.control.NumericEditField
      IncrementLabel                  matlab.ui.control.Label
      AbsolutepositionLabel           matlab.ui.control.Label
      XPosition                       matlab.ui.control.NumericEditField
      XIncrement                      matlab.ui.control.NumericEditField
      XUp                             matlab.ui.control.Button
      XDown                           matlab.ui.control.Button
      ScansTab                        matlab.ui.container.Tab
      GridLayout6                     matlab.ui.container.GridLayout
      Notifications                   matlab.ui.control.CheckBox
      maxFailedCollections            matlab.ui.control.NumericEditField
      TempdatapointdeviationLabel_3   matlab.ui.control.Label
      forcedCollectionPauseTime       matlab.ui.control.NumericEditField
      TempdatapointdeviationLabel_2   matlab.ui.control.Label
      DataPointDeviation              matlab.ui.control.NumericEditField
      TempdatapointdeviationLabel     matlab.ui.control.Label
      IterationsType                  matlab.ui.control.DropDown
      IterationstypeDropDownLabel     matlab.ui.control.Label
      MaxIterations                   matlab.ui.control.NumericEditField
      MaxIterationsEditFieldLabel     matlab.ui.control.Label
      RandomizescanCheckBox           matlab.ui.control.CheckBox
      DefaultScanChange               matlab.ui.control.DropDown
      DefaultchangeDropDownLabel      matlab.ui.control.Label
      ScanAddress                     matlab.ui.control.NumericEditField
      AddressEditField_3Label         matlab.ui.control.Label
      DeleteScanStep                  matlab.ui.control.Button
      AddScanStep                     matlab.ui.control.Button
      ScanningTable                   matlab.ui.control.Table
      OutputTab                       matlab.ui.container.Tab
      GridLayout5                     matlab.ui.container.GridLayout
      PlotDimensionality              matlab.ui.control.DropDown
      HideLinesCheckBox               matlab.ui.control.CheckBox
      LineDeleteAddress               matlab.ui.control.NumericEditField
      ScanforxaxisLabel_4             matlab.ui.control.Label
      DeleteLineButton                matlab.ui.control.Button
      AddLineButton                   matlab.ui.control.Button
      LineNucleusType                 matlab.ui.control.DropDown
      NucleusTypeLabel                matlab.ui.control.Label
      manLocation                     matlab.ui.control.NumericEditField
      ScanforxaxisLabel_3             matlab.ui.control.Label
      ContrasttypeDropDownLabel_2     matlab.ui.control.Label
      AdditionType                    matlab.ui.control.DropDown
      IterationEnd                    matlab.ui.control.NumericEditField
      IterationStart                  matlab.ui.control.NumericEditField
      CustomIterationBoundsLabel      matlab.ui.control.Label
      BaselineSubtraction             matlab.ui.control.NumericEditField
      BaselineSubtractionLabel        matlab.ui.control.Label
      PulseNumToPlot                  matlab.ui.control.NumericEditField
      ScanforxaxisLabel_2             matlab.ui.control.Label
      ThingToPlot                     matlab.ui.control.DropDown
      ForceplotupdateButton           matlab.ui.control.Button
      ContrastType                    matlab.ui.control.DropDown
      ContrasttypeDropDownLabel       matlab.ui.control.Label
      DisplayIterations               matlab.ui.control.DropDown
      PlotLimits                      matlab.ui.control.Table
      twoDOutput                      matlab.ui.container.Tab
      GridLayout11                    matlab.ui.container.GridLayout
      yAxisScanNum                    matlab.ui.control.NumericEditField
      ScanforyaxisLabel               matlab.ui.control.Label
      xAxisScanNum                    matlab.ui.control.NumericEditField
      ScanforxaxisLabel_5             matlab.ui.control.Label
      ScanStepTable                   matlab.ui.control.Table
      StaticColorLimits               matlab.ui.control.Table
      UseStaticColorLimits            matlab.ui.control.CheckBox
      ForcesquareaspectratioCheckBox  matlab.ui.control.CheckBox
      ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox  matlab.ui.control.CheckBox
      SaveLoadTab                     matlab.ui.container.Tab
      GridLayout9                     matlab.ui.container.GridLayout
      UserName                        matlab.ui.control.DropDown
      LoadNames                       matlab.ui.control.ListBox
      LoadDates                       matlab.ui.control.ListBox
      DataSetupsSwitch                matlab.ui.control.Switch
      SavedUserNotes                  matlab.ui.control.TextArea
      LoadButton                      matlab.ui.control.Button
      SaveButton                      matlab.ui.control.Button
      SaveFolderName                  matlab.ui.control.EditField
      SavenameLabel                   matlab.ui.control.Label
      BoxAddress                      matlab.ui.control.EditField
      BoxaddressEditFieldLabel        matlab.ui.control.Label
      PulseSequenceTab                matlab.ui.container.Tab
      GridLayout10                    matlab.ui.container.GridLayout
      BoxaddressEditFieldLabel_3      matlab.ui.control.Label
      PulseAddress                    matlab.ui.control.EditField
      pulsePlotVisible                matlab.ui.control.StateButton
      UserName2                       matlab.ui.control.DropDown
      CopyAddresses                   matlab.ui.control.EditField
      CopytoButton                    matlab.ui.control.StateButton
      PulseUserNotes                  matlab.ui.control.TextArea
      PulseSequenceNames              matlab.ui.control.ListBox
      PulseSaveName                   matlab.ui.control.EditField
      SavenameLabel_2                 matlab.ui.control.Label
      PulseBoxAddress                 matlab.ui.control.EditField
      BoxaddressEditFieldLabel_2      matlab.ui.control.Label
      LoadPulse                       matlab.ui.control.StateButton
      SavePulseSequence               matlab.ui.control.StateButton
      TotalLoops                      matlab.ui.control.NumericEditField
      LoopsOrDuration                 matlab.ui.control.DropDown
      DeleteStepsButton               matlab.ui.control.StateButton
      AddStepsButton                  matlab.ui.control.StateButton
      PulseSequenceTable              matlab.ui.control.Table
      SequenceTemplatesTab            matlab.ui.container.Tab
      GridLayout12                    matlab.ui.container.GridLayout
      LoadTemplate                    matlab.ui.control.Button
      TemplateTable                   matlab.ui.control.Table
      TemplateType                    matlab.ui.control.DropDown
      ContextMenu                     matlab.ui.container.ContextMenu
      Menu                            matlab.ui.container.Menu
      Menu2                           matlab.ui.container.Menu
      versionsContext                 matlab.ui.container.ContextMenu
      DatesDelete                     matlab.ui.container.Menu
      ContextMenu2                    matlab.ui.container.ContextMenu
      NamesDelete                     matlab.ui.container.Menu
      PulseSequenceContext            matlab.ui.container.ContextMenu
      PulseDelete                     matlab.ui.container.Menu
   end

    properties
       userList = {'Common','Mikael','David','Lakshmy','Kyle','Swastik','Aksshay','James-Lucius'}
       ex = experiment() %Contains information necesary to run experiment as well as all the instrument objects
       storedDataSettings
       storedSetupSettings
       storedTemplateSettings = struct([])
       storedMeanSequenceDuration
       scanStatus = 'reset'
       scanStartTime = {}
       videoRunning = false
       displayInfo
       general
       performScanReset
       abortTime = []
    end

    % TO DO
    %Convert box address to more generalized save location
    %Make number of loops change to match given duration if duration is
    %selected while pulse sequence is changed
    %Make scans and pulse sequence start with 1 default
    %Add random scanning
    %Fix "step size changed" bug
    %Fix continuous collection
    %Add raw number of scans to scan time update

    methods (Access = private)

       function appUpdate(app)
          %Updates all pages of the app
          ScanUpdate(app);
          % PositionUpdate(app);
          ConnectionsUpdate(app);
          GeneralUpdate(app);
          SaveDataUpdate(app);
          StatusUpdate(app);
          OutputSettingsUpdate(app);
          PulseSequenceUpdate(app);
          TemplateUpdate(app);
          % disp(numel(app.ex.instrumentIdentifiers))
       end

       function PulseSequenceUpdate(app)
          if isempty(app.ex.pulseBlaster)
             return
          end
          if ~isempty(app.ex.pulseBlaster.userSequence)
             for ii = 1:length(app.ex.pulseBlaster.userSequence)

                tableBinary(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).channelsBinary); %#ok<*AGROW>
                tableDirection(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).directionType);
                tableContext(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).contextInfo);
                tableNotes(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).notes);
                tableActive{ii} = c2s(join(app.ex.pulseBlaster.userSequence(ii).activeChannels));
                if tableActive{ii} == ""
                   tableActive{ii} = "None";
                end
             end
             tableNumOut = [app.ex.pulseBlaster.userSequence.numericalOutput];
             tableDuration = [app.ex.pulseBlaster.userSequence.duration];

             app.PulseSequenceTable.Data = [(1:numel(tableDuration))',tableActive',tableBinary',tableNumOut',tableDuration',tableDirection',tableContext',tableNotes'];
             app.ex.pulseBlaster = sendToInstrument(app.ex.pulseBlaster);
          end

          %Alters number of total loops depending on setting
          if strcmpi(app.LoopsOrDuration.Value,'# of Loops')
             if isempty(app.ex.pulseBlaster.nTotalLoops)
                app.ex.pulseBlaster.nTotalLoops = 1;
             end
             app.TotalLoops.Value = app.ex.pulseBlaster.nTotalLoops;
          elseif ~isempty(app.ex.pulseBlaster.userSequence)
             app.ex.pulseBlaster = calculateDuration(app.ex.pulseBlaster,'adjusted');
             app.TotalLoops.Value = app.ex.pulseBlaster.sequenceDurations.adjusted.totalSeconds;
          else
             app.TotalLoops.Value = 1;
          end

          chooseVisiblePlot(app)
          if app.PulseSequenceAxes.Visible == "on"
             DisplayUpdate(app)
          end

          rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
             app.UserName2.Value,'\Experiment Editor\Pulse Sequences');
          directory = dir(rootName);
          if numel(directory) >= 3
             directory = struct2cell(directory(3:end));
             recencyOrder = datetime(directory(3,:));
             [~,idx] = sort(recencyOrder,'descend');
             names = directory(1,:);
             names = names(idx);
             names(2:end+1) = names;

             names{1} = 'Pulse Sequence Names';
             %erases the .mat from the sequence name, not necessary but
             %looks nicer
             for ii = 1:numel(names)
                names{ii} =c2s(names{ii});
                names{ii} = erase(names{ii},".mat");
                names{ii} = s2c(names{ii});
             end
             app.PulseSequenceNames.Items = names;

          else
             app.PulseSequenceNames.Items = {'Pulse Sequence Names'};
             app.PulseSequenceNames.Value = 'Pulse Sequence Names';
             return
          end
          if ~isempty(app.ex.pulseBlaster)
             if~isempty(app.ex.pulseBlaster.nTotalLoops)
                app.TotalLoops.Value = app.ex.pulseBlaster.nTotalLoops;
             else
                app.TotalLoops.Value = 0;
             end
          end

       end

       function TemplateUpdate(app)
          %Creates defaults for each template. These defaults use the names and values that are more user friendly than
          %those used in the template defaults themselves.

          for ii = 1:size(app.TemplateTable.Data,1)
             currentfn = app.TemplateTable.Data{ii,1};
             currentfn = string(replace(currentfn," ","_"));
             currentData = app.TemplateTable.Data{ii,2};
             if all(isstrprop(currentData,'digit') | isstrprop(currentData,'punct'))
                currentData = str2double(currentData);
             end
             app.storedTemplateSettings.(currentfn) = currentData;
          end
          switch app.TemplateType.Value
             case 'Rabi'
                defaults.RF_Frequency = 2.87;
                defaults.RF_Start_Duration = 10;
                defaults.RF_End_Duration = 100;
                defaults.Step_Size = 10;
                defaults.Collection_Duration_0_default = 0;
                defaults.Collection_Buffer = 100;
                defaults.Repolarization_Duration = 10000;
                defaults.Intermission_Buffer = 12000;
                defaults.Data_On_Buffer = 0;
                defaults.Extra_Buffer = 0;
                defaults.AOM_Compensation = 0;
                defaults.Sequence_Time_per_Data_Point = 3;
                defaults.Use_Compensating_Pulses = 0;

             case 'Spin Echo'
                defaults.RF_Frequency = 2.87;
                defaults.Pi_Time = 40;
                defaults.Tau_Start = 100;
                defaults.Tau_End = 1000;
                defaults.Tau_Step_Size = 50;
                defaults.Collection_Duration_0_default = 0;
                defaults.Collection_Buffer = 100;
                defaults.Repolarization_Duration = 10000;
                defaults.Intermission_Buffer = 12000;
                defaults.Data_On_Buffer = 0;
                defaults.Extra_Buffer = 0;
                defaults.AOM_Compensation = 0;
                defaults.RF_Ramp_Time = 0;
                defaults.IQ_Pre_Buffer = 0;
                defaults.IQ_Post_Buffer = 0;
                defaults.Sequence_Time_per_Data_Point = 3;
                defaults.Use_Compensating_Pulses = 0;

             case {'XYN','CPMG'}
                defaults.RF_Frequency = 2.87;
                defaults.Pi_Time = 40;
                defaults.Tau_Start = 100;
                defaults.Tau_End = 1000;
                defaults.Tau_Step_Size = 50;
                defaults.n_XY = 8;
                defaults.Sets_of_XYN = 1;
                defaults.Collection_Duration_0_default = 0;
                defaults.Collection_Buffer = 100;
                defaults.Repolarization_Duration = 7000;
                defaults.Intermission_Buffer = 10000;
                defaults.Data_On_Buffer = 0;
                defaults.Extra_Buffer = 0;
                defaults.AOM_Compensation = 0;
                defaults.RF_Ramp_Time = 0;
                defaults.IQ_Pre_Buffer = 0;
                defaults.IQ_Post_Buffer = 0;
                defaults.Sequence_Time_per_Data_Point = 3;
                defaults.Use_Compensating_Pulses = 0;

             case 'Correlation'
                defaults.RF_Frequency = 2.87;
                defaults.Pi_Time = 40;
                defaults.Tau_Duration = 500;
                defaults.T_Start = 100;
                defaults.T_End = 1000;
                defaults.T_Step_Size = 50;
                defaults.n_XY = 8;
                defaults.Sets_of_XYN = 1;
                defaults.Collection_Duration_0_default = 0;
                defaults.Collection_Buffer = 100;
                defaults.Repolarization_Duration = 10000;
                defaults.Intermission_Buffer = 12000;
                defaults.Data_On_Buffer = 0;
                defaults.Extra_Buffer = 0;
                defaults.AOM_Compensation = 0;
                defaults.RF_Ramp_Time = 0;
                defaults.IQ_Pre_Buffer = 0;
                defaults.IQ_Post_Buffer = 0;
                defaults.Sequence_Time_per_Data_Point = 1;
                defaults.Use_Compensating_Pulses = 0;

             case 'DEER Frequency'
                defaults.RF_1_Frequency = 2.87;
                defaults.Pi_Time = 40;
                defaults.Tau_Time = 100;
                defaults.Frequency_Start = 2;
                defaults.Frequency_End = 3;
                defaults.Frequency_Step_Size = 0.1;
                defaults.RF2_Duration = 50;
                defaults.n_RF2_Pulses = 1;
                defaults.Collection_Duration_0_default = 0;
                defaults.Collection_Buffer = 100;
                defaults.Repolarization_Duration = 10000;
                defaults.Intermission_Buffer = 12000;
                defaults.Data_On_Buffer = 0;
                defaults.Extra_Buffer = 0;
                defaults.AOM_Compensation = 0;
                defaults.RF_Ramp_Time = 0;
                defaults.IQ_Pre_Buffer = 0;
                defaults.IQ_Post_Buffer = 0;
                defaults.Sequence_Time_per_Data_Point = 1;

             case 'DEER Duration'
                defaults.RF_1_Frequency = 2.87;
                defaults.Pi_Time = 40;
                defaults.Tau_Time = 100;
                defaults.RF_2_Duration_Start = 10;
                defaults.RF_2_Duration_End = 100;
                defaults.RF_2_Duration_Step_Size = 10;
                defaults.RF_2_Frequency = 40;
                defaults.n_RF2_Pulses = 1;
                defaults.Collection_Duration_0_default = 0;
                defaults.Collection_Buffer = 100;
                defaults.Repolarization_Duration = 10000;
                defaults.Intermission_Buffer = 12000;
                defaults.Data_On_Buffer = 0;
                defaults.Extra_Buffer = 0;
                defaults.AOM_Compensation = 0;
                defaults.RF_Ramp_Time = 0;
                defaults.IQ_Pre_Buffer = 0;
                defaults.IQ_Post_Buffer = 0;
                defaults.Sequence_Time_per_Data_Point = 1;
          end

          %Adds default values if the field is not already present
          app.storedTemplateSettings = mustContainField(app.storedTemplateSettings,defaults);

          %Gets only the values corresponding to fields the defaults struct has from the stored settings
          fn = fieldnames(defaults);
          v = cellfun(@(f) app.storedTemplateSettings.(f), fn, 'UniformOutput', false);

          %Replaces underscore with space for readability
          fn = string(replace(fn,"_"," "));

          app.TemplateTable.Data = [fn, v];
       end

       function ConnectionsUpdate(app)

          %Checks connected status of each instrument and makes corresponding lamp red (unconnected) or green (connected)
          if checkInstrumentStatus(app,'PIstage')
             app.StageLamp.Color = [0.25,0.61,0.25];%on, green
          else
             app.StageLamp.Color = [0.78,0.09,0.22];%off, red
          end

          if checkInstrumentStatus(app,'pulseBlaster')
             app.PulseBlasterLamp.Color = [0.25,0.61,0.25];
          else
             app.PulseBlasterLamp.Color = [0.78,0.09,0.22];
          end

          if checkInstrumentStatus(app,'ndYAG')
             app.SproutLaserLamp.Color = [0.25,0.61,0.25];
          else
             app.SproutLaserLamp.Color = [0.78,0.09,0.22];
          end

          if checkInstrumentStatus(app,'SRS_RF')
             app.SRSRFLamp.Color = [0.25,0.61,0.25];
          else
             app.SRSRFLamp.Color = [0.78,0.09,0.22];
          end

          if checkInstrumentStatus(app,'windfreak_RF')
             app.WindfreakRFLamp.Color = [0.25,0.61,0.25];
          else
             app.WindfreakRFLamp.Color = [0.78,0.09,0.22];
          end

          if checkInstrumentStatus(app,'DAQ')
             app.DAQLamp.Color = [0.25,0.61,0.25];
          else
             app.DAQLamp.Color = [0.78,0.09,0.22];
          end

       end

       function GeneralUpdate(app)
          %For each instrument, check if instrument is connected and, if not, display default values. If it is connected,
          %change the display of each option to the current setting
          %For binary settings (on/off), value from instrument object is converted into state usable by display method


          if checkInstrumentStatus(app,'ndYAG')
             app.OffWhenDeleted.Value = app.ex.ndYAG.offWhenDeleted;
             app.WaitForLaserEquilibrium.Value = app.ex.ndYAG.waitForEquilibrium;
             app.ToggleLaser.Value = app.ex.ndYAG.enabled;
             app.SetLaserPower.Value = app.ex.ndYAG.setPower;
             app.LaserPowerReading.Value = app.ex.ndYAG.actualPower;
          else
             app.OffWhenDeleted.Value = false;
             app.WaitForLaserEquilibrium.Value = false;
             app.ToggleLaser.Value = 'off';
             app.SetLaserPower.Value = 0;
             app.LaserPowerReading.Value = false;
          end

          if checkInstrumentStatus(app,'SRS_RF')
             app.SRS_RFFrequency.Value = app.ex.SRS_RF.frequency;
             app.SRS_RFAmplitude.Value = app.ex.SRS_RF.amplitude;
             if strcmpi("On",app.ex.SRS_RF.modulationEnabled)
                app.SRS_RFModulationDropDown.Value = app.ex.SRS_RF.modulationType;
             else
                app.SRS_RFModulationDropDown.Value = "Off";
             end
             app.SRSModWaveform.Value = app.ex.SRS_RF.modulationWaveform;
          else
             app.SRS_RFFrequency.Value = 0;
             app.SRS_RFAmplitude.Value = 0;
             app.SRS_RFModulationDropDown.Value = 'Off';
             app.SRSModWaveform.Value = 'sine';
          end

          if checkInstrumentStatus(app,'windfreak_RF')
             app.windfreakRFFrequency.Value = app.ex.windfreak_RF.frequency;
             app.windfreakRFAmplitude.Value = app.ex.windfreak_RF.amplitude;
             if strcmpi("On",app.ex.windfreak_RF.modulationEnabled)
                app.windfreakRFModulationDropDown.Value = app.ex.windfreak_RF.modulationType;
             else
                app.windfreakRFModulationDropDown.Value = "Off";
             end
             app.windfreakModWaveform.Value = app.ex.windfreak_RF.modulationWaveform;
          else
             app.windfreakRFFrequency.Value = 0;
             app.windfreakRFAmplitude.Value = 0;
             app.windfreakRFModulationDropDown.Value = 'Off';
             app.windfreakModWaveform.Value = 'sine';
          end
       end

       function SaveDataUpdate(app)
          rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
             app.UserName.Value,'\Experiment Editor\',app.DataSetupsSwitch.Value);
          firstDir = dir(rootName);
          if numel(firstDir) >= 3
             firstDir = struct2cell(firstDir(3:end));
             recencyOrder = datetime(firstDir(3,:));
             [~,idx] = sort(recencyOrder,'descend');
             firstNames = firstDir(1,:);
             firstNames = firstNames(idx);
             firstNames(2:end+1) = firstNames;

          else
             app.LoadNames.Items = {[app.DataSetupsSwitch.Value,' Names']};
             app.LoadDates.Items = {[app.DataSetupsSwitch.Value,' Dates']};
             app.LoadNames.Value = [app.DataSetupsSwitch.Value,' Names'];
             app.LoadDates.Value = [app.DataSetupsSwitch.Value,' Dates'];
             return
          end

          if strcmp(app.DataSetupsSwitch.Value,'Data')
             %List all dates
             firstNames{1} = [app.DataSetupsSwitch.Value,' Dates'];
             app.LoadDates.Items = firstNames;
             rootName = strcat(rootName,'\',app.LoadDates.Value);


          else %Setups
             %List all names
             firstNames{1} = [app.DataSetupsSwitch.Value,' Names'];
             app.LoadNames.Items = firstNames;
             rootName = strcat(rootName,'\',app.LoadNames.Value);
          end

          secondDir = dir(rootName);

          if numel(secondDir) >= 3
             secondDir = struct2cell(secondDir(3:end));
             recencyOrder = datetime(secondDir(3,:));
             [~,idx] = sort(recencyOrder,'descend');
             secondNames = secondDir(1,:);
             secondNames = secondNames(idx);
             secondNames(2:end+1) = secondNames;


             if strcmp(app.DataSetupsSwitch.Value,'Data')
                secondNames{1} = [app.DataSetupsSwitch.Value,' Names'];
                app.LoadNames.Items = secondNames;
             else
                secondNames{1} = [app.DataSetupsSwitch.Value,' Dates'];
                app.LoadDates.Items = secondNames;
             end

          elseif strcmp(app.DataSetupsSwitch.Value,'Data')
             app.LoadNames.Items = {[app.DataSetupsSwitch.Value,' Names']};
          else
             app.LoadDates.Items = {[app.DataSetupsSwitch.Value,' Dates']};
          end

          if ~ismember(app.LoadNames.Value,app.LoadNames.Items)
             app.LoadNames.Value = [app.DataSetupsSwitch.Value,' Names'];
          end

          app.LoadNames.Value = app.LoadNames.Value;

          if ~ismember(app.LoadDates.Value,app.LoadDates.Items)
             app.LoadDates.Value = [app.DataSetupsSwitch.Value,' Dates'];
          end

          app.LoadDates.Value = app.LoadDates.Value;

          %Loads the user notes when the file version is changed
          if ~strcmpi(app.LoadDates.Value,'Setups Dates') && ~strcmpi(app.LoadNames.Value,'Data Names')
             if strcmpi(app.DataSetupsSwitch.Value,'setups')
                rootName = strcat(rootName,'\',app.LoadDates.Value,'\MatLab_User_Notes');
             else
                rootName = strcat(rootName,'\',app.LoadNames.Value,'\MatLab_User_Notes');
             end
             load(rootName,"userNotes")
             app.SavedUserNotes.Value = userNotes{1};
          end

       end

       function PositionUpdate(app)
          %Updates the absolute position of stage axes
          if checkInstrumentStatus(app,'PIstage')
             app.XPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'X'),2};
             app.YPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'Y'),2};
             app.ZPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'Z'),2};
             app.StageTolerance.Value = app.ex.PIstage.tolerance;
          end

          app.StageAutoOptimization.Value = app.ex.optimizationInfo.enableOptimization;

          app.OptimizationInterval.Value = app.ex.optimizationInfo.timeBetweenOptimizations;

          app.OptimizationPercentage.Value = app.ex.optimizationInfo.percentageToForceOptimization;

          app.OptimizationTimer.Value = app.ex.optimizationInfo.useTimer;

          app.OptimizationPercentageCheck.Value = app.ex.optimizationInfo.usePercentageDifference;

          app.OptimizationmethodDropDown.Value = app.ex.optimizationInfo.algorithmType;

          if strcmp(app.ex.optimizationInfo.rfStatus, 'off')
             app.OptimizationparameterDropDown.Value = 'Counts (RF off)';
          elseif strcmp(app.ex.optimizationInfo.rfStatus, 'on')
             app.OptimizationparameterDropDown.Value = 'Counts (RF on)';
          else
             app.OptimizationparameterDropDown.Value = 'Contrast';
          end

          optSteps = cell2mat(app.ex.optimizationInfo.steps);
          app.StageOptimizationInstructions.Data = [app.ex.optimizationInfo.stageAxes' app.ex.optimizationInfo.radius' optSteps'];

       end

       function OutputSettingsUpdate(app)

          app.ForcesquareaspectratioCheckBox.Value = app.displayInfo.squareRatio;

          app.DisplayIterations.Value = app.displayInfo.dataSelection;

          for ii = 1:3
             lowBounds(ii) = app.displayInfo.limitsIfOverriden{ii}(1);
             highBounds(ii) = app.displayInfo.limitsIfOverriden{ii}(2);
             if app.displayInfo.incorporateOffset(ii) == "true"
                lowBounds(ii) = lowBounds(ii) + app.displayInfo.offset(ii);
                highBounds(ii) = highBounds(ii)+ app.displayInfo.offset(ii);
             end
             lowBounds(ii) = string(lowBounds(ii));
             highBounds(ii) = string(highBounds(ii));
          end
          overrideLimits = app.displayInfo.overrideLimits;
          incOffset = app.displayInfo.incorporateOffset;
          offset = app.displayInfo.offset;
          app.PlotLimits.Data = [["x";"y";"color"] lowBounds' highBounds' overrideLimits' offset' incOffset'];

          % if ~isempty(app.storedDataSettings) && isfield(app.storedDataSettings,'scan')
          %     parameters = [c2s(app.storedDataSettings.scan.parameter)]';
          %     stepsToView = [app.storedDataSettings.scan.stepToView]';
          %     app.ScanStepTable.Data = [parameters stepsToView];
          %
          %     % if ~app.ex.randomizeScanPoints
          %     %     scanProgress = cell(1,app.storedDataSettings.nScans);
          %     %     for ii = 1:app.storedDataSettings.nScans
          %     %         scanProgress{ii} = sprintf('%s %d/%d',app.storedDataSettings.scan.parameter(ii),...
          %     %             app.storedDataSettings.completed(ii),app.storedDataSettings.scan.nSteps(ii));
          %     %     end
          %     % else
          %     %     scanProgress = {sprintf('%d/%d total steps',app.ex.odometer,...
          %     %         prod(app.storedDataSettings.scan.nSteps))};
          %     % end
          %     % app.ScanProgress.Value = scanProgress;
          % end

          %Must be done individually because it would make a single string
          %rather than a string arrray
          colorLimits(1) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(1)));
          colorLimits(2) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(2)));

          app.StaticColorLimits.Data = [["minimum";"maximum"] colorLimits'];

          app.UseStaticColorLimits.Value = app.displayInfo.useStaticColorLimits;

          app.DataPointDeviation.Value = app.ex.nPointsTolerance;

          DisplayUpdate(app)

       end

       function DisplayUpdate(app)

          %Sets which plot should be visible
          chooseVisiblePlot(app)

          %Plots the pulse sequence
          if app.PulseSequenceAxes.Visible == "on" && (~isempty(app.ex.pulseBlaster)||~isempty(app.ex.pulseBlaster.userSequence))
             pulseSequencePlotUpdate(app)
             UpdatePlotlabel(app)
             return
          end

          %Does not attempt to plot if no data is present
          if isempty(app.ex.data) || app.PulseSequenceAxes.Visible == "on" || app.ex.data.iteration(1) == 0
             return
          end

          %Gets data in a simple matrix that can easily be plotted
          dataMatrix = obtainDataMatrix(app);

          %Gets scan settings or creates dummy scan to use for continuous collection
          if isfield(app.storedDataSettings,'scan')
             scan = app.storedDataSettings.scan;
          else
             scan.stepToView = 1;
             scan.nSteps = app.NumberOfPointsForContCollection.Value;
             scan.stepSize = 1;
             scan.bounds = {[1,app.NumberOfPointsForContCollection.Value]};
          end

          %Plots either the 1D or 2D plot
          if strcmpi(app.OneDAxes.Visible,"on")
             oneDPlotUpdate(app,dataMatrix,scan)
             AdjustPlotLimits(app)
          else
             twoDPlotUpdate(app,dataMatrix,scan)
             % AdjustPlotLimits(app)
          end

          %Updates current plot labels
          UpdatePlotlabel(app)

       end

       function oneDPlotUpdate(app,dataMatrix,scan)

           %Pull data from correct location(s)
           %Reduce to single vector
           %Perform fft if needed
           %Plot/update

           assignin("base","dataMatrix",dataMatrix)

           if isscalar(app.ex.scan)
               %Simple case, only 1 scan
           elseif numel(app.ex.scan) == 2
               %Multiple scans, needs to pick individual slices to show
               %NOT IMPLEMENTED
               dataMatrix = squeeze(dataMatrix(:,1,:));
           end

           if strcmpi(app.ThingToPlot.Value,'fft')
               %Removes incomplete iteration if present
               if any(app.ex.data.iteration ~= max(app.ex.data.iteration))
                   dataMatrix(:,end) = [];
               end
               %Can't plot without 1 complete iteration
               if size(dataMatrix,2) == 0
                   return
               end
           end

           switch lower(app.DisplayIterations.Value)
               case 'average'
                   %Whole data set
               case 'prior'
                   %If more than one iteration, remove current iteration
                   if size(dataMatrix,2) > 1
                    dataMatrix = dataMatrix(:,end-1);
                   end
               case 'current'
                   %Last data set
                   dataMatrix = dataMatrix(:,end);
               case 'custom'
                   %Goes from iteration start to iteration end
                   
                   if size(dataMatrix,2) < app.IterationStart.Value
                       %If start iteration is larger than current iteration, start iteration becomes current
                       startIt = size(dataMatrix,2);
                   else
                       startIt = app.IterationStart.Value;
                   end
                   if size(dataMatrix,2) < app.IterationEnd.Value
                       %If end iteration is larger than current iteration, end iteration becomes current
                       endIt = size(dataMatrix,2);
                   else
                       endIt = app.IterationEnd.Value;
                   end

                   dataMatrix = dataMatrix(:,startIt:endIt);
           end

           %Checks for data that has not yet been taken
           if any(dataMatrix == 0,"all") || any(isnan(dataMatrix),"all")
               if size(dataMatrix,2) > 1
                   %Performs "manual" averaging only counting non-zero/non-nan values
                   dataMatrix(isnan(dataMatrix)) = 0;
                   dataVector = sum(dataMatrix,2);
                   nPoints = sum(dataMatrix~=0,2);
                   dataVector = dataVector ./ nPoints;
               else
                   %Sets untaken data points to a baseline value
                   dataMatrix(isnan(dataMatrix)) = 0;
                   if std(dataMatrix(dataMatrix ~=0)) ~= 0
                       baselineValue = min(dataMatrix(dataMatrix ~=0)) - std(dataMatrix(dataMatrix ~=0));
                   else
                       baselineValue = 0;
                   end
                   dataMatrix(dataMatrix == 0) = baselineValue;
                   %Only 1 iteration, can be directly turned to data vector
                   dataVector = dataMatrix;
               end
           else %all data in target range has been taken
               %Take mean of remaining iterations
               dataVector = mean(dataMatrix,2);
           end

           %Gets x axis to use in scan
           if ~strcmpi(app.ThingToPlot.Value,'fft')
               if isa(scan.bounds,'cell')
                   scanBounds = scan.bounds{app.PulseNumToPlot.Value};
               else
                   scanBounds = scan.bounds;
               end               
               scanAxis = linspace(scanBounds(1),scanBounds(2),scan.nSteps);
           else
               %Takes fourier transform of data matrix
               dataVector =  abs(fftshift(fft(dataVector-mean(dataVector))));
               %Gets frequency step size
               %ONLY FOR 1D SCAN***
               frequencyStepSize = scan.stepSize(1)*1e-9;%Hz
               scanAxis = (1:(scan.nSteps-1)/2)/(frequencyStepSize*scan.nSteps);
               %Converts to larmor frequency
               if ~isempty(app.ex.SRS_RF)
                   scanAxis = scanAxis/(magnetStrength(app.ex.SRS_RF.frequency,'strength')*1e-4);%1e4 is gauss to tesla
               end
               scanAxis = scanAxis*1e-6;%MHz
           end

           %Incorporates offset
           if strcmpi(app.displayInfo.incorporateOffset(1),"true")
               scanAxis = scanAxis + app.displayInfo.offset(1);
           end

           %Either plots data or updates current plot
           if isempty(app.displayInfo.oneDPlot)
               app.displayInfo.oneDPlot = plot(app.OneDAxes,scanAxis,dataVector);
           else
               app.displayInfo.oneDPlot.XData = scanAxis;
               app.displayInfo.oneDPlot.YData = dataVector;
           end

           %Makes 1D plot visible
          app.displayInfo.oneDPlot.Visible = "on"; 

          % %creates three cells which are later used as indices when
          % %reducing the data to a single vector or matrix to plot.
          % %indices are based on the stepToView for each scan.
          % scansViewing = [app.xAxisScanNum.Value, app.yAxisScanNum.Value];
          % if min(scansViewing) ~= 0
          %    stepsBetweenViewing = {scan(min(scansViewing)+1:max(scansViewing)-1).stepToView};
          % else
          %    stepsBetweenViewing = {scan(1:max(scansViewing) - 1).stepToView};
          % end
          % stepsAfterViewing = {scan(max(scansViewing)+1:end).stepToView};
          % currentIteration = size(dataMatrix);
          % currentIteration = currentIteration(end);
          % 
          % if strcmpi(app.ThingToPlot.Value,'fft')
          %    %Checks if iteration is complete
          %      if any(app.ex.data.iteration ~= max(app.ex.data.iteration))
          %          %Removes ongoing iteration
          %          dataMatrix(:,end) = [];
          %          %FFT requires 1 complete iteration
          %          if isempty(dataMatrix)
          %              errordlg('Cannot plot fourier transform until at least one iteration is complete','No Iterations Complete')
          %              app.ThingToPlot.Value = 'Reference';
          %              DisplayUpdate(app)
          %              return
          %          end
          %      end
          % 
          %      if strcmpi(app.DisplayIterations.Value,'current')
          %          dataMatrix = dataMatrix(:,end);
          % 
          %      elseif strcmpi(app.DisplayIterations.Value,'custom')
          %          iterationBounds = cutsomBounds(app);
          %          dataMatrix = dataMatrix(:,min(iterationBounds):max(iterationBounds));
          %      end
          % 
          %      for ii = 1:width(dataMatrix)
          %          iterationFFT = abs(fftshift(fft(dataMatrix(:,ii)-mean(dataMatrix(:,ii)))));
          %          iterationFFT = iterationFFT(ceil((scan.nSteps+1)/2)+1:end);
          %          if ii == 1
          %              reducedData = iterationFFT;
          %          else
          %              reducedData = reducedData + iterationFFT;
          %          end
          %      end
          %      frequencyStepSize = scan.stepSize(1)*1e-9;%Hz
          %      scanAxis = (1:(scan.nSteps-1)/2)/(frequencyStepSize*scan.nSteps);
          %      if ~isempty(app.ex.SRS_RF)
          %          scanAxis = scanAxis/(magnetStrength(app.ex.SRS_RF.frequency,'strength')*1e-4);%1e4 is gauss to tesla
          %      else
          %          scanAxis = scanAxis/(magnetStrength(2.1,'strength')*1e-4);
          %      end
          %      scanAxis = scanAxis*1e-6;
          % else
          %    %reduces the data down to a plotable vector
          %    if strcmpi(app.DisplayIterations.Value,'average')
          %       reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},:);
          %       if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.dataType,'data analog')
          %          reducedData = reducedData.*sqrt(app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},:));
          %       end
          %       reducedData(reducedData==0) = NaN;
          %       reducedData = mean(reducedData,numel(size(dataMatrix)),'omitnan');
          %       reducedData(isnan(reducedData)) = 0;
          %    elseif strcmpi(app.DisplayIterations.Value,'prior') && any(app.ex.data.iteration >=2)
          %       reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},1:currentIteration-1);
          %       if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.dataType,'data analog')
          %          reducedData = reducedData.*sqrt(app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},1:currentIteration-1));
          %       end
          %       reducedData(reducedData==0) = NaN;
          %       reducedData = mean(reducedData,numel(size(dataMatrix)),'omitnan');
          %       reducedData(isnan(reducedData)) = 0;
          %    elseif strcmpi(app.DisplayIterations.Value,'custom')
          %       iterationBounds = cutsomBounds(app);
          %       reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},min(iterationBounds):max(iterationBounds));
          %       if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.dataType,'data analog')
          %          reducedData = reducedData.*sqrt(app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},min(iterationBounds):max(iterationBounds)));
          %       end
          %       reducedData(reducedData==0) = NaN;
          %       reducedData = mean(reducedData,numel(size(dataMatrix)),'omitnan');
          %       reducedData(isnan(reducedData)) = 0;
          % 
          %    else %Current iteration
          %       reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},currentIteration);
          %       if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.dataType,'data analog')
          %          reducedData = reducedData.*sqrt(app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},currentIteration));
          %       end
          %       reducedData(isnan(reducedData)) = 0;
          %    end
          % 
          %    if app.PulseNumToPlot.Value > numel(scan(max(scansViewing)).bounds)
          %       warndlg('Chosen pulse number is greater than number of pulses being scanned, plotting pulse 1','Cannot Plot Chosen Pulse Number')
          %       app.PulseNumToPlot.Value = 1;
          %    end
          %    scanBounds = scan(max(scansViewing)).bounds{app.PulseNumToPlot.Value};
          % 
          %    steps = scan(max(scansViewing)).nSteps;
          %    scanAxis = linspace(scanBounds(1),scanBounds(2),steps);
          %    if any(reducedData == 0)
          %       if std(reducedData(reducedData ~=0)) ~= 0
          %          baselineValue = min(reducedData(reducedData ~=0)) - std(reducedData(reducedData ~=0));
          %       else
          %          baselineValue = 0;
          %       end
          %       reducedData(reducedData == 0) = baselineValue;
          %    end
          % end
          % 
          % if strcmpi(app.displayInfo.incorporateOffset(1),"true")
          %    scanAxis = scanAxis + app.displayInfo.offset(1);
          % end
          % 
          % if strcmpi(app.displayInfo.incorporateOffset(2),"true")
          %    scanAxis = scanAxis + app.displayInfo.offset(1);
          % end
          % 
          % assignin("base","reducedData",reducedData)
          % assignin("base","scanAxis",scanAxis)
          % 
          % if isempty(app.displayInfo.oneDPlot)
          %    if app.xAxisScanNum.Value ~= 0
          %       app.displayInfo.oneDPlot = plot(app.OneDAxes,scanAxis,reducedData);
          %    else %plots with data on x axis
          %       app.displayInfo.oneDPlot = plot(app.OneDAxes,reducedData,scanAxis);
          %    end
          % 
          % else
          % 
          %    if app.xAxisScanNum.Value ~= 0
          %       app.displayInfo.oneDPlot.XData = scanAxis;
          %       app.displayInfo.oneDPlot.YData = reducedData;
          %    else
          %       app.displayInfo.oneDPlot.XData = reducedData;
          %       app.displayInfo.oneDPlot.YData = scanAxis;
          %    end
          % end
          % 
          % app.displayInfo.oneDPlot.Visible = "on"; %makes data visible
       end

       function twoDPlotUpdate(app,dataMatrix,scan)
          %Gets form of data that will be used to plot
          switch lower(app.DisplayIterations.Value)
             case 'average'
                reducedData = mean(dataMatrix,3);
             case 'custom'
                reducedData = dataMatrix(:,:,cutsomBounds(app));
                reducedData = mean(reducedData,3);
             case 'prior'
                reducedData = dataMatrix(:,:,1:end-1);
                reducedData = mean(reducedData,3);
             case 'current'
                reducedData = dataMatrix(:,:,end);
          end

          %Replaces data not yet acquired with baseline value
          if any(isnan(reducedData))
             baselineValue = min(reducedData,[],"all");
             if baselineValue < 0
                baselineValue = baselineValue*1.1;
             else
                baselineValue = baselineValue*.9;
             end
             reducedData(isnan(reducedData)) = baselineValue;
          end

          %Flips data if second axis is first
          if app.xAxisScanNum.Value < app.yAxisScanNum.Value
             reducedData = reducedData';
          end

          %Creates image or updates existing image
          if ~isempty(app.displayInfo.twoDPlot) && isfield(app.displayInfo.twoDPlot,'CData')
             app.displayInfo.twoDPlot.CData = reducedData;
          else
             app.displayInfo.twoDPlot = imagesc(app.TwoDAxes,reducedData);

             %Creates dummy surface to use for colorbar in other axes
             surf(app.ColorbarAxes, [0 1; 0 1], [0 1; 0 1], [0 0; 0 0], [0 1; 0 1], ...
                'EdgeColor', 'none', 'FaceColor', 'interp', 'Visible', 'off');

             % Add colorbar to dummy axes
             app.displayInfo.externalColorbar = colorbar(app.ColorbarAxes, 'Location', 'southoutside');

             % Hide the dummy image and axes ticks, leave only the colorbar visible
             app.displayInfo.externalColorbar.AxisLocation = 'out';  % Ensure colorbar is outside of dummy axes

             app.ColorbarAxes.Visible = 'off';        % Hide ticks, labels, box, etc.
             app.ColorbarAxes.XColor = 'none';        % No X axis line
             app.ColorbarAxes.YColor = 'none';        % No Y axis line
             app.ColorbarAxes.Box = 'off';            % No box
             app.ColorbarAxes.BackgroundColor = 'none';

          end

          %Matches color limits with actual plot
          app.ColorbarAxes.CLim = app.TwoDAxes.CLim;

          %Updates x and y data
          if isa(scan(app.xAxisScanNum.Value).bounds,'cell')
             %Gets bounds of pulse number to plot if
             %multiple sets of bounds
             if numel(scan(app.xAxisScanNum.Value).bounds) >= app.PulseNumToPlot.Value
             xScanBounds = scan(app.xAxisScanNum.Value).bounds{app.PulseNumToPlot.Value};
             else
                xScanBounds = scan(app.xAxisScanNum.Value).bounds{end};
             end
          else
             xScanBounds = scan(app.xAxisScanNum.Value).bounds;
          end
          xax = linspace(xScanBounds(1),xScanBounds(2),scan(app.xAxisScanNum.Value).nSteps);
          app.displayInfo.twoDPlot.XData = xax;

          if isa(scan(app.yAxisScanNum.Value).bounds,'cell')
             %Gets bounds of pulse number to plot if
             %multiple sets of bounds
             if numel(scan(app.yAxisScanNum.Value).bounds) >= app.PulseNumToPlot.Value
             yScanBounds = scan(app.yAxisScanNum.Value).bounds{app.PulseNumToPlot.Value};
             else
                yScanBounds = scan(app.yAxisScanNum.Value).bounds{end};
             end
             
          else
             yScanBounds = scan(app.yAxisScanNum.Value).bounds;
          end
          yax = linspace(yScanBounds(1),yScanBounds(2),scan(app.yAxisScanNum.Value).nSteps);
          app.displayInfo.twoDPlot.YData = yax;

          %Updates limits on axes
          halfStep = abs(xax(end)-xax(1))/(2*(numel(xax)-1));
          app.TwoDAxes.XLim = [min(xax)-halfStep max(xax)+halfStep];
          halfStep = abs(yax(end)-yax(1))/(2*(numel(yax)-1));
          app.TwoDAxes.YLim = [min(yax)-halfStep max(yax)+halfStep];

          if app.displayInfo.incorporateOffset(1) == "true"
             app.TwoDAxes.XLim = app.TwoDAxes.XLim + app.displayInfo.offset(1);
             app.displayInfo.twoDPlot.XData = app.displayInfo.twoDPlot.XData + app.displayInfo.offset(1);
          end
          if app.displayInfo.incorporateOffset(2) == "true"
             app.TwoDAxes.YLim = app.TwoDAxes.YLim + app.displayInfo.offset(2);
             app.displayInfo.twoDPlot.YData = app.displayInfo.twoDPlot.YData + app.displayInfo.offset(2);
          end
       end

       function dataMatrix = obtainDataMatrix(app)
          %Gives expected matrix of data that can be plotted

          %If plotting data points, no need to manipulate data obtained
          if strcmpi(app.ThingToPlot.Value,'datapoints')
             dataMatrix = app.ex.data.nPoints;
             return
          end

          %Gets data values from experiment object
          data = app.ex.data.values;

          %Subtracts baseline
          data = cellfun(@(d) d(d ~= 0)-app.BaselineSubtraction.Value,data,'UniformOutput',false);

          %Gives the data points that haven't been taken a value of 0 rather than being an empty vector
          emptyLocations = cellfun(@(x)isempty(x),data);
          [data{emptyLocations}] = deal([0 0]);

          %Determines function by which data can be obtained
          switch lower(app.ThingToPlot.Value)
             case {'contrast','fft'}
                %Obtains contrast depending on type
                switch lower(app.ContrastType.Value)
                   case 'ref - sig'
                      dataFunction = @(x)x(1)-x(2);
                   case '(ref - sig)/ref'
                      dataFunction = @(x)(x(1)-x(2))/x(1);
                   case '(ref - sig)/sig'
                      dataFunction = @(x)(x(1)-x(2))/x(2);
                   case '(ref-sig)/(ref+sig)'
                      dataFunction = @(x)(x(1)-x(2))/(x(1)+x(2));
                end
             case 'reference'
                dataFunction = @(x)x(1);
             case 'signal'
                dataFunction = @(x)x(2);
             case 'snr'
                data =@(x) (x(1)-x(2))/x(1)*sqrt(x(1));
          end

          %Applies function to extract relevant data then converts to matrix
          dataMatrix = cell2mat(cellfun(dataFunction,data,'UniformOutput',false));
       end

       function pulseSequencePlotUpdate(app)
          %creates plottable data from binary
          seq = app.ex.pulseBlaster.userSequence;
          xData = [];
          yData = [];
          for ii = 1:numel(seq)
             binChannels = seq(ii).channelsBinary;
             binChannels = binChannels(~isspace(binChannels));
             if ii == 1
                xData = 0;
             else
                xData = [xData,xData(end)+seq(ii-1).duration];
             end
             for jj = 1:numel(binChannels)
                yData(ii,jj) = str2double(binChannels(jj))+(jj-1)*2+.5; %#ok<*SAGROW>
             end
          end
          %adds last point in sequence
          xData = [xData,xData(end)+seq(end).duration];
          for jj = 1:numel(binChannels)
             yData(numel(seq)+1,jj) = str2double(binChannels(jj))+(jj-1)*2+.5; %#ok<*SAGROW>
          end
          %If there is no plot, makes a new plot and adjusts the axes labels. Otherwise just
          %updates the data
          if isempty(app.displayInfo.pulsePlot)
             app.displayInfo.pulsePlot = stairs(app.PulseSequenceAxes,xData,yData);

          else
             for ii = 1:numel(binChannels)
                app.displayInfo.pulsePlot(ii).XData = xData;
                app.displayInfo.pulsePlot(ii).YData = yData(:,ii);
                app.displayInfo.pulsePlot(ii).Visible = "on";
             end
          end
          %List of colors for pulse sequence line, in order:
          %green, blue, orange,purple,yellow,red
          colorList = [[0.4660, 0.6740, 0.1880]; [0, 0.4470, 0.7410];[0.8500, 0.3250, 0.0980];[0.4940, 0.1840, 0.5560];[0.9290, 0.6940, 0.1250];[0.6350, 0.0780, 0.1840]];
          for ii = 1:6
             app.displayInfo.pulsePlot(end-ii+1).Color = colorList(ii,:);
          end
          for ii = 1:numel(binChannels)
             app.displayInfo.pulsePlot(ii).LineWidth = 1;
          end
          app.PulseSequenceAxes.YTick = [1:numel(binChannels)].*2 -1; %#ok<NBRAK1>
          yLabels = [];
          for ii = 1:numel(binChannels)
             yLabelList = ["AOM","Data","Signal","RF","I","Q","windfreak RF"];
             yLabels = [yLabels,yLabelList(ii)];
          end
          app.PulseSequenceAxes.YTickLabel = fliplr(yLabels);
          app.PulseSequenceAxes.XLim = [0 max(app.displayInfo.pulsePlot(1).XData)];
       end

       function ScanUpdate(app)
          app.RandomizescanCheckBox.Value = app.ex.randomizeScanPoints;

          if ~isempty(app.ex.DAQ)
             app.DataType.Value = app.ex.DAQ.activeDataChannel;
          end
          app.maxFailedCollections.Value = app.ex.maxFailedCollections;
          app.forcedCollectionPauseTime.Value = app.ex.forcedCollectionPauseTime;

          if isempty(app.ex.scan(1).parameter)
             return
          end

          for ii = 1:numel(app.ex.scan)
             tablenSteps(ii) = [c2s(num2str(app.ex.scan(ii).nSteps))]';
             tableNotes(ii) = [c2s(app.ex.scan(ii).notes)]';
             tableInstruments(ii) = c2s(app.ex.scan(ii).identifier);
             tableType(ii) = c2s(app.ex.scan(ii).parameter);
             tablePulse(ii) = strjoin(string(app.ex.scan(ii).address), ',');
             tableStepSize(ii) = strjoin(string(app.ex.scan(ii).stepSize), ',');
             scanStarts = "";
             scanEnds = "";
             if isa(app.ex.scan(ii).bounds,'cell')
                for jj = 1:numel(app.ex.scan(ii).bounds)
                   scanStarts(jj) = string(app.ex.scan(ii).bounds{jj}(1));
                   scanEnds(jj) = string(app.ex.scan(ii).bounds{jj}(2));
                end
             else
                scanStarts = string(app.ex.scan(ii).bounds(1));
                scanEnds = string(app.ex.scan(ii).bounds(2));
             end
             tableStart(ii) = strjoin(scanStarts,',');
             tableEnd(ii) = strjoin(scanEnds,',');
          end

          app.ScanningTable.Data = [tableType', tableInstruments', tableStart', tableEnd', tablenSteps', tableStepSize', tablePulse',tableNotes'];

       end

       function StatusUpdate(app)
          switch app.scanStatus
             case {'running','continuous'}
                app.StatusLamp.Color = [0,1,0]; %green
             case 'reset'
                app.StatusLamp.Color = [0,0,1]; %dark blue
             case 'aborted'
                app.StatusLamp.Color = [1,0,0]; %red
             case 'aborting'
                app.StatusLamp.Color = [1,1,0]; %yellow
             case 'completed'
                app.StatusLamp.Color = [0,1,1]; %cyan
          end
       end

       function errorPresent = ErrorCheck(app)
          scan = app.ex.scan; %shorthand
          errorPresent = true;   

           %Check if scan is already running
          if strcmp(app.scanStatus,'running')
             errordlg('Cannot run experiment while optimization is running','Cannot Run Experiment')
             return
          end

          %BOX ADDRESS --> SAVE ADDRESS
          boxDir = dir(strcat(app.BoxAddress.Value,'\Backlund lab shared\code\Function Library'));
          if isempty(boxDir)
             errordlg('Current box address invalid. Go to General tab to fix','Invalid box address')
             return
          end

          %Checks if scan is the exact same settings as default. It is presumed that this scan is very unlikely to
          %actually be desired
          for ii = 1:numel(scan)
          if isa(scan(ii).bounds,'double') && strcmpi(scan(ii).parameter,"X") && scan(ii).bounds(1) == 1 && scan(ii).bounds(2) == 10 && scan(ii).nSteps == 10
             errordlg('Scan has not been changed from default. Go to scan tab to change','Default Scan')
             return
          end

          %Gets cell array with scan parameters and instruments
          scanParams = s2c({scan(ii).parameter});
          scanParams = cellfun(@(x)s2c(x),scanParams,'UniformOutput',false);
          scanInstruments = instrumentType.giveProperIdentifier({scan(ii).identifier});
          if isa(scanInstruments,'cell')
            scanInstruments = cellfun(@(x)s2c(x),scanInstruments,'UniformOutput',false);
          else
              scanInstruments = {scanInstruments};
          end

          %Checks if there are multiple of the same parameter present
          if numel(unique(scanParams)) ~= numel(scanParams)
             errordlg('Multiple scans with identical types detected. Go to scan tab to change','Replicate Scans')
             return
          end

          %If stage is being scanned or stage optimization is enabled
          if any(strcmpi(scanInstruments,'pi stage')) || app.StageAutoOptimization.Value

             %Make sure stage is present
             if isempty(app.ex.PIstage) ||~app.ex.PIstage.connected
                shouldLoadInstruments = uiconfirm(app.UIFigure,'Stage is not currently loaded. Would you like to load it or cancel measurement?',...
                   'Instruments unloaded','Icon','warning','Options',{'Load Stage','Cancel'});

                if strcmpi(shouldLoadInstruments,'cancel'),      return;     end

                StageLoadButtonPushed(app)
                appUpdate(app)
             end

             %Check for overlap between scan axes and optimization axes
             if ~isempty(app.ex.optimizationInfo.stageAxes) && any(ismember(scanParams,app.ex.optimizationInfo.stageAxes)) && app.StageAutoOptimization.Value
                proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while attempting stage scan of the same axis.' ...
                   'This is not recommended as the optimization process can affect the scan. Proceed anyways?'],...
                   'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});

                if strcmpi(proceedExperiment,'cancel'),      return;     end
             end
          end

          %Instruments must be present if they are being scanned
          if any(ismember(scanInstruments,'SRS RF')) && (isempty(app.ex.SRS_RF) || ~app.ex.SRS_RF.connected)
             shouldLoadInstruments = uiconfirm(app.UIFigure,'SRS is not currently loaded. Would you like to load it or cancel measurement?',...
                   'Instruments unloaded','Icon','warning','Options',{'Load SRS','Cancel'});
                if strcmpi(shouldLoadInstruments,'cancel'),      return;     end
                SRSRFLoadButtonPushed(app)
                appUpdate(app)
          end
          if any(ismember(scanInstruments,'WF RF')) && (isempty(app.ex.windfreak_RF) || ~app.ex.windfreak_RF.connected)
             shouldLoadInstruments = uiconfirm(app.UIFigure,'Windfreak RF is not currently loaded. Would you like to load it or cancel measurement?',...
                   'Instruments unloaded','Icon','warning','Options',{'Load WF','Cancel'});
                if strcmpi(shouldLoadInstruments,'cancel'),      return;     end
                WindfreakRFLoadButtonPushed(app)
                appUpdate(app)
          end
          if any(ismember(scanInstruments,'532 nm Laser')) && (isempty(app.ex.ndYAG) || ~app.ex.ndYAG.connected)
             shouldLoadInstruments = uiconfirm(app.UIFigure,'nd:YAG laser is not currently loaded. Would you like to load it or cancel measurement?',...
                   'Instruments unloaded','Icon','warning','Options',{'Load laser','Cancel'});
                if strcmpi(shouldLoadInstruments,'cancel'),      return;     end
                SproutLaserLoadButtonPushed(app)
                appUpdate(app)
          end
          if isempty(app.ex.pulseBlaster) || ~app.ex.pulseBlaster.connected
             shouldLoadInstruments = uiconfirm(app.UIFigure,'Pulse blaster is not currently loaded. Would you like to load it or cancel measurement?',...
                   'Instruments unloaded','Icon','warning','Options',{'Load PB','Cancel'});
                if strcmpi(shouldLoadInstruments,'cancel'),      return;     end
                PulseBlasterLoadButtonPushed(app)
                appUpdate(app)
          end
          if isempty(app.ex.DAQ) || ~app.ex.DAQ.connected
             shouldLoadInstruments = uiconfirm(app.UIFigure,'DAQ is not currently loaded. Would you like to load it or cancel measurement?',...
                   'Instruments unloaded','Icon','warning','Options',{'Load DAQ','Cancel'});
                if strcmpi(shouldLoadInstruments,'cancel'),      return;     end
                DAQLoadButtonPushed(app)
                appUpdate(app)
          end

          end

          %Scan requires pulse sequence
           if isempty(app.ex.pulseBlaster.userSequence)
              errordlg('No pulse sequence selected','No Pulse Sequence')
              return
           end            

          %If it reaches the end without triggering an error, set output to false
          errorPresent = false;
       end

       function newStructure = ModifyAllFields(~,oldStructure,fieldsToChange,fieldValues,address)
          %This function replaces holding all these settings in a variable
          %then changing them all at once to the structure. Instead it
          %changes each substructure one at a time
          %Honestly I don't think I would do it this way again if I had to
          %go back and redo it

          %It is necessary to input the fields to change not because matlab
          %cannot identify what fields are there, but because it needs to
          %match the field name to the new value to be input

          %An input of {'delete'} for the first cell in fieldValues input
          %will cause the function to delete the values of the address given

          if strcmp(fieldValues{1},'delete')
             oldStructure(address) = [];
             newStructure = oldStructure;
          else
             newStructure = struct2cell(oldStructure);%Create new structure based of the old
             namesList = fieldnames(oldStructure);%Create list of field names in order


             for ii = 1:numel(fieldsToChange)
                %Find where the field name of the structure is the field name
                %that has been input
                cellNumber = find(strcmp(fieldsToChange(ii),namesList));
                if isempty(cellNumber)
                   error('Invalid field name. Name must be one of the fields of the structure input')
                end
                %Take the old structure, replace the value based on given
                %address, then add it back into the new structure


                newValue = newStructure(cellNumber,1:numel(oldStructure));
                if address <= numel(newValue)
                   newValue(address+1:end+1) = newValue(address:end);
                end
                newValue{address} = fieldValues{ii};
                %Converts cell back to structure using the names found from the
                %input structure
                newStructure(cellNumber,1:numel(oldStructure)+1) = newValue;
             end
             newStructure = cell2struct(newStructure,namesList,1);
             % gets rid of an empty row of information that is present after
             %adding the first scan
             if isempty(getfield(newStructure(end), namesList{1})) %#ok<GFLD>
                newStructure(end) = [];
             end
          end

       end

       function newRootName = CheckFolder(~,oldRootName,folderName)
          %Updates what the new root name will be
          newRootName = strcat(oldRootName,'\',folderName);

          %Load the directory
          fileList = struct2cell(dir(oldRootName));
          fileList = fileList(1,:);%Only name row
          folderName = s2c(folderName);

          %Makes a new folder if one doesn't already exist
          fileExists = any(strcmpi(folderName,fileList),"all");
          if ~fileExists
             mkdir(oldRootName, folderName)
          end
       end

       function DataSave(app,rootSaveName)
          %Should be a single variable not part of a structure when saved
          data = app.ex.data;

          %Saves the data in a format readable by MatLab
          mSaveName = strcat(rootSaveName,'\', 'MatLab_Data.mat');
          save(mSaveName,"data")
          dataSettings = app.storedDataSettings;
          mSaveName = strcat(rootSaveName,'\', 'MatLab_Data_Settings.mat');
          save(mSaveName,"dataSettings")
          userNotes = app.SavedUserNotes.Value;
          mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
          save(mSaveName,"userNotes")

          %If saving as a tif is not selected, end the function here. I did
          %this instead of putting everything below in one massive if
          %statement because it makes code formatting nicer in my opinion
          uialert(app.UIFigure,'Data Saved!','Save Successful','Icon','success')

       end

       function SetupSave(app,rootSaveName)

          setupSettings = [];
          setupSettings.general = [];
          setupSettings.instruments = [];
          setupSettings.display = [];
          setupSettings.values = [];

          %StoredSetupSettings is dividied into general and insturments.
          %This is done beacuse the properites of the instruments need
          %to be loaded differnetly than the properties of the app

          displayCopy = {'offset','incorporateOffset','limitsIfOverriden','overrideLimits'};
          for ii = 1:numel(displayCopy)
             setupSettings.display.(displayCopy{ii}) = app.displayInfo.(displayCopy{ii});
          end

          valueCopy = {'PulseNumToPlot','IterationsType','MaxIterations'};
          for ii = 1:numel(valueCopy)
             setupSettings.values.(valueCopy{ii}) = app.(valueCopy{ii}).Value;
          end

          if ~isempty(app.ex)
             generalCopy = {'scan','optimizationInfo','randomizeScanPoints','useManualSteps','manualSteps','nPointsTolerance','forcedCollectionPauseTime','maxFailedCollections'};
             for ii = 1:numel(generalCopy)
                setupSettings.general.(generalCopy{ii}) = app.ex.(generalCopy{ii});
             end

             if ~isempty(app.ex.pulseBlaster)
                pulseCopy = {'userSequence','useTotalLoop','nTotalLoops'};
                for ii = 1:numel(pulseCopy)
                   setupSettings.instruments.pulseBlaster.(pulseCopy{ii}) = app.ex.pulseBlaster.(pulseCopy{ii});
                end
             end

             if ~isempty(app.ex.PIstage)
                stageCopy = {'tolerance','toleranceStandardDeviations','checkTolerance','pauseTime','absoluteTolerance'};
                for ii = 1:numel(stageCopy)
                   setupSettings.instruments.PIstage.(stageCopy{ii}) = app.ex.PIstage.(stageCopy{ii});
                end
             end

             if ~isempty(app.ex.DAQ)
                DAQCopy = {'differentiateSignal','activeDataChannel'};
                for ii = 1:numel(DAQCopy)
                   setupSettings.instruments.DAQ.(DAQCopy{ii}) = app.ex.DAQ.(DAQCopy{ii});
                end
             end

             if ~isempty(app.ex.SRS_RF)
                SRSCopy = {'enabled','frequency','amplitude','modulationEnabled','modulationType','modulationWaveform'};
                for ii = 1:numel(SRSCopy)
                   setupSettings.instruments.SRS_RF.(SRSCopy{ii}) = app.ex.SRS_RF.(SRSCopy{ii});
                end
             end

             if ~isempty(app.ex.windfreak_RF)
                windfreakCopy = {'enabled','frequency','amplitude','modulationEnabled','modulationType','modulationWaveform'};
                for ii = 1:numel(windfreakCopy)
                   setupSettings.instruments.windfreak_RF.(windfreakCopy{ii}) = app.ex.windfreak_RF.(windfreakCopy{ii});
                end
             end

             mSaveName = strcat(rootSaveName,'\', 'MatLab_Setup_Settings.mat');
             save(mSaveName,"setupSettings")
             userNotes = app.SavedUserNotes.Value;
             mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
             save(mSaveName,"userNotes")
             uialert(app.UIFigure,'Setup Saved!','Save Successful','Icon','success')
          end
       end

       function LoadPulseSequence(app, newSequence)
          if ~isempty(app.ex.pulseBlaster.userSequence)
             for ii = 1:numel(app.ex.pulseBlaster.userSequence)
                app.ex.pulseBlaster = deletePulse(app.ex.pulseBlaster,1);
             end
          end
          for ii = 1:numel(newSequence)
             pulseInfo = [];
             pulseInfo.numericalOutput = newSequence(ii).numericalOutput;
             pulseInfo.duration = newSequence(ii).duration;
             pulseInfo.directionType = newSequence(ii).directionType;
             pulseInfo.contextInfo = newSequence(ii).contextInfo;
             pulseInfo.notes = newSequence(ii).notes;
             app.ex.pulseBlaster = addPulse(app.ex.pulseBlaster,pulseInfo,ii);
          end

       end

       function LoadSavedSettings(app, instrument)
          %If there is stored settings for a given instrument due to
          %loading a previous setup, replace the default value with the
          %loaded value
          if isfield(app.storedSetupSettings,instrument)
             newPropNames = fieldnames(app.storedSetupSettings.(instrument));
             for ii = 1:numel(newPropNames)
                if c2s(newPropNames{ii} == "userSequence")
                   LoadPulseSequence(app,app.storedSetupSettings.pulseBlaster.userSequence)
                else
                   app.ex.(instrument).(newPropNames{ii}) = app.storedSetupSettings.(instrument).(newPropNames{ii});
                end
             end
          end
       end

       function instrumentOn = checkInstrumentStatus(app,propertyName)

          if isempty(app.ex.(propertyName)) || ~app.ex.(propertyName).connected
             instrumentOn = false;
          else
             instrumentOn = true;
          end
       end

       function abortScan(app)
          %necessary for scans lasting for a specified time. There might
          %be a better way to do this.
          app.scanStatus = 'aborted';
       end

       function chooseVisiblePlot(app)
          %Decides which plot should be visible based selection of what to plot and the number of scans present

          %Defaults to not having any of the axes visible
          %Note: this does not cause flickering due to all being in one function
          app.TwoDAxes.Visible = false;
          app.OneDAxes.Visible = false;
          app.PulseSequenceAxes.Visible = false;

          %Does the same but for the lines/images themselves
          for ii = 1:numel(app.displayInfo.pulsePlot)
             app.displayInfo.pulsePlot(ii).Visible = false;
          end
          app.displayInfo.twoDPlot.Visible = false;
          app.displayInfo.owoDPlot.Visible = false;

          %If pulse sequence is selected, check for existence of pulse sequence
          if strcmpi(app.ThingToPlot.Value, 'pulsesequence')
             if ~isempty(app.ex.pulseBlaster) && ~isempty(app.ex.pulseBlaster.userSequence)
                app.PulseSequenceAxes.Visible = true;
             end
             return
          end

          %Checks for presence of data
          if isempty(app.ex.data)
             return
          end

          %If 1 scan, set 1 scan to visible, otherwise set 2 scans to visible
          if numel(app.storedDataSettings.scan) == 1
             app.OneDAxes.Visible = true;
          else
             app.TwoDAxes.Visible = true;
          end
       end

       function AdjustPlotLimits(app)
          %adjusts the limits and the offset of the plot based on the
          %settings of the table on top of the output tab
          if isempty(app.ex.data) && app.PulseSequenceAxes.Visible ~= "on"
             return
          end
          if app.OneDAxes.Visible == "on"
             visiblePlot = 'oneDPlot';
             appVisPlot = 'OneDAxes';
             extraAxisLength = 0.1;
          elseif app.PulseSequenceAxes.Visible == "on"
             visiblePlot = 'pulsePlot';
             appVisPlot = 'PulseSequenceAxes';
             extraAxisLength = 0;
          elseif app.TwoDAxes.Visible == "on"
             visiblePlot = 'twoDPlot';
             appVisPlot = 'TwoDAxes';
             extraAxisLength = 0;
          else
             %no visible plots
             return
          end

          %Creates default limits
          if ~isempty(app.displayInfo.(visiblePlot))
             if strcmpi(visiblePlot,'PulsePlot')
                defaultLimits = [0, max(app.displayInfo.pulsePlot(1).XData); 0,numel(app.displayInfo.pulsePlot)*2];
             elseif app.TwoDAxes.Visible == "on"
                defaultLimits = [app.displayInfo.(visiblePlot).XData(1),app.displayInfo.(visiblePlot).XData(end);...
                   app.displayInfo.(visiblePlot).YData(1),app.displayInfo.(visiblePlot).YData(end)];
             elseif app.xAxisScanNum.Value ~= 0 && ~strcmpi(visiblePlot,'PulsePlot')
                dataRange = abs(app.displayInfo.(visiblePlot).YData(end) - app.displayInfo.(visiblePlot).YData(1));

                defaultLimits = [min(app.displayInfo.(visiblePlot).XData), max(app.displayInfo.(visiblePlot).XData);
                   min(app.displayInfo.(visiblePlot).YData) - extraAxisLength*dataRange, max(app.displayInfo.(visiblePlot).YData)+extraAxisLength*dataRange];

                if defaultLimits(2,1) == 0 && defaultLimits(2,2) == 0
                   app.defaultLimits(2,2) = 1;
                end

             else %data on x axis for 1D plot
                dataRange = max(app.displayInfo.(visiblePlot).XData) - min(app.displayInfo.(visiblePlot).XData);
                defaultLimits = [min(app.displayInfo.(visiblePlot).XData) - extraAxisLength*dataRange, max(app.displayInfo.(visiblePlot).XData)+extraAxisLength*dataRange;
                   min(app.displayInfo.(visiblePlot).YData), max(app.displayInfo.(visiblePlot).YData)];
             end
          end

          %Checks if x limits should be overriden, then updates x
          %limits
          if strcmpi(app.displayInfo.overrideLimits{1},"true")
             app.(appVisPlot).XLim = app.displayInfo.limitsIfOverriden{1};
             if app.displayInfo.incorporateOffset(1) == "true"
                app.(appVisPlot).XLim = app.(appVisPlot).XLim + app.displayInfo.offset(1);
             end
          else
             app.(appVisPlot).XLim = [defaultLimits(1,1),defaultLimits(1,2)];
          end

          %Repeat above for y
          if strcmpi(app.displayInfo.overrideLimits{2},"true")
             app.(appVisPlot).YLim = app.displayInfo.limitsIfOverriden{2};
             if app.displayInfo.incorporateOffset(2) == "true"
                app.(appVisPlot).YLim = app.(appVisPlot).XLim + app.displayInfo.offset(2);
             end
          else
             app.(appVisPlot).YLim = [defaultLimits(2,1),defaultLimits(2,2)];
          end


       end

       function UpdatePlotlabel(app)
          if ~isempty(app.storedDataSettings) && isfield(app.storedDataSettings,'scan')
             if app.OneDAxes.Visible == "on"
                if app.xAxisScanNum.Value ~= 0
                   parameter = app.storedDataSettings.scan(app.xAxisScanNum.Value).parameter;
                   scanAxis = 'XLabel';
                   dataAxis = 'YLabel';
                   app.OneDAxes.Title.String = app.storedDataSettings.scan(app.xAxisScanNum.Value).notes;
                else
                   parameter = app.storedDataSettings.scan(app.yAxisScanNum.Value).parameter;
                   scanAxis = 'YLabel';
                   dataAxis = 'XLabel';
                   app.OneDAxes.Title.String = app.storedDataSettings.scan(app.yAxisScanNum.Value).notes;
                end

                app.OneDAxes.Title.FontSize = 16;

                if numel(app.OneDAxes.Title.String) > 75
                   app.OneDAxes.Title.HorizontalAlignment = 'left';
                   app.OneDAxes.Title.Position = [1.6 5.1872e-04 0];
                end

                parameter = strcat(' ',parameter);
                if any(strcmpi(parameter,'x'))
                   app.OneDAxes.(scanAxis).String = 'Stage X (μm)';
                elseif any(strcmpi(parameter,'y'))
                   app.OneDAxes.(scanAxis).String = 'Stage Y (μm)';
                elseif any(strcmpi(parameter,'z'))
                   app.OneDAxes.(scanAxis).String = 'Stage Z (μm)';
                elseif strcmpi(parameter,'frequency')
                   app.OneDAxes.(scanAxis).String = 'RF Frequency (GHz)';
                elseif strcmpi(parameter,'amplitude')
                   app.OneDAxes.(scanAxis).String = 'RF Amplitude (dBm)';
                elseif strcmpi(parameter,'duration')
                   app.OneDAxes.(scanAxis).String = 'Pulse Duration (ns)';
                end
                if ~strcmpi(app.ThingToPlot.Value,'pulsesequence')
                   app.OneDAxes.(dataAxis).String = app.ThingToPlot.Value;
                   if strcmpi(app.ThingToPlot.Value,'fft')
                      app.OneDAxes.XLabel.String = 'γ/2π (MHz/T)';
                      app.OneDAxes.YLabel.String = 'Intensity (a.u.)';
                   end
                   if any(strcmpi(app.ThingToPlot.Value,{'reference','signal'}))
                      if strcmpi(app.DataType.Value, 'Data analog')
                         app.OneDAxes.(dataAxis).String = strcat(app.OneDAxes.(dataAxis).String,' (V)');
                      else
                         app.OneDAxes.(dataAxis).String = strcat(app.OneDAxes.(dataAxis).String,' (counts)');
                      end
                   end
                end

             end
          else
             %Continuous collection labels
             if app.OneDAxes.Visible == "on"
                app.OneDAxes.XLabel.String = "Data Point";
                app.OneDAxes.YLabel.String = app.ThingToPlot.Value;
                if strcmpi(app.ThingToPlot.Value,'fft')
                   app.OneDAxes.XLabel.String = 'γ/2π (MHz/T)';
                   app.OneDAxes.YLabel.String = 'Intensity (a.u.)';
                end
                if strcmpi(app.DataType.Value, 'Data analog') && ~strcmpi(app.ThingToPlot.Value,'contrast')
                   app.OneDAxes.YLabel.String = strcat(app.OneDAxes.YLabel.String,' (V)');
                else
                   app.OneDAxes.YLabel.String = strcat(app.OneDAxes.YLabel.String,' (counts)');
                end
             end
          end

       end

       function iterationBounds = cutsomBounds(app)
          if app.IterationStart.Value > max(app.ex.data.iteration)
             errtitle = sprintf('Cannot Plot Iteration %d',app.IterationStart.Value);
             warndlg('First iteration bound is greater than the number of iterations, changing to last iteration',errtitle)
             iterationBounds(1) = max(app.ex.data.iteration);
             app.IterationStart.Value = iterationBounds(1);
          else
             iterationBounds(1) = app.IterationStart.Value;
          end
          if app.IterationEnd.Value > max(app.ex.data.iteration)
             errtitle = sprintf('Cannot Plot Iteration %d',app.IterationEnd.Value);
             warndlg('Second iteration bound is greater than the number of iterations, changing to last iteration',errtitle)
             iterationBounds(2) = max(app.ex.data.iteration);
             app.IterationEnd.Value = iterationBounds(2);
          else
             iterationBounds(2) = app.IterationEnd.Value;
          end
       end

       function templateStruct = convertTable2Struct(~,table)
          templateStruct = [];
          for ii = 1:length(table)
             if contains(table(ii,1),"Start")
                table(ii,1) = "scanStart";
             elseif contains(table(ii,1),"End")
                table(ii,1) = "scanEnd";
             elseif contains(table(ii,1),"Step Size")
                table(ii,1) = "scanStepSize";
             else
                switch lower(string(table(ii,1)))
                   case "rf frequency"
                      table(ii,1) = "RFResonanceFrequency";
                   case {"collection duration (0 default)","collection duration","collection duration 0 default"}
                      table(ii,1) = "collectionDuration";
                   case "collection buffer"
                      table(ii,1) = "collectionBufferDuration";
                   case "repolarization duration"
                      table(ii,1) = "repolarizationDuration";
                   case "data on buffer"
                      table(ii,1) = "dataOnBuffer";
                   case "extra buffer"
                      table(ii,1) = "extraBuffer";
                   case "intermission buffer"
                      table(ii,1) = "intermissionBufferDuration";
                   case "aom compensation"
                      table(ii,1) = "AOMCompensation";
                   case "rf ramp time"
                      table(ii,1) = "RFRampTime";
                   case "sequence time per data point"
                      table(ii,1) = "sequenceTimePerDataPoint";
                   case "pi time"
                      table(ii,1) = "piTime";
                   case {"iq pre-buffer","iq pre buffer"}
                      table(ii,1) = "IQPreBuffer";
                   case {"iq post-buffer", "iq post buffer"}
                      table(ii,1) = "IQPostBuffer";
                   case "sets of xyn"
                      table(ii,1) = "setsXYN";
                   case "n xy"
                      table(ii,1) = "nXY";
                   case "tau duration"
                      table(ii,1) = "tauDuration";
                   case "rf 1 frequency"
                      table(ii,1) = "RF1ResonanceFrequency";
                   case "tau time"
                      table(ii,1) = "tauTime";
                   case "rf2 duration"
                      table(ii,1) = "RF2Duration";
                   case "n rf2 pulses"
                      table(ii,1) = "nRF2Pulses";
                   case "rf 2 frequency"
                      table(ii,1) = "RF2Frequency";
                   case "use compensating pulses"
                      table(ii,1) = "useCompensatingPulses";
                end
             end
             templateStruct.(table(ii,1)) = str2double(table(ii,2));

          end

          if isfield(templateStruct,'scanStart') && isfield(templateStruct,'scanEnd') && isfield(templateStruct,'scanStepSize')
             templateStruct.scanBounds = [templateStruct.scanStart,templateStruct.scanEnd];
             templateStruct.scanNSteps = (templateStruct.scanEnd-templateStruct.scanStart)/templateStruct.scanStepSize +1;
             if floor(templateStruct.scanNSteps) ~= templateStruct.scanNSteps
                templateStruct.scanNSteps = round(templateStruct.scanNSteps);
                templateStruct.scanStepSize = (templateStruct.scanEnd - templateStruct.scanStart)/(templateStruct.scanNSteps-1);
                warndlg('Steps size resulted in a fractional number of steps, adjusting step size','Number of Steps Must be a Whole Number')
             end
          end

          if isfield(templateStruct,'IQPreBuffer') && isfield(templateStruct,'IQPostBuffer')
             templateStruct.IQBuffers = [templateStruct.IQPreBuffer,templateStruct.IQPostBuffer];
             templateStruct = rmfield(templateStruct,{'IQPreBuffer','IQPostBuffer'});
          end
       end

       function iterations = checkIterations(app)
          %Gets maximum iterations 
          %Depends on whether iteration type is set to number, time, or perpetual
          if strcmpi(app.IterationsType.Value,'number')
             iterations = app.MaxIterations.Value;
          elseif strcmpi(app.IterationsType.Value,'time (min)')
             collectionTimer = timer;
             collectionTimer.StartDelay = 60*app.MaxIterations.Value;
             collectionTimer.TimerFcn = abortScan(app);
             iterations = Inf;
          else
             %If iterations is set to perpetual,uses one as a default value.
             % The "for while" will keep repeaing until the abort button is stopped
             iterations = Inf;
          end
       end

       function UpdateScanTime(app)

          %If there is no scan, there is no way to estimate scan timing
          if isempty(app.ex.scan) || isempty(app.ex.scan(1).nSteps)
             return
          end

          %If there has been at least 1 completed scan, use the actual average time to complete each scan, otherwise
          %estimate based on sequence duration, collection pause time, number of steps, and a fudge factor
          %The totalCompleted will not yet include the current scan
          if numel(app.scanStartTime) > 1
             totalCompletedRunTime = minutes(app.scanStartTime{end}-app.scanStartTime{1});
             timePerScan = totalCompletedRunTime ./ (numel(app.scanStartTime)-1);
          else
             stepDuration = app.ex.pulseBlaster.sequenceDurations.sent.totalSeconds + app.ex.forcedCollectionPauseTime*1.5;
             stepDuration  = (stepDuration + .2)/60;%.2 is fudge factor for computational processing
             timePerScan = prod([app.ex.scan.nSteps])*stepDuration;
             totalCompletedRunTime = 0;
          end

          %Gets the parameters relevant for display
          totalTime = timePerScan*app.MaxIterations.Value;
          currentScanRunTime = minutes(datetime-app.scanStartTime{end});
          totalCompletedRunTime = totalCompletedRunTime + currentScanRunTime;

          %Displays info for current scan as well as total
          if timePerScan/60 > 2
             app.scanTime.Text{1} = sprintf('Current Scan Time: %.1f /%.1f hours',currentScanRunTime/60,timePerScan/60);
          else
             app.scanTime.Text{1} = sprintf('Current Scan Time: %.1f /%.1f minutes',currentScanRunTime,timePerScan);
          end

          if totalTime/60 > 2
             app.scanTime.Text{2} = sprintf('Total Scan Time: %.1f /%.1f hours',totalCompletedRunTime/60,totalTime/60);
          else
             app.scanTime.Text{2} = sprintf('Total Scan Time: %.1f /%.1f minutes',totalCompletedRunTime,totalTime);
          end

       end
    end

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFunction(app)
           %Creates experiment object

           app.StageConfig.Value = 'PI_stage';
           app.PulseBlasterConfig.Value = 'pulse_blaster_default';
           app.DAQConfig.Value = 'daq_6361';
           app.SRSRFConfig.Value = 'SRS_RF';
           app.WindfreakRFConfig.Value = 'windfreak_RF';
           app.SproutLaserConfig.Value = 'laser_532';

           app.UserName.Items = app.userList;
           app.UserName2.Items = app.userList;
           app.UIFigure.Units = "normalized";


           app.SaveFolderName.Value = [];
           app.UserName.Value = 'Common';
           app.SavedUserNotes.Value = '';
           app.StageAddress.Value = double(1);

           app.ex.scan.address = [];
           app.ex.scan.nSteps = [];
           app.ex.scan.parameter = [];
           app.ex.scan.bounds = [];
           app.ex.scan.stepSize = [];
           app.ex.scan.identifier = [];
           app.ex.scan.stepToView = [];
           app.ex.scan.notes = [];
           app.ex.randomizeScanPoints = 0;

           app.storedDataSettings = [];

           app.storedSetupSettings = [];

           app.performScanReset = true;

           app.displayInfo.squareRatio = 0;
           app.displayInfo.dataSelection = 'Average';
           app.displayInfo.limitsIfOverriden = {[0,100];[0,100];[0,100]};
           app.displayInfo.overrideLimits = {"false","false","false"};
           app.displayInfo.staticColorLimits = [0 1];
           app.displayInfo.useStaticColorLimits = 0;
           app.displayInfo.offset = [0,0,0];
           app.displayInfo.incorporateOffset = ["false","false","false"];
           app.displayInfo.xLines = [];
           app.Numberofmanualoptimizations.Value = 1;


           app.displayInfo.pulsePlot = [];
           app.displayInfo.oneDPlot = [];
           app.displayInfo.twoDPlot = [];
           app.PulseBoxAddress.Value = app.BoxAddress.Value;

           appUpdate(app)

      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
           %Runs scan

           % profile on -memory

           %Checks for errors that would prevent scan function and ends start if they are present
           if ErrorCheck(app),      return;     end

           %Checks whether data should be reset
           continuePreviousScan = false;%default to reset
           if  ~any(strcmpi(app.scanStatus,{'aborting','aborted','completed'}))
              %Resets data if no data present
              app.ex = resetAllData(app.ex,[0,0]);
           else
              %Query user on erasing old data
              if ~isempty(app.ex.data)
                 answer = questdlg('Erase old data or average with new data?','Data present','Erase old data','Average with new data','Cancel','Cancel');
                 if strcmpi(answer,'cancel')
                    return
                 elseif strcmpi(answer,'erase old data')
                    app.ex = resetAllData(app.ex,[0,0]);
                 else %average with new
                    %Check if all steps of all iterations are already complete
                    if ~all(cell2mat(app.ex.odometer) == [app.ex.scan.nSteps]) || app.MaxIterations.Value > app.ex.data.nIteration(end)
                       continuePreviousScan = true;
                    else
                       warndlg('All iterations already complete','Already Done')
                       return
                    end
                 end
              end
           end

           %Set scan status
           app.scanStatus = 'running';
           StatusUpdate(app)

           %Save current settings as stored data settings to use for plotting
           app.storedDataSettings.scan = app.ex.scan;
           app.storedDataSettings.dataType = app.ex.DAQ.activeDataChannel;
           app.storedDataSettings.baselineSubtraction = app.BaselineSubtraction.Value;

           %Finds max iterations
           maxIterations = checkIterations(app);

           %Empties scan start times list
           app.scanStartTime = {};

           %While loops for iterations
           while any(app.ex.data.iteration ~= maxIterations)

              %Adds current scan start time to list and updates displayed info
              app.scanStartTime{end+1} = datetime;
              UpdateScanTime(app)

              %Resets scan to starting point unless continuing previous scan
              if ~continuePreviousScan
                 app.ex = resetScan(app.ex);
              else
                 continuePreviousScan = false;
              end

              %While loop for data points within an iteration
              while ~all(cell2mat(app.ex.odometer) == [app.ex.scan.nSteps])

                 %Checks for stage optimization
                 if app.StageAutoOptimization.Value == 1 && ~isempty(app.ex.PIstage) && app.ex.PIstage.connected == 1
                    [app.ex,doOptimization] = checkOptimization(app.ex);
                    if doOptimization
                       %Create a message box that shows optimization is running, then close it after completion
                       runningOptAlert = msgbox(app.UIFigure,'Beginning stage optimization','Beginning Optimization');
                       app.ex = stageOptimization(app.ex);
                       close(runningOptAlert)
                    end
                 end

                 %Attempts to take data. This often fail for a variety of reasons, most often incorrectly setting the
                 %data type
                 try
                    app.ex = takeNextDataPoint(app.ex,'pulse sequence');
                 catch
                    warndlg('Could not collect data, try changing data collection type','Collection Falied')
                    app.scanStatus = 'aborted';
                    StatusUpdate(app)
                    return
                 end

                 %Updates the plots using new data
                 DisplayUpdate(app)

                 %Checks if scan status is aborting or aborted to end the current scan
                 if any(strcmpi(app.scanStatus,{'aborted','aborting'}))
                    app.scanStatus = 'aborted';
                    app.performScanReset = false;
                    StatusUpdate(app)
                    return
                 end

                 %Updates scan timing info
                 UpdateScanTime(app)
              end

              %Updates maximum iteration
              setMaxIterations = checkIterations(app);
              if setMaxIterations < any(app.ex.data.iteration)
                 errordlg('The set number of iterations cannont be lower than the number of iterations already complete','Cannot Decrease Iteration Number')
                 app.MaxIterations.Value = maxIterations;
              else
                 maxIterations = setMaxIterations;
              end
           end

           % profile off
           % profile viewer

           %Sets scan to completed and refreshes app
           app.scanStatus = 'completed';
           appUpdate(app);
      end

      % Button pushed function: AbortButton
      function AbortButtonPushed(app, event)

           if strcmpi(app.scanStatus,'running')  || strcmp(app.scanStatus,'continuous')
              app.scanStatus = 'aborting';
              app.abortTime = datetime;
           elseif strcmpi(app.scanStatus,'aborting')
              warndlg('Already aborting. To force reset, wait 10 seconds then press reset','Already Aborting')
              app.abortTime = datetime;
           else
              app.scanStatus = 'aborted';
              % profile off
           end
           StatusUpdate(app);
      end

      % Button pushed function: StageUnload
      function StageUnloadButtonPushed(app, event)
           %Unloads stage by running CleanUp function then updates the app
           disconnect(app.ex.PIstage);
           appUpdate(app);
      end

      % Button pushed function: RefreshAppButton
      function RefreshAppButtonPushed(app, event)
           %Refreshes all panels of the app
           appUpdate(app);
      end

      % Cell edit callback: ScanningTable
      function ScanningTableCellEdit(app, event)
           if strcmpi(app.scanStatus,'running')
              errordlg('Cannot edit scan while running, abort scan to continue','Scan Running')
              ScanUpdate(app)
              return
           end
           rowNum = event.Indices(1);
           newData = event.NewData;

           if isa(app.ex.scan(rowNum).bounds,"double")
              boundsToUse = {app.ex.scan(rowNum).bounds};
           else
              boundsToUse = app.ex.scan(rowNum).bounds;
           end


           switch event.Indices(2)
              case 1 %type
                 switch lower(newData)
                    case {'stage x','x'}
                       app.ex.scan(rowNum).identifier = 'stage';
                       app.ex.scan(rowNum).parameter = "x";
                    case {'stage y','y'}
                       app.ex.scan(rowNum).identifier = 'stage';
                       app.ex.scan(rowNum).parameter = "y";
                    case {'stage z','z'}
                       app.ex.scan(rowNum).identifier = 'stage';
                       app.ex.scan(rowNum).parameter = "z";
                    case {'rf','rf frequency','frequency','f','freq','odmr'}
                       if ~strcmpi(app.ex.scan(rowNum).identifier,'windfreak_RF')
                          app.ex.scan(rowNum).identifier = 'SRS_RF';
                       end
                       app.ex.scan(rowNum).parameter = "frequency";
                    case {'rf amplitude','amplitude','amp'}
                       if ~strcmpi(app.ex.scan(rowNum).identifier,'windfreak_RF')
                          app.ex.scan(rowNum).identifier = 'SRS_RF';
                       end
                       app.ex.scan(rowNum).parameter = "amplitude";
                    case {'pulse blaster','pb','pulse','duration','pulse duration','dur'}
                       app.ex.scan(rowNum).identifier = 'Pulse Blaster';
                       app.ex.scan(rowNum).parameter = 'duration';
                    case {'blank','nothing''dummy','empty','none','na'}
                       app.ex.scan(rowNum).parameter = 'blank';
                       app.ex.scan(rowNum).identifier = 'blank';
                    case {'laser','power','pow','ndyag'}
                       app.ex.scan(rowNum).identifier ='ndYAG';
                       app.ex.scan(rowNum).parameter = 'set power';
                       
                 end
                 if ~any(strcmpi(newData,{'pulse blaster','pb','pulse','duration','pulse duration','dur'}))
                    if isa(app.ex.scan(rowNum).bounds,'cell')
                       app.ex.scan(rowNum).bounds = app.ex.scan(rowNum).bounds{1};
                    end
                 end
              case 2
                 switch lower(newData)
                    case {'stage','s'}
                       app.ex.scan(rowNum).identifier = 'stage';
                       if ~any(strcmpi(app.ex.scan(rowNum).parameter,{'y','z'}))
                          app.ex.scan(rowNum).parameter = "x";
                       end
                    case {'srs','srsrf','srs rf','srs_rf'}
                       app.ex.scan(rowNum).identifier = 'SRS_RF';
                       if ~strcmpi(app.ex.scan(rowNum).parameter,'amplitude')
                          app.ex.scan(rowNum).parameter = 'frequency';
                       end
                    case {'wf','windfreak','windfreakrf','windfreak rf','windfreak_rf'}
                       app.ex.scan(rowNum).identifier = 'windfreak_RF';
                       if ~strcmpi(app.ex.scan(rowNum).parameter,'amplitude')
                          app.ex.scan(rowNum).parameter = 'frequency';
                       end
                    case {'pulse blaster','pulseblaster','pb'}
                       app.ex.scan(rowNum).identifier = 'Pulse Blaster';
                       app.ex.scan(rowNum).parameter = 'duration';
                    case {'blank','nothing''dummy','empty','none','na'}
                       app.ex.scan(rowNum).parameter = 'blank';
                       app.ex.scan(rowNum).identifier = 'blank';
                    case {'laser','power','pow','ndyag'}
                       app.ex.scan(rowNum).identifier ='ndYAG';
                       app.ex.scan(rowNum).parameter = 'set power';
                 end
                 if ~any(strcmpi(newData,{'pulse blaster','pulseblaster','pb'}))
                    if isa(app.ex.scan(rowNum).bounds,'cell')
                       app.ex.scan(rowNum).bounds = app.ex.scan(rowNum).bounds{1};
                    end
                 end
              case {3,4}
                 %start or end
                 %TO DO: ensure scans where start is less than end work
                 %properly
                 %Check old data and compare to new. If multiple of one
                 %number, prompt user on whether they want to change it all
                 %Compare to app.ex.scan vals
                 %Interpret input and old scan info into simple double

                 if ~isa(boundsToUse,'cell')
                    if event.Indices(2) == 3 %start
                       boundsToUse(1) = newData;
                    else%end
                       boundsToUse(2) = newData;
                    end
                 else %multiple bounds
                    %Split input based on commas and spaces and convert to vector
                    vectorBound = split(c2s(newData),[", ",","," ","  ","   "]);
                    vectorBound = str2double(vectorBound(vectorBound ~= ""));

                    %Get previous bounds and convert to vector for start/end
                    for ii = 1:numel(boundsToUse)
                       oldVectorBound(ii,:) = boundsToUse{ii};
                    end
                    if event.Indices(2) == 3
                       oldVectorBound = oldVectorBound(:,1);
                    else
                       oldVectorBound = oldVectorBound(:,2);
                    end

                    %Propogates changes where applicable
                    if numel(oldVectorBound) == numel(vectorBound)

                       % Find indices where values differ
                       changeIdx = find(oldVectorBound ~= vectorBound);
                       oldChangedVals = oldVectorBound(changeIdx);
                       newChangedVals = vectorBound(changeIdx);

                       userCheck = false;
                       doOverride = false;
                       gaveWarning = false;
                       for ii = 1:numel(oldChangedVals)

                          % Check for duplicates
                          if sum(oldVectorBound == oldChangedVals(ii)) > 1
                             if ~userCheck
                                % Prompt user with checkbox-style dialog
                                choice = questdlg( ...
                                   'Duplicate values detected. Propagate changes to all matching entries?', ...
                                   'Propagate Changes', ...
                                   'Yes', 'No', 'No');

                                doOverride = strcmp(choice, 'Yes');
                                userCheck = true;
                             end

                             if doOverride
                                allNewVals = unique(newChangedVals(oldChangedVals == oldChangedVals(ii)));
                                if numel(allNewVals) > 1
                                   if ~gaveWarning
                                      warning('Multiple different new values detected for one old value. Skipping automatic propagation for these values.');
                                      gaveWarning = true;
                                   end
                                   continue;  % Skip this propagation to avoid conflict
                                end

                                % Propagate the first detected change to all duplicates
                                firstChangeVal = newChangedVals(ii);
                                vectorBound(oldVectorBound == oldChangedVals(ii)) = firstChangeVal;
                             end
                          end
                       end
                    end

                    %Changes bounds based on processed input
                    if event.Indices(2) == 3
                       bondLoc = 1;
                    else
                       bondLoc = 2;
                    end
                    for ii = 1:numel(vectorBound)
                       boundsToUse{ii}(bondLoc) = vectorBound(ii);
                    end

                 end

                 % if event.Indices(2) == 3
                 %    scanStarts = split(c2s(newData),[", ",","," "]);
                 %    scanStarts = scanStarts(scanStarts ~= "");
                 %    for ii = 1:numel(scanStarts)
                 %       boundsToUse{ii}(1) = str2double(scanStarts(ii));
                 %    end
                 % else
                 %    scanEnds = split(c2s(newData),[", ",","," "]);
                 %    scanEnds = scanEnds(scanEnds ~= "");
                 %    for ii = 1:numel(scanEnds)
                 %       boundsToUse{ii}(2) = str2double(scanEnds(ii));
                 %    end
                 % end

                 %app.ex.scan(rowNum).bounds


                 if strcmpi(app.DefaultScanChange.Value,'Step size') || numel(app.ex.scan(rowNum).address) > 1
                    %Change step size while keeping number of steps constant
                    for ii = 1:numel(boundsToUse)
                       app.ex.scan(rowNum).stepSize(ii) = (boundsToUse{ii}(2) - boundsToUse{ii}(1))./(app.ex.scan(rowNum).nSteps-1);
                    end
                 else
                    %Change number of steps while keeping step size as close
                    %as possible to the original

                    scanStart = boundsToUse{1}(1);
                    scanEnd = boundsToUse{1}(2);
                    stepChecker = (scanEnd - scanStart) ./ app.ex.scan(rowNum).stepSize + 1;

                    if stepChecker <= 0
                       errordlg('Number of steps became negative, changing step size instead')
                       app.ex.scan(rowNum).stepSize = (scanEnd - scanStart) ./ (app.ex.scan(rowNum).nSteps - 1);
                    elseif stepChecker == floor(stepChecker)
                       app.ex.scan(rowNum).nSteps = stepChecker;
                    else
                       stepChecker = round(stepChecker);
                       app.ex.scan(rowNum).nSteps = stepChecker;
                       app.ex.scan(rowNum).stepSize = (scanEnd - scanStart) ./ (app.ex.scan(rowNum).nSteps-1);
                       % changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.ex.scan(rowNum).stepSize);
                       % warndlg(changeWarning,'Altered Step Size')
                    end

                 end

              case 5 % # of steps
                 if str2double(newData) == floor(str2double(newData)) && str2double(newData) > 0
                    app.ex.scan(rowNum).nSteps = str2double(newData);
                 else
                    errordlg('Number of steps must be a whole number','Invalid # of Steps')
                 end
                 for ii = 1:numel(boundsToUse)
                    app.ex.scan(rowNum).stepSize(ii) = (boundsToUse{ii}(2) - boundsToUse{ii}(1)) ./ (app.ex.scan(rowNum).nSteps-1);
                 end
              case 6 %step size
                 scanSteps = split(c2s(newData),[", ",","," "]);
                 scanSteps = str2double(scanSteps(scanSteps ~= ""));
                 addressNum = numel(app.ex.scan(rowNum).address);
                 if numel(scanSteps) > addressNum && addressNum ~= 0
                    warndlg('Number of step sizes is greater than the number of addresses being scanned, deleting last items in list','Too Many Step Sizes');
                 end
                 if numel(scanSteps) == 1 %#ok<*ISCL> %adujusts number of steps when the step size is changed
                    stepChecker = (boundsToUse{1}(2) - boundsToUse{1}(1)) ./ str2double(newData) + 1;
                    if  stepChecker <= 0
                       errordlg('Number of steps must be a whole number, step size must have corresponding sign','Invalid Step Size')
                    elseif stepChecker == round(stepChecker)
                       app.ex.scan(rowNum).nSteps = stepChecker;
                       app.ex.scan(rowNum).stepSize = str2double(newData);
                    else
                       stepChecker = round(stepChecker);
                       app.ex.scan(rowNum).nSteps = stepChecker;
                       app.ex.scan(rowNum).stepSize = (boundsToUse{1}(2) - boundsToUse{1}(1)) ./ (app.ex.scan(rowNum).nSteps-1);
                       changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.ex.scan(rowNum).stepSize);
                       warndlg(changeWarning,'Altered Step Size')
                    end
                 else % If there are multiple addresses being scanned, automatically sets step size based on nsteps and bounds
                    for ii = 1:addressNum
                       app.ex.scan(rowNum).stepSize(ii) = (boundsToUse{ii}(2) - boundsToUse{ii}(1)) ./ (app.ex.scan(rowNum).nSteps-1);
                    end
                    warndlg('Step size cannot be set when scanning multiple addresses, change bounds or number of steps instead','Cannot Change Step Size')
                 end
              case 7 %pulse addresses
                 addresses = split(newData,[", ",","," "]);
                 addresses = addresses(addresses ~= "");
                 app.ex.scan(rowNum).address = str2double(addresses);
              case 8
                 app.ex.scan(rowNum).notes = newData;

           end

           if isa(app.ex.scan(rowNum).bounds,"double")
              [app.ex.scan(rowNum).bounds] = deal(boundsToUse);
           else
              app.ex.scan(rowNum).bounds = boundsToUse;
           end

           % for ii = 1:numel(app.ex.scan)
           %     boundsNum = numel(app.ex.scan(ii).bounds);
           %     addressNum = numel(app.ex.scan(ii).address);
           %     stepsNum = numel(app.ex.scan(ii).stepSize);
           %     if addressNum > boundsNum
           %         for jj = 1:(addressNum-boundsNum)
           %             app.ex.scan(ii).bounds(boundsNum+jj) = app.ex.scan(ii).bounds(1);
           %         end
           %     elseif addressNum < boundsNum
           %         warndlg('Sets of bounds is greater than the number of addresses, deleting last set','Too Many Bounds')
           %         app.ex.scan(ii).bounds = app.ex.scan(ii).bounds(1:addressNum);
           %     end
           %     if addressNum > stepsNum
           %         for jj = 1:(addressNum-stepsNum)
           %             app.ex.scan(ii).stepSize(stepsNum+jj) = app.ex.scan(ii).stepSize(1);
           %         end
           %     elseif addressNum<stepsNum
           %         app.ex.scan(ii).stepSize = app.ex.scan(ii).stepSize(1:addressNum);
           %     end
           % end

           ScanUpdate(app)
           OutputSettingsUpdate(app)

      end

      % Button pushed function: DeleteScanStep
      function DeleteScanStepPushed(app, event)
           if strcmpi(app.scanStatus,'running')
              errordlg('Cannot edit scan while running, abort scan to continue','Scan Running')
              return
           end
           address = app.ScanAddress.Value;
           currentLength = length([app.ex.scan.parameter]);

           if address > currentLength
              errordlg('Selected address does not exist','Invalid Address')
           elseif address == currentLength && address == 1
              errordlg('Cannot delete the last step','Cannot Delete')
           else
              fieldsToChange = {'parameter','bounds','nSteps','stepSize','identifier','address','stepToView','notes'};
              fieldValues = {'delete'};
              app.ex.scan = ModifyAllFields(app,app.ex.scan,fieldsToChange,fieldValues,address);
           end

           ScanUpdate(app)
           % OutputSettingsUpdate(app) %Needed for step to view selection and the like
      end

      % Button pushed function: ResetButton
      function ResetButtonPushed(app, event)

           if strcmp(app.scanStatus,'running') || strcmp(app.scanStatus,'continuous')
              errordlg('Abort what is currently running before attempting to reset','Abort First')
              return
           elseif  strcmp(app.scanStatus,'aborting') && seconds(datetime-app.abortTime) < 10
              errmsg = sprintf('Wait %d more seconds then press again to force reset',10-round(seconds(datetime-app.abortTime)));
              errordlg(errmsg,'Wait For Forced Reset')
              return
           end
           app.scanStatus = 'reset';
           for ii = 1:numel(app.ex.scan) %Is this needed?**
              app.ex.scan(ii).completed = 0;
           end
           % profile off
           app.abortTime = [];
           StatusUpdate(app)
      end

      % Cell edit callback: ScanStepTable
      function ScanStepTableCellEdit(app, event)
           newData = str2double(event.NewData);
           if isnan(newData)
              OutputSettingsUpdate(app)
              errordlg('Step to view must be a number','Invalid Step')
              return
           end
           if newData <= app.storedDataSettings.scan(event.Indices(1)).nSteps && newData > 0 && floor(newData) == newData
              app.storedDataSettings.scan(event.Indices(1)).stepToView = newData;
           else
              errordlg('Step to view must be an integer between 1 and total number of steps inclusive','Invalid Step')
           end
           OutputSettingsUpdate(app)
      end

      % Value changed function: RandomizescanCheckBox
      function RandomizescanCheckBoxValueChanged(app, event)
           app.ex.randomizeScanPoints = app.RandomizescanCheckBox.Value;
           ScanUpdate(app)
           OutputSettingsUpdate(app)
      end

      % Value changed function: ForcesquareaspectratioCheckBox
      function ForcesquareaspectratioCheckBoxValueChanged(app, event)
           app.displayInfo.squareRatio = app.ForcesquareaspectratioCheckBox.Value;
           OutputSettingsUpdate(app)
      end

      % Value changed function: BoxAddress
      function BoxAddressValueChanged(app, event)
           app.PulseBoxAddress.Value = app.BoxAddress.Value;
           SaveDataUpdate(app)
           PulseSequenceUpdate(app)
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)

           if strcmp(app.DataSetupsSwitch.Value,'Data') && (isempty(app.ex.data) || isempty(app.ex))
              errordlg('No data to save','Save Unsuccessful')
              return
           end

           %**rootSaveName now changed in 2 parts to allow proper
           %assessment of box address. Also 'Backlund lab shared' should
           %be a variable created in startup
           rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared');

           %Checks if the directory is valid
           setupDirectory = struct2cell(dir(rootSaveName));
           if isempty(setupDirectory)
              errordlg('Current box address invalid','Invalid box address')
              return
           end

           rootSaveName = strcat(rootSaveName,'\data\',app.UserName.Value);

           rootSaveName = CheckFolder(app,rootSaveName,'Experiment Editor');

           rootSaveName = CheckFolder(app,rootSaveName,app.DataSetupsSwitch.Value);

           if isempty(app.SaveFolderName.Value)
              errordlg('Need a folder name to save (save name box)','Cannot Save')
              return
           end

           %Checks if the given folders are present and makes them if they
           %aren't. Data folders are first sorted by date and then by name
           %while setup folders are the reverse
           if strcmp(app.DataSetupsSwitch.Value,'Data')
              rootSaveName = CheckFolder(app,rootSaveName,string(datetime('today','Format','yyyy-MM-dd')));
              rootSaveName = CheckFolder(app,rootSaveName,app.SaveFolderName.Value);
           else

              rootSaveName = CheckFolder(app,rootSaveName,app.SaveFolderName.Value);

              versionDirectory = struct2cell(dir(rootSaveName));
              versionDirectory = versionDirectory(1,:);%Only name row
              if numel(versionDirectory) == 2
                 versionNumber = 1;
              else
                 versionDirectory = versionDirectory(:,3:end);
                 versionDirectory = cellfun(@(a)a(2:end-13),versionDirectory,'UniformOutput',false);
                 versionNumber = max(str2double(versionDirectory))+1;
              end
              newName = sprintf('v%d - %s',versionNumber,string(datetime('today','Format','yyyy-MM-dd')));
              rootSaveName = CheckFolder(app,rootSaveName,newName);
           end

           %At this point, the root name is the exact folder the files will
           %be saved in
           %Two functions are done here to make the code more legible

           if strcmp(app.DataSetupsSwitch.Value,'Data')
              DataSave(app,rootSaveName)
           else
              SetupSave(app,rootSaveName)
           end

           SaveDataUpdate(app)


      end

      % Cell edit callback: PlotLimits
      function PlotLimitsCellEdit(app, event)
           rowNum = event.Indices(1);
           switch event.Indices(2)
              case 2
                 if app.displayInfo.incorporateOffset(rowNum) == "true"
                    newData = str2double(event.NewData)- app.displayInfo.offset(rowNum);
                 else
                    newData = str2double(event.NewData);
                 end
                 if isnan(newData)
                    errordlg('Lower limit value must be a number')
                    return
                 end
                 if newData > app.displayInfo.limitsIfOverriden{rowNum}(2)
                    errordlg('Invalid minimum. Must be less than maximum.','Invalid Minimum')
                    OutputSettingsUpdate(app)
                    return
                 end
                 app.displayInfo.limitsIfOverriden{rowNum}(1) = newData;

              case 3
                 if app.displayInfo.incorporateOffset(rowNum) == "true"
                    newData = str2double(event.NewData)- app.displayInfo.offset(rowNum);
                 else
                    newData = str2double(event.NewData);
                 end
                 if isnan(newData)
                    errordlg('Upper limit value must be a number')
                    return
                 end
                 if newData < app.displayInfo.limitsIfOverriden{rowNum}(1)
                    errordlg('Invalid maximum. Must be greater than minimum.','Invalid maximum')
                    OutputSettingsUpdate(app)
                    return
                 end
                 app.displayInfo.limitsIfOverriden{rowNum}(2) = newData;

              case 4
                 if any(strcmpi(event.NewData,{'true','on','yes','t','1'}))
                    app.displayInfo.overrideLimits{rowNum} = "true";
                 else
                    app.displayInfo.overrideLimits{rowNum}  = "false";
                 end
              case 5
                 newData = str2double(event.NewData);
                 if isnan(newData)
                    errordlg('Offset value must be a number')
                    OutputSettingsUpdate(app)
                    return
                 end
                 app.displayInfo.offset(rowNum) = newData;
              case 6
                 if any(strcmpi(event.NewData,{'true','on','yes','t','1'}))
                    app.displayInfo.incorporateOffset(rowNum) = "true";
                 else
                    app.displayInfo.incorporateOffset(rowNum)  = "false";
                 end
           end

           OutputSettingsUpdate(app)
      end

      % Value changed function: DisplayIterations
      function DisplayIterationsValueChanged(app, event)
           app.displayInfo.dataSelection = app.DisplayIterations.Value;
           OutputSettingsUpdate(app)
      end

      % Cell edit callback: StaticColorLimits
      function StaticColorLimitsCellEdit(app, event)
           newData = str2double(event.NewData);%Can fail
           if isnan(newData)
              errordlg('Invalid input. Must be a number','Invalid Input')
              OutputSettingsUpdate(app)
              return
           end
           if event.Indices(1) == 1 && newData >= app.displayInfo.staticColorLimits(2)
              errordlg('Invalid input. Minimum must be less than maximum','Invalid Input')
              OutputSettingsUpdate(app)
              return
           elseif event.Indices(1) == 2 && newData <= app.displayInfo.staticColorLimits(1)
              errordlg('Invalid input. Maximum must be more than minimum','Invalid Input')
              OutputSettingsUpdate(app)
              return
           end
           app.displayInfo.staticColorLimits(event.Indices(1)) = newData;

           OutputSettingsUpdate(app)
      end

      % Value changed function: UseStaticColorLimits
      function UseStaticColorLimitsValueChanged(app, event)
           app.displayInfo.useStaticColorLimits = app.UseStaticColorLimits.Value;
           app.displayInfo.overrideLimits{3,2} = app.displayInfo.useStaticColorLimits;
           app.displayInfo.alteredLimits = true;
           OutputSettingsUpdate(app)
      end

      % Button pushed function: LoadButton
      function LoadButtonPushed(app, event)


           %When loading data, overwrite necessary output tab settings (i.e. scan type)
           if any(strcmpi(app.scanStatus,{'running','aborting'}))
              errordlg('Cannot load setup or data while a scan is running','Scan Running')
           end

           if any(strcmpi(app.LoadDates.Value,{'setups dates','data dates'})) || any(strcmpi(app.LoadNames.Value,{'data names','setups names'}))
              errordlg('please select a file version','No Version Selected')
              return
           end

           rootLoadName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value);
           if strcmp(app.DataSetupsSwitch.Value,'Data')
              rootLoadName = strcat(rootLoadName,'\Experiment Editor\Data');
              %Makes the root now include the date then name
              rootLoadName = strcat(rootLoadName,'\', app.LoadDates.Value,'\',app.LoadNames.Value);

              loadName = strcat(rootLoadName,'\', 'MatLab_Data.mat');
              load(loadName,"data")
              loadName = strcat(rootLoadName,'\', 'MatLab_Data_Settings.mat');
              load(loadName,"dataSettings")

              app.ex.data = data;
              app.storedDataSettings  = dataSettings;

              SaveDataUpdate(app)
              DisplayUpdate(app)

              uialert(app.UIFigure,'Data Loaded!','Save Successful','Icon','success')

           else %Setups
              rootLoadName = strcat(rootLoadName,'\Experiment Editor\Setups');
              %Makes the root now include the name then date
              rootLoadName = strcat(rootLoadName,'\', app.LoadNames.Value,'\',app.LoadDates.Value);

              loadName = strcat(rootLoadName,'\', 'MatLab_Setup_Settings');
              load(loadName,"setupSettings")

              %Loads the saved properties that don't require connection
              %to an instruments (scans, optimizationInfo etc.)
              generalNames = fieldnames(setupSettings.general);
              for ii = 1:numel(generalNames)
                 app.ex.(generalNames{ii}) = setupSettings.general.(generalNames{ii});
              end
              if isfield(setupSettings,'display')
                 displayNames = fieldnames(setupSettings.display);
                 for ii = 1:numel(displayNames)
                    app.displayInfo.(displayNames{ii}) = setupSettings.display.(displayNames{ii});
                 end
              end
              if isfield(setupSettings,'values')
                 valueNames = fieldnames(setupSettings.values);
                 for ii = 1:numel(valueNames)
                    app.(valueNames{ii}).Value = setupSettings.values.(valueNames{ii});
                 end
              end

              %Loads the saved properties associated with each instrument that is connected. If the instrument isn't connected,
              %store the saved information in storedSetupSettings, which are loaded when the instrument is connected.
              if ~isempty(setupSettings.instruments)
                 instrumentNames = fieldnames(setupSettings.instruments);
                 for ii = 1:numel(instrumentNames)
                    if ~isempty(app.ex.(instrumentNames{ii})) && app.ex.(instrumentNames{ii}).connected == 1
                       propNames = fieldnames(setupSettings.instruments.(instrumentNames{ii}));
                       for jj = 1:numel(propNames)
                          %special exception made for userSequence
                          %because it's not an editable property
                          if c2s(propNames{jj}) == "userSequence"
                             LoadPulseSequence(app,setupSettings.instruments.pulseBlaster.userSequence)
                          else
                             app.ex.(instrumentNames{ii}).(propNames{jj}) = setupSettings.instruments.(instrumentNames{ii}).(propNames{jj});
                          end
                       end
                    else
                       app.storedSetupSettings.(instrumentNames{ii}) = setupSettings.instruments.(instrumentNames{ii});
                    end
                 end
              end

              appUpdate(app)

              uialert(app.UIFigure,'Setup Loaded!','Save Successful','Icon','success')

           end


      end

      % Button pushed function: ScreenshotButton
      function ScreenshotButtonPushed(app, event)
           %Saves the screenshot to current date folder on box for the
           %individual

           %This is the name for the data folder for this app for this person
           rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value);

           rootSaveName = CheckFolder(app,rootSaveName,'Experiment Editor');

           rootSaveName = CheckFolder(app,rootSaveName,'Screenshots');

           %Load the directory
           fileList = struct2cell(dir(rootSaveName));

           %If no folders appear, the box address is almost certainly the
           %issue
           if isempty(fileList)
              errordlg('Invalid box address. Change on the Save/Load tab','Invalid Box Address')
              return
           end

           %Finds current date
           currentDate = string(datetime('today','Format','yyyy-MM-dd'));

           %Check to see if the folder in question exists, then, if not, make
           %one. Update root save name to include new folder
           fileExists = any(strcmp(currentDate,fileList),"all");

           if ~fileExists
              mkdir(rootSaveName,currentDate)
           end
           rootSaveName = strcat(rootSaveName,'\',currentDate);

           fileName = replace(app.SaveFolderName.Value," ","_");
           %Check to see if the file in question already exists
           fileList = struct2cell(dir(rootSaveName));
           fileList = fileList(1,:); %only name row
           fileExists = contains(fileList,fileName);

           if ~any(fileExists,'all')
              %No file with this name exists, so it is saved just with the
              %base name
              fullSaveName = strcat(rootSaveName,'\',fileName);
           elseif sum(fileExists,"all") == 1
              %Only 1 file with the name exists so the next one is saved with
              %a 2
              fullSaveName = strcat(rootSaveName,'\',fileName,' 2');
           else
              versionNumber = cellfun(@(a)str2double(a(numel(fileName)+2:end-4)),fileList,'UniformOutput',false);
              versionNumber = max(cell2mat(versionNumber(cellfun(@(a)~isnan(a),versionNumber))))+1;
              fullSaveName = sprintf('%s%s %d',strcat(rootSaveName,'\'),fileName,versionNumber);
           end

           %Takes a screenshot of the app's current screen
           if strcmpi(app.SaveFolderName.Value,'')
              try
                 exportapp(app.UIFigure,strcat(fullSaveName,'MatLab_App_Screenshot.png'))
              catch
                 errordlg('Unable to take screenshot','Screenshot Failed')
                 return
              end
           else
              try
                 exportapp(app.UIFigure,strcat(fullSaveName,'.png'))
              catch
                 errordlg('Unable to take screenshot','Screenshot Failed')
                 return
              end
           end

           uialert(app.UIFigure,'Screenshot Saved!','Screenshot Successful','Icon','success')

      end

      % Button pushed function: CollectdatacontinuouslyButton
      function CollectdatacontinuouslyButtonPushed(app, event)


           if isempty(app.ex.pulseBlaster) || isempty(app.ex.pulseBlaster.userSequence)
              warndlg('Pulse sequence needed for continuous collection','No Pulse Sequence')
              return
           elseif isempty(app.ex.DAQ) || app.ex.DAQ.connected == 0
              warndlg('connect DAQ to collect data','DAQ not connected')
              return
           end

           if any(strcmpi(app.scanStatus,{'running','aborting'}))
              warndlg('Cannot collect continuoisly while scan is running. Abort scan to contiue.','Scan Running')
              return
           elseif strcmpi(app.scanStatus,'aborted')
              answer = questdlg('Continuing will erase old data.','Data Present','Continue','Cancel','Cancel');
              if strcmpi(answer,'cancel')
                 return
              end
           end

           if ~isempty(app.storedDataSettings) && isfield(app.storedDataSettings,'scan')
              %field removed to make plot labels adjust properly,
              %doesn't actually delete scan
              app.storedDataSettings = rmfield(app.storedDataSettings,'scan');
           end

           app.storedDataSettings.dataType = 'data analog';
           app.scanStatus = 'continuous';
           StatusUpdate(app)

           dataPoints = app.NumberOfPointsForContCollection.Value;
           iteration = 1;

           app.ex.data = [];
           app.ex.data.iteration(1:dataPoints) = 0;
           for ii = 1:dataPoints
              app.ex.data.values{ii,1} = [0,0];
           end
           app.ex.data.nPoints(1:dataPoints,1) = 0;
           app.ex.data.failedPoints = [];
           app.ex.odometer = {1};


           while strcmpi(app.scanStatus, 'continuous')
              for ii = 1:dataPoints
                 % try
                 [app.ex,data,nPoints] =  getData(app.ex,'pulse sequence');
                 % catch
                 %     warndlg('Could not collect data, try changing data collection type','Collection Falied')
                 %     app.scanStatus = 'aborted';
                 %     StatusUpdate(app)
                 %     return
                 % end
                 app.ex.data.values{ii,iteration} = data;
                 app.ex.data.iteration(ii) = app.ex.data.iteration(ii) + 1;
                 app.ex.data.nPoints(ii,iteration) = nPoints;
                 DisplayUpdate(app)
                 if any(strcmpi(app.scanStatus,{'aborted','aborting'}))
                    app.scanStatus = 'aborted';
                    app.performScanReset = false;
                    StatusUpdate(app)
                    return
                 end
              end
              iteration = iteration +1;
           end


      end

      % Button pushed function: CopyappobjecttoworkspaceButton
      function CopyappobjecttoworkspaceButtonPushed(app, event)
           %Makes a copy of the current app in the workspace. Mainly for
           %debugging
           assignin("base","app",app)
      end

      % Button pushed function: StageLoad
      function StageLoadButtonPushed(app, event)
           try
              app.ex = loadInstruments(app.ex,'PIstage',app.StageConfig.Value,false);
           catch
              errordlg('Unable to connect to PI stage','Connection Failed')
              return
           end
           LoadSavedSettings(app,'PIstage')
           appUpdate(app)
      end

      % Button pushed function: PulseBlasterLoad
      function PulseBlasterLoadButtonPushed(app, event)
           try
              app.ex = loadInstruments(app.ex,'pulseBlaster',app.PulseBlasterConfig.Value,false);
           catch
              errordlg('Unable to connect to Pulse Blaster','Connection Failed')
              return
           end
           app.ex.pulseBlaster.useTotalLoop = true;
           LoadSavedSettings(app,'pulseBlaster')
           appUpdate(app)
      end

      % Button pushed function: PulseBlasterUnload
      function PulseBlasterUnloadButtonPushed(app, event)
           disconnect(app.ex.pulseBlaster);
           appUpdate(app)
      end

      % Button pushed function: SproutLaserLoad
      function SproutLaserLoadButtonPushed(app, event)
           try
              app.ex = loadInstruments(app.ex,'ndYAG',app.SproutLaserConfig.Value);
           catch
              errordlg('Unable to connect to laser','Connection Failed')
              return
           end
           appUpdate(app)
      end

      % Button pushed function: SproutLaserUnload
      function SproutLaserUnloadButtonPushed(app, event)
           %Most of this should be in a disconnect fucntion in the laser
           %class but this shoud work.
           if ~isempty(app.ex.ndYAG)
              if ~app.ex.ndYAG.connected
                 return
              end
              if ~isempty(app.ex.ndYAG.handshake)
                 app.ex.ndYAG.handshake = [];
              end
              app.ex.ndYAG.connected = false;
              delete(app.ex.ndYAG)%Turns the laser off when unloaded if that box is checked
           end
           appUpdate(app)
      end

      % Button pushed function: SRSRFLoad
      function SRSRFLoadButtonPushed(app, event)
           try
              disp(numel(app.ex.instrumentIdentifiers))
              app.ex = loadInstruments(app.ex,'SRS_RF',app.SRSRFConfig.Value,false);
              disp(numel(app.ex.instrumentIdentifiers))
           catch
              errordlg('Unable to connect to SRS RF','Connection Failed')
              return
           end
           LoadSavedSettings(app,'SRS_RF')
           appUpdate(app)
      end

      % Button pushed function: SRSRFUnload
      function SRSRFUnloadButtonPushed(app, event)
           disconnect(app.ex.SRS_RF);
           appUpdate(app)
      end

      % Button pushed function: WindfreakRFLoad
      function WindfreakRFLoadButtonPushed(app, event)
           try
              app.ex = loadInstruments(app.ex,'windfreak_RF',app.WindfreakRFConfig.Value);
           catch
              errordlg('Unable to connect to windfreak RF','Connection Failed')
              return
           end
           LoadSavedSettings(app,'windfreak_RF')
           appUpdate(app)
      end

      % Button pushed function: WindfreakRFUnload
      function WindfreakRFUnloadButtonPushed(app, event)
           disconnect(app.ex.windfreak_RF);
           appUpdate(app)
      end

      % Button pushed function: DAQLoad
      function DAQLoadButtonPushed(app, event)
           try
              app.ex = loadInstruments(app.ex,'DAQ',app.DAQConfig.Value);
           catch
              errordlg('Unable to connect to DAQ','Connection Failed')
              return
           end
           LoadSavedSettings(app,'DAQ')
           appUpdate(app)
      end

      % Button pushed function: DAQUnload
      function DAQUnloadButtonPushed(app, event)
           disconnect(app.ex.DAQ);
           appUpdate(app)
      end

      % Value changed function: OffWhenDeleted
      function OffWhenDeletedValueChanged(app, event)
           %offWhenDeleted is whether the laser should turn off when its object is deleted
           if ~checkInstrumentStatus(app,'ndYAG'), return; end

           app.ex.ndYAG.offWhenDeleted = app.OffWhenDeletedValueChanged.Value;
           GeneralUpdate(app)
      end

      % Value changed function: WaitForLaserEquilibrium
      function WaitForLaserEquilibriumValueChanged(app, event)
           %waitForEquilibrium is a looping function that waits until the actual power read off the laser equals the set
           %power
           if ~checkInstrumentStatus(app,'ndYAG'), return; end

           app.ex.ndYAG.waitForEquilibrium = app.WaitForLaserEquilibriumValueChanged.Value;
           GeneralUpdate(app)
      end

      % Value changed function: SetLaserPower
      function SetLaserPowerValueChanged(app, event)
           % setPower changes the current power setting of the laser
           if ~checkInstrumentStatus(app,'ndYAG'), return; end

           app.ex.ndYAG.setPower = app.SetLaserPower.Value;
           GeneralUpdate(app)
      end

      % Value changed function: ToggleLaser
      function ToggleLaserValueChanged(app, event)
           %Turns laser on or off
           if ~checkInstrumentStatus(app,'ndYAG'), return; end

           if strcmpi(app.SetLaserPowerValueChanged.Value,'on')
              app.ex.ndYAG.enabled = true;
           else
              app.ex.ndYAG.enabled = false;
           end
           GeneralUpdate(app)
      end

      % Button pushed function: RefreshLaserPowerReading
      function RefreshLaserPowerReadingButtonPushed(app, event)
           %Obtains the current output reading of the laser
           if ~checkInstrumentStatus(app,'ndYAG'), return; end

           app.ex.ndYAG = queryActualPower(app.ex.ndYAG);
           app.LaserPowerReading.Value = app.ex.ndYAG.actualPower;
           GeneralUpdate(app)
      end

      % Value changed function: DifferentiatesignalandreferenceCheckBox
      function DifferentiatesignalandreferenceCheckBoxValueChanged(app, event)
           %Toggles differentiation between signal and reference. If s/r differentiation is off, taking data will be more
           %efficient but unable to produce contrast as only a single value will be generated
           if ~checkInstrumentStatus(app,'DAQ'), return; end

           app.ex.DAQ.differentiateSignal = app.DifferentiatesignalandreferenceCheckBox.Value;
           GeneralUpdate(app)
      end

      % Value changed function: XPosition
      function XPositionValueChanged(app, event)
           %If stage is loaded, moves stage to desired absolute position
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = absoluteMove(app.ex.PIstage,'x',app.XPosition.Value);
           PositionUpdate(app)
      end

      % Value changed function: YPosition
      function YPositionValueChanged(app, event)
           %If stage is loaded, moves stage to desired absolute position
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = absoluteMove(app.ex.PIstage,'y',app.YPosition.Value);
           PositionUpdate(app)
      end

      % Value changed function: ZPosition
      function ZPositionValueChanged(app, event)
           %If stage is loaded, moves stage to desired absolute position
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = absoluteMove(app.ex.PIstage,'z',app.ZPosition.Value);
           PositionUpdate(app)
      end

      % Button pushed function: XDown
      function XDownButtonPushed(app, event)
           %If stage is loaded, moves stage by negative increment value
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = relativeMove(app.ex.PIstage,'x',-app.XIncrement.Value);
           PositionUpdate(app)
      end

      % Button pushed function: YDown
      function YDownButtonPushed(app, event)
           %If stage is loaded, moves stage by negative increment value
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = relativeMove(app.ex.PIstage,'y',-app.YIncrement.Value);
           PositionUpdate(app)
      end

      % Button pushed function: ZDown
      function ZDownButtonPushed(app, event)
           %If stage is loaded, moves stage by negative increment value
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = relativeMove(app.ex.PIstage,'z',-app.ZIncrement.Value);
           PositionUpdate(app)
      end

      % Button pushed function: XUp
      function XUpButtonPushed(app, event)
           %If stage is loaded, moves stage by positive increment value
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = relativeMove(app.ex.PIstage,'x',app.XIncrement.Value);
           PositionUpdate(app)
      end

      % Button pushed function: YUp
      function YUpButtonPushed(app, event)
           %If stage is loaded, moves stage by positive increment value
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = relativeMove(app.ex.PIstage,'y',app.YIncrement.Value);
           PositionUpdate(app)
      end

      % Button pushed function: ZUp
      function ZUpButtonPushed(app, event)
           %If stage is loaded, moves stage by positive increment value
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = relativeMove(app.ex.PIstage,'z',app.ZIncrement.Value);
           PositionUpdate(app)
      end

      % Button pushed function: XFineReset
      function XFineResetPushed(app, event)
           %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = fineReset(app.ex.PIstage,'x','middle');
           PositionUpdate(app)
      end

      % Button pushed function: YFineReset
      function YFineResetButtonPushed(app, event)
           %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = fineReset(app.ex.PIstage,'y','middle');
           PositionUpdate(app)
      end

      % Button pushed function: ZFineReset
      function ZFineResetButtonPushed(app, event)
           %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage = fineReset(app.ex.PIstage,'z','middle');
           PositionUpdate(app)
      end

      % Value changed function: StageTolerance
      function StageToleranceValueChanged(app, event)
           %If stage is loaded, changes maximum tolerance for stage movements i.e. how far can the stage's measured
           %distance be from the target location
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage.tolerance = app.StageTolerance.Value;
           PositionUpdate(app)
      end

      % Value changed function: StageSettlingTime
      function StageSettlingTimeValueChanged(app, event)
           %If stage is loaded, changes time stage pauses for every movement
           if ~checkInstrumentStatus(app,'PIstage'), return; end

           app.ex.PIstage.pauseTime = app.StageSettlingTime.Value;
           InfrequentUpdate(app)
      end

      % Button pushed function: AddOptimizationStep
      function AddOptimizationStepPushed(app, event)
           %Adds additional step whose settings can then be altered in the optimization table
           % If the Addrres is over the maximum, gives a warning and
           % chnages the address to the maximum
           if strcmp(app.OptimizationInsertorReplace.Value, 'Replace')
              if app.StageAddress.Value > numel(app.ex.optimizationInfo.stageAxes)
                 warnMessage = sprintf('Index greater than possible length, changed to %d',numel(app.ex.optimizationInfo.stageAxes));
                 warndlg(warnMessage,'Over Maximum Index')
                 app.StageAddress.Value = numel(app.ex.optimizationInfo.stageAxes);
              end
           else
              if app.StageAddress.Value > numel(app.ex.optimizationInfo.stageAxes) + 1
                 warnMessage = sprintf('Index greater than possible length, changed to %d',numel(app.ex.optimizationInfo.stageAxes)+1);
                 warndlg(warnMessage,'Over Maximum Index')
                 app.StageAddress.Value = numel(app.ex.optimizationInfo.stageAxes) + 1;
              end
           end
           % Inserts new row and shifts rows with a greater address up 1
           if strcmp(app.OptimizationInsertorReplace.Value,'Insert') && ~isempty(app.ex.optimizationInfo.stageAxes) && app.StageAddress.Value <= numel(app.ex.optimizationInfo.stageAxes)
              app.ex.optimizationInfo.stageAxes(app.StageAddress.Value+1:end+1) =  app.ex.optimizationInfo.stageAxes(app.StageAddress.Value:end);
              app.ex.optimizationInfo.radius(app.StageAddress.Value+1:end+1) =  app.ex.optimizationInfo.radius(app.StageAddress.Value:end);
              app.ex.optimizationInfo.steps{app.StageAddress.Value+1:end+1} =  app.ex.optimizationInfo.steps{app.StageAddress.Value:end};
           end
           % creates optimization info with default settings at the
           % specified address
           if ~isempty(app.ex.optimizationInfo.stageAxes)
              app.ex.optimizationInfo.stageAxes(app.StageAddress.Value) = "x";
           else
              app.ex.optimizationInfo.stageAxes = "x";
           end
           app.ex.optimizationInfo.radius(app.StageAddress.Value) = 1;
           app.ex.optimizationInfo.steps{app.StageAddress.Value} = 5;
           app.StageOptimizationInstructions.ColumnEditable = [true true true];

           PositionUpdate(app)
      end

      % Cell edit callback: StageOptimizationInstructions
      function StageOptimizationInstructionsCellEdit(app, event)
           switch event.Indices(2) %column (stage/radius/steps)

              case 1 %stage
                 if any(cellfun(@(a)strcmpi(a,event.NewData),{"x","y","z"}))
                    app.ex.optimizationInfo.stageAxes(event.Indices(1)) = convertCharsToStrings(event.NewData);
                 else
                    errordlg('Fuser stage must be x, y, or z','Improper Axis')
                 end
              case 2 %radius
                 app.ex.optimizationInfo.radius(event.Indices(1)) = str2double(event.NewData);
              case 3 %steps
                 if rem(str2double(event.NewData),2) == 1
                    app.ex.optimizationInfo.steps{event.Indices(1)} = str2double(event.NewData);
                 else
                    errordlg('Optimization number of steps must be odd','Must Be Odd')
                 end

           end
           PositionUpdate(app)
      end

      % Button pushed function: AddScanStep
      function AddScanStepPushed(app, event)
           if strcmpi(app.scanStatus,'running')
              errordlg('Cannot edit scan while running, abort scan to continue','Scan Running')
              return
           end
           address = app.ScanAddress.Value;
           currentLength = length([app.ex.scan.parameter]);

           if address > currentLength + 1
              address = currentLength + 1;
              warnMessage = sprintf('Address greater than possible length, changed to %d',address);
              warndlg(warnMessage,'Over Maximum Address')
           end

           newScan.parameter   = "x";
           newScan.bounds      = [1 10];
           newScan.stepSize    = 1;
           newScan.identifier  = "stage";
           newScan.notes       = "";

           app.ex = addScans(app.ex,newScan);

           ScanUpdate(app)
           % OutputSettingsUpdate(app) %Needed for step to view selection and the like
      end

      % Value changed function: windfreakRFFrequency
      function windfreakRFFrequencyValueChanged(app, event)
           %Changes frequency of windfreak RF output
           if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

           app.ex.windfreak_RF.frequency = app.RFFrequency.Value;
           GeneralUpdate(app)
      end

      % Value changed function: windfreakRFAmplitude
      function windfreakRFAmplitudeValueChanged(app, event)
           %Changes amplitude of windfreak RF output
           if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

           app.ex.windfreak_RF.amplitude = app.windfreakRFAmplitude.Value;
           GeneralUpdate(app)
      end

      % Value changed function: windfreakRFModulationDropDown
      function WindfreakRFwindfreakRFModulationDropDownValueChanged(app, event)
           if ~checkInstrumentStatus(app,'windfreak_RF'), return; end
           if strcmpi("off",app.SRS_RFModulationDropDown.Value)
              app.ex.windfreak_RF.modulationEnabled = "Off";
           else
              app.ex.windfreak_RF.modulationEnabled = "On";
              app.ex.windfreak_RF.modulationType = app.SRS_RFModulationDropDown.Value;
           end
           GeneralUpdate(app)
      end

      % Value changed function: SRS_RFFrequency
      function SRS_RFFrequencyValueChanged(app, event)
           if ~checkInstrumentStatus(app,'SRS_RF'), return; end

           app.ex.SRS_RF.frequency = app.SRS_RFFrequency.Value;
           GeneralUpdate(app)
      end

      % Value changed function: SRS_RFAmplitude
      function SRS_RFAmplitudeValueChanged(app, event)
           if ~checkInstrumentStatus(app,'SRS_RF'), return; end

           app.ex.SRS_RF.amplitude = app.SRS_RFAmplitude.Value;
           GeneralUpdate(app)
      end

      % Value changed function: SRS_RFModulationDropDown
      function SRS_RFModulationDropDownValueChanged(app, event)
           if ~checkInstrumentStatus(app,'SRS_RF'), return; end
           if strcmpi("off",app.SRS_RFModulationDropDown.Value)
              app.ex.SRS_RF.modulationEnabled = "Off";
           else
              app.ex.SRS_RF.modulationEnabled = "On";
              app.ex.SRS_RF.modulationType = app.SRS_RFModulationDropDown.Value;
           end
           GeneralUpdate(app)
      end

      % Value changed function: StageAutoOptimization
      function StageAutoOptimizationValueChanged(app, event)
           app.ex.optimizationInfo.enableOptimization = app.StageAutoOptimization.Value;
      end

      % Value changed function: OptimizationmethodDropDown
      function OptimizationmethodDropDownValueChanged(app, event)
           if strcmpi("max value", app.OptimizationmethodDropDown.Value)
              app.ex.optimizationInfo.algorithmType = 'max value';
           else
              app.ex.optimizationInfo.algorithmType = 'gaussian';
           end
      end

      % Value changed function: OptimizationInterval
      function OptimizationIntervalValueChanged(app, event)
           app.ex.optimizationInfo.timeBetweenOptimizations = app.OptimizationInterval.Value;
      end

      % Value changed function: OptimizationparameterDropDown
      function OptimizationparameterDropDownValueChanged(app, event)
           if strcmpi("Counts (RF on)", app.OptimizationparameterDropDown.Value)
              app.ex.optimizationInfo.rfStatus = 'on';
           elseif strcmpi("Counts (RF off)",app.OptimizationparameterDropDown.Value)
              app.ex.optimizationInfo.rfStatus = 'off';
           else %contrast
              app.ex.optimizationInfo.rfStatus = 'contrast';
           end
      end

      % Value changed function: OptimizationPercentageCheck
      function OptimizationPercentageCheckValueChanged(app, event)
           app.ex.optimizationInfo.usePercentageDifference = app.OptimizationPercentageCheck.Value;
      end

      % Value changed function: OptimizationTimer
      function OptimizationTimerValueChanged(app, event)
           app.ex.optimizationInfo.useTimer = app.OptimizationTimer.Value;
      end

      % Value changed function: OptimizationPercentage
      function OptimizationPercentageValueChanged(app, event)
           app.ex.optimizationInfo.percentageToForceOptimization = app.OptimizationPercentage.Value;
      end

      % Button pushed function: DeleteOptimizationStep
      function DeleteOptimizationStepPushed(app, event)
           address = app.StageAddress.Value;
           currentLength = length(app.ex.optimizationInfo.stageAxes);

           if address > currentLength
              errordlg('Selected address does not exist','Invalid Address')
           elseif address == currentLength && address == 1
              errordlg('Cannot delete the last step','Cannot Delete')
           else
              app.ex.optimizationInfo.stageAxes(address) = [];
              app.ex.optimizationInfo.radius(address) = [];
              app.ex.optimizationInfo.steps(address) = [];
              % fieldsToChange = {'stageAxes','radius','steps'};
              % fieldValues = {'delete'};
              % app.ex.optimizationInfo = AddToAllFields(app,app.ex.optimizationInfo,fieldsToChange,fieldValues,address);
           end
           PositionUpdate(app)
      end

      % Button pushed function: BeginmanualoptimizationButton
      function BeginmanualoptimizationButtonPushed(app, event)
           if ~checkInstrumentStatus(app,'PIstage'), return; end
           if app.Numberofmanualoptimizations.Value < 1
              return
           end
           for ii = 1:app.Numberofmanualoptimizations.Value
              app.ex = stageOptimization(app.ex);
           end
           PositionUpdate(app)
           uialert(app.UIFigure,'Manual Optimization Complete!','Success','Icon','success')
      end

      % Value changed function: AddStepsButton
      function AddStepsButtonValueChanged(app, event)
           if isempty(app.ex.pulseBlaster)
              message = sprintf('Pulse blaster must be connected to edit pulse sequence');
              warndlg(message,'Pulse Blaster Not Connected')
              return
           end

           addresses = split(c2s(app.PulseAddress.Value),[", ",","," "]);
           addresses = addresses(addresses ~= "");
           addressNums = [];
           for ii = 1:length(addresses)
              if contains(addresses(ii),":") || contains(addresses(ii),"-")
                 bounds = str2double(split(addresses(ii),[":","-"]));
                 addressNums = [addressNums, min(bounds):max(bounds)];
              else
                 addressNums = [addressNums, str2double(addresses(ii))];
              end
           end

           for ii = 1:numel(addressNums)
              currentLength = length([app.ex.pulseBlaster.userSequence]);
              address = addressNums(ii);
              if address > currentLength + 1
                 address = currentLength + 1;
                 warnMessage = sprintf('Address greater than possible length, changed to %d',address);
                 warndlg(warnMessage,'Over Maximum Address')
              end

              defaultPulse.activeChannels = {};
              defaultPulse.duration = 10;
              defaultPulse.notes = '';

              app.ex.pulseBlaster = addPulse(app.ex.pulseBlaster,defaultPulse,address);
           end
           PulseSequenceUpdate(app)

      end

      % Value changed function: DeleteStepsButton
      function DeleteStepsButtonValueChanged(app, event)
           if isempty(app.ex.pulseBlaster)
              message = sprintf('Pulse blaster must be connected to edit pulse sequence');
              warndlg(message,'Pulse Blaster Not Connected')
              return
           end


           addresses = split(c2s(app.PulseAddress.Value),[", ",","," "]);
           addresses = addresses(addresses ~= "");
           addressNums = [];
           for ii = 1:length(addresses)
              if contains(addresses(ii),":") || contains(addresses(ii),"-")
                 bounds = str2double(split(addresses(ii),[":","-"]));
                 addressNums = [addressNums, min(bounds):max(bounds)];
              else
                 addressNums = [addressNums, str2double(addresses(ii))];
              end
           end
           addressNums = fliplr(addressNums);
           currentLength = length([app.ex.pulseBlaster.userSequence]);

           if any(addressNums > currentLength)
              errordlg('One or more address do not exist, deleting other addresses','Invalid Address')
              addressNums(addressNums > currentLength) = [];
           end

           for ii = 1:numel(addressNums)
              if numel(app.ex.pulseBlaster.userSequence) ==1
                 errordlg('Cannot delete last Step','Invalid Address')
                 break
              end
              app.ex.pulseBlaster = deletePulse(app.ex.pulseBlaster,addressNums(ii));
           end
           PulseSequenceUpdate(app);

      end

      % Cell edit callback: PulseSequenceTable
      function PulseSequenceTableCellEdit(app, event)
           rowNum = event.Indices(1);
           switch event.Indices(2)
              case 2 %Active Channels
                 newChannels = lower(c2s(app.PulseSequenceTable.Data(rowNum,2)));
                 channels = {};
                 if contains(newChannels,["aom","laser"])
                    channels = {channels{1:end},'AOM'};
                 end
                 if contains(newChannels,["data","daq"])
                    channels = {channels{1:end},'DAQ'};
                 end
                 if contains(newChannels,"sig")
                    channels = {channels{1:end},'Signal'};
                 end
                 if contains(newChannels,"rf")
                    channels = {channels{1:end},'RF'};
                 end
                 if contains(newChannels, [" i", "i "]) || startsWith(newChannels,'i') || endsWith(newChannels,'i')
                    channels = {channels{1:end},'I'};
                 end
                 if contains(newChannels,[" q","q "]) || startsWith(newChannels,'q') || endsWith(newChannels,'q')
                    channels = {channels{1:end},'Q'};
                 end
                 modifyPulse(app.ex.pulseBlaster,rowNum,'activeChannels',channels);
              case 3 %Binnary channels
                 newData = s2c(app.PulseSequenceTable.Data(rowNum,3));
                 modifyPulse(app.ex.pulseBlaster,rowNum,'binary',newData);
              case 4 %Output Number
                 newOutput = double(app.PulseSequenceTable.Data(rowNum,4));
                 modifyPulse(app.ex.pulseBlaster,rowNum,'output',newOutput);
              case 5 %Duratione
                 newDuration = double(app.PulseSequenceTable.Data(rowNum,5));
                 modifyPulse(app.ex.pulseBlaster,rowNum,'Duration',newDuration);
              case 6 %Direction Type
                 newDirection = s2c(app.PulseSequenceTable.Data(rowNum,6));
                 modifyPulse(app.ex.pulseBlaster,rowNum,'direction',newDirection);
              case 7 %Context Info
                 newContext = double(app.PulseSequenceTable.Data(rowNum,7));
                 modifyPulse(app.ex.pulseBlaster,rowNum,'context',newContext);
              case 8 %notes
                 newNotes = s2c(app.PulseSequenceTable.Data(rowNum,8));
                 modifyPulse(app.ex.pulseBlaster,rowNum,'notes',newNotes);
           end
           PulseSequenceUpdate(app);

      end

      % Value changed function: CopytoButton
      function CopytoButtonValueChanged(app, event)
           %converts character array to string which is split into string
           %array based on location of commas and spaces. For loop allows
           %for the interprtation of something like 1:4 as addresses
           %1,2,3,4.
           if isnan(str2double(app.pulseAddress.Value))
              errordlg('Only one pulse address can be copied at a time','Cannot Copy Multiple Addresses')
              return
           end
           addressToCopy = str2double(app.PulseAddress.Value);
           copiedAddresses = split(c2s(app.CopyAddresses.Value),[", ",","," "]);
           copiedAddresses = copiedAddresses(copiedAddresses ~= "");
           addressNums = [];
           for ii = 1:length(copiedAddresses)
              if contains(copiedAddresses(ii),":") || contains(copiedAddresses(ii),"-")
                 bounds = str2double(split(copiedAddresses(ii),[":","-"]));
                 addressNums = [addressNums, min(bounds):max(bounds)];
              else
                 addressNums = [addressNums, str2double(copiedAddresses(ii))];
              end
           end


           nonnumbers = false;
           errAddresses = [];

           for ii = 1:length(addressNums)
              currentLength = length([app.ex.pulseBlaster.userSequence]);
              if isnan(addressNums(ii))
                 nonnumbers = true;
              elseif addressNums(ii) > currentLength +1
                 errAddresses = [errAddresses,addressNums(ii)];
              else
                 pulseInfo.activeChannels = app.ex.pulseBlaster.userSequence(addressToCopy).activeChannels;
                 pulseInfo.duration = app.ex.pulseBlaster.userSequence(addressToCopy).duration;
                 pulseInfo.notes = app.ex.pulseBlaster.userSequence(addressToCopy).notes;

                 app.ex.pulseBlaster = addPulse(app.ex.pulseBlaster,pulseInfo,addressNums(ii));

              end
           end

           if nonnumbers
              warnMessage = sprintf('Address Values must be numeric');
              warndlg(warnMessage, 'Nonnumeric Values')
           end
           if ~isempty(errAddresses)
              errAddresses = num2str(errAddresses);
              message = sprintf('Addresses %s greater than possible length', errAddresses);
              warndlg(message,'Over Maximum Address')
           end

           PulseSequenceUpdate(app)
      end

      % Value changed function: ThingToPlot
      function ThingToPlotValueChanged(app, event)
           chooseVisiblePlot(app)
           DisplayUpdate(app)
           UpdatePlotlabel(app)
      end

      % Value changed function: PulseBoxAddress
      function PulseBoxAddressValueChanged(app, event)
           app.BoxAddress.Value = app.PulseBoxAddress.Value;
           SaveDataUpdate(app)
           PulseSequenceUpdate(app)
      end

      % Value changed function: DataSetupsSwitch
      function DataSetupsSwitchValueChanged(app, event)
           SaveDataUpdate(app)
      end

      % Value changed function: UserName
      function UserNameValueChanged(app, event)
           SaveDataUpdate(app)
      end

      % Value changed function: LoadNames
      function LoadNamesValueChanged(app, event)
           SaveDataUpdate(app)
      end

      % Value changed function: LoadDates
      function LoadDatesValueChanged(app, event)
           SaveDataUpdate(app)
      end

      % Value changed function: UserName2
      function UserName2ValueChanged(app, event)
           PulseSequenceUpdate(app)
      end

      % Value changed function: PulseSequenceNames
      function PulseSequenceNamesValueChanged(app, event)
           PulseSequenceUpdate(app)

           if c2s(app.PulseSequenceNames.Value) ~= "Pulse Sequence Names"
              rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                 app.UserName2.Value,'\Experiment Editor\Pulse Sequences\',app.PulseSequenceNames.Value);
              load(rootName,"savedPulse")
              app.PulseUserNotes.Value = savedPulse.Notes;
           end
      end

      % Value changed function: SavePulseSequence
      function SavePulseSequenceValueChanged(app, event)
           if isempty(app.ex.pulseBlaster)
              message = sprintf('Pulse blaster must be connected to save pulse sequence');
              warndlg(message,'Pulse Blaster Not Connected')
              return
           end

           if isempty(app.ex.pulseBlaster.userSequence)
              errordlg('No sequence to save','Save Unsuccessful')
              return
           end

           rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName2.Value,'\Experiment Editor\Pulse Sequences');

           %Checks if the directory is valid. Even if it is empty, there will
           %be 2 "fake" files
           setupDirectory = struct2cell(dir(rootSaveName));
           if isempty(setupDirectory)
              errordlg('Current box address invalid','Invalid box address')
              return
           end

           if isempty(app.PulseSaveName.Value)
              errordlg('Need a folder name to save (save name box)','Cannot Save')
              return
           end

           savedPulse = [];
           savedPulse.Sequence = app.ex.pulseBlaster.userSequence;
           savedPulse.nTotalLoops = app.ex.pulseBlaster.nTotalLoops;
           savedPulse.Notes = app.PulseUserNotes.Value;
           mSaveName = strcat(rootSaveName,'\', app.PulseSaveName.Value);
           save(mSaveName,"savedPulse")
           uialert(app.UIFigure,'Pulse Sequence Saved!','Save Successful','Icon','success')

           PulseSequenceUpdate(app)
      end

      % Value changed function: LoadPulse
      function LoadPulseValueChanged(app, event)
           if isempty(app.ex.pulseBlaster)
              message = sprintf('Pulse blaster must be connected to save pulse sequence');
              warndlg(message,'Pulse Blaster Not Connected')
              return
           end

           if c2s(app.PulseSequenceNames.Value) ~= "Pulse Sequence Names"
              rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                 app.UserName2.Value,'\Experiment Editor\Pulse Sequences\',app.PulseSequenceNames.Value);
              load(rootName,"savedPulse")
              LoadPulseSequence(app,savedPulse.Sequence)
              if isfield(savedPulse,'nTotalLoops')
                 app.ex.pulseBlaster.nTotalLoops = savedPulse.nTotalLoops;
              end
           end

           PulseSequenceUpdate(app)

      end

      % Value changed function: yAxisScanNum
      function yAxisScanNumValueChanged(app, event)
           if isempty(app.ex.data)
              return
           end
           if numel(app.storedDataSettings.scan) < 2 && app.yAxisScanNum.Value == 1 && app.xAxisScanNum.Value == 1
              app.xAxisScanNum.Value = 0;
           end
           if app.yAxisScanNum.Value > numel(app.storedDataSettings.scan)
              errordlg('Scan To display cannot be greater than the number of scan, setting to 0','Cannot Display Scan')
           end
           DisplayUpdate(app)
           UpdatePlotlabel(app)

      end

      % Value changed function: xAxisScanNum
      function xAxisScanNumValueChanged(app, event)
           if isempty(app.ex.data)
              return
           end
           if numel(app.storedDataSettings.scan) < 2 && app.yAxisScanNum.Value == 1 && app.xAxisScanNum.Value == 1
              app.yAxisScanNum.Value = 0;
           end
           if app.xAxisScanNum.Value > numel(app.storedDataSettings.scan)
              errordlg('Scan To display cannot be greater than the number of scan, setting to 0','Cannot Display Scan')
           end

           DisplayUpdate(app)
           UpdatePlotlabel(app)
      end

      % Button pushed function: ForceplotupdateButton
      function ForceplotupdateButtonPushed(app, event)
           DisplayUpdate(app)
      end

      % Value changed function: PulseNumToPlot
      function PulseNumToPlotValueChanged(app, event)
           DisplayUpdate(app)
      end

      % Value changed function: TotalLoops
      function TotalLoopsValueChanged(app, event)
           if isempty(app.ex.pulseBlaster)
              return
           end
           if any(strcmpi(app.scanStatus,{'running','aborting'}))
              errordlg('Cannot update number of loops while a scan is running','Scan Running')
              PulseSequenceUpdate(app)
              return
           else
              if strcmpi(app.LoopsOrDuration.Value,'# of Loops')
                 app.ex.pulseBlaster.nTotalLoops = app.TotalLoops.Value;
              else
                 app.ex.pulseBlaster.nTotalLoops = 1;
                 app.ex.pulseBlaster = adjustSequence(app.ex.pulseBlaster);
                 app.ex.pulseBlaster.nTotalLoops = floor(app.TotalLoops.Value/app.ex.pulseBlaster.sequenceDurations.adjusted.totalSeconds);
              end
              PulseSequenceUpdate(app);
           end
      end

      % Value changed function: DataType
      function DataTypeValueChanged(app, event)
           if ~isempty(app.ex.DAQ)
              app.ex.DAQ.activeDataChannel = app.DataType.Value;
           end
           ScanUpdate(app)
      end

      % Value changed function: ContrastType
      function ContrastTypeValueChanged(app, event)
           DisplayUpdate(app)
      end

      % Value changed function: DataPointDeviation
      function DataPointDeviationValueChanged(app, event)
           %**Added temporary function to change data point deviation
           %tolerance
           app.ex.nPointsTolerance = app.DataPointDeviation.Value;
      end

      % Value changed function: LoopsOrDuration
      function LoopsOrDurationValueChanged(app, event)
           PulseSequenceUpdate(app);
      end

      % Value changed function: SRSModWaveform
      function SRSModWaveformValueChanged(app, event)
           if ~checkInstrumentStatus(app,'SRS_RF'), return; end

           app.ex.SRS_RF.modulationWaveform = app.SRSModWaveform.Value;
           GeneralUpdate(app)
      end

      % Value changed function: windfreakModWaveform
      function windfreakModWaveformValueChanged(app, event)
           if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

           app.ex.windfreak_RF.modulationWaveform = app.windfreakModWaveform.Value;
           GeneralUpdate(app)
      end

      % Value changed function: TemplateType
      function TemplateTypeValueChanged(app, event)
           TemplateUpdate(app);
      end

      % Button pushed function: LoadTemplate
      function LoadTemplateButtonPushed(app, event)
           if isempty(app.ex.pulseBlaster)
              errordlg('Cannot load template unless the pulse blaster is connected','Pulse Blaster Not Connected')
              return
           end

           templateStruct = convertTable2Struct(app,app.TemplateTable.Data);
           if templateStruct.collectionDuration == 0
              if ~isempty(app.ex.DAQ) && app.ex.DAQ.connected == 1
                 templateStruct.collectionDuration = (1/app.ex.DAQ.sampleRate)*1e9;
              else
                 templateStruct.collectionDuration = 500;
              end
           end

           try
              switch app.TemplateType.Value
                 case 'Rabi'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = Rabi_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
                 case 'Spin Echo'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = SpinEcho_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
                    %Automatically adds offset when template is loaded so
                    %the pi time is plotted on the x-axis
                    app.PulseNumToPlot.Value = 2;
                    app.displayInfo.offset(1) =  templateStruct.scanBounds(1) - scanInfo.bounds{2}(1);
                    app.displayInfo.incorporateOffset(1) = true;
                 case 'XYN'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = XYn_m_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
                    app.PulseNumToPlot.Value = 2;
                    app.displayInfo.offset(1) =  templateStruct.scanBounds(1) - scanInfo.bounds{2}(1);
                    app.displayInfo.incorporateOffset(1) = true;
                 case 'Correlation'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = Correlation_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
                 case 'DEER Frequency'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = DEER_frequency_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
                 case 'DEER Duration'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = DEER_Duration_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
                 case 'CPMG'
                    % try
                    [app.ex.pulseBlaster,scanInfo] = CPMG_template(app.ex.pulseBlaster,templateStruct);
                    % catch ME
                    %     message = ME.message;
                    %     errordlg(message,'Error Loading Template');
                    %     return
                    % end
              end
           catch ME
              assignin("base","ME",ME)
              errordlg(ME.message,'Error Loading Template');
              return
           end

           if ~strcmpi(app.TemplateType.Value,'Rabi') && ~isempty(app.ex.SRS_RF)
              app.ex.SRS_RF.modulationEnabled = "On";
           end

           if ~isempty(app.ex.SRS_RF)
              app.ex.SRS_RF.frequency = scanInfo.RFFrequency;
           end
           scanInfo = rmfield(scanInfo,'RFFrequency');
           for ii = 1:numel(scanInfo.address)
              scanInfo.stepSize(ii) = (scanInfo.bounds{ii}(2)-scanInfo.bounds{ii}(1))/(scanInfo.nSteps-1);
           end
           app.ex.scan = scanInfo;
           for ii = 1:numel(app.ex.scan)
              app.ex.scan(ii).stepToView = 1;
           end

           %Stores mean sequence for use in displaying expected time
           app.storedMeanSequenceDuration = app.ex.pulseBlaster.sequenceDurations.adjusted.totalSeconds;

           appUpdate(app);
           uialert(app.UIFigure,'Template Loaded!','Load Successful','Icon','success')
      end

      % Clicked callback: LoadDates
      function LoadDatesClicked(app, event)

      end

      % Value changed function: BaselineSubtraction
      function BaselineSubtractionValueChanged(app, event)

      end

      % Value changed function: IterationStart
      function IterationStartValueChanged(app, event)
           DisplayUpdate(app)
      end

      % Value changed function: IterationEnd
      function IterationEndValueChanged(app, event)
           DisplayUpdate(app)
      end

      % Value changed function: forcedCollectionPauseTime
      function forcedCollectionPauseTimeValueChanged(app, event)
           app.ex.forcedCollectionPauseTime = app.forcedCollectionPauseTime.Value;
      end

      % Value changed function: maxFailedCollections
      function maxFailedCollectionsValueChanged(app, event)
           app.ex.maxFailedCollections = app.maxFailedCollections.Value;
      end

      % Value changed function: pulsePlotVisible
      function pulsePlotVisibleValueChanged(app, event)
           app.ThingToPlot.Value = 'PulseSequence';
           chooseVisiblePlot(app)
           DisplayUpdate(app)
           UpdatePlotlabel(app)
      end

      % Menu selected function: DatesDelete
      function DatesDeleteSelected(app, event)
           selectedRow = event.InteractionInformation.Item;
           if isempty(selectedRow) || selectedRow == 1
              return
           end

           if strcmpi(app.DataSetupsSwitch.Value,'data')
              msg = sprintf('Deleting %s will also delete all of its contents, proceed?',app.LoadDates.Items{selectedRow});
              answer = questdlg(msg,'Deleting File','Delete File','Cancel','Cancel');
              if strcmpi(answer,'cancel')
                 return
              else
                 fileName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,'\Experiment Editor\Data\',app.LoadDates.Items{selectedRow});
                 rmdir(fileName,'s')
              end
           else
              % msg = sprintf('Delete setup %s?',app.LoadDates.Items{selectedRow});
              % answer = questdlg(msg,'Deleting Setup Version','Delete','Cancel','Cancel');
              % if strcmpi(answer,'cancel')
              %     return
              % else
              fileName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,'\Experiment Editor\Setups\',app.LoadNames.Value,'\',app.LoadDates.Items{selectedRow});
              rmdir(fileName,'s')
              % end
           end
           SaveDataUpdate(app);
      end

      % Button pushed function: AddLineButton
      function AddLineButtonPushed(app, event)
           if ~app.OneDAxes.Visible
              return
           end

           if strcmpi(app.AdditionType.Value,'man')
              lineLocation = app.manLocation.Value;
           elseif strcmpi(app.AdditionType.Value,'autoXY')
              if ~isempty(app.ex.SRS_RF)
                 magnet = magnetStrength(app.ex.SRS_RF.frequency,'strength');
              else
                 warndlg('Unable to find RF frequency, finding line location for a frequency of 2.1 GHz')
                 magnet = magnetStrength(2.1,'strength');
              end
              lineLocation = ResonanceDip(magnet,'time',app.LineNucleusType.Value);
           else
              switch app.LineNucleusType.Value
                 case '1H'
                    lineLocation = 42.5775;
                 case '13C'
                    lineLocation = 10.7084;
                 case '19F'
                    lineLocation = 40.078;
              end
           end

           if isempty(app.displayInfo.xLines)
              checkLineLocation = 0;
           else
              for ii = 1:numel(app.displayInfo.xLines)
                 checkLineLocation(ii) = eq(app.displayInfo.xLines{ii}.Value,lineLocation);
              end
           end
           if any(checkLineLocation)
              errmsg = sprintf('Vertical Line already present at x=%d',lineLocation);
              errordlg(errmsg,'Line Already Present')
           elseif ~strcmpi(app.AdditionType.Value,'man')
              app.displayInfo.xLines{numel(app.displayInfo.xLines)+1} = xline(app.OneDAxes,lineLocation,'--',strcat(app.LineNucleusType.Value,'-',num2str(lineLocation)));
           else
              app.displayInfo.xLines{numel(app.displayInfo.xLines)+1} = xline(app.OneDAxes,lineLocation,'--',lineLocation);
           end

           if app.HideLinesCheckBox.Value == 1
              for ii = 1:numel(app.displayInfo.xLines)
                 app.displayInfo.xLines{ii}.Visible = ~app.HideLinesCheckBox.Value;
              end
           end
      end

      % Button pushed function: DeleteLineButton
      function DeleteLineButtonPushed(app, event)
           address = app.LineDeleteAddress.Value;
           numberOfLines = numel(app.displayInfo.xLines);
           if address > numberOfLines
              errordlg('Selected address is greater than the number of lines, deleting last line','Line Not Present')
              address = numberOfLines;
           end
           for ii = 1:numberOfLines
              linePositions(ii) = app.displayInfo.xLines{ii}.Value;
           end
           linePositions = sort(linePositions);
           for ii = 1:numberOfLines
              if linePositions(address) == app.displayInfo.xLines{ii}.Value
                 delete(app.displayInfo.xLines{ii})
                 app.displayInfo.xLines(ii) = [];
                 return
              end
           end
      end

      % Menu selected function: NamesDelete
      function NamesDeleteSelected(app, event)
           selectedRow = event.InteractionInformation.Item;
           if isempty(selectedRow) || selectedRow == 1
              return
           end

           if strcmpi(app.DataSetupsSwitch.Value,'data')
              % msg = sprintf('Deleting data %s, proceed?',app.LoadNames.Items{selectedRow});
              % answer = questdlg(msg,'Deleting Data','Delete','Cancel','Cancel');
              % if strcmpi(answer,'cancel')
              %     return
              % else
              fileName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,'\Experiment Editor\Data\',app.LoadDates.Value,'\',app.LoadNames.Items{selectedRow});
              rmdir(fileName,'s')
              % end
           else
              msg = sprintf('Deleting setup %s will delete all of its versions, proceed?',app.LoadNames.Items{selectedRow});
              answer = questdlg(msg,'Deleting Setup','Delete All Versions','Cancel','Cancel');
              if strcmpi(answer,'cancel')
                 return
              else
                 fileName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,'\Experiment Editor\Setups\',app.LoadNames.Items{selectedRow});
                 rmdir(fileName,'s')
              end
           end
           SaveDataUpdate(app);
      end

      % Menu selected function: PulseDelete
      function PulseDeleteMenuSelected(app, event)
           selectedRow = event.InteractionInformation.Item;
           if isempty(selectedRow) || selectedRow == 1
              return
           end

           msg = sprintf('Deleting sequence %s, proceed?',app.PulseSequenceNames.Items{selectedRow});
           answer = questdlg(msg,'Deleting Pulse Sequence','Delete','Cancel','Cancel');
           if strcmpi(answer,'cancel')
              return
           else
              fileName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName2.Value,'\Experiment Editor\Pulse Sequences\',app.PulseSequenceNames.Items{selectedRow},'.mat');
              delete(fileName)
           end
           PulseSequenceUpdate(app);
      end

      % Value changed function: Notifications
      function NotificationsValueChanged(app, event)
           app.ex.notifications = app.Notifications.Value;
      end

      % Button down function: TwoDAxes
      function TwoDAxesButtonDown(app, event)

      end

      % Value changed function: HideLinesCheckBox
      function HideLinesCheckBoxValueChanged(app, event)
           for ii = 1:numel(app.displayInfo.xLines)
              app.displayInfo.xLines{ii}.Visible = ~app.HideLinesCheckBox.Value;
           end
      end

      % Cell edit callback: TemplateTable
      function TemplateTableCellEdit(app, event)
           TemplateUpdate(app);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.LeftPanel = uipanel(app.UIFigure);
         app.TabGroup = uitabgroup(app.LeftPanel);
         app.ConnectionsTab = uitab(app.TabGroup);
         app.ConnectionsTab.Title = 'Connections';
         app.GridLayout2 = uigridlayout(app.ConnectionsTab);
         app.StageLoad = uibutton(app.GridLayout2, 'push');
         app.StageLoad.ButtonPushedFcn = createCallbackFcn(app, @StageLoadButtonPushed, true);
         app.StageLoad.Text = 'Load';
         app.StageUnload = uibutton(app.GridLayout2, 'push');
         app.StageUnload.ButtonPushedFcn = createCallbackFcn(app, @StageUnloadButtonPushed, true);
         app.StageUnload.Text = 'Unload';
         app.StageLamp = uilamp(app.GridLayout2);
         app.PulseBlasterLoad = uibutton(app.GridLayout2, 'push');
         app.PulseBlasterLoad.ButtonPushedFcn = createCallbackFcn(app, @PulseBlasterLoadButtonPushed, true);
         app.PulseBlasterLoad.Text = 'Load';
         app.SRSRFLoad = uibutton(app.GridLayout2, 'push');
         app.SRSRFLoad.ButtonPushedFcn = createCallbackFcn(app, @SRSRFLoadButtonPushed, true);
         app.SRSRFLoad.Text = 'Load';
         app.PulseBlasterLamp = uilamp(app.GridLayout2);
         app.SRSRFLamp = uilamp(app.GridLayout2);
         app.DAQLamp = uilamp(app.GridLayout2);
         app.PulseBlasterUnload = uibutton(app.GridLayout2, 'push');
         app.PulseBlasterUnload.ButtonPushedFcn = createCallbackFcn(app, @PulseBlasterUnloadButtonPushed, true);
         app.PulseBlasterUnload.Text = 'Unload';
         app.SRSRFUnload = uibutton(app.GridLayout2, 'push');
         app.SRSRFUnload.ButtonPushedFcn = createCallbackFcn(app, @SRSRFUnloadButtonPushed, true);
         app.SRSRFUnload.Text = 'Unload';
         app.DAQLoad = uibutton(app.GridLayout2, 'push');
         app.DAQLoad.ButtonPushedFcn = createCallbackFcn(app, @DAQLoadButtonPushed, true);
         app.DAQLoad.Text = 'Load';
         app.DAQUnload = uibutton(app.GridLayout2, 'push');
         app.DAQUnload.ButtonPushedFcn = createCallbackFcn(app, @DAQUnloadButtonPushed, true);
         app.DAQUnload.Text = 'Unload';
         app.SproutLaserLoad = uibutton(app.GridLayout2, 'push');
         app.SproutLaserLoad.ButtonPushedFcn = createCallbackFcn(app, @SproutLaserLoadButtonPushed, true);
         app.SproutLaserLoad.Text = 'Load';
         app.SproutLaserUnload = uibutton(app.GridLayout2, 'push');
         app.SproutLaserUnload.ButtonPushedFcn = createCallbackFcn(app, @SproutLaserUnloadButtonPushed, true);
         app.SproutLaserUnload.Text = 'Unload';
         app.SproutLaserLamp = uilamp(app.GridLayout2);
         app.StageLabel = uilabel(app.GridLayout2);
         app.StageLabel.Text = 'Stage';
         app.PulseBlasterLabel = uilabel(app.GridLayout2);
         app.PulseBlasterLabel.Text = 'Pulse Blaster';
         app.SproutLaserLabel = uilabel(app.GridLayout2);
         app.SproutLaserLabel.Text = 'Sprout Laser';
         app.SRSRFLabel = uilabel(app.GridLayout2);
         app.SRSRFLabel.Text = 'SRS RF';
         app.DAQLabel = uilabel(app.GridLayout2);
         app.DAQLabel.Text = 'DAQ';
         app.ConfigFileNameLabel = uilabel(app.GridLayout2);
         app.ConfigFileNameLabel.Text = 'Config File Name';
         app.StageConfig = uieditfield(app.GridLayout2, 'text');
         app.PulseBlasterConfig = uieditfield(app.GridLayout2, 'text');
         app.SproutLaserConfig = uieditfield(app.GridLayout2, 'text');
         app.SRSRFConfig = uieditfield(app.GridLayout2, 'text');
         app.DAQConfig = uieditfield(app.GridLayout2, 'text');
         app.WindfreakRFLabel = uilabel(app.GridLayout2);
         app.WindfreakRFLabel.Text = 'Windfreak RF';
         app.WindfreakRFConfig = uieditfield(app.GridLayout2, 'text');
         app.WindfreakRFUnload = uibutton(app.GridLayout2, 'push');
         app.WindfreakRFUnload.ButtonPushedFcn = createCallbackFcn(app, @WindfreakRFUnloadButtonPushed, true);
         app.WindfreakRFUnload.Text = 'Unload';
         app.WindfreakRFLamp = uilamp(app.GridLayout2);
         app.WindfreakRFLoad = uibutton(app.GridLayout2, 'push');
         app.WindfreakRFLoad.ButtonPushedFcn = createCallbackFcn(app, @WindfreakRFLoadButtonPushed, true);
         app.WindfreakRFLoad.Text = 'Load';
         app.SettingsTab = uitab(app.TabGroup);
         app.SettingsTab.Title = 'Settings';
         app.GridLayout4 = uigridlayout(app.SettingsTab);
         app.SetLaserPower = uieditfield(app.GridLayout4, 'numeric');
         app.SetLaserPower.ValueChangedFcn = createCallbackFcn(app, @SetLaserPowerValueChanged, true);
         app.LaserLabel_2 = uilabel(app.GridLayout4);
         app.LaserLabel_2.Text = 'Sprout Laser';
         app.ToggleLaser = uiswitch(app.GridLayout4, 'slider');
         app.ToggleLaser.Items = {'off', 'on'};
         app.ToggleLaser.ValueChangedFcn = createCallbackFcn(app, @ToggleLaserValueChanged, true);
         app.ToggleLaser.Value = 'off';
         app.RefreshAppButton = uibutton(app.GridLayout4, 'push');
         app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
         app.RefreshAppButton.Text = 'Refresh App';
         app.SetpowermWLabel = uilabel(app.GridLayout4);
         app.SetpowermWLabel.Text = {'Set power'; '(mW)'};
         app.PowerreadingmWLabel = uilabel(app.GridLayout4);
         app.PowerreadingmWLabel.Text = {'Power'; 'reading (mW)'};
         app.LaserPowerReading = uieditfield(app.GridLayout4, 'numeric');
         app.LaserPowerReading.Editable = 'off';
         app.RefreshLaserPowerReading = uibutton(app.GridLayout4, 'push');
         app.RefreshLaserPowerReading.ButtonPushedFcn = createCallbackFcn(app, @RefreshLaserPowerReadingButtonPushed, true);
         app.RefreshLaserPowerReading.Text = {'Refresh'; 'power'; 'reading'};
         app.CollectdatacontinuouslyButton = uibutton(app.GridLayout4, 'push');
         app.CollectdatacontinuouslyButton.ButtonPushedFcn = createCallbackFcn(app, @CollectdatacontinuouslyButtonPushed, true);
         app.CollectdatacontinuouslyButton.Text = 'Collect data continuously';
         app.CopyappobjecttoworkspaceButton = uibutton(app.GridLayout4, 'push');
         app.CopyappobjecttoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CopyappobjecttoworkspaceButtonPushed, true);
         app.CopyappobjecttoworkspaceButton.Text = 'Copy app object to workspace';
         app.SRS_RFFrequency = uieditfield(app.GridLayout4, 'numeric');
         app.SRS_RFFrequency.ValueChangedFcn = createCallbackFcn(app, @SRS_RFFrequencyValueChanged, true);
         app.SRS_RFAmplitude = uieditfield(app.GridLayout4, 'numeric');
         app.SRS_RFAmplitude.ValueChangedFcn = createCallbackFcn(app, @SRS_RFAmplitudeValueChanged, true);
         app.DifferentiatesignalandreferenceCheckBox = uicheckbox(app.GridLayout4);
         app.DifferentiatesignalandreferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifferentiatesignalandreferenceCheckBoxValueChanged, true);
         app.DifferentiatesignalandreferenceCheckBox.Text = {'  Differentiate signal'; ' and reference'};
         app.DifferentiatesignalandreferenceCheckBox.Value = true;
         app.WaitForLaserEquilibrium = uicheckbox(app.GridLayout4);
         app.WaitForLaserEquilibrium.ValueChangedFcn = createCallbackFcn(app, @WaitForLaserEquilibriumValueChanged, true);
         app.WaitForLaserEquilibrium.Text = {'  Wait for laser'; '  equilibrium'};
         app.OffWhenDeleted = uicheckbox(app.GridLayout4);
         app.OffWhenDeleted.ValueChangedFcn = createCallbackFcn(app, @OffWhenDeletedValueChanged, true);
         app.OffWhenDeleted.Text = {'  Turn laser off'; '  when unloaded'};
         app.SRS_RFModulationDropDown = uidropdown(app.GridLayout4);
         app.SRS_RFModulationDropDown.Items = {'Off', 'I/Q', 'amplitude'};
         app.SRS_RFModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SRS_RFModulationDropDownValueChanged, true);
         app.SRS_RFModulationDropDown.Value = 'Off';
         app.NumberofpointsforcontinuouscollectionLabel = uilabel(app.GridLayout4);
         app.NumberofpointsforcontinuouscollectionLabel.Text = {'Number of points for '; 'continuous collection'};
         app.NumberOfPointsForContCollection = uieditfield(app.GridLayout4, 'numeric');
         app.NumberOfPointsForContCollection.Limits = [5 Inf];
         app.NumberOfPointsForContCollection.RoundFractionalValues = 'on';
         app.NumberOfPointsForContCollection.Value = 10;
         app.RFFrequencyGHzLabel_2 = uilabel(app.GridLayout4);
         app.RFFrequencyGHzLabel_2.Text = 'Frequency (GHz)';
         app.windfreakRFFrequency = uieditfield(app.GridLayout4, 'numeric');
         app.windfreakRFFrequency.ValueChangedFcn = createCallbackFcn(app, @windfreakRFFrequencyValueChanged, true);
         app.windfreakRFAmplitude = uieditfield(app.GridLayout4, 'numeric');
         app.windfreakRFAmplitude.ValueChangedFcn = createCallbackFcn(app, @windfreakRFAmplitudeValueChanged, true);
         app.ModulationLabel = uilabel(app.GridLayout4);
         app.ModulationLabel.Text = 'Modulation  Type';
         app.windfreakRFModulationDropDown = uidropdown(app.GridLayout4);
         app.windfreakRFModulationDropDown.Items = {'Off', 'I/Q', 'amplitude'};
         app.windfreakRFModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @WindfreakRFwindfreakRFModulationDropDownValueChanged, true);
         app.windfreakRFModulationDropDown.Value = 'Off';
         app.SRSRFsettingsLabel = uilabel(app.GridLayout4);
         app.SRSRFsettingsLabel.Text = 'SRS RF settings';
         app.WindfreakRFsettingsLabel = uilabel(app.GridLayout4);
         app.WindfreakRFsettingsLabel.Text = 'Windfreak RF settings';
         app.AmplitudedBmLabel = uilabel(app.GridLayout4);
         app.AmplitudedBmLabel.Text = 'Amplitude (dBm)';
         app.ModulationLabel_2 = uilabel(app.GridLayout4);
         app.ModulationLabel_2.Text = 'Waveform';
         app.windfreakModWaveform = uidropdown(app.GridLayout4);
         app.windfreakModWaveform.Items = {'Sine', 'Ramp', 'Triangle', 'Square', 'Noise', 'External'};
         app.windfreakModWaveform.ItemsData = {'sine', 'ramp', 'triangle', 'square', 'noise', 'external'};
         app.windfreakModWaveform.ValueChangedFcn = createCallbackFcn(app, @windfreakModWaveformValueChanged, true);
         app.windfreakModWaveform.Value = 'sine';
         app.SRSModWaveform = uidropdown(app.GridLayout4);
         app.SRSModWaveform.Items = {'Sine', 'Ramp', 'Triangle', 'Square', 'Noise', 'External'};
         app.SRSModWaveform.ItemsData = {'sine', 'ramp', 'triangle', 'square', 'noise', 'external'};
         app.SRSModWaveform.ValueChangedFcn = createCallbackFcn(app, @SRSModWaveformValueChanged, true);
         app.SRSModWaveform.Value = 'sine';
         app.DataTypeLabel = uilabel(app.GridLayout4);
         app.DataTypeLabel.Text = 'Data Type';
         app.DataType = uidropdown(app.GridLayout4);
         app.DataType.Items = {'Analog', 'Counter'};
         app.DataType.ItemsData = {'Data analog', 'Data counter'};
         app.DataType.ValueChangedFcn = createCallbackFcn(app, @DataTypeValueChanged, true);
         app.DataType.Value = 'Data analog';
         app.PositionTab = uitab(app.TabGroup);
         app.PositionTab.Title = 'Position';
         app.GridLayout3 = uigridlayout(app.PositionTab);
         app.XDown = uibutton(app.GridLayout3, 'push');
         app.XDown.ButtonPushedFcn = createCallbackFcn(app, @XDownButtonPushed, true);
         app.XDown.Text = '<';
         app.XUp = uibutton(app.GridLayout3, 'push');
         app.XUp.ButtonPushedFcn = createCallbackFcn(app, @XUpButtonPushed, true);
         app.XUp.Text = '>';
         app.XIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition.ValueChangedFcn = createCallbackFcn(app, @XPositionValueChanged, true);
         app.AbsolutepositionLabel = uilabel(app.GridLayout3);
         app.AbsolutepositionLabel.Text = {'Absolute'; 'position'};
         app.IncrementLabel = uilabel(app.GridLayout3);
         app.IncrementLabel.Text = 'Increment';
         app.YIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.YPosition = uieditfield(app.GridLayout3, 'numeric');
         app.YPosition.ValueChangedFcn = createCallbackFcn(app, @YPositionValueChanged, true);
         app.StagetolerancemLabel = uilabel(app.GridLayout3);
         app.StagetolerancemLabel.Text = {'Stage'; 'tolerance'; '(μm)'};
         app.StageTolerance = uieditfield(app.GridLayout3, 'numeric');
         app.StageTolerance.Limits = [0 Inf];
         app.StageTolerance.ValueChangedFcn = createCallbackFcn(app, @StageToleranceValueChanged, true);
         app.StageTolerance.Value = 0.01;
         app.AxisLabel = uilabel(app.GridLayout3);
         app.AxisLabel.Text = 'Axis';
         app.StagexLabel = uilabel(app.GridLayout3);
         app.StagexLabel.Text = 'Stage x';
         app.StageyLabel = uilabel(app.GridLayout3);
         app.StageyLabel.Text = 'Stage y';
         app.StagezLabel = uilabel(app.GridLayout3);
         app.StagezLabel.Text = 'Stage z';
         app.MovenegativeLabel = uilabel(app.GridLayout3);
         app.MovenegativeLabel.Text = {'Move'; 'negative'};
         app.MovepositiveLabel = uilabel(app.GridLayout3);
         app.MovepositiveLabel.Text = {'Move'; 'positive'};
         app.YDown = uibutton(app.GridLayout3, 'push');
         app.YDown.ButtonPushedFcn = createCallbackFcn(app, @YDownButtonPushed, true);
         app.YDown.Text = '<';
         app.ZDown = uibutton(app.GridLayout3, 'push');
         app.ZDown.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
         app.ZDown.Text = '<';
         app.YUp = uibutton(app.GridLayout3, 'push');
         app.YUp.ButtonPushedFcn = createCallbackFcn(app, @YUpButtonPushed, true);
         app.YUp.Text = '>';
         app.ZUp = uibutton(app.GridLayout3, 'push');
         app.ZUp.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
         app.ZUp.Text = '>';
         app.ZPosition = uieditfield(app.GridLayout3, 'numeric');
         app.ZPosition.ValueChangedFcn = createCallbackFcn(app, @ZPositionValueChanged, true);
         app.ZIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.AddressEditField_2Label = uilabel(app.GridLayout3);
         app.AddressEditField_2Label.Text = 'Address';
         app.StageAddress = uieditfield(app.GridLayout3, 'numeric');
         app.StageAddress.Limits = [1 Inf];
         app.StageAddress.Value = 1;
         app.StageOptimizationInstructions = uitable(app.GridLayout3);
         app.StageOptimizationInstructions.ColumnName = {'Stage'; 'Radius'; 'Steps'};
         app.StageOptimizationInstructions.RowName = {};
         app.StageOptimizationInstructions.CellEditCallback = createCallbackFcn(app, @StageOptimizationInstructionsCellEdit, true);
         app.AddOptimizationStep = uibutton(app.GridLayout3, 'push');
         app.AddOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @AddOptimizationStepPushed, true);
         app.AddOptimizationStep.Text = 'Add';
         app.DeleteOptimizationStep = uibutton(app.GridLayout3, 'push');
         app.DeleteOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteOptimizationStepPushed, true);
         app.DeleteOptimizationStep.Text = 'Delete ';
         app.StageAutoOptimization = uicheckbox(app.GridLayout3);
         app.StageAutoOptimization.ValueChangedFcn = createCallbackFcn(app, @StageAutoOptimizationValueChanged, true);
         app.StageAutoOptimization.Text = {'Auto-optimize'; 'during scan'};
         app.TimebetweenoptimizationsminLabel = uilabel(app.GridLayout3);
         app.TimebetweenoptimizationsminLabel.Text = {'Time between'; 'optimizations (min)'};
         app.OptimizationInterval = uieditfield(app.GridLayout3, 'numeric');
         app.OptimizationInterval.ValueChangedFcn = createCallbackFcn(app, @OptimizationIntervalValueChanged, true);
         app.BeginmanualoptimizationButton = uibutton(app.GridLayout3, 'push');
         app.BeginmanualoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginmanualoptimizationButtonPushed, true);
         app.BeginmanualoptimizationButton.Text = 'Begin manual optimization';
         app.NumberofmanualoptimizationsLabel = uilabel(app.GridLayout3);
         app.NumberofmanualoptimizationsLabel.Text = {'Number of manual'; ' optimizations'};
         app.Numberofmanualoptimizations = uieditfield(app.GridLayout3, 'numeric');
         app.OptimizationmethodDropDownLabel = uilabel(app.GridLayout3);
         app.OptimizationmethodDropDownLabel.Text = {'Optimization'; 'method'};
         app.OptimizationmethodDropDown = uidropdown(app.GridLayout3);
         app.OptimizationmethodDropDown.Items = {'max value', 'gaussian'};
         app.OptimizationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationmethodDropDownValueChanged, true);
         app.OptimizationmethodDropDown.Value = 'max value';
         app.PausedurationforfinestagemovementsmsLabel = uilabel(app.GridLayout3);
         app.PausedurationforfinestagemovementsmsLabel.Text = {'Settling duration'; 'for stage'; 'movements (ms)'};
         app.StageSettlingTime = uieditfield(app.GridLayout3, 'numeric');
         app.StageSettlingTime.ValueChangedFcn = createCallbackFcn(app, @StageSettlingTimeValueChanged, true);
         app.ResetFineAxisLabel = uilabel(app.GridLayout3);
         app.ResetFineAxisLabel.Text = {'Reset'; 'Fine Axis'};
         app.XFineReset = uibutton(app.GridLayout3, 'push');
         app.XFineReset.ButtonPushedFcn = createCallbackFcn(app, @XFineResetPushed, true);
         app.XFineReset.Text = {'Reset'; 'fine x'};
         app.YFineReset = uibutton(app.GridLayout3, 'push');
         app.YFineReset.ButtonPushedFcn = createCallbackFcn(app, @YFineResetButtonPushed, true);
         app.YFineReset.Text = {'Reset'; 'fine y'};
         app.ZFineReset = uibutton(app.GridLayout3, 'push');
         app.ZFineReset.ButtonPushedFcn = createCallbackFcn(app, @ZFineResetButtonPushed, true);
         app.ZFineReset.Text = {'Reset'; 'fine z'};
         app.OptimizationparameterLabel = uilabel(app.GridLayout3);
         app.OptimizationparameterLabel.Text = {'Optimization'; 'parameter'};
         app.OptimizationparameterDropDown = uidropdown(app.GridLayout3);
         app.OptimizationparameterDropDown.Items = {'Counts (RF off)', 'Counts (RF on)', 'Contrast'};
         app.OptimizationparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationparameterDropDownValueChanged, true);
         app.OptimizationparameterDropDown.Value = 'Counts (RF off)';
         app.OptimizationTimer = uicheckbox(app.GridLayout3);
         app.OptimizationTimer.ValueChangedFcn = createCallbackFcn(app, @OptimizationTimerValueChanged, true);
         app.OptimizationTimer.Text = {'Use timer to '; 'force optimization'};
         app.OptimizationPercentageCheck = uicheckbox(app.GridLayout3);
         app.OptimizationPercentageCheck.ValueChangedFcn = createCallbackFcn(app, @OptimizationPercentageCheckValueChanged, true);
         app.OptimizationPercentageCheck.Text = {'Use percentage '; 'to force optimization'};
         app.TimebetweenoptimizationsminLabel_2 = uilabel(app.GridLayout3);
         app.TimebetweenoptimizationsminLabel_2.Text = {'Percentage to'; 'force optimization'};
         app.OptimizationPercentage = uieditfield(app.GridLayout3, 'numeric');
         app.OptimizationPercentage.ValueChangedFcn = createCallbackFcn(app, @OptimizationPercentageValueChanged, true);
         app.OptimizationInsertorReplace = uidropdown(app.GridLayout3);
         app.OptimizationInsertorReplace.Items = {'Insert', 'Replace'};
         app.OptimizationInsertorReplace.Value = 'Insert';
         app.ScansTab = uitab(app.TabGroup);
         app.ScansTab.Title = 'Scans';
         app.GridLayout6 = uigridlayout(app.ScansTab);
         app.ScanningTable = uitable(app.GridLayout6);
         app.ScanningTable.ColumnName = {'Type'; 'Instrument'; 'Start'; 'End'; '# of steps'; 'Step size'; 'Pulse addresses'; 'notes'};
         app.ScanningTable.RowName = {};
         app.ScanningTable.ColumnEditable = true;
         app.ScanningTable.CellEditCallback = createCallbackFcn(app, @ScanningTableCellEdit, true);
         app.AddScanStep = uibutton(app.GridLayout6, 'push');
         app.AddScanStep.ButtonPushedFcn = createCallbackFcn(app, @AddScanStepPushed, true);
         app.AddScanStep.Text = 'Add scan';
         app.DeleteScanStep = uibutton(app.GridLayout6, 'push');
         app.DeleteScanStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanStepPushed, true);
         app.DeleteScanStep.Text = 'Delete scan';
         app.AddressEditField_3Label = uilabel(app.GridLayout6);
         app.AddressEditField_3Label.Text = 'Address';
         app.ScanAddress = uieditfield(app.GridLayout6, 'numeric');
         app.ScanAddress.Limits = [1 10];
         app.ScanAddress.RoundFractionalValues = 'on';
         app.ScanAddress.Value = 1;
         app.DefaultchangeDropDownLabel = uilabel(app.GridLayout6);
         app.DefaultchangeDropDownLabel.Text = 'Default change';
         app.DefaultScanChange = uidropdown(app.GridLayout6);
         app.DefaultScanChange.Items = {'# of steps', 'Step size'};
         app.DefaultScanChange.Value = '# of steps';
         app.RandomizescanCheckBox = uicheckbox(app.GridLayout6);
         app.RandomizescanCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizescanCheckBoxValueChanged, true);
         app.RandomizescanCheckBox.Text = 'Randomize scan points';
         app.MaxIterationsEditFieldLabel = uilabel(app.GridLayout6);
         app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
         app.MaxIterations = uieditfield(app.GridLayout6, 'numeric');
         app.MaxIterations.Limits = [0 Inf];
         app.MaxIterations.Value = 1;
         app.IterationstypeDropDownLabel = uilabel(app.GridLayout6);
         app.IterationstypeDropDownLabel.Text = 'Iterations type';
         app.IterationsType = uidropdown(app.GridLayout6);
         app.IterationsType.Items = {'Number', 'Time (min)', 'Perpetual'};
         app.IterationsType.Value = 'Number';
         app.TempdatapointdeviationLabel = uilabel(app.GridLayout6);
         app.TempdatapointdeviationLabel.Text = {'Data Point '; 'Deviation'};
         app.DataPointDeviation = uieditfield(app.GridLayout6, 'numeric');
         app.DataPointDeviation.ValueChangedFcn = createCallbackFcn(app, @DataPointDeviationValueChanged, true);
         app.TempdatapointdeviationLabel_2 = uilabel(app.GridLayout6);
         app.TempdatapointdeviationLabel_2.Text = {'forced collection'; 'pause time'};
         app.forcedCollectionPauseTime = uieditfield(app.GridLayout6, 'numeric');
         app.forcedCollectionPauseTime.Limits = [0 Inf];
         app.forcedCollectionPauseTime.ValueChangedFcn = createCallbackFcn(app, @forcedCollectionPauseTimeValueChanged, true);
         app.TempdatapointdeviationLabel_3 = uilabel(app.GridLayout6);
         app.TempdatapointdeviationLabel_3.Text = {'max failed'; 'collections'};
         app.maxFailedCollections = uieditfield(app.GridLayout6, 'numeric');
         app.maxFailedCollections.Limits = [0 Inf];
         app.maxFailedCollections.RoundFractionalValues = 'on';
         app.maxFailedCollections.ValueChangedFcn = createCallbackFcn(app, @maxFailedCollectionsValueChanged, true);
         app.Notifications = uicheckbox(app.GridLayout6);
         app.Notifications.ValueChangedFcn = createCallbackFcn(app, @NotificationsValueChanged, true);
         app.Notifications.Text = 'Notifications';
         app.OutputTab = uitab(app.TabGroup);
         app.OutputTab.Title = 'Output';
         app.GridLayout5 = uigridlayout(app.OutputTab);
         app.PlotLimits = uitable(app.GridLayout5);
         app.PlotLimits.ColumnName = {'Axis'; 'Minimum'; 'Maximum'; 'Override'; 'Offset'; 'Incorporate Offset'};
         app.PlotLimits.RowName = {};
         app.PlotLimits.ColumnEditable = [false true true true true true];
         app.PlotLimits.CellEditCallback = createCallbackFcn(app, @PlotLimitsCellEdit, true);
         app.DisplayIterations = uidropdown(app.GridLayout5);
         app.DisplayIterations.Items = {'Display average across iterations', 'Display current iteration', 'Display prior iterations', 'Display custom iterations'};
         app.DisplayIterations.ItemsData = {'Average', 'Current', 'Prior', 'Custom'};
         app.DisplayIterations.ValueChangedFcn = createCallbackFcn(app, @DisplayIterationsValueChanged, true);
         app.DisplayIterations.Value = 'Average';
         app.ContrasttypeDropDownLabel = uilabel(app.GridLayout5);
         app.ContrasttypeDropDownLabel.Text = 'Contrast type';
         app.ContrastType = uidropdown(app.GridLayout5);
         app.ContrastType.Items = {'Ref - Sig', '(Ref - Sig)/Ref', '(Ref - Sig)/Sig', '(Ref - Sig)/(Ref + Sig)'};
         app.ContrastType.ValueChangedFcn = createCallbackFcn(app, @ContrastTypeValueChanged, true);
         app.ContrastType.Value = '(Ref - Sig)/Ref';
         app.ForceplotupdateButton = uibutton(app.GridLayout5, 'push');
         app.ForceplotupdateButton.ButtonPushedFcn = createCallbackFcn(app, @ForceplotupdateButtonPushed, true);
         app.ForceplotupdateButton.Text = 'Force plot update';
         app.ThingToPlot = uidropdown(app.GridLayout5);
         app.ThingToPlot.Items = {'Plot contrast', 'Plot signal', 'Plot reference', 'Plot pulse sequence', 'Plot SNR', 'Plot number of data points', 'Plot Fourier Transform'};
         app.ThingToPlot.ItemsData = {'Contrast', 'Signal', 'Reference', 'PulseSequence', 'SNR', 'DataPoints', 'fft'};
         app.ThingToPlot.ValueChangedFcn = createCallbackFcn(app, @ThingToPlotValueChanged, true);
         app.ThingToPlot.Value = 'Contrast';
         app.ScanforxaxisLabel_2 = uilabel(app.GridLayout5);
         app.ScanforxaxisLabel_2.Text = 'Pulse # to plot';
         app.PulseNumToPlot = uieditfield(app.GridLayout5, 'numeric');
         app.PulseNumToPlot.Limits = [0 Inf];
         app.PulseNumToPlot.RoundFractionalValues = 'on';
         app.PulseNumToPlot.ValueChangedFcn = createCallbackFcn(app, @PulseNumToPlotValueChanged, true);
         app.PulseNumToPlot.Value = 1;
         app.BaselineSubtractionLabel = uilabel(app.GridLayout5);
         app.BaselineSubtractionLabel.Text = {'Baseline '; 'Subtraction'};
         app.BaselineSubtraction = uieditfield(app.GridLayout5, 'numeric');
         app.BaselineSubtraction.ValueChangedFcn = createCallbackFcn(app, @BaselineSubtractionValueChanged, true);
         app.CustomIterationBoundsLabel = uilabel(app.GridLayout5);
         app.CustomIterationBoundsLabel.Text = 'Custom Iteration Bounds                              :                            ';
         app.IterationStart = uieditfield(app.GridLayout5, 'numeric');
         app.IterationStart.Limits = [1 Inf];
         app.IterationStart.RoundFractionalValues = 'on';
         app.IterationStart.ValueChangedFcn = createCallbackFcn(app, @IterationStartValueChanged, true);
         app.IterationStart.Value = 1;
         app.IterationEnd = uieditfield(app.GridLayout5, 'numeric');
         app.IterationEnd.Limits = [1 Inf];
         app.IterationEnd.ValueChangedFcn = createCallbackFcn(app, @IterationEndValueChanged, true);
         app.IterationEnd.Value = 1;
         app.AdditionType = uidropdown(app.GridLayout5);
         app.AdditionType.Items = {'Manual', 'automatic (correlation)', 'automatic (XY8-N)'};
         app.AdditionType.ItemsData = {'man', 'autoCorr', 'autoXY'};
         app.AdditionType.Value = 'man';
         app.ContrasttypeDropDownLabel_2 = uilabel(app.GridLayout5);
         app.ContrasttypeDropDownLabel_2.Text = {'Vertical Line'; ' Addtion Type'};
         app.ScanforxaxisLabel_3 = uilabel(app.GridLayout5);
         app.ScanforxaxisLabel_3.Text = {'x Location '; '(manual)'};
         app.manLocation = uieditfield(app.GridLayout5, 'numeric');
         app.NucleusTypeLabel = uilabel(app.GridLayout5);
         app.NucleusTypeLabel.Text = {'Nucleus Type'; '(automatic)'};
         app.LineNucleusType = uidropdown(app.GridLayout5);
         app.LineNucleusType.Items = {'1H', '13C', '19F'};
         app.LineNucleusType.Value = '1H';
         app.AddLineButton = uibutton(app.GridLayout5, 'push');
         app.AddLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddLineButtonPushed, true);
         app.AddLineButton.Text = 'Add Line';
         app.DeleteLineButton = uibutton(app.GridLayout5, 'push');
         app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
         app.DeleteLineButton.Text = 'Delete Line';
         app.ScanforxaxisLabel_4 = uilabel(app.GridLayout5);
         app.ScanforxaxisLabel_4.Text = {'Line to delete (1 '; 'is lowest x value)'};
         app.LineDeleteAddress = uieditfield(app.GridLayout5, 'numeric');
         app.LineDeleteAddress.Limits = [1 Inf];
         app.LineDeleteAddress.RoundFractionalValues = 'on';
         app.LineDeleteAddress.Value = 1;
         app.HideLinesCheckBox = uicheckbox(app.GridLayout5);
         app.HideLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideLinesCheckBoxValueChanged, true);
         app.HideLinesCheckBox.Text = 'Hide Lines';
         app.PlotDimensionality = uidropdown(app.GridLayout5);
         app.PlotDimensionality.Items = {'One D', 'Two D'};
         app.PlotDimensionality.Value = 'One D';
         app.twoDOutput = uitab(app.TabGroup);
         app.twoDOutput.Title = '2D output ';
         app.GridLayout11 = uigridlayout(app.twoDOutput);
         app.ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox = uicheckbox(app.GridLayout11);
         app.ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox.Text = {'Convert to counts/s '; '**May not perfectly '; 'calculate for small times'};
         app.ForcesquareaspectratioCheckBox = uicheckbox(app.GridLayout11);
         app.ForcesquareaspectratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcesquareaspectratioCheckBoxValueChanged, true);
         app.ForcesquareaspectratioCheckBox.Text = {'Force square '; 'aspect ratio'};
         app.UseStaticColorLimits = uicheckbox(app.GridLayout11);
         app.UseStaticColorLimits.ValueChangedFcn = createCallbackFcn(app, @UseStaticColorLimitsValueChanged, true);
         app.UseStaticColorLimits.Text = {'Use static '; 'color limits'};
         app.StaticColorLimits = uitable(app.GridLayout11);
         app.StaticColorLimits.ColumnName = {'Static color limit'; 'Value'};
         app.StaticColorLimits.RowName = {};
         app.StaticColorLimits.ColumnEditable = [false true];
         app.StaticColorLimits.CellEditCallback = createCallbackFcn(app, @StaticColorLimitsCellEdit, true);
         app.ScanStepTable = uitable(app.GridLayout11);
         app.ScanStepTable.ColumnName = {'Scan Type'; 'Step to View'};
         app.ScanStepTable.RowName = {};
         app.ScanStepTable.ColumnEditable = [false true];
         app.ScanStepTable.CellEditCallback = createCallbackFcn(app, @ScanStepTableCellEdit, true);
         app.ScanforxaxisLabel_5 = uilabel(app.GridLayout11);
         app.ScanforxaxisLabel_5.Text = 'Scan # for x axis';
         app.xAxisScanNum = uieditfield(app.GridLayout11, 'numeric');
         app.xAxisScanNum.Limits = [0 Inf];
         app.xAxisScanNum.RoundFractionalValues = 'on';
         app.xAxisScanNum.ValueChangedFcn = createCallbackFcn(app, @xAxisScanNumValueChanged, true);
         app.xAxisScanNum.Value = 1;
         app.ScanforyaxisLabel = uilabel(app.GridLayout11);
         app.ScanforyaxisLabel.Text = 'Scan # for y axis';
         app.yAxisScanNum = uieditfield(app.GridLayout11, 'numeric');
         app.yAxisScanNum.Limits = [0 Inf];
         app.yAxisScanNum.RoundFractionalValues = 'on';
         app.yAxisScanNum.ValueChangedFcn = createCallbackFcn(app, @yAxisScanNumValueChanged, true);
         app.SaveLoadTab = uitab(app.TabGroup);
         app.SaveLoadTab.Title = 'Save/Load';
         app.GridLayout9 = uigridlayout(app.SaveLoadTab);
         app.BoxaddressEditFieldLabel = uilabel(app.GridLayout9);
         app.BoxaddressEditFieldLabel.Text = 'Box address';
         app.BoxAddress = uieditfield(app.GridLayout9, 'text');
         app.BoxAddress.ValueChangedFcn = createCallbackFcn(app, @BoxAddressValueChanged, true);
         app.BoxAddress.Value = 'C:\Users\User\Box';
         app.SavenameLabel = uilabel(app.GridLayout9);
         app.SavenameLabel.Text = {'Save name'; ''};
         app.SaveFolderName = uieditfield(app.GridLayout9, 'text');
         app.SaveButton = uibutton(app.GridLayout9, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.LoadButton = uibutton(app.GridLayout9, 'push');
         app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
         app.LoadButton.Text = 'Load';
         app.SavedUserNotes = uitextarea(app.GridLayout9);
         app.SavedUserNotes.Value = {'User notes'; ''};
         app.DataSetupsSwitch = uiswitch(app.GridLayout9, 'slider');
         app.DataSetupsSwitch.Items = {'Data', 'Setups'};
         app.DataSetupsSwitch.ValueChangedFcn = createCallbackFcn(app, @DataSetupsSwitchValueChanged, true);
         app.DataSetupsSwitch.Value = 'Setups';
         app.LoadDates = uilistbox(app.GridLayout9);
         app.LoadDates.Items = {'Setup Versions', 'v# - yyyy/mm/dd', ''};
         app.LoadDates.ValueChangedFcn = createCallbackFcn(app, @LoadDatesValueChanged, true);
         app.LoadDates.ClickedFcn = createCallbackFcn(app, @LoadDatesClicked, true);
         app.LoadDates.Value = 'Setup Versions';
         app.LoadNames = uilistbox(app.GridLayout9);
         app.LoadNames.Items = {'Setup Names', 'any string'};
         app.LoadNames.ValueChangedFcn = createCallbackFcn(app, @LoadNamesValueChanged, true);
         app.LoadNames.Value = 'Setup Names';
         app.UserName = uidropdown(app.GridLayout9);
         app.UserName.Items = {''};
         app.UserName.ValueChangedFcn = createCallbackFcn(app, @UserNameValueChanged, true);
         app.UserName.Value = '';
         app.PulseSequenceTab = uitab(app.TabGroup);
         app.PulseSequenceTab.Title = 'Pulse Sequence';
         app.GridLayout10 = uigridlayout(app.PulseSequenceTab);
         app.PulseSequenceTable = uitable(app.GridLayout10);
         app.PulseSequenceTable.ColumnName = {'#'; 'Active Channels'; 'Binary'; '# Output'; 'Duration (ns)'; 'Direction Type'; 'Context Info'; 'notes'};
         app.PulseSequenceTable.RowName = {};
         app.PulseSequenceTable.ColumnEditable = true;
         app.PulseSequenceTable.CellEditCallback = createCallbackFcn(app, @PulseSequenceTableCellEdit, true);
         app.AddStepsButton = uibutton(app.GridLayout10, 'state');
         app.AddStepsButton.ValueChangedFcn = createCallbackFcn(app, @AddStepsButtonValueChanged, true);
         app.AddStepsButton.Text = 'Add Steps';
         app.DeleteStepsButton = uibutton(app.GridLayout10, 'state');
         app.DeleteStepsButton.ValueChangedFcn = createCallbackFcn(app, @DeleteStepsButtonValueChanged, true);
         app.DeleteStepsButton.Text = 'Delete Steps';
         app.LoopsOrDuration = uidropdown(app.GridLayout10);
         app.LoopsOrDuration.Items = {'# of Loops', 'Duration (s)'};
         app.LoopsOrDuration.ValueChangedFcn = createCallbackFcn(app, @LoopsOrDurationValueChanged, true);
         app.LoopsOrDuration.Value = '# of Loops';
         app.TotalLoops = uieditfield(app.GridLayout10, 'numeric');
         app.TotalLoops.Limits = [0 Inf];
         app.TotalLoops.ValueChangedFcn = createCallbackFcn(app, @TotalLoopsValueChanged, true);
         app.TotalLoops.Value = 1000;
         app.SavePulseSequence = uibutton(app.GridLayout10, 'state');
         app.SavePulseSequence.ValueChangedFcn = createCallbackFcn(app, @SavePulseSequenceValueChanged, true);
         app.SavePulseSequence.Text = 'Save';
         app.LoadPulse = uibutton(app.GridLayout10, 'state');
         app.LoadPulse.ValueChangedFcn = createCallbackFcn(app, @LoadPulseValueChanged, true);
         app.LoadPulse.Text = 'Load';
         app.BoxaddressEditFieldLabel_2 = uilabel(app.GridLayout10);
         app.BoxaddressEditFieldLabel_2.Text = 'Box address';
         app.PulseBoxAddress = uieditfield(app.GridLayout10, 'text');
         app.PulseBoxAddress.ValueChangedFcn = createCallbackFcn(app, @PulseBoxAddressValueChanged, true);
         app.PulseBoxAddress.Value = 'C:\Users\User\Box';
         app.SavenameLabel_2 = uilabel(app.GridLayout10);
         app.SavenameLabel_2.Text = {'Save name'; ''};
         app.PulseSaveName = uieditfield(app.GridLayout10, 'text');
         app.PulseSequenceNames = uilistbox(app.GridLayout10);
         app.PulseSequenceNames.Items = {'Pulse Sequence Names'};
         app.PulseSequenceNames.ValueChangedFcn = createCallbackFcn(app, @PulseSequenceNamesValueChanged, true);
         app.PulseSequenceNames.Value = 'Pulse Sequence Names';
         app.PulseUserNotes = uitextarea(app.GridLayout10);
         app.PulseUserNotes.Placeholder = 'User Notes';
         app.CopytoButton = uibutton(app.GridLayout10, 'state');
         app.CopytoButton.ValueChangedFcn = createCallbackFcn(app, @CopytoButtonValueChanged, true);
         app.CopytoButton.Text = 'Copy to';
         app.CopyAddresses = uieditfield(app.GridLayout10, 'text');
         app.UserName2 = uidropdown(app.GridLayout10);
         app.UserName2.Items = {''};
         app.UserName2.ValueChangedFcn = createCallbackFcn(app, @UserName2ValueChanged, true);
         app.UserName2.Value = '';
         app.pulsePlotVisible = uibutton(app.GridLayout10, 'state');
         app.pulsePlotVisible.ValueChangedFcn = createCallbackFcn(app, @pulsePlotVisibleValueChanged, true);
         app.pulsePlotVisible.Text = 'Display Pulse Sequence Plot';
         app.PulseAddress = uieditfield(app.GridLayout10, 'text');
         app.BoxaddressEditFieldLabel_3 = uilabel(app.GridLayout10);
         app.BoxaddressEditFieldLabel_3.Text = 'Pulse Address';
         app.SequenceTemplatesTab = uitab(app.TabGroup);
         app.SequenceTemplatesTab.Title = 'Sequence Templates';
         app.GridLayout12 = uigridlayout(app.SequenceTemplatesTab);
         app.TemplateType = uidropdown(app.GridLayout12);
         app.TemplateType.Items = {'Rabi', 'Spin Echo', 'XYN', 'Correlation', 'DEER Frequency', 'DEER Duration', 'CPMG'};
         app.TemplateType.ValueChangedFcn = createCallbackFcn(app, @TemplateTypeValueChanged, true);
         app.TemplateType.Value = 'Rabi';
         app.TemplateTable = uitable(app.GridLayout12);
         app.TemplateTable.ColumnName = {'Pulse Type'; 'Duration'};
         app.TemplateTable.RowName = {};
         app.TemplateTable.ColumnEditable = [false true];
         app.TemplateTable.CellEditCallback = createCallbackFcn(app, @TemplateTableCellEdit, true);
         app.LoadTemplate = uibutton(app.GridLayout12, 'push');
         app.LoadTemplate.ButtonPushedFcn = createCallbackFcn(app, @LoadTemplateButtonPushed, true);
         app.LoadTemplate.Text = 'Load Template';
         app.RightPanel = uipanel(app.UIFigure);
         app.Panel = uipanel(app.RightPanel);
         app.GridLayout7 = uigridlayout(app.Panel);
         app.OutputPanel = uipanel(app.GridLayout7);
         app.OneDAxes = uiaxes(app.OutputPanel);
         xlabel(app.OneDAxes, 'X')
         ylabel(app.OneDAxes, 'Y')
         zlabel(app.OneDAxes, 'Z')
         app.OneDAxes.Visible = 'off';
         app.PulseSequenceAxes = uiaxes(app.OutputPanel);
         xlabel(app.PulseSequenceAxes, 'Time(ns)')
         zlabel(app.PulseSequenceAxes, 'Z')
         app.PulseSequenceAxes.Visible = 'off';
         app.TwoDAxes = uiaxes(app.OutputPanel);
         app.TwoDAxes.ButtonDownFcn = createCallbackFcn(app, @TwoDAxesButtonDown, true);
         app.TwoDAxes.Visible = 'off';
         app.StartButton = uibutton(app.GridLayout7, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.ResetButton = uibutton(app.GridLayout7, 'push');
         app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
         app.ResetButton.Text = 'Reset';
         app.ColorBarPanel = uipanel(app.GridLayout7);
         app.ColorbarAxes = uiaxes(app.ColorBarPanel);
         app.ColorbarAxes.Visible = 'off';
         app.StatusLamp = uilamp(app.GridLayout7);
         app.AbortButton = uibutton(app.GridLayout7, 'push');
         app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
         app.AbortButton.Text = 'Abort';
         app.ScreenshotButton = uibutton(app.GridLayout7, 'push');
         app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
         app.ScreenshotButton.Text = 'Screenshot';
         app.scanTime = uilabel(app.GridLayout7);
         app.scanTime.Text = {'Total Scan Time:'; 'Time Remaining:'};
         app.ContextMenu = uicontextmenu(app.UIFigure);
         app.Menu = uimenu(app.ContextMenu);
         app.Menu.Text = 'Menu';
         app.Menu2 = uimenu(app.ContextMenu);
         app.Menu2.Text = 'Menu2';
         app.versionsContext = uicontextmenu(app.UIFigure);
         app.DatesDelete = uimenu(app.versionsContext);
         app.DatesDelete.MenuSelectedFcn = createCallbackFcn(app, @DatesDeleteSelected, true);
         app.DatesDelete.Text = 'Delete';
         % Assign app.versionsContext
         app.LoadDates.ContextMenu = app.versionsContext;
         app.ContextMenu2 = uicontextmenu(app.UIFigure);
         app.NamesDelete = uimenu(app.ContextMenu2);
         app.NamesDelete.MenuSelectedFcn = createCallbackFcn(app, @NamesDeleteSelected, true);
         app.NamesDelete.Text = 'Delete';
         % Assign app.ContextMenu2
         app.LoadNames.ContextMenu = app.ContextMenu2;
         app.PulseSequenceContext = uicontextmenu(app.UIFigure);
         app.PulseDelete = uimenu(app.PulseSequenceContext);
         app.PulseDelete.MenuSelectedFcn = createCallbackFcn(app, @PulseDeleteMenuSelected, true);
         app.PulseDelete.Text = 'Delete';
         % Assign app.PulseSequenceContext
         app.PulseSequenceNames.ContextMenu = app.PulseSequenceContext;
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = NV_ExperimentEditor

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFunction)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end