classdef ExperimentEditor_OOP < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                        matlab.ui.Figure
      Panel                           matlab.ui.container.Panel
      GridLayout2                     matlab.ui.container.GridLayout
      AbortButton                     matlab.ui.control.Button
      ScreenshotButton                matlab.ui.control.Button
      StatusLamp                      matlab.ui.control.Lamp
      ColorBarPanel                   matlab.ui.container.Panel
      OutputInfo                      matlab.ui.control.TextArea
      ResetButton                     matlab.ui.control.Button
      StartButton                     matlab.ui.control.Button
      OutputPanel                     matlab.ui.container.Panel
      TabGroup                        matlab.ui.container.TabGroup
      ConnectionsTab                  matlab.ui.container.Tab
      GridLayout                      matlab.ui.container.GridLayout
      AllinstrumentsLabel             matlab.ui.control.Label
      nmlaserLabel_3                  matlab.ui.control.Label
      nmlaserLabel_2                  matlab.ui.control.Label
      nmlaserLabel                    matlab.ui.control.Label
      HammamatsusCMOScameraLabel      matlab.ui.control.Label
      StaticDelayLineSDLLabel         matlab.ui.control.Label
      DynamicDelayLineDDLLabel        matlab.ui.control.Label
      StageLabel                      matlab.ui.control.Label
      AllInstrumentsLamp              matlab.ui.control.Lamp
      UnloadAll                       matlab.ui.control.Button
      LoadAll                         matlab.ui.control.Button
      StaticDelayLineLamp             matlab.ui.control.Lamp
      SDLUnload                       matlab.ui.control.Button
      SDLLoad                         matlab.ui.control.Button
      Unload488                       matlab.ui.control.Button
      Load488                         matlab.ui.control.Button
      Load561                         matlab.ui.control.Button
      Unload561                       matlab.ui.control.Button
      Unload640                       matlab.ui.control.Button
      HammUnload                      matlab.ui.control.Button
      DDLUnload                       matlab.ui.control.Button
      Lamp561                         matlab.ui.control.Lamp
      Lamp488                         matlab.ui.control.Lamp
      Lamp640                         matlab.ui.control.Lamp
      HammamatsuLamp                  matlab.ui.control.Lamp
      DynamicDelayLineLamp            matlab.ui.control.Lamp
      Load640                         matlab.ui.control.Button
      HammLoad                        matlab.ui.control.Button
      DDLLoad                         matlab.ui.control.Button
      StageLamp                       matlab.ui.control.Lamp
      StageUnload                     matlab.ui.control.Button
      StageLoad                       matlab.ui.control.Button
      SettingsTab                     matlab.ui.container.Tab
      GridLayout4                     matlab.ui.container.GridLayout
      CopyappobjecttoworkspaceButton  matlab.ui.control.Button
      ShowdatacontinuouslyButton      matlab.ui.control.Button
      TakesingleimageButton           matlab.ui.control.Button
      NumberofiterationsEditField     matlab.ui.control.NumericEditField
      NumberofiterationsLabel         matlab.ui.control.Label
      RefreshLabel                    matlab.ui.control.Label
      OnOffswitchLabel                matlab.ui.control.Label
      LasertypeLabel                  matlab.ui.control.Label
      Refresh640Laser                 matlab.ui.control.Button
      Refresh561Laser                 matlab.ui.control.Button
      CurrentOutput640                matlab.ui.control.NumericEditField
      CurrentOutput561                matlab.ui.control.NumericEditField
      PowerreadingmWLabel             matlab.ui.control.Label
      SetpowermWLabel                 matlab.ui.control.Label
      Refresh488Laser                 matlab.ui.control.Button
      CurrentOutput488                matlab.ui.control.NumericEditField
      RandomizeScan                   matlab.ui.control.CheckBox
      RefreshAppButton                matlab.ui.control.Button
      Toggle640                       matlab.ui.control.Switch
      LaserLabel_3                    matlab.ui.control.Label
      SetPower640                     matlab.ui.control.NumericEditField
      Toggle561                       matlab.ui.control.Switch
      LaserLabel_2                    matlab.ui.control.Label
      SetPower561                     matlab.ui.control.NumericEditField
      SetPower488                     matlab.ui.control.NumericEditField
      Toggle488                       matlab.ui.control.Switch
      LaserLabel                      matlab.ui.control.Label
      OutputTab                       matlab.ui.container.Tab
      GridLayout5                     matlab.ui.container.GridLayout
      Switch                          matlab.ui.control.Switch
      forceConcatenation              matlab.ui.control.CheckBox
      StaticColorLimits               matlab.ui.control.Table
      UseStaticColorLimits            matlab.ui.control.CheckBox
      DropDown                        matlab.ui.control.DropDown
      ForcesquareaspectratioCheckBox  matlab.ui.control.CheckBox
      ZoomOutButton                   matlab.ui.control.Button
      ZoomIn2buttonsquareButton       matlab.ui.control.Button
      ScanProgress                    matlab.ui.control.TextArea
      StartVideoButton                matlab.ui.control.Button
      LoopvideoCheckBox               matlab.ui.control.CheckBox
      OverridecurrentstepviewCheckBox  matlab.ui.control.CheckBox
      ScantoshowvideoofDropDown       matlab.ui.control.DropDown
      ScantoshowvideoofDropDownLabel  matlab.ui.control.Label
      ScanStepTable                   matlab.ui.control.Table
      ImageOrVideo                    matlab.ui.control.DropDown
      VideoPauseTime                  matlab.ui.control.NumericEditField
      PauseforeachvideostepEditFieldLabel  matlab.ui.control.Label
      LogarithmicContrastCheckBox     matlab.ui.control.CheckBox
      PlotLimits                      matlab.ui.control.Table
      ScansTab                        matlab.ui.container.Tab
      GridLayout6                     matlab.ui.container.GridLayout
      RandomizescanCheckBox           matlab.ui.control.CheckBox
      PossibletypesTextArea           matlab.ui.control.TextArea
      PossibletypesTextAreaLabel      matlab.ui.control.Label
      DefaultchangeDropDown           matlab.ui.control.DropDown
      DefaultchangeDropDownLabel      matlab.ui.control.Label
      ScanAddress                     matlab.ui.control.NumericEditField
      AddressEditField_3Label         matlab.ui.control.Label
      DeleteScanStep                  matlab.ui.control.Button
      AddScanStep                     matlab.ui.control.Button
      ScanningTable                   matlab.ui.control.Table
      AutofuseCheckBox                matlab.ui.control.CheckBox
      SaveLoadTab                     matlab.ui.container.Tab
      GridLayout9                     matlab.ui.container.GridLayout
      UserName                        matlab.ui.control.DropDown
      LoadNamesBox                    matlab.ui.control.ListBox
      LoadDatesBox                    matlab.ui.control.ListBox
      DataSetupsSwitch                matlab.ui.control.Switch
      TextArea                        matlab.ui.control.TextArea
      LoadButton                      matlab.ui.control.Button
      SaveButton                      matlab.ui.control.Button
      SaveoptionsDropDown             matlab.ui.control.DropDown
      SaveoptionsDropDownLabel        matlab.ui.control.Label
      SaveFolderName                  matlab.ui.control.EditField
      SavenameLabel                   matlab.ui.control.Label
      BoxaddressEditField             matlab.ui.control.EditField
      BoxaddressEditFieldLabel        matlab.ui.control.Label
      PositionTab                     matlab.ui.container.Tab
      GridLayout3                     matlab.ui.container.GridLayout
      StageOptimizationInterval       matlab.ui.control.NumericEditField
      TimebetweenoptimizationsminLabel  matlab.ui.control.Label
      StageAutoOptimization           matlab.ui.control.CheckBox
      StageoptimizationDropDown       matlab.ui.control.DropDown
      StageoptimizationDropDownLabel  matlab.ui.control.Label
      DeleteOptimizationStep          matlab.ui.control.Button
      AddOptimizationStep             matlab.ui.control.Button
      StageOptimizationInstructions   matlab.ui.control.Table
      StageAddress                    matlab.ui.control.NumericEditField
      AddressEditField_2Label         matlab.ui.control.Label
      XIncrement_6                    matlab.ui.control.NumericEditField
      XIncrement_5                    matlab.ui.control.NumericEditField
      XIncrement_4                    matlab.ui.control.NumericEditField
      XPosition_6                     matlab.ui.control.NumericEditField
      XPosition_5                     matlab.ui.control.NumericEditField
      ZIncrement                      matlab.ui.control.NumericEditField
      XPosition_4                     matlab.ui.control.NumericEditField
      ZPosition                       matlab.ui.control.NumericEditField
      XUp_6                           matlab.ui.control.Button
      XUp_5                           matlab.ui.control.Button
      XUp_4                           matlab.ui.control.Button
      ZUp                             matlab.ui.control.Button
      YUp                             matlab.ui.control.Button
      XDown_6                         matlab.ui.control.Button
      XDown_5                         matlab.ui.control.Button
      XDown_4                         matlab.ui.control.Button
      ZDown                           matlab.ui.control.Button
      YDown                           matlab.ui.control.Button
      SDLyawLabel                     matlab.ui.control.Label
      SDLpitchLabel                   matlab.ui.control.Label
      SDLlinearLabel                  matlab.ui.control.Label
      MovepositiveLabel               matlab.ui.control.Label
      MovenegativeLabel               matlab.ui.control.Label
      StagezLabel                     matlab.ui.control.Label
      StageyLabel                     matlab.ui.control.Label
      StagexLabel                     matlab.ui.control.Label
      AxisLabel                       matlab.ui.control.Label
      StagePrecision                  matlab.ui.control.NumericEditField
      StagetolerancemLabel            matlab.ui.control.Label
      YPosition                       matlab.ui.control.NumericEditField
      YIncrement                      matlab.ui.control.NumericEditField
      IncrementLabel                  matlab.ui.control.Label
      AbsolutepositionLabel           matlab.ui.control.Label
      XPosition                       matlab.ui.control.NumericEditField
      XIncrement                      matlab.ui.control.NumericEditField
      XUp                             matlab.ui.control.Button
      XDown                           matlab.ui.control.Button
      CameraTab                       matlab.ui.container.Tab
      GridLayout8                     matlab.ui.container.GridLayout
      SaveeveryframeSwitch            matlab.ui.control.Switch
      SaveeveryframeSwitchLabel       matlab.ui.control.Label
      UsesecondboundsSwitch           matlab.ui.control.Switch
      UsesecondboundsLabel            matlab.ui.control.Label
      UseboundsSwitch                 matlab.ui.control.Switch
      UseboundsSwitchLabel            matlab.ui.control.Label
      FuserAddress                    matlab.ui.control.NumericEditField
      AddressEditFieldLabel           matlab.ui.control.Label
      DeleteFuserStep                 matlab.ui.control.Button
      AddFuserStep                    matlab.ui.control.Button
      ImageBounds                     matlab.ui.control.Table
      ExposuretimeEditField           matlab.ui.control.NumericEditField
      ExposuretimeEditFieldLabel      matlab.ui.control.Label
      FramesperfuserstepEditField     matlab.ui.control.NumericEditField
      FramesperfuserstepLabel         matlab.ui.control.Label
      FramespertriggerEditField       matlab.ui.control.NumericEditField
      FramespertriggerEditFieldLabel  matlab.ui.control.Label
      FuseCiclesButton                matlab.ui.control.Button
      FuserInstructions               matlab.ui.control.Table
      FuserpercentilesEditField       matlab.ui.control.EditField
      FuserpercentilesEditFieldLabel  matlab.ui.control.Label
      FusercutoffmultipleEditField    matlab.ui.control.NumericEditField
      FusercutoffmultipleLabel        matlab.ui.control.Label
      SelectImageBoundsButton         matlab.ui.control.Button
      InfrequentTab                   matlab.ui.container.Tab
      GridLayout7                     matlab.ui.container.GridLayout
      StagePauseTime                  matlab.ui.control.NumericEditField
      PausedurationforfinestagemovementsmsLabel  matlab.ui.control.Label
      DefectCorrectionSwitch          matlab.ui.control.Switch
      DefectCorrectionSwitchLabel     matlab.ui.control.Label
      DefectcorrectionlevelDropDown   matlab.ui.control.DropDown
      DefectcorrectionlevelDropDownLabel  matlab.ui.control.Label
      XFineReset_6                    matlab.ui.control.Button
      XFineReset_5                    matlab.ui.control.Button
      XFineReset_4                    matlab.ui.control.Button
      IgnorePIstagesettlingtimeCheckBox  matlab.ui.control.CheckBox
      OffWhenCleaned640               matlab.ui.control.CheckBox
      OffWhenCleaned561               matlab.ui.control.CheckBox
      OffWhenCleaned488               matlab.ui.control.CheckBox
      WaitForEquilibrium640           matlab.ui.control.CheckBox
      WaitForEquilibrium561           matlab.ui.control.CheckBox
      WaitForEquilibrium488           matlab.ui.control.CheckBox
   end

   properties
   hammCam
   stageControl
   general
   storedData
   displayInfo
   laser488
   laser561
   laser640
   savedSettings
   end

   methods (Access = private)

      %Current reflections and deliberations ******

      %Replace app.general.scan.completed with
      %app.general.storedDataSettings.completed because user does not
      %control this and it is only relevant for whatever data is currently
      %stored

      %Likewise for .scan.stepToView, it is only relevant for display so I
      %should really put it in .display.stepToView

      %All stored settings necessary to interpret data:
      %bounds usage
      %frame stack usage
      %which step was most recently completed (not output display, just
      %info shown to user in outputSettings tab)
      %scan info: type, nSteps, start, end, and optionally stepSize


      %Notes regarding this app and its associated functions

      %First, I use a global variable - master - for holding all
      %information I wish to maintain between various functions. I am aware
      %global variables are generally frowned upon, however, after
      %analyzing my options, I found it to be the simplest and most
      %flexible solution. It is critical that every function be "on the
      %same page" and, quite often, those functions reference a whole host
      %of variables. It is possible that I could have instead used a
      %variable to hold all the information and used that as an argument in
      %every function I wrote but at that point it is just a global
      %variable by a different name.

      %Second, this is my second time developing an instrument-software app
      %like this. I have learned quite a few lessons from my first attempt
      %and this is significantly better for it. That said, there are still
      %many things I would do differently if I were to begin anew but, for
      %this app, have already been baked into its foundations too much to
      %easily change. My guiding goals when making this app were
      %simplicity, flexibility, and user friendliness. My previous attempt
      %was certainly spaghetti code; it was nearly impossible to weave
      %through and understand in its entirity, a state I wish to resolve
      %here. My previous code also continually ran into issues where new
      %features needed to be added but I was unable to integrate them
      %without essentially breaking everything or making something heavily
      %suboptimal. Finally, I am actually employing graphic design
      %principles in this app (at least to some degree) and I am trying to
      %make the process of learning the app easier.

      %Third, you will quickly notice that my variables could charitably be
      %called "thorough". I made this change as there are simply too many
      %variables throughout this for me to adequately keep track of and it
      %is thus necessary to refresh myself whenever I wished to employ
      %them. I found abbreviations were both annoying to remember and
      %difficult to teach to others. While my variables are long, they are
      %always very descriptive of what they represent. For less complex and
      %interwoven code bases I would be much more likely to simplify
      %variable names to make typing it out easier but, as mentioned
      %previously, there is altogether too much to keep track of as it is.

      %Fourth, much of the features within this app and its associated
      %functions were developed due to requests from users. As such, they
      %may be niche or otherwise somewhat odd, but this app was made to be
      %used by our lab and so I attempted to make it as useful to the
      %operators as possible. If anyone from outside of the Backlund lab at
      %UIUC is reading this, first of all you will need to greatly adjust
      %these functions to meet your instrument needs as this was developed
      %for the exact instruments we are using; additionally, you may wish
      %to add or remove features depending on what your users desire.
      %Nothing is sacred within this code base, alter it as you wish.
      %Though this comes with the warning that some alterations may have
      %unexpected side-effects elsewhere.

      %Fifth, as you can see from the above, I have attempted to be very
      %thorough with my comments. You will find that even relatively simple
      %processes are described in plain english. This is partly to assist
      %myself with keeping track of everything but mostly it is for any new
      %programmers working with this code. I hope you appreciate it :)

      function AppUpdate(app)
         %Updates all pages of the app
         ConnectionsUpdate(app)
         ScanUpdate(app)
         GeneralUpdate(app)
         SaveDataUpdate(app)
         InfrequentUpdate(app)
         PositionUpdate(app)
         CameraUpdate(app)
         StatusUpdate(app)
         OutputSettingsUpdate(app)
      end

      function ConnectionsUpdate(app)
         %Check initialization status and make corresponding lamp red
         %(uninitialized) or green (initialized)
         if app.stageControl.connected
            app.StageLamp.Color = [0.25,0.61,0.25];
         else
            app.StageLamp.Color = [0.78,0.09,0.22];
         end
         if app.stageControl.connected %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            app.DynamicDelayLineLamp.Color = [0.25,0.61,0.25];
         else
            app.DynamicDelayLineLamp.Color = [0.78,0.09,0.22];
         end
         if app.stageControl.connected %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            app.StaticDelayLineLamp.Color = [0.25,0.61,0.25];
         else
            app.StaticDelayLineLamp.Color = [0.78,0.09,0.22];
         end
         if app.hammCam.connected
            app.HammamatsuLamp.Color = [0.25,0.61,0.25];
         else
            app.HammamatsuLamp.Color = [0.78,0.09,0.22];
         end
         if app.laser640.connected
            app.Lamp640.Color = [0.25,0.61,0.25];
         else
            app.Lamp640.Color = [0.78,0.09,0.22];
         end
         if app.laser561.connected
            app.Lamp561.Color = [0.25,0.61,0.25];
         else
            app.Lamp561.Color = [0.78,0.09,0.22];
         end
         if app.laser488.connected
            app.Lamp488.Color = [0.25,0.61,0.25];
         else
            app.Lamp488.Color = [0.78,0.09,0.22];
         end
         tempVar = app.laser488.connected && app.laser561.connected && ...
            app.laser640.connected && app.stageControl.connected && app.hammCam.connected;
         if tempVar
            app.AllInstrumentsLamp.Color = [0.25,0.61,0.25];
         else
            app.AllInstrumentsLamp.Color = [0.78,0.09,0.22];
         end
      end

      function GeneralUpdate(app)
         %The following sections update the set power and actual power
         %based on the saved values. It does NOT actually check what those
         %values are by asking the laser
         %I could have done this as a table instead of separate components
         %and thus been able to do this more systematically by checking for
         %initialized lasers but honestly I think this looks nicer
         if app.laser488.connected
            app.SetPower488.Value = app.laser488.setPower;
            app.CurrentOutput488.Value = app.laser488.actualPower;
         end
         
         if app.laser561.connected
            app.SetPower561.Value = app.laser561.setPower;
            app.CurrentOutput561.Value = app.laser561.actualPower;
         end
         
         if app.laser640.connected
            app.SetPower640.Value = app.laser640.setPower;
            app.CurrentOutput640.Value = app.laser640.actualPower;
         end

         app.NumberofiterationsEditField.Value = app.general.maxIteration;

      end

      function SaveDataUpdate(app)
         app.SaveFolderName.Value = app.general.saveName;

         app.BoxaddressEditField.Value = app.general.boxAddress;

         app.SaveoptionsDropDown.Value = app.general.saveType;

         app.TextArea.Value = app.general.userNotes;

         app.UserName.Value = app.general.userName;

         app.DataSetupsSwitch.Value = app.general.dataOrSetups;


         %Wants to remove space between SM and data/setups
         rootName = strcat(app.general.boxAddress,'\Backlund lab shared\data\',...
            app.general.userName, ['\Experiment Controller SM ',app.general.dataOrSetups]);
         firstDir = dir(rootName);
         if numel(firstDir) >= 3
            firstDir = struct2cell(firstDir(3:end));
            recencyOrder = datetime(firstDir(3,:));
            [~,idx] = sort(recencyOrder,'descend');
            firstNames = firstDir(1,:);
            firstNames = firstNames(idx);
            firstNames(2:end+1) = firstNames;

         else
            app.LoadNamesBox.Items = {[app.general.dataOrSetups,' Names']};
            app.LoadDatesBox.Items = {[app.general.dataOrSetups,' Dates']};
            app.general.loadName = [app.general.dataOrSetups,' Names'];
            app.general.loadDate = [app.general.dataOrSetups,' Dates'];
            return
         end

         if strcmp(app.general.dataOrSetups,'Data')
            %List all dates
            firstNames{1} = [app.general.dataOrSetups,' Dates'];
            app.LoadDatesBox.Items = firstNames;
            rootName = strcat(rootName,'\',app.general.loadDate);

         else %Setups
            %List all names
            firstNames{1} = [app.general.dataOrSetups,' Names'];
            app.LoadNamesBox.Items = firstNames;
            rootName = strcat(rootName,'\',app.general.loadName);
         end

         secondDir = dir(rootName);

         if numel(secondDir) >= 3
            secondDir = struct2cell(secondDir(3:end));
            recencyOrder = datetime(secondDir(3,:));
            [~,idx] = sort(recencyOrder,'descend');
            secondNames = secondDir(1,:);
            secondNames = secondNames(idx);
            secondNames(2:end+1) = secondNames;


            if strcmp(app.general.dataOrSetups,'Data')
               secondNames{1} = [app.general.dataOrSetups,' Names'];
               app.LoadNamesBox.Items = secondNames;
            else
               secondNames{1} = [app.general.dataOrSetups,' Dates'];
               app.LoadDatesBox.Items = secondNames;
            end

         elseif strcmp(app.general.dataOrSetups,'Data')
            app.LoadNamesBox.Items = {[app.general.dataOrSetups,' Names']};
         else
            app.LoadDatesBox.Items = {[app.general.dataOrSetups,' Dates']};
         end

         if ~ismember(app.general.loadName,app.LoadNamesBox.Items)
            app.general.loadName = [app.general.dataOrSetups,' Names'];
         end

         app.LoadNamesBox.Value = app.general.loadName;

         if ~ismember(app.general.loadDate,app.LoadDatesBox.Items)
            app.general.loadDate = [app.general.dataOrSetups,' Dates'];
         end

         app.LoadDatesBox.Value = app.general.loadDate;
         %Check if selection is not just default

         %If it isn't, repeat process from above


         %          %Find the folder for data from this app
         %          rootSaveName = strcat(app.general.boxAddress,'\Backlund lab shared\data\SM ExperimentController');
         %          dateList = struct2cell(dir(rootSaveName));
         %          dateList = dateList(1,:); %Only looks at name row
         %
         %          %If no folders appear, the box address is almost certainly the
         %          %issue
         %          if isempty(dateList)
         %             return
         %          end
         %
         %          %First two are always useless nothings
         %          dateList = dateList(:,3:end);
         %
         %          %Probably doesn't actually need to be sorted because they are
         %          %already saved in order of date but it doesn't hurt
         %          [~,idx] = sort(dateList);
         %          dateList = dateList(idx);
         %
         %          %Shift everything by 1 to allow for a header
         %          dateList(2:end+1) = dateList;
         %          dateList{1} = 'Dates:';
         %
         %          %Displays a list of all folders
         %          app.SavedDates.Value = dateList;
         %
         %          %If the designated date is not among the dates that have folders,
         %          %end this function
         %          if ~contains(dateList,string(app.general.loadDate))
         %             return
         %          end
         %
         %          %Add the load date to the save name then finds folders within
         %          rootSaveName = strcat(rootSaveName,'/',string(app.general.loadDate));
         %          folderList = struct2cell(dir(rootSaveName));
         %          folderList = folderList(1,:); %Only looks at name row
         %
         %          %If there are only 2 elements, there are no actual folders present
         %          if numel(folderList) < 3
         %             app.SavedFolders.Value = 'Folder Names:';
         %             return
         %          end
         %
         %          %First two are always useless nothings
         %          folderList = folderList(:,3:end);
         %
         %          %Shift everything by 1 to allow for a header
         %          folderList(2:end+1) = folderList;
         %          folderList{1} = 'Folder Names:';
         %
         %          %Displays a list of all folders
         %          app.SavedFolders.Value = folderList;


      end

      function InfrequentUpdate(app)
         %Matches displayed GUI value to the object's value
         app.WaitForEquilibrium488.Value = app.laser488.waitForEquilibrium;
         app.WaitForEquilibrium561.Value = app.laser561.waitForEquilibrium;
         app.WaitForEquilibrium640.Value = app.laser640.waitForEquilibrium;

         app.OffWhenCleaned488.Value = app.laser488.offWhenDeleted;
         app.OffWhenCleaned561.Value = app.laser561.offWhenDeleted;
         app.OffWhenCleaned640.Value = app.laser640.offWhenDeleted;

         app.StagePauseTime.Value = app.stageControl.pauseTime;

      end

      function PositionUpdate(app)

         %Updates the absolute position of stage axes
         if app.stageControl.connected
            app.XPosition.Value = app.stageControl.axisSum{strcmp(app.stageControl.axisSum(:,1),'X'),2};
            app.YPosition.Value = app.stageControl.axisSum{strcmp(app.stageControl.axisSum(:,1),'Y'),2};
            app.ZPosition.Value = app.stageControl.axisSum{strcmp(app.stageControl.axisSum(:,1),'Z'),2};
         end

         %Updates the increments for stage axes
         app.XIncrement.Value = app.general.xInc;
         app.YIncrement.Value = app.general.yInc;
         app.ZIncrement.Value = app.general.zInc;

         app.StageoptimizationDropDown.Value = app.stageControl.optimizationType;

         app.StagePrecision.Value = app.stageControl.tolerance;

         app.StageAddress.Value = app.general.stageAddress;

         app.StageAutoOptimization.Value = app.general.autoStageOptimize;

         app.StageOptimizationInterval.Value = app.general.stageOptimizationInterval;

         app.StageOptimizationInstructions.Data = [app.stageControl.sequence' app.stageControl.radius' app.stageControl.steps'];

      end

      function CameraUpdate(app)
         app.FramespertriggerEditField.Value = app.hammCam.framesPerTrigger;

         app.ExposuretimeEditField.Value = app.hammCam.exposureTime;

         app.FramesperfuserstepEditField.Value = app.hammCam.fuser.frames;

         app.FuserpercentilesEditField.Value = MultiFieldConversion(app.hammCam.fuser.percentiles);

         app.FusercutoffmultipleEditField.Value = app.hammCam.fuser.cutoffMultiple;

         app.FuserAddress.Value = app.general.fuserAddress;

         if app.general.saveAllFrames
            app.SaveeveryframeSwitch.Value = 'yes';
         else
            app.SaveeveryframeSwitch.Value = 'no';
         end

         if app.hammCam.useBounds
            app.UseboundsSwitch.Value = 'yes';
         else
            app.UseboundsSwitch.Value = 'no';
         end

         if app.hammCam.useBounds(2)
            app.UsesecondboundsSwitch.Value = 'yes';
         else
            app.UsesecondboundsSwitch.Value = 'no';
         end

         %Rows of cell array are x/y and the element of the matrix is the
         %upper/lower limit
         lowBounds = string(app.hammCam.bounds{1,2}(1));
         lowBounds(2) = string(app.hammCam.bounds{2,2}(1));
         highBounds = string(app.hammCam.bounds{1,2}(2));
         highBounds(2) = string(app.hammCam.bounds{2,2}(2));

         lowBounds(3) = string(app.hammCam.bounds{3,2}(1));
         lowBounds(4) = string(app.hammCam.bounds{4,2}(1));
         highBounds(3) = string(app.hammCam.bounds{3,2}(2));
         highBounds(4) = string(app.hammCam.bounds{4,2}(2));

         app.ImageBounds.Data = [["x";"y";"x2";"y2"] lowBounds' highBounds'];

         app.FuserInstructions.Data = [app.hammCam.fuser.sequence' app.hammCam.fuser.radius' app.hammCam.fuser.steps'];

      end

      function OutputSettingsUpdate(app)

         app.VideoPauseTime.Value = app.general.videoPauseTime;

         dropDownOptions = cell(1,app.general.storedDataSettings.nScans);
         for ii = 1:app.general.storedDataSettings.nScans
            dropDownOptions{ii} = sprintf('%d',ii);
         end

         app.ScantoshowvideoofDropDown.Items = dropDownOptions;

         if str2double(app.general.videoScan) > app.general.storedDataSettings.nScans
            app.general.videoScan = sprintf('%d',app.general.storedDataSettings.nScans);
         end

         app.ScantoshowvideoofDropDown.Value = app.general.videoScan;

         app.OverridecurrentstepviewCheckBox.Value = app.general.overrideCurrentStep;

         app.LoopvideoCheckBox.Value = app.general.loopVideo;

         app.LogarithmicContrastCheckBox.Value = app.displayInfo.logarithmicContrast;

         app.ForcesquareaspectratioCheckBox.Value = app.displayInfo.squareRatio;

         app.DropDown.Value = app.displayInfo.dataSelection;

         lowBounds = string(app.displayInfo.limitsIfOverriden{1,2}(1));
         lowBounds(2) = string(app.displayInfo.limitsIfOverriden{2,2}(1));
         lowBounds(3) = string(app.displayInfo.limitsIfOverriden{3,2}(1));
         highBounds = string(app.displayInfo.limitsIfOverriden{1,2}(2));
         highBounds(2) = string(app.displayInfo.limitsIfOverriden{2,2}(2));
         highBounds(3) = string(app.displayInfo.limitsIfOverriden{3,2}(2));

         for ii = 1:size(app.displayInfo.overrideLimits,1)
            if app.displayInfo.overrideLimits{ii,2}
               overrideLimits(ii) = "true"; %#ok<AGROW>
            else
               overrideLimits(ii) = "false"; %#ok<AGROW>
            end
         end


         app.PlotLimits.Data = [["x";"y";"color"] lowBounds' highBounds' overrideLimits'];

         app.ScanStepTable.Data = [app.general.storedDataSettings.scan.type' app.general.storedDataSettings.scan.stepToView'];

         if ~app.general.randomScan
            scanProgress = cell(1,app.general.storedDataSettings.nScans);
            for ii = 1:app.general.storedDataSettings.nScans
               scanProgress{ii} = sprintf('%s %d/%d',app.general.storedDataSettings.scan.type(ii),...
                  app.general.storedDataSettings.completed(ii),app.general.storedDataSettings.scan.nSteps(ii));
            end
         else
            scanProgress = {sprintf('%d/%d total steps',app.general.currentStepRandomScan,...
               prod(app.general.sotredDataSettingsscan.nSteps))};
         end
         app.ScanProgress.Value = scanProgress;

         %Must be done individually because it would make a single string
         %rather than a string arrray
         colorLimits(1) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(1)));
         colorLimits(2) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(2)));

         app.StaticColorLimits.Data = [["minimum";"maximum"] colorLimits'];

         app.UseStaticColorLimits.Value = app.displayInfo.useStaticColorLimits;
         
         app.forceConcatenation.Value = app.displayInfo.forceConcatenation;

         DisplayUpdate(app)

      end

      function DisplayUpdate(app)
         %display only the data that has been chosen, leave the regular
         %data unchanged

         %If no data is present, don't execute this function
         if ~isempty(app.storedData)
            return
         end

         %Cannot show video while scan is ongoing
         if app.general.scanRunning && strcmp(app.general.imageVideo,'Video')
            warndlg('Video cannot be run while a scan is running. Setting display to single image',...
               'Cannot Show Video')
            app.general.imageVideo = 'Single Image';
            OutputSettingsUpdate(app)
         end


         %MAKE THIS BIT OF DETERMINING DATA INTO A SEPARATE FUNCTION*******

         %Chooses which data point should be shown
         if strcmp(app.general.imageVideo,'Single Image')
            if ~app.general.overrideCurrentStep
               cellDisplayed = num2cell(app.general.storedDataSettings.scan.completed);
            else
               cellDisplayed = num2cell(app.general.scan.stepToView);
            end
         else
            cellDisplayed = num2cell(app.general.scan.stepToView);
            cellDisplayed{str2double(app.general.videoScan)} = app.general.videoFrame;
         end

         if any(cellfun(@(a) a == 0,cellDisplayed))
            return
         end

         %Gets the relevant cell from the data array
         displayData = app.storedData{cellDisplayed{:}};

         %FIX THIS FOR SECOND BOUNDS DISPLAY
         if isempty(displayData)
            return %No data in the current cell to display
         else
            %Sets the data to the correct matrix within the data cell
            %array dependent on what is to be shown
            switch app.displayInfo.dataSelection
               case 'Average'
                  displayData = displayData{4,1};
               case 'Current'
                  displayData = displayData{2,1};
               case 'Prior'
                  %Prior data is just added together so to get a proper
                  %measure it must be divided by the number of prior
                  %iterations
                  displayData = displayData{1,1} ./ (displayData{3,1}-1);
            end
         end

         %Takes the log of the data if desired
         if app.displayInfo.logarithmicContrast
            displayData = log(displayData);
         end

         %If the current data and limits are the same as the previous,
         %end this function
         if isfield(app.displayInfo,'previousData') && ...
               all(all(displayData ~= app.displayInfo.previousData)) && ...
               ~app.displayInfo.alteredLimits

            return
         end

         if ~isfield(app.displayInfo,'axes')
            %If no image has been made yet, generate one "normally"
            app.displayInfo.axes = axes(app.OutputPanel);
            app.displayInfo.image = imagesc(app.displayInfo.axes,displayData);
            colormap(app.displayInfo.axes,"gray")
         else
            %Just edit data to prevent memory leak which will occur if
            %image is continually overwritten
            app.displayInfo.image.CData = displayData;
         end

         %Alter x/y limits if needed
         %+/- .5 comes from how matlab displays images. If not
         %included, the first and last points will be cut in half
         if app.displayInfo.overrideLimits{1,2} %x
            % app.displayInfo.axes.XLimMode = 'manual';
            app.displayInfo.axes.XLim(1) = app.displayInfo.limitsIfOverriden{1,2}(1) - .5;
            app.displayInfo.axes.XLim(2) = app.displayInfo.limitsIfOverriden{1,2}(2) + .5;
         else
            % app.displayInfo.axes.XLimMode = 'auto';
            app.displayInfo.axes.XLim(1) = 1 - .5;
            app.displayInfo.axes.XLim(2) = size(displayData,2) + .5;
         end
         if app.displayInfo.overrideLimits{2,2} %y
            % app.displayInfo.axes.YLimMode = 'manual';
            app.displayInfo.axes.YLim(1) = app.displayInfo.limitsIfOverriden{2,2}(1) - .5;
            app.displayInfo.axes.YLim(2) = app.displayInfo.limitsIfOverriden{2,2}(2) + .5;
         else
            % app.displayInfo.axes.YLimMode = 'auto';
            app.displayInfo.axes.YLim(1) = 1 - .5;
            app.displayInfo.axes.YLim(2) = size(displayData,1) + .5;
         end


         if app.displayInfo.overrideLimits{3,2} %color
            if ~app.displayInfo.useStaticColorLimits
               %Color limits are based on percentiles rather than specific
               %numbers. This means limits of 0 and 100 show default colors
               %while 50 and 75 means the bottom 50% of points are all the
               %lowest color while the top 25% are all the highest color with
               %all the variation in between those values
               lowLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(1),"all");
               highLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(2),"all");
            else
               %Invalidate the above and just make them a static number.
               %Useful primarily for videos desiring a constant color limit
               lowLimit = app.displayInfo.staticColorLimits(1);
               highLimit = app.displayInfo.staticColorLimits(2);
            end
         else
            lowLimit = min(displayData,[],"all");
            highLimit = max(displayData,[],"all");
         end

         %Adjusts the upper limit temporarily to let lower limit be
         %established
         if lowLimit > app.displayInfo.axes.CLim(2)
            app.displayInfo.axes.CLim(2) = lowLimit + .01;
         end
         %Sets color limits based on previously obtained values
         app.displayInfo.axes.CLim(1) = lowLimit;
         app.displayInfo.axes.CLim(2) = highLimit;


         %Changes aspect ratio of the data to be square which makes the
         %side lengths equivalent for each pixel
         %Only first 2 numbers (x and y) matter, third is for z axis which
         %isn't present
         if app.displayInfo.squareRatio && strcmp(app.displayInfo.axes.DataAspectRatioMode,'auto')
            app.displayInfo.axes.DataAspectRatio = [1 1 1];
         elseif ~app.displayInfo.squareRatio && ~strcmp(app.displayInfo.axes.DataAspectRatioMode,'auto')
            app.displayInfo.axes.DataAspectRatioMode = 'auto';
         end

         %Sets previous displayed data and limits to what was just shown
         app.displayInfo.previousData = displayData;
         app.displayInfo.previousLimits{1,2} = app.displayInfo.axes.XLim;
         app.displayInfo.previousLimits{2,2} = app.displayInfo.axes.YLim;
         app.displayInfo.previousLimits{3,2} = app.displayInfo.axes.CLim;

         %Repeat figure creation/alteration process if needed for the colorbar
         if ~isfield(app.displayInfo,'colorbarProper')
            %If no colorbar has been made yet, generate one "normally"
            app.displayInfo.colorbarAxes = axes(app.ColorBarPanel);

            %All these settings just make the colorbar look nice
            colormap(app.displayInfo.colorbarAxes,"gray")
            app.displayInfo.colorbarAxes.Visible = 'off';
            app.displayInfo.colorbarProper = colorbar(app.displayInfo.colorbarAxes,'north');
            app.displayInfo.colorbarProper.Position = [.02 .3 .96 .7];
            app.displayInfo.colorbarProper.FontSize = 8;
            app.displayInfo.colorbarProper.TickLength = .02;
            app.displayInfo.colorbarProper.AxisLocation = 'in';

            %See the end of this function for a small rant regarding this
            %section of code
            %Edits position and font size of exponential label
            if app.displayInfo.colorbarAxes.CLim(2) >= 0
               colorbarExponentialPosition = app.displayInfo.colorbarAxes.CLim(2) * .98;
            else
               colorbarExponentialPosition = app.displayInfo.colorbarAxes.CLim(2) * 1.02;
            end
            app.displayInfo.colorbarProper.Ruler.SecondaryLabel.Position = [colorbarExponentialPosition .6];
            app.displayInfo.colorbarProper.Ruler.SecondaryLabel.FontSize = 9;
         end


         if app.displayInfo.colorbarProper.Limits ~= app.displayInfo.axes.CLim
            %If the limits of the colorbar don't already match the limits
            %of the color, change them to match. Just updates the limits on
            %them to prevent memory leak
            app.displayInfo.colorbarAxes.CLim = app.displayInfo.axes.CLim;
            app.displayInfo.colorbarProper.Limits = app.displayInfo.colorbarAxes.CLim;
         end

         %Puts what iteration is currently running. This is different than
         %the iteration of any single data point as that describes how many
         %times the data of that point has been taken. This describes how
         %many times the entire scan has repeated itself
         infoString{1} = sprintf('Iteration %d',app.general.scan.iteration);

         app.OutputInfo.Value = infoString;


         %/begin rant
         %Some general background: Matlab figures, axes, and images as well
         %as similar visual items are all structures whose variables
         %indicate what MatLab should generate when run. This entire
         %function is premised on manipulation of those structures to
         %display whatever information is desired at the right time. These
         %structures have a whole host of properties and can only be
         %accessed directly by someone intentionally doing so.
         %Now, for whatever reason, the fine programmers at MatLab decided
         %that some of the variables within these structures should be
         %hidden. Let me repeat that: some of the variables within these
         %structures, that contain dozens of other variables mind, are
         %hidden from the user despite only relatively advanced users being
         %able to access this information at all. This might make sense if
         %the variables are critical to the function of the item and any
         %alteration would result in a cascading series of errors. However,
         %one of these hidden variables is the easily altered and not at
         %all critical position of the secondary label. The primary label
         %of something like a colorbar would be the numbers relating to
         %tick marks while the secondary label is for an exponential such
         %as x10^5 and is only present when needed. I do not know why such
         %a simple thing needs to be hidden from the user and not even
         %mentioned on official websites. I only wished to edit the
         %location of the exponential of the colorbar so that colorbar
         %could more fully utilize the space it is in and not cut out
         %important information. That was all. It is utterly baffling that
         %I needed to "find" the hidden variables to do such a simple task.
         %/rant
      end

      function ScanUpdate(app)

         app.ScanAddress.Value = app.general.scanAddress;

         app.RandomizescanCheckBox.Value = app.general.randomScan;

         tableType = app.general.scan.type';
         tableStart = app.general.scan.start';
         tableEnd = app.general.scan.end';
         tablenSteps = app.general.scan.nSteps';
         tableStepSize = app.general.scan.stepSize';

         app.ScanningTable.Data = [tableType tableStart tableEnd tablenSteps tableStepSize];


      end

      function StatusUpdate(app)
         if app.general.scanRunning
            app.StatusLamp.Color = [0,1,0]; %green
         elseif app.general.videoRunning
            app.StatusLamp.Color = [1,1,0]; %yellow
         elseif app.general.scan.completed == app.general.scan.nSteps %finished scan
            app.StatusLamp.Color = [0,1,1]; %cyan
         elseif app.general.reset
            app.StatusLamp.Color = [0,0,1]; %dark blue
         else
            app.StatusLamp.Color = [1,0,0]; %red
         end
      end

      function ScanIncrement(app,scanNumber)
         if ~app.general.randomScan
            scan = app.general.scan;
            app.general.scan.completed(scanNumber) = scan.completed(scanNumber)+1;
            app.general.scan.storedDataSettings.completed = app.general.scan.completed;
         else
            %scanNumber input becomes irrelevant as all steps need to be
            %changed. It instead becomes a vector containing every scan
            %whose number is not equivalent to the previous

         end
         switch scan.type(scanNumber)
            case {'Stage X','Stage Y','Stage Z'}
               stageAxis = convertStringsToChars(scan.type(scanNumber));
               stageAbsoluteMove(stageAxis(end),scan.start(scanNumber) + (scan.completed(scanNumber)-1)*scan.stepSize)
         end
      end


      function errorPresent = ErrorCheck(app)
         scan = app.general.scan; %shorthand
         errorPresent = true;

         if ~app.stageControl.connected
            loadinstruments = uiconfirm(app.UIFigure,'Stage is not currently loaded. Would you like to load it or cancel measurement?',...
               'Instruments unloaded','Icon','warning','Options',{'Load Stage','Cancel'});
            switch loadinstruments
               case 'Load Stage'
                  stageInitialization
                  appUpdate(app)
               case 'Cancel'
                  return
            end
         end

         if ~app.hammCam.connected
            loadinstruments = uiconfirm(app.UIFigure,'Camera is not currently loaded. Would you like to load it or cancel measurement?',...
               'Instruments unloaded','Icon','warning','Options',{'Load Camera','Cancel'});
            switch loadinstruments
               case 'Load Stage'
                  sCMOSInitialization
                  appUpdate(app)
               case 'Cancel'
                  return
            end
         end

         if ~app.general.reset
            loadinstruments = uiconfirm(app.UIFigure,'This will resume previous run adding on to old data. Proceed?',...
               'Not Reset','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(loadinstruments,'Cancel')
               return
            end
         end

         %          if  ~strcmp(app.Warnings.Value{1},'No warnings present')
         %             disregard = uiconfirm(app.UIFigure,'Continue despite warnings?','Warnings present','Icon','warning');
         %             if strcmp(disregard,'Cancel')
         %                return
         %             end
         %          end

         if length(scan.start) == 1 && strcmp(scan.type,"Stage X") && scan.start == 1 && scan.end == 10 && scan.nSteps == 10 && scan.stepSize == 1
            errordlg('Scan has not been changed from default. Go to scan tab to change','Default Scan')
            return
         end

         boxDir = dir(strcat(app.general.boxAddress,'\Backlund lab shared\code\Function Library'));
         if isempty(boxDir)
            errordlg('Current box address invalid. Go to General tab to fix','Invalid box address')
            return
         end

         [~,uniqueIndices] = unique(scan.type);
         if numel(scan.type) ~= numel(uniqueIndices)
            errordlg('Multiple scans with identical types detected. Go to scan tab to change','Replicate Scans')
            return
         end

         scanStages = convertStringsToChars(scan.type);
         if isa(scanStages,'cell')
            scanStages = convertStringsToChars(scan.type);
            scanStages = cellfun(@(x) x(end),scanStages,'UniformOutput',false);
         else
            scanStages = convertStringsToChars(scan.type);
            scanStages = scanStages(end);
         end
         if any(ismember(scanStages,app.stageControl.sequence)) && app.general.autoStageOptimize
            proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while attempting stage scan of the same axis.' ...
               'This is not recommended as the optimization process can affect the scan. Proceed anyways?'],...
               'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(proceedExperiment,'Cancel')
               return
            end
         end

         if  app.stageControl.ignoreWait && app.general.autoStageOptimize
            proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while ignore wait is also on.' ...
               'This is not recommended as it skews the optimization process. Proceed anyways?'],...
               'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
            if strcmp(proceedExperiment,'Cancel')
               return
            end
         end

         if app.general.scanRunning
            errordlg('Cannot run experiment while optimization is running','Cannot Run Experiment')
            return
         end

         %% Warnings
         if ~app.general.reset && app.general.dataTakenWithTwoBounds ~= app.hammCam.useBounds(2)
            warndlg('Cannot change usage of secondary bounds without reset. Changing setting to previous state','Invalid Secondary Bounds')
            app.hammCam.useBounds(2) = app.general.dataTakenWithTwoBounds;
            CameraUpdate(app)
         end


         %If it reaches the end without triggering an error, set the output
         %to false
         errorPresent = false;
      end

      function newStructure = ModifyAllFields(~,oldStructure,fieldsToChange,fieldValues,address)
         %This function replaces holding all these settings in a variable
         %then changing them all at once to the structure. Instead it
         %changes each substructure one at a time
         %Honestly I don't think I would do it this way again if I had to
         %go back and redo it

         %It is necessary to input the fields to change not because matlab
         %cannot identify what fields are there, but because it needs to
         %match the field name to the new value to be input

         %An input of {'delete'} for the first cell in fieldValues input
         %will cause the function to delete the values of the address given

         newStructure = struct2cell(oldStructure);%Create new structure based of the old
         namesList = fieldnames(oldStructure);%Create list of field names in order

         for ii = 1:numel(fieldsToChange)
            %Find where the field name of the structure is the field name
            %that has been input
            cellNumber = find(strcmp(fieldsToChange(ii),namesList));

            if isempty(cellNumber)
               error('Invalid field name. Name must be one of the fields of the structure input')
            end

            %Take the old structure, replace the value based on given
            %address, then add it back into the new structure
            newValue = newStructure{cellNumber};
            if strcmp(fieldValues{1},'delete')
               newValue(address) = [];
            else
               if address <= numel(newValue)
                  newValue(address+1:end+1) = newValue(address:end); %Shift all values by 1 leaving n to be replaced
               end
               newValue(address) = fieldValues{ii};
            end

            newStructure{cellNumber} = newValue;
         end

         %Converts cell back to structure using the names found from the
         %input structure
         newStructure = cell2struct(newStructure,namesList,1);

      end


      function TakeData(app)
         %Take data point and add it to the data cell matrix

         %Creates an empty cell matrix for the correct number of outputs as
         %caclulated at the beginning of StartButtonPushed
         newData = cell(1,app.general.nOutputs);

         %Takes an image and assigns the corresponding outputs to the cell
         %array created above
         [newData{:}] = sCMOSImage;

         if ~app.hammCam.outputFrameStack(1) && app.hammCam.useBounds(2)
            %Extra output here that corresponds to the frame
            %stack of the first set of bounds. This will be the
            %second cell and as such it is deleted
            newData(2) = [];
         end

         %Converts the current scan location to "coordinates" for a cell
         %array. Necessary because MatLab will only accept a comma
         %separated list for coordinates, not a double vector like
         %app.general.scan.completed is
         cellCompleted = num2cell(app.general.scan.completed);

         %Loads the previous data of the cell within the data matrix that
         %is at the coordinates found above
         data = app.storedData{cellCompleted{:}};

         if isempty(data)
            data = cell(4,app.general.nOutputs);
            for ii = 1:app.general.nOutputs
               %Makes the previous iterations, current iteration, and
               %average a matrix of 0s of the correct size
               data{1,ii} = zeros(size(newData{ii}));%previous
               data{2,ii} = data{1,ii};%current
               data{4,ii} = data{1,ii};%total average
            end
            %Sets the number of times data has been taken for this cell to
            %0
            data{3,1} = 0;
         end

         %Increases the number of times data has been taken for this cell
         data{3,1} = data{3,1} + 1;

         for ii = 1:app.general.nOutputs
            %Adds the previous "current iteration" data to all previous
            %data
            data{1,ii} = data{1,ii} + data{2,ii};

            %Sets the new "current iteration" data to what was just taken
            data{2,ii} = newData{ii};

            %Finds the running average of all the data taken together
            %divided by the number of times data has been taken
            data{4,ii} = (data{1,ii} + data{2,ii}) ./ data{3,1};
         end

         %Sets the relevant data cell to the new values
         app.storedData{cellCompleted{:}} = data;
      end

      function newRootName = CheckFolder(~,oldRootName,folderName)
         %Updates what the new root name will be
         newRootName = strcat(oldRootName,'\',folderName);

         %Load the directory
         fileList = struct2cell(dir(oldRootName));
         fileList = fileList(1,:);%Only name row

         %Makes a new folder if one doesn't already exist
         fileExists = any(strcmp(folderName,fileList),"all");
         if ~fileExists
            mkdir(oldRootName,folderName)
         end

      end

      function DataSave(app,rootSaveName)
         %Should be a single variable not part of a structure when saved
         data = app.storedData;

         %Saves the data in a format readable by MatLab
         if strcmp(app.general.saveType,'.m') || strcmp(app.general.saveType,'.m & .tif')
            mSaveName = strcat(rootSaveName,'\', 'MatLab_Data.mat');
            save(mSaveName,"data")
            dataSettings = app.general.storedDataSettings;
            mSaveName = strcat(rootSaveName,'\', 'MatLab_Data_Settings.mat');
            save(mSaveName,"dataSettings")
            userNotes = app.general.userNotes;
            mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
            save(mSaveName,"userNotes")
         end

         %If saving as a tif is not selected, end the function here. I did
         %this instead of putting everything below in one massive if
         %statement because it makes code formatting nicer in my opinion
         if strcmp(app.general.saveType,'.m')
            uialert(app.UIFigure,'Data Saved!','Save Successful','Icon','success')
            return
         end

         %Saving as tifs is much more complex with many more files
         %Because a user could have changed settings between the data
         %collection finishing and actually saving, I cannot reference
         %anything outside of the actual data variable itself which makes
         %identification of what to save more difficult

         %Identify how many columns are within the first data cell of the
         %entire array
         switch size(data{1},2)
            case 1
               %Only the average image of the first bounds was saved
               secondBounds = false;
               frameStack = [false,false];
            case 2
               if size(data{1}{4,2},3) > 1
                  %If there is more than one image, it is a frame stack of
                  %the first bounds
                  secondBounds = false;
                  frameStack = [true,false];
               else
                  %Only one image means the second set of bounds were used
                  %but only the average image of it
                  secondBounds = true;
                  frameStack = [false,false];
               end
            case 3
               if size(data{1}{4,2},3) > 1
                  %If there is more than one image, it is a frame stack
                  %which is only possible if the first bounds didn't have a
                  %frame stack but the second one did
                  secondBounds = true;
                  frameStack = [false,true];
               else
                  %Only one image means the second set of bounds were used
                  %but only the average image of it while the frame stack
                  %of the first image was also saved
                  secondBounds = true;
                  frameStack = [true,false];
               end
            case 4
               %Every possible option was used
               secondBounds = true;
               frameStack = [true,true];

         end

         %Makes all the folders as needed
         if ~secondBounds
            mkdir(rootSaveName,'Average Images')
         else
            mkdir(rootSaveName,'Average Images 1')
            mkdir(rootSaveName,'Average Images 2')
         end
         if frameStack(1) && ~secondBounds
            mkdir(rootSaveName,'Frame Stacks')
         else
            if frameStack(1)
               mkdir(rootSaveName,'Frame Stacks 1')
            end
            if frameStack(2)
               mkdir(rootSaveName,'Frame Stacks 2')
            end
         end

         dataLocation = cell(ndims(data),1);
         namesList = cell(4,1);

         %For each data point, save relevant info
         for ii = 1:numel(data)
            currentCell = data{ii};
            [dataLocation{:}] = ind2sub(size(data),ii);

            %Puts the current location into a string for use in the name of
            %the saved file(s)
            dimensionsName = '';
            for jj = 1:numel(dataLocation)
               dimensionsName = strcat(dimensionsName,num2str(dataLocation{jj}),' ');
            end
            dimensionsName(end) = []; %deletes the last extra space
            dimensionsName = strcat(dimensionsName,'.tiff');


            %Identifies what the saved names should be for each column of
            %the data
            n = 1;
            if ~secondBounds
               namesList{n} = '\Average Images\';
               n = n+1;
               if frameStack(1)
                  namesList{n} = '\Frame Stacks\';
               end
            else %Using second bounds
               namesList{n} = '\Average Images 1\';
               n = n + 1;
               if frameStack(1)
                  namesList{n} = '\Frame Stacks 1\';
                  n = n + 1;
               end
               namesList{n} = '\Average Images 2\';
               n = n+1;
               if frameStack(2)
                  namesList{n} = '\Frame Stacks 2\';
               end
            end

            %For each column, save the average data for the current cell
            for kk = 1:size(currentCell,2)
               currentSaveName = strcat(rootSaveName,namesList{kk},dimensionsName);
               %Save the image. If it is a frame stack, save the first
               %frame
               imwrite(currentCell{4,kk}(:,:,1),currentSaveName)

               %If there is only one dimension, add a video of the average
               %images
               if ndims(data) == 1
                  if contains(namesList{kk},'Average Image')
                     videoSaveName = strcat(rootSaveName,namesList{kk},'\Video');
                     imwrite(currentCell{4,kk},videoSaveName,"WriteMode","append")
                  end
               end

               %If a frame stack is being saved, the image must be
               %repeatedly saved for each frame using the same name
               if contains(namesList{kk},'Frame Stacks')
                  for mm = 2:size(currentCell{4,kk},3)
                     imwrite(currentCell{4,kk}(:,:,mm),currentSaveName,"WriteMode","append")
                  end
               end
            end

         end

         uialert(app.UIFigure,'Data Saved!','Save Successful','Icon','success')

      end

      function SetupSave(app,rootSaveName)
         %Saves a number of settings that allow for easy loading back to
         %what one was doing before
         %REDO FOR OBJECT **********

         if isfield(master,'sCMOS')
            %Select substructure name
            instrumentName = 'sCMOS';
            %Select variables to copy within the above substructure
            toCopy = {'correctionLevel','framesPerTrigger','exposureTime','outputFrameStack',...
               'bounds','imageDimensions','useBounds','fuser'};

            %For each variable within the substructure selected above, copy it
            %over to savedSettings. Dynamic field naming is convenient :)
            for ii = 1:numel(toCopy)
               app.savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
            end
         end

         %Repeat for all other substructures
         %I could have looped this more, but I prefer to see the names
         %written out clearly like so

         if isfield(master,'stage')
            instrumentName = 'stage';
            toCopy = {'pauseTime','optimizationType','ignoreWait','tolerance',...
               'sequence','radius','steps'};
            for ii = 1:numel(toCopy)
               savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
            end
         end

         if isfield(master,'lasers')
            instrumentName = 'lasers';
            toCopy = {'wavelength','tolerance','waitForEquilibrium','offWhenCleaned'};
            for ii = 1:numel(toCopy)
               savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
            end
         end

         instrumentName = 'gui';
         toCopy = {'scan','autoStageOptimize','stageOptimizationInterval','loopVideo',...
            'xInc','yInc','zInc','videoPauseTime','videoScan','overrideCurrentStep',...
            'randomScan','saveName','saveType','saveAllFrames','imageVideo'};
         for ii = 1:numel(toCopy)
            savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
         end

         %Make all scan completion values equal 0 ******* Change this to
         %make completion not within scan?
         savedSettings.gui.scan.completed = zeros(numel(savedSettings.gui.scan.completed),1);

         %Do a similar process for the substructure display within gui
         instrumentName = 'display';
         toCopy = {'squareRatio','logarithmicContrast','dataSelection','useStaticColorLimits',...
            'staticColorLimits','limitsIfOverriden','overrideLimits'};
         for ii = 1:numel(toCopy)
            savedSettings.gui.(instrumentName).(toCopy{ii}) = app.general.(instrumentName).(toCopy{ii});
         end

         %Saves the setup and user notes
         if strcmp(app.general.saveType,'.m') || strcmp(app.general.saveType,'.m & .tif')
            mSaveName = strcat(rootSaveName,'\', 'Setup_Settings.mat');
            save(mSaveName,"savedSettings")
            userNotes = app.general.userNotes;
            mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
            save(mSaveName,"userNotes")
         end

         uialert(app.UIFigure,'Setup Saved!','Save Successful','Icon','success')

      end
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFunction(app)
         %EDITABLE
         app.general.userList = {'Cheyenne','Zach','Mikael','David'};


         %UNEDITABLE
         
         %Loads any settings that were saved when closing the app
         %previously in the same MatLab session
         if ~isempty(app.savedSettings)
            app.general = app.savedSettings.general;
            app.displayInfo = app.savedSettings.displaySettings;
            app.stageControl = app.savedSettings.stageControl;
            app.hammCam = app.savedSettings.hammCam;
            app.laser488 = app.savedSettings.laser488;
            app.laser561 = app.savedSettings.laser561;
            app.laser640 = app.savedSettings.laser640;
            app.savedSettings = [];
         end         

         %Creates default states for a ton of different settings
         if ~isfield(app.general,'xInc'), app.general.xInc = 1;    end

         if ~isfield(app.general,'yInc'), app.general.yInc = 1;    end

         if ~isfield(app.general,'zInc'), app.general.zInc = 1;    end

         if ~isfield(app.general,'fuserAddress'), app.general.fuserAddress = 1;    end

         if ~isfield(app.general,'stageAddress'), app.general.stageAddress = 1;    end

         if ~isfield(app.general,'scanAddress'), app.general.scanAddress = 1;    end
         
         if ~isfield(app.general,'defaultScanChange'), app.general.defaultScanChange = 'stepSize'; end

         if ~isfield(app.general,'autoStageOptimize'), app.general.autoStageOptimize = false; end

         if ~isfield(app.general,'stageOptimizationInterval'), app.general.stageOptimizationInterval = 5; end

         if ~isfield(app.general,'aborted'), app.general.aborted = false; end

         if ~isfield(app.general,'ready'), app.general.reset = true; end

         if ~isfield(app.general,'scanRunning'), app.general.scanRunning = false; end

         if ~isfield(app.general,'loopVideo'), app.general.loopVideo = false; end

         if ~isfield(app.general,'videoRunning'), app.general.videoRunning = false; end

         if ~isfield(app.general,'videoPauseTime'), app.general.videoPauseTime = .5; end

         if ~isfield(app.general,'videoScan'), app.general.videoScan = '1'; end

         if ~isfield(app.general,'overrideCurrentStep'), app.general.overrideCurrentStep = false; end

         if ~isfield(app.general,'randomScan'), app.general.randomScan = false; end

         if ~isfield(app.general,'currentStepRandomScan'), app.general.currentStepRandomScan = 0; end

         if ~isfield(app.general,'saveName'), app.general.saveName = 'Untitled'; end

         if ~isfield(app.general,'saveType'), app.general.saveType = '.m & .tif'; end

         if ~isfield(app.general,'saveAllFrames'), app.general.saveAllFrames = true; end

         if ~isfield(app.general,'userNotes'), app.general.userNotes = 'User notes. Please edit me'; end

         if ~isfield(app.general,'scanVideoOptions'), app.general.scanVideoOptions = {'1'}; end

         if ~isfield(app.general,'storedDataSettings'), app.general.storedDataSettings.nScans = numel(app.general.scan.nSteps); end

         if ~isfield(app.general,'imageVideo'), app.general.imageVideo = 'Single Image'; end

         if ~isfield(app.general,'userName'), app.general.userName = app.general.userList{1}; end

         if ~isfield(app.general,'dataOrSetups'), app.general.dataOrSetups = 'Setups'; end

         if ~isfield(app.general,'loadName'), app.general.loadName = sprintf('%s Names',app.general.dataOrSetups); end

         if ~isfield(app.general,'loadDate'), app.general.loadDate = sprintf('%s Dates',app.general.dataOrSetups); end

         if ~isfield(app.general,'maxIteration'), app.general.maxIteration = 1; end

         if ~isfield(app.general,'scan'), app.general.scan.type = "Stage X";    end

         if ~isfield(app.general.scan,'type'), app.general.scan.type = "Stage X";    end

         if ~isfield(app.general.scan,'start'), app.general.scan.start = 1;    end

         if ~isfield(app.general.scan,'end'), app.general.scan.end = 10;    end

         if ~isfield(app.general.scan,'nSteps'), app.general.scan.nSteps = 10;    end

         if ~isfield(app.general.scan,'stepSize'), app.general.scan.stepSize = 1;    end

         if ~isfield(app.general.scan,'completed'), app.general.scan.completed = 0;    end

         if ~isfield(app.general.scan,'iteration'), app.general.scan.iteration = 0;    end

         if ~isfield(app.general.scan,'stepToView'), app.general.scan.stepToView = 1;    end

         if ~isfield(app.displayInfo,'squareRatio'), app.displayInfo.squareRatio = true;    end

         if ~isfield(app.displayInfo,'logarithmicContrast'), app.displayInfo.logarithmicContrast = false;    end

         if ~isfield(app.displayInfo,'alteredLimits'), app.displayInfo.alteredLimits = false;    end

         if ~isfield(app.displayInfo,'dataSelection'), app.displayInfo.dataSelection = 'Average';    end

         if ~isfield(app.displayInfo,'useStaticColorLimits'), app.displayInfo.useStaticColorLimits = false;    end

         if ~isfield(app.displayInfo,'staticColorLimits'), app.displayInfo.staticColorLimits = [0 1]; end

         if ~isfield(app.displayInfo,'limitsIfOverriden')
            app.displayInfo.limitsIfOverriden = {'x',app.hammCam.bounds{1,2};'y',app.hammCam.bounds{2,2};'color',[0 100]};
         end

         if ~isfield(app.displayInfo,'overrideLimits')
            app.displayInfo.overrideLimits = {'x',false;'y',false;'color',false};
         end

         if ~isfield(app.general.storedDataSettings,'nScans'), app.general.storedDataSettings.nScans = numel(app.general.scan.nSteps); end

         if ~isfield(app.general.storedDataSettings,'scan'), app.general.storedDataSettings.scan = app.general.scan; end

         if ~isfield(app.general.storedDataSettings,'completed'), app.general.storedDataSettings.completed = app.general.scan.completed; end
         
         %By setting initialization input to false, the initialization just
         %loads the default settings for the instrument. This is needed
         %because those values will be referenced by the display of the app
         %even when the instruments themselves haven't been initialized
         stageInitialization(false)
         LasersInitialization(488,false)
         LasersInitialization(561,false)
         LasersInitialization(640,false)
         sCMOSInitialization(false)

         app.UserName.Items = app.general.userList;

         AppUpdate(app)

      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         %leave saved settings as global *******
         %write to workspace instead?
         closeapp = uiconfirm(app,'Are you sure you want to close the app?','Yes, close it','No','Icon','warning');
         if strcmp(closeapp,'No')
            return
         end
         global savedSettings
         if isfield(app.displayInfo,'colorbarProper')
            delete(app.displayInfo.colorbarProper)
            app.displayInfo = rmfield(app.displayInfo,'colorbarProper');
         end
         if isfield(app.displayInfo,'image')
            delete(app.displayInfo.image)
            app.displayInfo = rmfield(app.displayInfo,'image');
         end
         if isfield(app.displayInfo,'colorbarAxes')
            delete(app.displayInfo.colorbarAxes)
            app.displayInfo = rmfield(app.displayInfo,'colorbarAxes');
         end
         if isfield(app.displayInfo,'axes')
            delete(app.displayInfo.axes)
            app.displayInfo = rmfield(app.displayInfo,'axes');
         end
         
         %Saves settings to be used if starting the app again within the
         %same MatLab session
         savedSettings.general = app.general;
         savedSettings.displaySettings = app.displayInfo;
         savedSettings.hammCam = app.hammCam;
         savedSettings.stageControl = app.stageControl;

         
         delete(app)
      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
         %If there are any errors, stop this function
         if ErrorCheck(app)
            return
         end

         %If a new scan is ready, create empty data matrix with the correct
         %dimensions and set the completed scan to 0 for all scan
         %dimensions
         if app.general.reset
            %Removes old saved data if there is any
            if ~isempty(app.storedData)
               app.storedData = [];
            end
            if numel(app.general.scan.nSteps) == 1
               app.storedData = cell(app.general.scan.nSteps,1);
            else
               app.storedData = cell(app.general.scan.nSteps);
            end
            app.general.scan.completed = zeros(size(app.general.scan.nSteps));
            app.general.scan.iteration = 1;
            app.general.reset = false;%Do not reset again until the reset button is pressed
            %If random scan is on, create a permutation list for this
            %iteration
            if app.general.randomScan
               app.general.scan.permutation = randperm(prod(app.general.scan.nSteps));
               app.general.currentStepRandomScan = 0;
            end
            app.general.dataTakenWithTwoBounds = app.hammCam.useBounds(2);

            app.general.storedDataSettings.useBounds = app.hammCam.useBounds;
            app.general.storedDataSettings.frameStacks = app.hammCam.outputFrameStack;
            app.general.storedDataSettings.scan = app.general.scan;
            app.general.storedDataSettings.nScans = numel(app.general.scan.start);

         end

         app.general.scanRunning = true;
         StatusUpdate(app)

         if ~app.hammCam.useBounds(2)
            %Only 1 set of bounds. First set of bounds only regulates the
            %size of the output but the second set regulates both the size
            %and whether a second image is produced at all
            if app.hammCam.outputFrameStack
               %Outputs frame stack
               app.general.nOutputs = 2;
            else
               app.general.nOuputs = 1;
            end
         else
            %2 sets of bounds
            if app.hammCam.outputFrameStack(2)
               %Outputs frame stack of second set of bounds
               app.general.nOutputs = 4;
            else
               app.general.nOuputs = 3;
            end
         end


         while true %Iteration Loop

            %Add all of previous iteration to the sum and set current
            %iteration to 0s


            while app.general.scanRunning %Scanning Loop


               if ~app.general.randomScan

                  %Begin by checking if any scans haven't moved to their starting
                  %location and if they haven't, move them there
                  unstarted = find(app.general.scan.completed == 0,1);
                  if ~isempty(unstarted)
                     for ii = 1:numel(unstarted)
                        ScanIncrement(app,unstarted(ii))
                     end
                     TakeData(app)
                     DisplayUpdate(app)
                  end

                  %Incremental loop to change instrument setting dependent on
                  %where the scan currently is
                  ii = numel(app.general.scan.nSteps); %Counting variable that begins at the last scan
                  while true

                     if app.general.scan.completed == app.general.scan.nSteps %All scans are done
                        %Completely stop running and set preparedness for next scan
                        %to ready
                        takeDataBoolean = false;
                        break %Incremental loop

                     elseif app.general.scan.completed(ii) == app.general.scan.nSteps(ii) %Check if current scan is done
                        %Reset current scan and go to next scan
                        %e.g. if scan 3 has completed the number of steps it
                        %does in total, set scan 3 back to its first step then
                        %look at scan 2
                        app.general.scan.completed(ii) = 0;
                        ScanIncrement(app,ii)
                        ii = ii-1;

                     else %Current scan isn't done
                        %Increment current scan
                        ScanIncrement(app,ii)
                        takeDataBoolean = true;
                        break %Incremental loop
                     end

                  end

                  if takeDataBoolean
                     %Take a new data point and add it to the data cell matrix
                     TakeData(app)
                  else
                     break %Scanning loop
                  end

               else %Randomized scan
                  %Uses linear indexing of a permuted list of scans to
                  %determine

                  if app.general.currentStepRandomScan ~= prod(app.general.scan.nSteps) %not done
                     app.general.currentStepRandomScan = app.general.currentStepRandomScan + 1;
                     %Convert linear index to row/col, use that to
                     %determine what step to move to then take data

                  end
               end

               app.general.storedDataSettings.scan.completed = app.general.scan.completed;

               %Update the output page - will only change if needed
               DisplayUpdate(app)

            end

            if app.general.scanRunning %Not aborted
               app.general.scan.completed = zeros(size(app.general.scan.nSteps));
               if app.general.randomScan
                  app.general.scan.permutation = randperm(prod(app.general.scan.nSteps));
                  app.general.currentStepRandomScan = 0;
               end
            else
               return
            end

            %If all iterations are done
            if app.general.scan.iteration == app.general.maxIteration && app.general.scanRunning
               %Set app back to starting condition and break iteration loop
               app.general.reset = true;
               app.general.scanRunning = false;
               StatusUpdate(app)
               break %Iteration loop
            end

         end

      end

      % Button pushed function: StartVideoButton
      function StartVideoButtonPushed(app, event)
         if app.general.scanRunning
            errordlg('Cannot start video while scan is running','Cannot Start Video')
         else
            app.general.videoRunning = true;
         end
         StatusUpdate(app)

         app.general.videoFrame = 1;
         while app.general.videoRunning
            app.general.videoFrame = app.general.videoFrame + 1;

            if app.general.videoFrame > app.general.scan.nSteps(str2double(app.general.videoScan))
               if app.general.loopVideo
                  app.general.videoFrame = 1;
               else
                  app.general.videoRunning = false;
                  return
               end
            end

            DisplayUpdate(app)

            pause(app.general.videoPauseTime)
         end
      end

      % Button pushed function: AbortButton
      function AbortButtonPushed(app, event)
         app.general.scanRunning = false;
         app.general.videoRunning = false;
         app.StatusLamp.Color = [1,0,0];
         AppUpdate(app)
      end

      % Button pushed function: StageLoad
      function StageLoadButtonPushed(app, event)
         %Loads coarse and fine PI stages then updates the app
         stageInitialization
         AppUpdate(app)
      end

      % Button pushed function: HammLoad
      function HammLoadButtonPushed(app, event)
         %Loads Hammamatsu sCMOS camera then updates the app
         sCMOSInitialization
         AppUpdate(app)
      end

      % Button pushed function: Load488
      function Load488ButtonPushed(app, event)
         %I could have done all the laser loading/unloading by having a box
         %to enter the laser wavelength then a button to load/unload it but
         %I think this is easier for the end user and it wouldn't be that
         %hard to add/subtract a row for another laser
         %Initializes 488 nm laser
         LasersInitialization(488,true)
         AppUpdate(app)
      end

      % Button pushed function: Load561
      function Load561ButtonPushed(app, event)
         %Initializes 561 nm laser
         LasersInitialization(561,true)
         AppUpdate(app)
      end

      % Button pushed function: Load640
      function Load640ButtonPushed(app, event)
         %Initializes 640 nm laser
         LasersInitialization(640,true)
         AppUpdate(app)
      end

      % Button pushed function: LoadAll
      function LoadAllButtonPushed(app, event)
         %Loads all the instruments supported by the app then refreshes app
         LasersInitialization(488,true)
         LasersInitialization(561,true)
         LasersInitialization(640,true)
         stageInitialization(true)
         sCMOSInitialization(true)
         AppUpdate(app)
      end

      % Button pushed function: StageUnload
      function StageUnloadButtonPushed(app, event)
         %Unloads stage by running CleanUp function then updates the app
         CleanUp("stage")
         AppUpdate(app)
      end

      % Button pushed function: HammUnload
      function HammUnloadButtonPushed(app, event)
         %Unloads Hammamatsu camera by running CleanUp function then updates the app
         CleanUp("sCMOS")
         AppUpdate(app)
      end

      % Button pushed function: Unload488
      function Unload488ButtonPushed(app, event)
         %Unloads 488 nm laser by running CleanUp function then updates the app
         CleanUp("laser488")
         AppUpdate(app)
      end

      % Button pushed function: Unload561
      function Unload561ButtonPushed(app, event)
         %Unloads 561 nm laser by running CleanUp function then updates the app
         CleanUp("laser561")
         AppUpdate(app)
      end

      % Button pushed function: Unload640
      function Unload640ButtonPushed(app, event)
         %Unloads 640 nm laser by running CleanUp function then updates the app
         CleanUp("laser640")
         AppUpdate(app)
      end

      % Button pushed function: UnloadAll
      function UnloadAllButtonPushed(app, event)
         %Unloads all instruments by running CleanUp function then updates the app
         CleanUp("all")
         AppUpdate(app)
      end

      % Value changed function: Toggle488
      function Toggle488ValueChanged(app, event)
         %Changes global laser on/off switch then runs function to turn
         %instrument on/off. Updates current laser output
         app.laser488 = toggle(app.laser488,app.Toggle488.Value);
         GeneralUpdate(app)
      end

      % Value changed function: Toggle561
      function Toggle561ValueChanged(app, event)
         %Changes global laser on/off switch then runs function to turn
         %instrument on/off. Updates current laser output
         app.laser561 = toggle(app.laser561,app.Toggle561.Value);
         GeneralUpdate(app)
      end

      % Value changed function: Toggle640
      function Toggle640ValueChanged(app, event)
         %Changes global laser on/off switch then runs function to turn
         %instrument on/off. Updates current laser output
         app.laser640 = toggle(app.laser640,app.Toggle640.Value);
         GeneralUpdate(app)
      end

      % Value changed function: SetPower488
      function SetPower488ValueChanged(app, event)
         %Changes global set power for laser then runs function to change
         %instrument set value. Updates current laser output
         app.laser488 = setLaserPower(app.laser488,app.SetPower488);
         GeneralUpdate(app)
      end

      % Value changed function: SetPower561
      function SetPower561ValueChanged(app, event)
         %Changes global set power for laser then runs function to change
         %instrument set value. Updates current laser output
         app.laser561 = setLaserPower(app.laser561,app.SetPower561);
         GeneralUpdate(app)
      end

      % Value changed function: SetPower640
      function SetPower640ValueChanged(app, event)
         %Changes global set power for laser then runs function to change
         %instrument set value. Updates current laser output
         app.laser640 = setLaserPower(app.laser640,app.SetPower640);
         GeneralUpdate(app)
      end

      % Button pushed function: Refresh488Laser
      function Refresh488LaserButtonPushed(app, event)
         %Obtains the current output reading of the 488 nm laser
         app.laser488 = actualLaserPower(app.laser488);
         GeneralUpdate(app)
      end

      % Button pushed function: Refresh561Laser
      function Refresh561LaserButtonPushed(app, event)
         %Obtains the current output reading of the 561 nm laser
         app.laser561 = actualLaserPower(app.laser561);
         GeneralUpdate(app)
      end

      % Button pushed function: Refresh640Laser
      function Refresh640LaserButtonPushed(app, event)
         %Obtains the current output reading of the 640 nm laser
         app.laser640 = actualLaserPower(app.laser640);
         GeneralUpdate(app)
      end

      % Button pushed function: RefreshAppButton
      function RefreshAppButtonPushed(app, event)
         %Refreshes all panels of the app
         AppUpdate(app)
      end

      % Callback function
      function NotificationsValueChanged(app, event)
         %Turns command window notifications on or off
         app.hammCam.notifications = true;
         app.stageControl.notifications = true;
      end

      % Value changed function: WaitForEquilibrium488
      function WaitForEquilibrium488ValueChanged(app, event)
         %Changes waitForEquilibrium for the 488 nm laser. This makes it so
         %that the laser has to reach its target value before anything else
         %can proceed.
         app.laser488.waitForEquilibrium = app.WaitForEquilibrium488.Value;
         InfrequentUpdate(app)
      end

      % Value changed function: WaitForEquilibrium561
      function WaitForEquilibrium561ValueChanged(app, event)
         %Changes waitForEquilibrium for the 561 nm laser. This makes it so
         %that the laser has to reach its target value before anything else
         %can proceed.
         app.laser561.waitForEquilibrium = app.WaitForEquilibrium561.Value;
         InfrequentUpdate(app)
      end

      % Value changed function: WaitForEquilibrium640
      function WaitForEquilibrium640ValueChanged(app, event)
         %Changes waitForEquilibrium for the 640 nm laser. This makes it so
         %that the laser has to reach its target value before anything else
         %can proceed.
         app.laser640.waitForEquilibrium = app.WaitForEquilibrium640.Value;
         InfrequentUpdate(app)
      end

      % Value changed function: OffWhenCleaned488
      function OffWhenCleaned488ValueChanged(app, event)
         %Changes offWhenCleaned for the 488 nm laser. While this setting
         %is true, the laser turns off when it is unloaded.
         app.laser488.offWhenDeleted = app.OffWhenCleaned488ValueChanged.Value;
         InfrequentUpdate(app)
      end

      % Value changed function: OffWhenCleaned561
      function OffWhenCleaned561ValueChanged(app, event)
         %Changes offWhenCleaned for the 561 nm laser. While this setting
         %is true, the laser turns off when it is unloaded.
         app.laser561.offWhenDeleted = app.OffWhenCleaned561ValueChanged.Value;
         InfrequentUpdate(app)
      end

      % Value changed function: OffWhenCleaned640
      function OffWhenCleaned640ValueChanged(app, event)
         %Changes offWhenCleaned for the 640 nm laser. While this setting
         %is true, the laser turns off when it is unloaded.
         app.laser640.offWhenDeleted = app.OffWhenCleaned640ValueChanged.Value;
         InfrequentUpdate(app)
      end

      % Button pushed function: XDown
      function XDownButtonPushed(app, event)
         %Moves the x stage down by whatever the current x increment is
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('X',-app.general.xInc)
         end
         PositionUpdate(app)
      end

      % Button pushed function: XUp
      function XUpButtonPushed(app, event)
         %Moves the x stage up by whatever the current x increment is
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('X',app.general.xInc)
         end
         PositionUpdate(app)
      end

      % Button pushed function: YDown
      function YDownButtonPushed(app, event)
         %Moves the y stage down by whatever the current y increment is
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('y',-app.general.yInc)
         end
         PositionUpdate(app)
      end

      % Button pushed function: YUp
      function YUpButtonPushed(app, event)
         %Moves the y stage up by whatever the current y increment is
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('Y',app.general.yInc)
         end
         PositionUpdate(app)
      end

      % Button pushed function: ZDown
      function ZDownButtonPushed(app, event)
         %Moves the z stage down by whatever the current z increment is
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('Z',-app.general.zInc)
         end
         PositionUpdate(app)
      end

      % Button pushed function: ZUp
      function ZUpButtonPushed(app, event)
         %Moves the z stage up by whatever the current z increment is
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageRelativeMove('Z',app.general.zInc)
         end
         PositionUpdate(app)
      end

      % Value changed function: XPosition
      function XPositionValueChanged(app, event)
         %If stage is loaded, moves stage to desired absolute position
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageAbsoluteMove('X',app.XPosition.Value)
         end
         PositionUpdate(app)
      end

      % Value changed function: YPosition
      function YPositionValueChanged(app, event)
         %If stage is loaded, moves stage to desired absolute position
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageAbsoluteMove('Y',app.YPosition.Value)
         end
         PositionUpdate(app)
      end

      % Value changed function: ZPosition
      function ZPositionValueChanged(app, event)
         %If stage is loaded, moves stage to desired absolute position
         if ~app.stageControl.connected
            errordlg('PI stage must be loaded before attempting to move it','Must load stage')
         else
            stageAbsoluteMove('Y',app.ZPosition.Value)
         end
         PositionUpdate(app)
      end

      % Value changed function: XIncrement
      function XIncrementValueChanged(app, event)
         %Changes x increment used for relative movements
         app.general.xInc = app.XIncrement.Value;
         PositionUpdate(app)
      end

      % Value changed function: YIncrement
      function YIncrementValueChanged(app, event)
         %Changes y increment used for relative movements
         app.general.yInc = app.YIncrement.Value;
         PositionUpdate(app)
      end

      % Value changed function: ZIncrement
      function ZIncrementValueChanged(app, event)
         %Changes z increment used for relative movements
         app.general.zInc = app.ZIncrement.Value;
         PositionUpdate(app)
      end

      % Value changed function: FramespertriggerEditField
      function FramespertriggerEditFieldValueChanged(app, event)
         app.hammCam.framesPerTrigger = app.FramespertriggerEditField.Value;
         CameraUpdate(app)
      end

      % Value changed function: ExposuretimeEditField
      function ExposuretimeEditFieldValueChanged(app, event)
         app.hammCam.exposureTime = app.ExposuretimeEditField.Value;
         CameraUpdate(app)
      end

      % Button pushed function: FuseCiclesButton
      function FuseCiclesButtonPushed(app, event)
         sCMOSCircleFuser
         DisplayUpdate(app)
      end

      % Button pushed function: SelectImageBoundsButton
      function SelectImageBoundsButtonPushed(app, event)
         sCMOSBoundSelector
      end

      % Value changed function: FramesperfuserstepEditField
      function FramesperfuserstepEditFieldValueChanged(app, event)
         app.hammCam.fuser.frames = app.FramesperfuserstepEditField.Value;
         CameraUpdate(app)
      end

      % Value changed function: FuserpercentilesEditField
      function FuserpercentilesEditFieldValueChanged(app, event)
         app.hammCam.fuser.percentiles = MultiFieldConversion(app.FuserpercentilesEditField.Value);
         CameraUpdate(app)
      end

      % Value changed function: FusercutoffmultipleEditField
      function FusercutoffmultipleEditFieldValueChanged(app, event)
         app.hammCam.fuser.steps = MultiFieldConversion(app.FusercutoffmultipleEditField.Value);
         CameraUpdate(app)
      end

      % Value changed function: UseboundsSwitch
      function UseboundsSwitchValueChanged(app, event)
         if app.general.reset
            switch app.UseboundsSwitch.Value
               case 'yes'
                  app.hammCam.useBounds(1) = true;
               case 'no'
                  app.hammCam.useBounds(1) = false;
            end
         else
            errordlg('Cannot switch usage of bounds while not reset','Cannot Switch Bounds')
         end
         CameraUpdate(app)
      end

      % Cell edit callback: ImageBounds
      function ImageBoundsCellEdit(app, event)
         newData = str2double(event.NewData);
         %Check if bounds are valid
         if event.Indices(2) == 2
            %Low bound
            if newData > app.hammCam.bounds{event.Indices(1),2}(2)
               errordlg('Lower bound cannot be more than upper bound','Invalid Bound')
               CameraUpdate(app)
               return
            end
         else
            %High bound
            if newData < app.hammCam.bounds{event.Indices(1),2}(1)
               errordlg('Upper bound cannot be less than lower bound','Invalid Bound')
               CameraUpdate(app)
               return
            end
         end

         if newData > 2304 || newData < 1
            errordlg('Bounds must be between 1 and 2304','Invalid Bound')
            CameraUpdate(app)
            return
         end

         %Sets the new bound
         app.hammCam.bounds{event.Indices(1),2}(event.Indices(2)-1) = newData;

         CameraUpdate(app)
      end

      % Value changed function: UsesecondboundsSwitch
      function UsesecondboundsSwitchValueChanged(app, event)
         if app.general.reset
            switch app.UsesecondboundsSwitch.Value
               case 'yes'
                  if ~app.hammCam.useBounds(1)
                     errordlg('First bounds also turned on','First Bounds On')
                     CameraUpdate(app)
                     return
                  end
                  app.hammCam.useBounds(2) = true;
               case 'no'
                  app.hammCam.useBounds(2) = false;
            end
         else
            errordlg('Cannot switch usage of bounds while not reset','Cannot Switch Bounds')
         end
         CameraUpdate(app)

      end

      % Value changed function: FuserAddress
      function FuserAddressValueChanged(app, event)
         app.general.fuserAddress = app.FuserAddress.Value;
         CameraUpdate(app)
      end

      % Cell edit callback: FuserInstructions
      function FuserInstructionsCellEdit(app, event)
         switch event.Indices(2) %column (stage/radius/steps)

            case 1 %stage
               if strcmp(event.NewData,"X") || strcmp(event.NewData,"Y")
                  app.hammCam.fuser.sequence(event.Indices(1)) = convertCharsToStrings(event.NewData);
               else
                  errordlg('Fuser stage must be either X or Y','Improper Axis')
               end
            case 2 %radius
               app.hammCam.fuser.radius(event.Indices(1)) = str2double(event.NewData);
            case 3 %steps
               if rem(str2double(event.NewData),2) == 1
                  app.hammCam.fuser.steps(event.Indices(1)) = str2double(event.NewData);
               else
                  errordlg('Fuser number of steps must be odd','Must Be Odd')
               end

         end
         CameraUpdate(app)
      end

      % Button pushed function: AddFuserStep
      function AddFuserStepPushed(app, event)
         address = app.general.fuserAddress;
         currentLength = length(app.hammCam.fuser.sequence);

         if address > currentLength + 1
            address = currentLength + 1;
            warnMessage = sprintf('Address greater than possible length, changed to %d',address);
            warndlg(warnMessage,'Over Maximum Address')
         end

         fieldsToChange = {'sequence','radius','steps'};
         fieldValues = {"x",1,5};
         app.hammCam.fuser = ModifyAllFields(app,app.stageControl,fieldsToChange,fieldValues,address);

         CameraUpdate(app)
      end

      % Button pushed function: DeleteFuserStep
      function DeleteFuserStepPushed(app, event)
         address = app.general.fuserAddress;
         currentLength = length(app.hammCam.fuser.sequence);

         if address > currentLength
            errordlg('Selected address does not exist','Invalid Address')
         elseif address == currentLength && address == 1
            errordlg('Cannot delete the last step','Cannot Delete')
         else
            fieldsToChange = {'sequence','radius','steps'};
            fieldValues = {'delete'};
            app.hammCam.fuser = AddToAllFields(app,app.hammCam.fuser,fieldsToChange,fieldValues,address);
         end
         CameraUpdate(app)
      end

      % Value changed function: StageoptimizationDropDown
      function StageoptimizationDropDownValueChanged(app, event)
         app.stageControl.optimizationType = app.StageoptimizationDropDown.Value;
         PositionUpdate(app)
      end

      % Value changed function: StagePrecision
      function StagePrecisionValueChanged(app, event)
         app.stageControl.tolerance = app.StagePrecision.Value;
         PositionUpdate(app)
      end

      % Value changed function: StageAddress
      function StageAddressValueChanged(app, event)
         app.general.stageAddress = app.StageAddress.Value;
         PositionUpdate(app)
      end

      % Button pushed function: AddOptimizationStep
      function AddOptimizationStepPushed(app, event)
         address = app.general.stageAddress;
         currentLength = length(app.stageControl.sequence);

         if address > currentLength + 1
            address = currentLength + 1;
            warnMessage = sprintf('Address greater than possible length, changed to %d',address);
            warndlg(warnMessage,'Over Maximum Address')
         end

         fieldsToChange = {'sequence','radius','steps'};
         fieldValues = {"x",1,5};
         app.stageControl = ModifyAllFields(app,app.stageControl,fieldsToChange,fieldValues,address);

         PositionUpdate(app)
      end

      % Button pushed function: DeleteOptimizationStep
      function DeleteOptimizationStepPushed(app, event)
         address = app.general.stageAddress;
         currentLength = length(app.stageControl.sequence);

         if address > currentLength
            errordlg('Selected address does not exist','Invalid Address')
         elseif address == currentLength && address == 1
            errordlg('Cannot delete the last step','Cannot Delete')
         else
            fieldsToChange = {'sequence','radius','steps'};
            fieldValues = {'delete'};
            app.stageControl = AddToAllFields(app,app.stageControl,fieldsToChange,fieldValues,address);
         end
         PositionUpdate(app)
      end

      % Cell edit callback: StageOptimizationInstructions
      function StageOptimizationInstructionsCellEdit(app, event)
         switch event.Indices(2) %column (stage/radius/steps)

            case 1 %stage
               if strcmp(event.NewData,"X") || strcmp(event.NewData,"Y") || strcmp(event.NewData,"Z")
                  app.stageControl.sequence(event.Indices(1)) = convertCharsToStrings(event.NewData);
               else
                  errordlg('Fuser stage must be X, Y, or Z','Improper Axis')
               end
            case 2 %radius
               app.stageControl.radius(event.Indices(1)) = str2double(event.NewData);
            case 3 %steps
               if rem(str2double(event.NewData),2) == 1
                  app.stageControl.steps(event.Indices(1)) = str2double(event.NewData);
               else
                  errordlg('Fuser number of steps must be odd','Must Be Odd')
               end

         end
         PositionUpdate(app)
      end

      % Cell edit callback: ScanningTable
      function ScanningTableCellEdit(app, event)
         rowNum = event.Indices(1);
         newData = event.NewData;
         switch event.Indices(2)
            case 1 %type
               if strcmp(newData,"Stage X") || strcmp(newData,"Stage Y") || strcmp(newData,"Stage Z")
                  app.general.scan.type(rowNum) = newData;
               else
                  errordlg('Scan type must be Stage X, Stage Y, or Stage Z','Invalid Type')
               end
            case {2,3}
               %start or end
               %TO DO: ensure scans where start is less than end work
               %properly
               if event.Indices(2) == 2
                  app.general.scan.start(rowNum) = str2double(newData);
               else
                  app.general.scan.end(rowNum) = str2double(newData);
               end

               if strcmp(app.general.defaultScanChange,'stepSize')
                  %Change step size while keeping number of steps constant
                  app.general.scan.stepSize(rowNum) = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ (app.general.scan.nSteps(rowNum)-1);
               else
                  %Change number of steps while keeping step size as close
                  %as possible to the original
                  stepChecker = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ app.general.scan.stepSize(rowNum) + 1;

                  if stepChecker <= 0
                     errordlg('Number of steps became negative, changing step size instead')
                     app.general.scan.stepSize(rowNum) = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ (app.general.scan.nSteps(rowNum)-1);
                  elseif stepChecker == floor(stepChecker)
                     app.general.scan.nSteps(rowNum) = stepChecker;
                  else
                     stepChecker = round(stepChecker);
                     app.general.scan.nSteps(rowNum) = stepChecker;
                     app.general.scan.stepSize(rowNum) = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ (app.general.scan.nSteps(rowNum)-1);
                     changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.general.scan.stepSize(rowNum));
                     warndlg(changeWarning,'Altered Step Size')
                  end

               end

            case 4 % # of steps
               if str2double(newData) == floor(str2double(newData)) && str2double(newData) > 0
                  app.general.scan.nSteps(rowNum) = str2double(newData);
               else
                  errordlg('Number of steps must be a whole number','Invalid # of Steps')
               end
               app.general.scan.stepSize(rowNum) = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ (app.general.scan.nSteps(rowNum)-1);
            case 5 %step size
               stepChecker = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ str2double(newData) + 1;
               if  stepChecker <= 0
                  errordlg('Number of steps must be a whole number, step size must have corresponding sign','Invalid Step Size')
               elseif stepChecker == floor(stepChecker)
                  app.general.scan.nSteps(rowNum) = stepChecker;
                  app.general.scan.stepSize(rowNum) = str2double(newData);
               else
                  stepChecker = round(stepChecker);
                  app.general.scan.nSteps(rowNum) = stepChecker;
                  app.general.scan.stepSize(rowNum) = (app.general.scan.end(rowNum) - app.general.scan.start(rowNum)) ./ (app.general.scan.nSteps(rowNum)-1);
                  changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.general.scan.stepSize(rowNum));
                  warndlg(changeWarning,'Altered Step Size')
               end
         end

         ScanUpdate(app)
         OutputSettingsUpdate(app)

      end

      % Button pushed function: AddScanStep
      function AddScanStepPushed(app, event)
         %Note: I chose to have a 1x1 structure with 1xN elements as it
         %is functionally the same except it is easier to deal with
         %individual elements this way

         address = app.general.scanAddress;
         currentLength = length(app.general.scan.type);

         if address > currentLength + 1
            address = currentLength + 1;
            warnMessage = sprintf('Address greater than possible length, changed to %d',address);
            warndlg(warnMessage,'Over Maximum Address')
         end

         fieldsToChange = {'type','start','end','nSteps','stepSize','completed','stepToView'};
         fieldValues = {"Stage X",1,10,10,1,0,1};
         app.general.scan = ModifyAllFields(app,app.general.scan,fieldsToChange,fieldValues,address);

         ScanUpdate(app)
         OutputSettingsUpdate(app) %Needed for step to view selection and the like
      end

      % Value changed function: ScanAddress
      function ScanAddressValueChanged(app, event)
         app.general.scanAddress = app.ScanAddress.Value;
         ScanUpdate(app)
      end

      % Value changed function: DefaultchangeDropDown
      function DefaultchangeDropDownValueChanged(app, event)
         switch app.DefaultchangeDropDown.Value
            case '# of steps'
               app.general.defaultScanChange = 'nSteps';
            case 'Step size'
               app.general.defaultScanChange = 'stepSize';
         end

         ScanUpdate(app)
      end

      % Button pushed function: DeleteScanStep
      function DeleteScanStepPushed(app, event)
         address = app.general.scanAddress;
         currentLength = length(app.general.scan.type);

         if address > currentLength
            errordlg('Selected address does not exist','Invalid Address')
         elseif address == currentLength && address == 1
            errordlg('Cannot delete the last step','Cannot Delete')
         else
            fieldsToChange = {'type','start','end','nSteps','stepSize','completed','stepToView'};
            fieldValues = {'delete'};
            app.general.scan = ModifyAllFields(app,app.general.scan,fieldsToChange,fieldValues,address);
         end

         ScanUpdate(app)
         OutputSettingsUpdate(app) %Needed for step to view selection and the like
      end

      % Value changed function: StageAutoOptimization
      function StageAutoOptimizationValueChanged(app, event)
         app.general.autoStageOptimize = app.StageAutoOptimization.Value;
         PositionUpdate(app)
      end

      % Value changed function: StageOptimizationInterval
      function StageOptimizationIntervalValueChanged(app, event)
         app.general.stageOptimizationInterval = app.StageOptimizationInterval.Value;
         PositionUpdate(app)
      end

      % Button pushed function: ResetButton
      function ResetButtonPushed(app, event)
         if app.general.scanRunning
            errordlg('Abort the what is currently running before attempting to reset','Abort First')
         else
            %Set ready status to true,
            app.general.reset = true;
            app.general.scan.completed = zeros(1,numel(app.general.scan.start));
            app.general.storedDataSettings.scan.completed = app.general.scan.completed;
         end
         StatusUpdate(app)


      end

      % Callback function
      function OutputrefreshButtonPushed(app, event)
         DisplayUpdate(app)
      end

      % Value changed function: VideoPauseTime
      function VideoPauseTimeValueChanged(app, event)
         app.general.videoPauseTime = app.VideoPauseTime.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: ScantoshowvideoofDropDown
      function ScantoshowvideoofDropDownValueChanged(app, event)
         app.general.videoScan = app.ScantoshowvideoofDropDown.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: ImageOrVideo
      function ImageOrVideoValueChanged(app, event)
         if app.general.scanRunning
            app.general.imageVideo = app.ImageOrVideo.Value;
         else
            errordlg('Cannot show video while scan is running','Cannot Show Video')
         end
         OutputSettingsUpdate(app)
      end

      % Cell edit callback: ScanStepTable
      function ScanStepTableCellEdit(app, event)
         newData = str2double(event.NewData);
         if isnan(newData)
            OutputSettingsUpdate(app)
            errordlg('Step to view must be a number','Invalid Step')
            return
         end
         if newData <= app.general.scan.nSteps(event.Indices(1)) && newData > 0 && floor(newData) == newData
            app.general.scan.stepToView(event.Indices(1)) = newData;
            app.general.storedDataSettings.scan.stepToView(event.Indices(1)) = newData;
         else
            errordlg('Step to view must be an integer between 1 and total number of steps inclusive','Invalid Step')
         end
         OutputSettingsUpdate(app)
      end

      % Value changed function: OverridecurrentstepviewCheckBox
      function OverridecurrentstepviewCheckBoxValueChanged(app, event)
         app.general.overrideCurrentStep = app.OverridecurrentstepviewCheckBox.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: LoopvideoCheckBox
      function LoopvideoCheckBoxValueChanged(app, event)
         app.general.loopVideo = app.LoopvideoCheckBox.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: RandomizescanCheckBox
      function RandomizescanCheckBoxValueChanged(app, event)
         app.general.randomScan = app.RandomizescanCheckBox.Value;
         ScanUpdate(app)
         OutputSettingsUpdate(app)
      end

      % Value changed function: LogarithmicContrastCheckBox
      function LogarithmicContrastCheckBoxValueChanged(app, event)
         app.displayInfo.logarithmicContrast = app.LogarithmicContrastCheckBox.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: SaveeveryframeSwitch
      function SaveeveryframeSwitchValueChanged(app, event)
         if app.general.reset
            app.general.saveAllFrames = app.SaveeveryframeSwitch.Value;
         else
            errordlg('Cannot change frame saving settings while not reset','Cannot Change Setting')
         end
         CameraUpdate(app)
      end

      % Value changed function: ForcesquareaspectratioCheckBox
      function ForcesquareaspectratioCheckBoxValueChanged(app, event)
         app.displayInfo.squareRatio = app.ForcesquareaspectratioCheckBox.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: SaveFolderName
      function SaveFolderNameValueChanged(app, event)
         app.general.saveName = app.SaveFolderName.Value;
         SaveDataUpdate(app)
      end

      % Value changed function: BoxaddressEditField
      function BoxaddressEditFieldValueChanged(app, event)
         app.general.boxAddress = app.BoxaddressEditField.Value;
         SaveDataUpdate(app)
      end

      % Value changed function: SaveoptionsDropDown
      function SaveoptionsDropDownValueChanged(app, event)
         app.general.saveType = app.SaveoptionsDropDown.Value;
         SaveDataUpdate(app)
      end

      % Value changed function: TextArea
      function TextAreaValueChanged(app, event)
         app.general.userNotes = app.TextArea.Value;
         SaveDataUpdate(app)
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)
         
         if strcmp(app.general.dataOrSetups,'Data') && ~isempty(app.storedData)
            errordlg('No data to save','Save Unsuccessful')
            return
         end

         rootSaveName = strcat(app.general.boxAddress,'\Backlund lab shared\data\',app.general.userName,['\Experiment Controller SM ',app.general.dataOrSetups]);

         %Checks if the directory is valid. Even if it is empty, there will
         %be 2 "fake" files
         setupDirectory = struct2cell(dir(rootSaveName));
         if isempty(setupDirectory)
            errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
            return
         end

         %Checks if the given folders are present and makes them if they
         %aren't. Data folders are first sorted by date and then by name
         %while setup folders are the reverse
         if strcmp(app.general.dataOrSetups,'Data')
            rootSaveName = CheckFolder(app,rootSaveName,string(datetime('today','Format','yyyy-MM-dd')));
            rootSaveName = CheckFolder(app,rootSaveName,app.general.saveName);
         else
            rootSaveName = CheckFolder(app,rootSaveName,app.general.saveName);
            
            versionDirectory = struct2cell(dir(rootSaveName));
            versionDirectory = versionDirectory(1,:);%Only name row
            if numel(versionDirectory) == 2
               versionNumber = 1;
            else
               versionDirectory = versionDirectory(:,3:end);
               versionDirectory = cellfun(@(a)a(2:end-13),versionDirectory,'UniformOutput',false);
               versionNumber = max(str2double(versionDirectory))+1;                  
            end
            newName = sprintf('v%d - %s',versionNumber,string(datetime('today','Format','yyyy-MM-dd')));
            rootSaveName = CheckFolder(app,rootSaveName,newName);
         end

         %At this point, the root name is the exact folder the files will
         %be saved in
         %Two functions are done here to make the code more legible

         if strcmp(app.general.dataOrSetups,'Data')
            DataSave(app,rootSaveName)
         else
            SetupSave(app,rootSaveName)
         end

         SaveDataUpdate(app)


      end

      % Value changed function: StagePauseTime
      function StagePauseTimeValueChanged(app, event)
         app.stageControl.pauseTime = app.StagePauseTime.Value;
         InfrequentUpdate(app)
      end

      % Cell edit callback: PlotLimits
      function PlotLimitsCellEdit(app, event)
         if event.Indices(2) == 4
            %If input character array is true/false (or some variant), set the
            %corresponding row of the overrideLimits cell to a logical true
            %or false
            if any(strcmp(event.NewData,{'true','True','TRUE','yes','Yes','YES','on','On','ON','t','T','1'}))
               app.displayInfo.overrideLimits{event.Indices(1),2} = true;
            elseif any(strcmp(event.NewData,{'false','False','FALSE','no','No','NO','off','Off','OFF','f','F','0'}))
               app.displayInfo.overrideLimits{event.Indices(1),2} = false;
            else
               errordlg('Invalid override limit choise. Must be "true" or "false"','Invalid Override Choice')
            end
         else
            newData = str2double(event.NewData);
            if event.Indices(2)-1 == 1
               %Minimum
               if newData >= app.displayInfo.limitsIfOverriden{event.Indices(1),2}(2)
                  %New minimum is greater than or equal to the maximum for
                  %that axis
                  errordlg('Invalid minimum. Must be less than maximum.','Invalid Minimum')
                  OutputSettingsUpdate(app)
                  return
               end
            else
               %Maximum
               if newData <= app.displayInfo.limitsIfOverriden{event.Indices(1),2}(1)
                  %New maximum is less than or equal to the minimum for
                  %that axis
                  errordlg('Invalid maximum. Must be greater than minimum.','Invalid Maximum')
                  OutputSettingsUpdate(app)
                  return
               end
            end

            if event.Indices(1) == 3
               if newData > 100 || newData < 0
                  %Color percentile must be between 0 and 100 because that
                  %is how percentiles work
                  errordlg('Invalid color percentile. Must be between 0 and 100.','Invalid Percentile')
                  OutputSettingsUpdate(app)
                  return
               end
            end

            %Set the relevant row of the display limits cell to the new
            %value with the column indicating minimum or maximum within the
            %limits matrix
            app.displayInfo.limitsIfOverriden{event.Indices(1),2}(event.Indices(2)-1) = newData;
         end

         %Lets app know that the limits have been changed
         app.displayInfo.alteredLimits = true;

         OutputSettingsUpdate(app)
      end

      % Button pushed function: ZoomIn2buttonsquareButton
      function ZoomIn2buttonsquareButtonPushed(app, event)
         if ~isfield(app.displayInfo,'axes')
            errordlg('No image is present to zoom in on','Invalid Zoom')
            return
         end

         %The user must select 2 points within the axes and
         regionOfInterest = drawrectangle(app.displayInfo.axes);

         try

            %changes x and y axis limits as chosen by the above rectangle
            app.displayInfo.limitsIfOverriden{1,2} = ...
               [round(regionOfInterest.Vertices(1,1)) round(regionOfInterest.Vertices(3,1))];
            app.displayInfo.limitsIfOverriden{2,2} = ...
               [round(regionOfInterest.Vertices(1,2)) round(regionOfInterest.Vertices(2,2))];

            %Lets app know that the limits have been changed
            app.displayInfo.alteredLimits = true;

            app.displayInfo.overrideLimits{1,2} = true;
            app.displayInfo.overrideLimits{2,2} = true;

            delete(regionOfInterest)
         catch
            warndlg('Zoom did not complete due to invalid bounds','Zoom Aborted')
         end

         OutputSettingsUpdate(app)
      end

      % Button pushed function: ZoomOutButton
      function ZoomOutButtonPushed(app, event)
         %WARNING: there is a known bug where MatLab does not properly zoom
         %out when the limits are changed back to their defaults. I have no
         %idea why this happens as the solution is literally to enter the
         %same values in manually. Scroll wheel out still works, so this
         %isn't debilitating, merely annoying
         if ~isfield(app.displayInfo,'previousData')
            %If no data has been displayed, zooming out makes no sense
            warndlg('Cannot zoom out when no data is displayed','Failed Zoom Out')
            return
         end

         app.displayInfo.overrideLimits{1,2} = false;
         app.displayInfo.overrideLimits{2,2} = false;

         %Lets app know that the limits have been changed
         app.displayInfo.alteredLimits = true;

         OutputSettingsUpdate(app)

      end

      % Value changed function: DropDown
      function DropDownValueChanged(app, event)
         app.displayInfo.dataSelection = app.DropDown.Value;
         OutputSettingsUpdate(app)
      end

      % Cell edit callback: StaticColorLimits
      function StaticColorLimitsCellEdit(app, event)
         newData = str2double(event.NewData);%Can fail
         if isnan(newData)
            errordlg('Invalid input. Must be a number','Invalid Input')
            OutputSettingsUpdate(app)
            return
         end
         if event.Indices(1) == 1 && newData >= app.displayInfo.staticColorLimits(2)
            errordlg('Invalid input. Minimum must be less than maximum','Invalid Input')
            OutputSettingsUpdate(app)
            return
         elseif event.Indices(1) == 2 && newData <= app.displayInfo.staticColorLimits(1)
            errordlg('Invalid input. Maximum must be more than minimum','Invalid Input')
            OutputSettingsUpdate(app)
            return
         end
         app.displayInfo.staticColorLimits(event.Indices(1)) = newData;

         OutputSettingsUpdate(app)
      end

      % Value changed function: UseStaticColorLimits
      function UseStaticColorLimitsValueChanged(app, event)
         app.displayInfo.useStaticColorLimits = app.UseStaticColorLimits.Value;
         app.displayInfo.overrideLimits{3,2} = app.displayInfo.useStaticColorLimits;
         app.displayInfo.alteredLimits = true;
         OutputSettingsUpdate(app)
      end

      % Button pushed function: LoadButton
      function LoadButtonPushed(app, event)
         %REDO THIS FUNCTION FOR NEW OBJECTS*****

         rootLoadName = strcat(app.general.boxAddress,'\Backlund lab shared\data\',app.general.userName);
         if strcmp(app.general.dataOrSetups,'Data')
            rootLoadName = strcat(rootLoadName,'\Experiment Controller SM Data');
            %Makes the root now include the date then name
            rootLoadName = strcat(rootLoadName,'\', app.general.loadDate,'\',app.general.loadName);

            loadName = strcat(rootLoadName,'\', 'MatLab_Data.mat');
            load(loadName,"data")
            loadName = strcat(rootLoadName,'\', 'MatLab_Data_Settings.mat');
            load(loadName,"dataSettings")

            app.storedData = data;
            app.general.storedDataSetttings = dataSettings;

            loadName = strcat(rootLoadName,'\', 'MatLab_User_Notes.mat');
            load(loadName,"userNotes")
            app.general.userNotes = userNotes;

            SaveDataUpdate(app)
            DisplayUpdate(app)

            uialert(app.UIFigure,'Data Loaded!','Save Successful','Icon','success')

         else
            rootLoadName = strcat(rootLoadName,'\Experiment Controller SM Setups');
            %Makes the root now include the name then date
            rootLoadName = strcat(rootLoadName,'\', app.general.loadName,'\',app.general.loadDate);

            loadName = strcat(rootLoadName,'\', 'Setup_Settings.mat');
            load(loadName,"savedSettings")

            savedNames = fieldnames(savedSettings);

            %For each field name present within the loaded settings,
            %replace the current setting with the loaded one
            for ii = 1:numel(savedNames)
               subSavedNames = fieldnames(savedSettings.(savedNames{ii}));
               for jj = 1:numel(subSavedNames)
                  master.(savedNames{ii}).(subSavedNames{jj}) = ...
                     savedSettings.(savedNames{ii}).(subSavedNames{jj});
               end
            end

            loadName = strcat(rootLoadName,'\', 'MatLab_User_Notes.mat');
            load(loadName,"userNotes")
            app.general.userNotes = userNotes;

            AppUpdate(app)

            uialert(app.UIFigure,'Setup Loaded!','Save Successful','Icon','success')

         end


      end

      % Button pushed function: ScreenshotButton
      function ScreenshotButtonPushed(app, event)
         %Saves the screenshot to current date folder on box for the
         %individual

         %This is the name for the data folder for this app for this person
         rootSaveName = strcat(app.general.boxAddress,'\Backlund lab shared\data\',app.general.userName,'\Experiment Controller SM Data');

         %Load the directory
         fileList = struct2cell(dir(rootSaveName));

         %If no folders appear, the box address is almost certainly the
         %issue
         if isempty(fileList)
            errordlg('Invalid box address. Change on the Save/Load tab','Invalid Box Address')
            return
         end

         %Finds current date
         currentDate = string(datetime('today','Format','yyyy-MM-dd'));

         %Check to see if the folder in question exists, then, if not, make
         %one. Update root save name to include new folder
         fileExists = any(strcmp(currentDate,fileList),"all");
         if ~fileExists
            mkdir(rootSaveName,currentDate)
         end
         rootSaveName = strcat(rootSaveName,'\',currentDate);

         %Check to see if the folder in question exists, then, if not, make
         %one. Update root save name to include new folder
         fileList = struct2cell(dir(rootSaveName));
         fileExists = any(strcmp('Screenshots',fileList),"all");
         if ~fileExists
            mkdir(rootSaveName,'Screenshots')
         end
         rootSaveName = strcat(rootSaveName,'\','Screenshots');

         %Check to see if the file in question already exists
         fileList = struct2cell(dir(rootSaveName));
         fileList = fileList(1,:); %only name row
         fileExists = contains(fileList,app.general.saveName);

         if ~any(fileExists,'all')
            %No file with this name exists, so it is saved just with the
            %base name
            fullSaveName = strcat(rootSaveName,'\',app.general.saveName);
         elseif sum(fileExists,"all") == 1
            %Only 1 file with the name exists so the next one is saved with
            %a 2
            fullSaveName = strcat(rootSaveName,'\',app.general.saveName,' 2');
         else
            versionNumber = cellfun(@(a)str2double(a(numel(app.general.saveName)+2:end-4)),fileList,'UniformOutput',false);
            versionNumber = max(cell2mat(versionNumber(cellfun(@(a)~isnan(a),versionNumber))))+1;
            fullSaveName = sprintf('%s%s %d',strcat(rootSaveName,'\'),app.general.saveName,versionNumber);
         end

         %Takes a screenshot of the app's current screen
         exportapp(app.UIFigure,strcat(fullSaveName,'MatLab_App_Screenshot.png'))

         %Creates temporary figure and axes that match the display axes so
         %that an image of them can be saved
         tempFigure = figure('Visible','off');
         tempAxes = copyobj(app.displayInfo.axes,tempFigure);
         %Changes appearance of figure to make a better picture
         set(tempAxes, 'Units', 'Normalized'); % First change to normalized units.
         set(tempAxes, 'OuterPosition', [0.05, 0.05, .9, .9]); % [xLeft, yBottom, width, height]
         %Saves the figure then closes it
         saveas(tempAxes,strcat(fullSaveName,'MatLab_Axes_Screenshot','png'))
         close(tempFigure)

      end

      % Value changed function: UserName
      function UserNameValueChanged(app, event)
         app.general.userName = app.UserName.Value;
         SaveDataUpdate(app)
      end

      % Value changed function: DataSetupsSwitch
      function DataSetupsSwitchValueChanged(app, event)
         app.general.dataOrSetups = app.DataSetupsSwitch.Value;
         SaveDataUpdate(app)
      end

      % Value changed function: LoadDatesBox
      function LoadDatesBoxValueChanged(app, event)
         app.general.loadDate = app.LoadDatesBox.Value;
         SaveDataUpdate(app)
      end

      % Value changed function: LoadNamesBox
      function LoadNamesBoxValueChanged(app, event)
         app.general.loadName = app.LoadNamesBox.Value;
         SaveDataUpdate(app)
      end

      % Button pushed function: ShowdatacontinuouslyButton
      function ShowdatacontinuouslyButtonPushed(app, event)
         %Sets video running to true. When aborted, this will be set to
         %false and the loop below will end
         app.general.videoRunning = true;
         StatusUpdate(app)


         firstLoop = true;
         while app.general.videoRunning
            %End the loop if the figure has been closed
            im = sCMOSImage;
            if ~isvalid(continuousData)
               app.general.videoRunning = false;
               break
            elseif firstLoop
               %Creates a separate figure outside of the app then populates it
               %with axes, an initial image set to grayscale, and a colorbar
               continuousData = figure;
               cdAxes = axes(continuousData); %#ok<LAXES>
               im = sCMOSImage;
               cdIm = imagesc(cdAxes,im);
               colormap(app.displayInfo.axes,"gray")
               colorbar
               firstLoop = false;
            else
               %Just updates the data of the image
               cdIm.CData = im;
            end

            %See DisplayUpdate for comments regarding this section
            if app.displayInfo.overrideLimits{3,2}
               if ~app.displayInfo.useStaticColorLimits
                  lowLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(1),"all");
                  highLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(2),"all");
               else
                  lowLimit = app.displayInfo.staticColorLimits(1);
                  highLimit = app.displayInfo.staticColorLimits(2);
               end
            else
               lowLimit = min(displayData,[],"all");
               highLimit = max(displayData,[],"all");
            end
            if lowLimit > cdAxes.CLim(2)
               cdAxes.CLim(2) = lowLimit + .01;
            end
            cdAxes.CLim(1) = lowLimit;
            cdAxes.CLim(2) = highLimit;
         end

         %If the figure is still valid, close it
         if isvalid(continuousData)
            close(continuousData)
         end

         StatusUpdate(app)

      end

      % Button pushed function: TakesingleimageButton
      function TakesingleimageButtonPushed(app, event)
         %Creates a separate figure outside of the app then populates it
         %with axes, an initial image set to grayscale, and a colorbar
         continuousData = figure;
         cdAxes = axes(continuousData);
         im = takeImage(app.hammCam);
         if app.displayInfo.forceConcatenation
            %Two images
            
            if app.displayInfo.horizontalConcatenation
               %Find if vertical sides are identical
               %If not, add rows of 0s to both top and bottom of the
               %shorter image      
               firstSide = size(im{1},1);
               secondSide = size(im{2},1);
               if firstSide < secondSide
                  sideDiff = secondSide - firstSide;
                  rowsToAdd = floor(sideDiff/2);
                  im{1}(rowsToAdd+1:end+rowsToAdd,:) = im{1};
                  im{1}(1:rowsToAdd,:) = zeros(rowsToAdd,size(im{1},2));
                  im{1}(end+1:end+rowsToAdd,:) = zeros(rowsToAdd,size(im{1},2));
                  if mod(sideDiff,2)
                     im{1}(end+1,:) = zeros(1,size(im{1},2));
                  end
                  finalIm = [im{1} im{2}];
               elseif firstSide > secondSide
                  sideDiff = abs(secondSide - firstSide);
                  rowsToAdd = floor(sideDiff/2);
                  im{2}(rowsToAdd+1:end+rowsToAdd,:) = im{2};
                  im{2}(1:rowsToAdd,:) = zeros(rowsToAdd,size(im{2},2));
                  im{2}(end+1:end+rowsToAdd,:) = zeros(rowsToAdd,size(im{2},2));
                  if mod(sideDiff,2)
                     im{2}(end+1,:) = zeros(1,size(im{2},2));
                  end
                  finalIm = [im{1} im{2}];
               end
            else
               %Find if horizontal sides are identical
               %If not, add columns of 0s to both right and left of the
               %skinnier image
               firstSide = size(im{1},2);
               secondSide = size(im{2},2);
               if firstSide < secondSide
                  sideDiff = secondSide - firstSide;
                  colsToAdd = floor(sideDiff/2);
                  im{1}(:,colsToAdd+1:end+colsToAdd) = im{1};
                  im{1}(:,1:colsToAdd) = zeros(size(im{1},1),colsToAdd);
                  im{1}(:,end+1:end+colsToAdd) = zeros(size(im{1},2),colsToAdd);
                  if mod(sideDiff,2)
                     im{1}(:,end+1) = zeros(size(im{1},2),1);
                  end
                  finalIm = [im{1};im{2}];
               elseif firstSide > secondSide
                  sideDiff = abs(secondSide - firstSide);
                  colsToAdd = floor(sideDiff/2);
                  im{2}(:,colsToAdd+1:end+colsToAdd) = im{2};
                  im{2}(:,1:colsToAdd) = zeros(size(im{2},1),colsToAdd);
                  im{2}(:,end+1:end+colsToAdd) = zeros(size(im{2},2),colsToAdd);
                  if mod(sideDiff,2)
                     im{2}(:,end+1) = zeros(size(im{2},2),1);
                  end
                  finalIm = [im{1};im{2}];
               end
            end
         else
            %Single image only
            finalIm = im{1};
         end
         imagesc(cdAxes,finalIm)
         colormap(app.displayInfo.axes,"gray")
         colorbar
      end

      % Value changed function: NumberofiterationsEditField
      function NumberofiterationsEditFieldValueChanged(app, event)
         app.general.maxIteration = app.NumberofiterationsEditField.Value;
         GeneralUpdate(app)
      end

      % Value changed function: forceConcatenation
      function forceConcatenationValueChanged(app, event)
         app.displayInfo.forceConcatenation = app.forceConcatenation.Value;
         OutputSettingsUpdate(app)
      end

      % Value changed function: Switch
      function SwitchValueChanged(app, event)
         app.displayInfo.horizontalConcatenation = app.Switch.Value;
         OutputSettingsUpdate(app)
      end

      % Button pushed function: CopyappobjecttoworkspaceButton
      function CopyappobjecttoworkspaceButtonPushed(app, event)
         %Makes a copy of the current app in the workspace. Mainly for
         %debugging
         assignin("base",copyobj("app"))
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.TabGroup = uitabgroup(app.UIFigure);
         app.ConnectionsTab = uitab(app.TabGroup);
         app.ConnectionsTab.Title = 'Connections';
         app.GridLayout = uigridlayout(app.ConnectionsTab);
         app.StageLoad = uibutton(app.GridLayout, 'push');
         app.StageLoad.ButtonPushedFcn = createCallbackFcn(app, @StageLoadButtonPushed, true);
         app.StageLoad.Text = 'Load';
         app.StageUnload = uibutton(app.GridLayout, 'push');
         app.StageUnload.ButtonPushedFcn = createCallbackFcn(app, @StageUnloadButtonPushed, true);
         app.StageUnload.Text = 'Unload';
         app.StageLamp = uilamp(app.GridLayout);
         app.DDLLoad = uibutton(app.GridLayout, 'push');
         app.DDLLoad.Text = 'Load';
         app.HammLoad = uibutton(app.GridLayout, 'push');
         app.HammLoad.ButtonPushedFcn = createCallbackFcn(app, @HammLoadButtonPushed, true);
         app.HammLoad.Text = 'Load';
         app.Load640 = uibutton(app.GridLayout, 'push');
         app.Load640.ButtonPushedFcn = createCallbackFcn(app, @Load640ButtonPushed, true);
         app.Load640.Text = 'Load';
         app.DynamicDelayLineLamp = uilamp(app.GridLayout);
         app.HammamatsuLamp = uilamp(app.GridLayout);
         app.Lamp640 = uilamp(app.GridLayout);
         app.Lamp488 = uilamp(app.GridLayout);
         app.Lamp561 = uilamp(app.GridLayout);
         app.DDLUnload = uibutton(app.GridLayout, 'push');
         app.DDLUnload.Text = 'Unload';
         app.HammUnload = uibutton(app.GridLayout, 'push');
         app.HammUnload.ButtonPushedFcn = createCallbackFcn(app, @HammUnloadButtonPushed, true);
         app.HammUnload.Text = 'Unload';
         app.Unload640 = uibutton(app.GridLayout, 'push');
         app.Unload640.ButtonPushedFcn = createCallbackFcn(app, @Unload640ButtonPushed, true);
         app.Unload640.Text = 'Unload';
         app.Unload561 = uibutton(app.GridLayout, 'push');
         app.Unload561.ButtonPushedFcn = createCallbackFcn(app, @Unload561ButtonPushed, true);
         app.Unload561.Text = 'Unload';
         app.Load561 = uibutton(app.GridLayout, 'push');
         app.Load561.ButtonPushedFcn = createCallbackFcn(app, @Load561ButtonPushed, true);
         app.Load561.Text = 'Load';
         app.Load488 = uibutton(app.GridLayout, 'push');
         app.Load488.ButtonPushedFcn = createCallbackFcn(app, @Load488ButtonPushed, true);
         app.Load488.Text = 'Load';
         app.Unload488 = uibutton(app.GridLayout, 'push');
         app.Unload488.ButtonPushedFcn = createCallbackFcn(app, @Unload488ButtonPushed, true);
         app.Unload488.Text = 'Unload';
         app.SDLLoad = uibutton(app.GridLayout, 'push');
         app.SDLLoad.Text = 'Load';
         app.SDLUnload = uibutton(app.GridLayout, 'push');
         app.SDLUnload.Text = 'Unload';
         app.StaticDelayLineLamp = uilamp(app.GridLayout);
         app.LoadAll = uibutton(app.GridLayout, 'push');
         app.LoadAll.ButtonPushedFcn = createCallbackFcn(app, @LoadAllButtonPushed, true);
         app.LoadAll.Text = 'Load';
         app.UnloadAll = uibutton(app.GridLayout, 'push');
         app.UnloadAll.ButtonPushedFcn = createCallbackFcn(app, @UnloadAllButtonPushed, true);
         app.UnloadAll.Text = 'Unload';
         app.AllInstrumentsLamp = uilamp(app.GridLayout);
         app.StageLabel = uilabel(app.GridLayout);
         app.StageLabel.Text = 'Stage';
         app.DynamicDelayLineDDLLabel = uilabel(app.GridLayout);
         app.DynamicDelayLineDDLLabel.Text = {'Dynamic Delay'; 'Line (DDL)'};
         app.StaticDelayLineSDLLabel = uilabel(app.GridLayout);
         app.StaticDelayLineSDLLabel.Text = {'Static Delay'; 'Line (SDL)'};
         app.HammamatsusCMOScameraLabel = uilabel(app.GridLayout);
         app.HammamatsusCMOScameraLabel.Text = {'Hammamatsu'; 'sCMOS camera'};
         app.nmlaserLabel = uilabel(app.GridLayout);
         app.nmlaserLabel.Text = '488 nm laser';
         app.nmlaserLabel_2 = uilabel(app.GridLayout);
         app.nmlaserLabel_2.Text = '561 nm laser';
         app.nmlaserLabel_3 = uilabel(app.GridLayout);
         app.nmlaserLabel_3.Text = '640 nm laser';
         app.AllinstrumentsLabel = uilabel(app.GridLayout);
         app.AllinstrumentsLabel.Text = 'All instruments';
         app.SettingsTab = uitab(app.TabGroup);
         app.SettingsTab.Title = 'Settings';
         app.GridLayout4 = uigridlayout(app.SettingsTab);
         app.LaserLabel = uilabel(app.GridLayout4);
         app.LaserLabel.Text = '488 Laser';
         app.Toggle488 = uiswitch(app.GridLayout4, 'slider');
         app.Toggle488.Items = {'off', 'on'};
         app.Toggle488.ValueChangedFcn = createCallbackFcn(app, @Toggle488ValueChanged, true);
         app.Toggle488.Value = 'off';
         app.SetPower488 = uieditfield(app.GridLayout4, 'numeric');
         app.SetPower488.ValueChangedFcn = createCallbackFcn(app, @SetPower488ValueChanged, true);
         app.SetPower561 = uieditfield(app.GridLayout4, 'numeric');
         app.SetPower561.ValueChangedFcn = createCallbackFcn(app, @SetPower561ValueChanged, true);
         app.LaserLabel_2 = uilabel(app.GridLayout4);
         app.LaserLabel_2.Text = '561 Laser';
         app.Toggle561 = uiswitch(app.GridLayout4, 'slider');
         app.Toggle561.Items = {'off', 'on'};
         app.Toggle561.ValueChangedFcn = createCallbackFcn(app, @Toggle561ValueChanged, true);
         app.Toggle561.Value = 'off';
         app.SetPower640 = uieditfield(app.GridLayout4, 'numeric');
         app.SetPower640.ValueChangedFcn = createCallbackFcn(app, @SetPower640ValueChanged, true);
         app.LaserLabel_3 = uilabel(app.GridLayout4);
         app.LaserLabel_3.Text = '640 Laser';
         app.Toggle640 = uiswitch(app.GridLayout4, 'slider');
         app.Toggle640.Items = {'off', 'on'};
         app.Toggle640.ValueChangedFcn = createCallbackFcn(app, @Toggle640ValueChanged, true);
         app.Toggle640.Value = 'off';
         app.RefreshAppButton = uibutton(app.GridLayout4, 'push');
         app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
         app.RefreshAppButton.Text = 'Refresh App';
         app.RandomizeScan = uicheckbox(app.GridLayout4);
         app.RandomizeScan.Text = {'Randomize'; 'scan'};
         app.CurrentOutput488 = uieditfield(app.GridLayout4, 'numeric');
         app.Refresh488Laser = uibutton(app.GridLayout4, 'push');
         app.Refresh488Laser.ButtonPushedFcn = createCallbackFcn(app, @Refresh488LaserButtonPushed, true);
         app.Refresh488Laser.Text = {'Refresh'; 'power'; 'reading'};
         app.SetpowermWLabel = uilabel(app.GridLayout4);
         app.SetpowermWLabel.Text = {'Set power'; '(mW)'};
         app.PowerreadingmWLabel = uilabel(app.GridLayout4);
         app.PowerreadingmWLabel.Text = {'Power'; 'reading (mW)'};
         app.CurrentOutput561 = uieditfield(app.GridLayout4, 'numeric');
         app.CurrentOutput640 = uieditfield(app.GridLayout4, 'numeric');
         app.Refresh561Laser = uibutton(app.GridLayout4, 'push');
         app.Refresh561Laser.ButtonPushedFcn = createCallbackFcn(app, @Refresh561LaserButtonPushed, true);
         app.Refresh561Laser.Text = {'Refresh'; 'power'; 'reading'};
         app.Refresh640Laser = uibutton(app.GridLayout4, 'push');
         app.Refresh640Laser.ButtonPushedFcn = createCallbackFcn(app, @Refresh640LaserButtonPushed, true);
         app.Refresh640Laser.Text = {'Refresh'; 'power'; 'reading'};
         app.LasertypeLabel = uilabel(app.GridLayout4);
         app.LasertypeLabel.Text = {'Laser'; 'type'};
         app.OnOffswitchLabel = uilabel(app.GridLayout4);
         app.OnOffswitchLabel.Text = {'On/Off'; 'switch'};
         app.RefreshLabel = uilabel(app.GridLayout4);
         app.RefreshLabel.Text = 'Refresh';
         app.NumberofiterationsLabel = uilabel(app.GridLayout4);
         app.NumberofiterationsLabel.Text = {'Number of'; 'iterations'};
         app.NumberofiterationsEditField = uieditfield(app.GridLayout4, 'numeric');
         app.NumberofiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofiterationsEditFieldValueChanged, true);
         app.TakesingleimageButton = uibutton(app.GridLayout4, 'push');
         app.TakesingleimageButton.ButtonPushedFcn = createCallbackFcn(app, @TakesingleimageButtonPushed, true);
         app.TakesingleimageButton.Text = 'Take single image';
         app.ShowdatacontinuouslyButton = uibutton(app.GridLayout4, 'push');
         app.ShowdatacontinuouslyButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdatacontinuouslyButtonPushed, true);
         app.ShowdatacontinuouslyButton.Text = 'Show data continuously';
         app.CopyappobjecttoworkspaceButton = uibutton(app.GridLayout4, 'push');
         app.CopyappobjecttoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CopyappobjecttoworkspaceButtonPushed, true);
         app.CopyappobjecttoworkspaceButton.Text = {'Copy app object '; 'to workspace'};
         app.OutputTab = uitab(app.TabGroup);
         app.OutputTab.Title = 'Output';
         app.GridLayout5 = uigridlayout(app.OutputTab);
         app.PlotLimits = uitable(app.GridLayout5);
         app.PlotLimits.ColumnName = {'Axis'; 'Minimum'; 'Maximum'; 'Override'};
         app.PlotLimits.RowName = {};
         app.PlotLimits.ColumnEditable = [false true true true];
         app.PlotLimits.CellEditCallback = createCallbackFcn(app, @PlotLimitsCellEdit, true);
         app.LogarithmicContrastCheckBox = uicheckbox(app.GridLayout5);
         app.LogarithmicContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogarithmicContrastCheckBoxValueChanged, true);
         app.LogarithmicContrastCheckBox.Text = 'Logarithmic Contrast';
         app.PauseforeachvideostepEditFieldLabel = uilabel(app.GridLayout5);
         app.PauseforeachvideostepEditFieldLabel.Text = {'Pause for each video step'; ''};
         app.VideoPauseTime = uieditfield(app.GridLayout5, 'numeric');
         app.VideoPauseTime.ValueChangedFcn = createCallbackFcn(app, @VideoPauseTimeValueChanged, true);
         app.ImageOrVideo = uidropdown(app.GridLayout5);
         app.ImageOrVideo.Items = {'Single Image', 'Video'};
         app.ImageOrVideo.ValueChangedFcn = createCallbackFcn(app, @ImageOrVideoValueChanged, true);
         app.ImageOrVideo.Value = 'Single Image';
         app.ScanStepTable = uitable(app.GridLayout5);
         app.ScanStepTable.ColumnName = {'Scan Type'; 'Step to View'};
         app.ScanStepTable.RowName = {};
         app.ScanStepTable.ColumnEditable = [false true];
         app.ScanStepTable.CellEditCallback = createCallbackFcn(app, @ScanStepTableCellEdit, true);
         app.ScantoshowvideoofDropDownLabel = uilabel(app.GridLayout5);
         app.ScantoshowvideoofDropDownLabel.Text = 'Scan to show video of';
         app.ScantoshowvideoofDropDown = uidropdown(app.GridLayout5);
         app.ScantoshowvideoofDropDown.Items = {'1'};
         app.ScantoshowvideoofDropDown.ValueChangedFcn = createCallbackFcn(app, @ScantoshowvideoofDropDownValueChanged, true);
         app.ScantoshowvideoofDropDown.Value = '1';
         app.OverridecurrentstepviewCheckBox = uicheckbox(app.GridLayout5);
         app.OverridecurrentstepviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverridecurrentstepviewCheckBoxValueChanged, true);
         app.OverridecurrentstepviewCheckBox.Text = 'Override current step view';
         app.LoopvideoCheckBox = uicheckbox(app.GridLayout5);
         app.LoopvideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoopvideoCheckBoxValueChanged, true);
         app.LoopvideoCheckBox.Text = 'Loop video';
         app.StartVideoButton = uibutton(app.GridLayout5, 'push');
         app.StartVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoButtonPushed, true);
         app.StartVideoButton.Text = 'Start video';
         app.ScanProgress = uitextarea(app.GridLayout5);
         app.ScanProgress.Editable = 'off';
         app.ScanProgress.Value = {'Shows completion of each scan. If it is a randomized scan, shows linear completion'};
         app.ZoomIn2buttonsquareButton = uibutton(app.GridLayout5, 'push');
         app.ZoomIn2buttonsquareButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomIn2buttonsquareButtonPushed, true);
         app.ZoomIn2buttonsquareButton.Text = 'Zoom In (2 button square)';
         app.ZoomOutButton = uibutton(app.GridLayout5, 'push');
         app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
         app.ZoomOutButton.Text = 'Zoom Out';
         app.ForcesquareaspectratioCheckBox = uicheckbox(app.GridLayout5);
         app.ForcesquareaspectratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcesquareaspectratioCheckBoxValueChanged, true);
         app.ForcesquareaspectratioCheckBox.Text = {'Force square'; 'aspect ratio'};
         app.DropDown = uidropdown(app.GridLayout5);
         app.DropDown.Items = {'Display average across iterations', 'Display current iteration', 'Display prior iterations'};
         app.DropDown.ItemsData = {'Average', 'Current', 'Prior'};
         app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
         app.DropDown.Value = 'Average';
         app.UseStaticColorLimits = uicheckbox(app.GridLayout5);
         app.UseStaticColorLimits.ValueChangedFcn = createCallbackFcn(app, @UseStaticColorLimitsValueChanged, true);
         app.UseStaticColorLimits.Text = 'Use static color limits';
         app.StaticColorLimits = uitable(app.GridLayout5);
         app.StaticColorLimits.ColumnName = {'Static color limit'; 'Value'};
         app.StaticColorLimits.RowName = {};
         app.StaticColorLimits.ColumnEditable = [false true];
         app.StaticColorLimits.CellEditCallback = createCallbackFcn(app, @StaticColorLimitsCellEdit, true);
         app.forceConcatenation = uicheckbox(app.GridLayout5);
         app.forceConcatenation.ValueChangedFcn = createCallbackFcn(app, @forceConcatenationValueChanged, true);
         app.forceConcatenation.Text = 'Concatenate 2 outputs for display';
         app.Switch = uiswitch(app.GridLayout5, 'slider');
         app.Switch.Items = {'Vertical', 'Horizontal'};
         app.Switch.ItemsData = {'false', 'true'};
         app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
         app.Switch.Value = 'false';
         app.ScansTab = uitab(app.TabGroup);
         app.ScansTab.Title = 'Scans';
         app.GridLayout6 = uigridlayout(app.ScansTab);
         app.AutofuseCheckBox = uicheckbox(app.GridLayout6);
         app.AutofuseCheckBox.Text = 'Auto-fuse';
         app.ScanningTable = uitable(app.GridLayout6);
         app.ScanningTable.ColumnName = {'Type'; 'Start'; 'End'; '# of steps'; 'Step size'};
         app.ScanningTable.RowName = {};
         app.ScanningTable.ColumnEditable = true;
         app.ScanningTable.CellEditCallback = createCallbackFcn(app, @ScanningTableCellEdit, true);
         app.AddScanStep = uibutton(app.GridLayout6, 'push');
         app.AddScanStep.ButtonPushedFcn = createCallbackFcn(app, @AddScanStepPushed, true);
         app.AddScanStep.Text = 'Add step';
         app.DeleteScanStep = uibutton(app.GridLayout6, 'push');
         app.DeleteScanStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanStepPushed, true);
         app.DeleteScanStep.Text = 'Delete step';
         app.AddressEditField_3Label = uilabel(app.GridLayout6);
         app.AddressEditField_3Label.Text = 'Address';
         app.ScanAddress = uieditfield(app.GridLayout6, 'numeric');
         app.ScanAddress.Limits = [1 3];
         app.ScanAddress.ValueChangedFcn = createCallbackFcn(app, @ScanAddressValueChanged, true);
         app.ScanAddress.Value = 1;
         app.DefaultchangeDropDownLabel = uilabel(app.GridLayout6);
         app.DefaultchangeDropDownLabel.Text = 'Default change';
         app.DefaultchangeDropDown = uidropdown(app.GridLayout6);
         app.DefaultchangeDropDown.Items = {'# of steps', 'Step size'};
         app.DefaultchangeDropDown.ValueChangedFcn = createCallbackFcn(app, @DefaultchangeDropDownValueChanged, true);
         app.DefaultchangeDropDown.Value = 'Step size';
         app.PossibletypesTextAreaLabel = uilabel(app.GridLayout6);
         app.PossibletypesTextAreaLabel.Text = 'Possible types';
         app.PossibletypesTextArea = uitextarea(app.GridLayout6);
         app.PossibletypesTextArea.Editable = 'off';
         app.PossibletypesTextArea.Value = {'Stage X'; 'Stage Y'; 'Stage Z'};
         app.RandomizescanCheckBox = uicheckbox(app.GridLayout6);
         app.RandomizescanCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizescanCheckBoxValueChanged, true);
         app.RandomizescanCheckBox.Text = 'Randomize scan';
         app.SaveLoadTab = uitab(app.TabGroup);
         app.SaveLoadTab.Title = 'Save/Load';
         app.GridLayout9 = uigridlayout(app.SaveLoadTab);
         app.BoxaddressEditFieldLabel = uilabel(app.GridLayout9);
         app.BoxaddressEditFieldLabel.Text = 'Box address';
         app.BoxaddressEditField = uieditfield(app.GridLayout9, 'text');
         app.BoxaddressEditField.ValueChangedFcn = createCallbackFcn(app, @BoxaddressEditFieldValueChanged, true);
         app.SavenameLabel = uilabel(app.GridLayout9);
         app.SavenameLabel.Text = {'Save name'; ''};
         app.SaveFolderName = uieditfield(app.GridLayout9, 'text');
         app.SaveFolderName.ValueChangedFcn = createCallbackFcn(app, @SaveFolderNameValueChanged, true);
         app.SaveoptionsDropDownLabel = uilabel(app.GridLayout9);
         app.SaveoptionsDropDownLabel.Text = 'Save options';
         app.SaveoptionsDropDown = uidropdown(app.GridLayout9);
         app.SaveoptionsDropDown.Items = {'.tif', '.m', '.m & .tif'};
         app.SaveoptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveoptionsDropDownValueChanged, true);
         app.SaveoptionsDropDown.Value = '.m & .tif';
         app.SaveButton = uibutton(app.GridLayout9, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.LoadButton = uibutton(app.GridLayout9, 'push');
         app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
         app.LoadButton.Text = 'Load';
         app.TextArea = uitextarea(app.GridLayout9);
         app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
         app.TextArea.Value = {'User notes'; ''};
         app.DataSetupsSwitch = uiswitch(app.GridLayout9, 'slider');
         app.DataSetupsSwitch.Items = {'Data', 'Setups'};
         app.DataSetupsSwitch.ValueChangedFcn = createCallbackFcn(app, @DataSetupsSwitchValueChanged, true);
         app.DataSetupsSwitch.Value = 'Data';
         app.LoadDatesBox = uilistbox(app.GridLayout9);
         app.LoadDatesBox.Items = {'Setup Versions', 'v# - yyyy/mm/dd', ''};
         app.LoadDatesBox.ValueChangedFcn = createCallbackFcn(app, @LoadDatesBoxValueChanged, true);
         app.LoadDatesBox.Value = 'Setup Versions';
         app.LoadNamesBox = uilistbox(app.GridLayout9);
         app.LoadNamesBox.Items = {'Setup Names', 'any string'};
         app.LoadNamesBox.ValueChangedFcn = createCallbackFcn(app, @LoadNamesBoxValueChanged, true);
         app.LoadNamesBox.Value = 'Setup Names';
         app.UserName = uidropdown(app.GridLayout9);
         app.UserName.Items = {};
         app.UserName.ValueChangedFcn = createCallbackFcn(app, @UserNameValueChanged, true);
         app.UserName.Value = {};
         app.PositionTab = uitab(app.TabGroup);
         app.PositionTab.Title = 'Position';
         app.GridLayout3 = uigridlayout(app.PositionTab);
         app.XDown = uibutton(app.GridLayout3, 'push');
         app.XDown.ButtonPushedFcn = createCallbackFcn(app, @XDownButtonPushed, true);
         app.XDown.Text = '<';
         app.XUp = uibutton(app.GridLayout3, 'push');
         app.XUp.ButtonPushedFcn = createCallbackFcn(app, @XUpButtonPushed, true);
         app.XUp.Text = '>';
         app.XIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.XIncrement.ValueChangedFcn = createCallbackFcn(app, @XIncrementValueChanged, true);
         app.XPosition = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition.Limits = [-12600 12600];
         app.XPosition.ValueChangedFcn = createCallbackFcn(app, @XPositionValueChanged, true);
         app.AbsolutepositionLabel = uilabel(app.GridLayout3);
         app.AbsolutepositionLabel.Text = {'Absolute'; 'position'};
         app.IncrementLabel = uilabel(app.GridLayout3);
         app.IncrementLabel.Text = 'Increment';
         app.YIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.YIncrement.ValueChangedFcn = createCallbackFcn(app, @YIncrementValueChanged, true);
         app.YPosition = uieditfield(app.GridLayout3, 'numeric');
         app.YPosition.Limits = [-12600 12600];
         app.YPosition.ValueChangedFcn = createCallbackFcn(app, @YPositionValueChanged, true);
         app.StagetolerancemLabel = uilabel(app.GridLayout3);
         app.StagetolerancemLabel.Text = {'Stage'; 'tolerance (μm)'};
         app.StagePrecision = uieditfield(app.GridLayout3, 'numeric');
         app.StagePrecision.Limits = [0 Inf];
         app.StagePrecision.ValueChangedFcn = createCallbackFcn(app, @StagePrecisionValueChanged, true);
         app.StagePrecision.Value = 0.01;
         app.AxisLabel = uilabel(app.GridLayout3);
         app.AxisLabel.Text = 'Axis';
         app.StagexLabel = uilabel(app.GridLayout3);
         app.StagexLabel.Text = 'Stage x';
         app.StageyLabel = uilabel(app.GridLayout3);
         app.StageyLabel.Text = 'Stage y';
         app.StagezLabel = uilabel(app.GridLayout3);
         app.StagezLabel.Text = 'Stage z';
         app.MovenegativeLabel = uilabel(app.GridLayout3);
         app.MovenegativeLabel.Text = {'Move'; 'negative'};
         app.MovepositiveLabel = uilabel(app.GridLayout3);
         app.MovepositiveLabel.Text = {'Move'; 'positive'};
         app.SDLlinearLabel = uilabel(app.GridLayout3);
         app.SDLlinearLabel.Text = 'SDL linear';
         app.SDLpitchLabel = uilabel(app.GridLayout3);
         app.SDLpitchLabel.Text = 'SDL pitch';
         app.SDLyawLabel = uilabel(app.GridLayout3);
         app.SDLyawLabel.Text = 'SDL yaw';
         app.YDown = uibutton(app.GridLayout3, 'push');
         app.YDown.ButtonPushedFcn = createCallbackFcn(app, @YDownButtonPushed, true);
         app.YDown.Text = '<';
         app.ZDown = uibutton(app.GridLayout3, 'push');
         app.ZDown.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
         app.ZDown.Text = '<';
         app.XDown_4 = uibutton(app.GridLayout3, 'push');
         app.XDown_4.Text = '<';
         app.XDown_5 = uibutton(app.GridLayout3, 'push');
         app.XDown_5.Text = '<';
         app.XDown_6 = uibutton(app.GridLayout3, 'push');
         app.XDown_6.Text = '<';
         app.YUp = uibutton(app.GridLayout3, 'push');
         app.YUp.ButtonPushedFcn = createCallbackFcn(app, @YUpButtonPushed, true);
         app.YUp.Text = '>';
         app.ZUp = uibutton(app.GridLayout3, 'push');
         app.ZUp.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
         app.ZUp.Text = '>';
         app.XUp_4 = uibutton(app.GridLayout3, 'push');
         app.XUp_4.Text = '>';
         app.XUp_5 = uibutton(app.GridLayout3, 'push');
         app.XUp_5.Text = '>';
         app.XUp_6 = uibutton(app.GridLayout3, 'push');
         app.XUp_6.Text = '>';
         app.ZPosition = uieditfield(app.GridLayout3, 'numeric');
         app.ZPosition.Limits = [-12600 12600];
         app.ZPosition.ValueChangedFcn = createCallbackFcn(app, @ZPositionValueChanged, true);
         app.XPosition_4 = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition_4.Limits = [-12600 12600];
         app.ZIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.ZIncrement.ValueChangedFcn = createCallbackFcn(app, @ZIncrementValueChanged, true);
         app.XPosition_5 = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition_5.Limits = [-12600 12600];
         app.XPosition_6 = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition_6.Limits = [-12600 12600];
         app.XIncrement_4 = uieditfield(app.GridLayout3, 'numeric');
         app.XIncrement_5 = uieditfield(app.GridLayout3, 'numeric');
         app.XIncrement_6 = uieditfield(app.GridLayout3, 'numeric');
         app.AddressEditField_2Label = uilabel(app.GridLayout3);
         app.AddressEditField_2Label.Text = 'Address';
         app.StageAddress = uieditfield(app.GridLayout3, 'numeric');
         app.StageAddress.Limits = [1 Inf];
         app.StageAddress.ValueChangedFcn = createCallbackFcn(app, @StageAddressValueChanged, true);
         app.StageAddress.Value = 1;
         app.StageOptimizationInstructions = uitable(app.GridLayout3);
         app.StageOptimizationInstructions.ColumnName = {'Stage'; 'Radius'; 'Steps'};
         app.StageOptimizationInstructions.RowName = {};
         app.StageOptimizationInstructions.CellEditCallback = createCallbackFcn(app, @StageOptimizationInstructionsCellEdit, true);
         app.AddOptimizationStep = uibutton(app.GridLayout3, 'push');
         app.AddOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @AddOptimizationStepPushed, true);
         app.AddOptimizationStep.Text = 'Add step';
         app.DeleteOptimizationStep = uibutton(app.GridLayout3, 'push');
         app.DeleteOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteOptimizationStepPushed, true);
         app.DeleteOptimizationStep.Text = 'Delete step';
         app.StageoptimizationDropDownLabel = uilabel(app.GridLayout3);
         app.StageoptimizationDropDownLabel.Text = {'Stage'; 'optimization'; ''};
         app.StageoptimizationDropDown = uidropdown(app.GridLayout3);
         app.StageoptimizationDropDown.Items = {'Raw Value', 'Gaussian Peak'};
         app.StageoptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @StageoptimizationDropDownValueChanged, true);
         app.StageoptimizationDropDown.Value = 'Raw Value';
         app.StageAutoOptimization = uicheckbox(app.GridLayout3);
         app.StageAutoOptimization.ValueChangedFcn = createCallbackFcn(app, @StageAutoOptimizationValueChanged, true);
         app.StageAutoOptimization.Text = 'Auto-optimize during scan';
         app.TimebetweenoptimizationsminLabel = uilabel(app.GridLayout3);
         app.TimebetweenoptimizationsminLabel.Text = {'Time between'; 'optimizations'; '(min)'};
         app.StageOptimizationInterval = uieditfield(app.GridLayout3, 'numeric');
         app.StageOptimizationInterval.ValueChangedFcn = createCallbackFcn(app, @StageOptimizationIntervalValueChanged, true);
         app.CameraTab = uitab(app.TabGroup);
         app.CameraTab.Title = 'Camera';
         app.GridLayout8 = uigridlayout(app.CameraTab);
         app.SelectImageBoundsButton = uibutton(app.GridLayout8, 'push');
         app.SelectImageBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageBoundsButtonPushed, true);
         app.SelectImageBoundsButton.Text = {'Select Image'; 'Bounds'};
         app.FusercutoffmultipleLabel = uilabel(app.GridLayout8);
         app.FusercutoffmultipleLabel.Text = {'Fuser cutoff'; 'multiple'};
         app.FusercutoffmultipleEditField = uieditfield(app.GridLayout8, 'numeric');
         app.FusercutoffmultipleEditField.ValueChangedFcn = createCallbackFcn(app, @FusercutoffmultipleEditFieldValueChanged, true);
         app.FuserpercentilesEditFieldLabel = uilabel(app.GridLayout8);
         app.FuserpercentilesEditFieldLabel.Text = 'Fuser percentiles';
         app.FuserpercentilesEditField = uieditfield(app.GridLayout8, 'text');
         app.FuserpercentilesEditField.ValueChangedFcn = createCallbackFcn(app, @FuserpercentilesEditFieldValueChanged, true);
         app.FuserInstructions = uitable(app.GridLayout8);
         app.FuserInstructions.ColumnName = {'Stage'; 'Radius'; 'Steps'};
         app.FuserInstructions.RowName = {};
         app.FuserInstructions.ColumnEditable = true;
         app.FuserInstructions.CellEditCallback = createCallbackFcn(app, @FuserInstructionsCellEdit, true);
         app.FuseCiclesButton = uibutton(app.GridLayout8, 'push');
         app.FuseCiclesButton.ButtonPushedFcn = createCallbackFcn(app, @FuseCiclesButtonPushed, true);
         app.FuseCiclesButton.Text = 'Fuse Cicles';
         app.FramespertriggerEditFieldLabel = uilabel(app.GridLayout8);
         app.FramespertriggerEditFieldLabel.Text = 'Frames per trigger';
         app.FramespertriggerEditField = uieditfield(app.GridLayout8, 'numeric');
         app.FramespertriggerEditField.ValueChangedFcn = createCallbackFcn(app, @FramespertriggerEditFieldValueChanged, true);
         app.FramesperfuserstepLabel = uilabel(app.GridLayout8);
         app.FramesperfuserstepLabel.Text = {'Frames per'; 'fuser step'};
         app.FramesperfuserstepEditField = uieditfield(app.GridLayout8, 'numeric');
         app.FramesperfuserstepEditField.ValueChangedFcn = createCallbackFcn(app, @FramesperfuserstepEditFieldValueChanged, true);
         app.ExposuretimeEditFieldLabel = uilabel(app.GridLayout8);
         app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
         app.ExposuretimeEditField = uieditfield(app.GridLayout8, 'numeric');
         app.ExposuretimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeEditFieldValueChanged, true);
         app.ImageBounds = uitable(app.GridLayout8);
         app.ImageBounds.ColumnName = {'Axis'; 'Lower bound'; 'Upper bound'};
         app.ImageBounds.RowName = {};
         app.ImageBounds.ColumnEditable = [false true true];
         app.ImageBounds.CellEditCallback = createCallbackFcn(app, @ImageBoundsCellEdit, true);
         app.AddFuserStep = uibutton(app.GridLayout8, 'push');
         app.AddFuserStep.ButtonPushedFcn = createCallbackFcn(app, @AddFuserStepPushed, true);
         app.AddFuserStep.Text = 'Add step';
         app.DeleteFuserStep = uibutton(app.GridLayout8, 'push');
         app.DeleteFuserStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteFuserStepPushed, true);
         app.DeleteFuserStep.Text = 'Delete step';
         app.AddressEditFieldLabel = uilabel(app.GridLayout8);
         app.AddressEditFieldLabel.Text = 'Address';
         app.FuserAddress = uieditfield(app.GridLayout8, 'numeric');
         app.FuserAddress.Limits = [1 Inf];
         app.FuserAddress.ValueChangedFcn = createCallbackFcn(app, @FuserAddressValueChanged, true);
         app.FuserAddress.Value = 1;
         app.UseboundsSwitchLabel = uilabel(app.GridLayout8);
         app.UseboundsSwitchLabel.Text = 'Use bounds';
         app.UseboundsSwitch = uiswitch(app.GridLayout8, 'slider');
         app.UseboundsSwitch.Items = {'yes', 'no'};
         app.UseboundsSwitch.ValueChangedFcn = createCallbackFcn(app, @UseboundsSwitchValueChanged, true);
         app.UseboundsSwitch.Value = 'yes';
         app.UsesecondboundsLabel = uilabel(app.GridLayout8);
         app.UsesecondboundsLabel.Text = {'Use second'; 'bounds'};
         app.UsesecondboundsSwitch = uiswitch(app.GridLayout8, 'slider');
         app.UsesecondboundsSwitch.Items = {'yes', 'no'};
         app.UsesecondboundsSwitch.ValueChangedFcn = createCallbackFcn(app, @UsesecondboundsSwitchValueChanged, true);
         app.UsesecondboundsSwitch.Value = 'yes';
         app.SaveeveryframeSwitchLabel = uilabel(app.GridLayout8);
         app.SaveeveryframeSwitchLabel.Text = 'Save every frame';
         app.SaveeveryframeSwitch = uiswitch(app.GridLayout8, 'slider');
         app.SaveeveryframeSwitch.Items = {'yes', 'no'};
         app.SaveeveryframeSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveeveryframeSwitchValueChanged, true);
         app.SaveeveryframeSwitch.Value = 'yes';
         app.InfrequentTab = uitab(app.TabGroup);
         app.InfrequentTab.Title = 'Infrequent';
         app.GridLayout7 = uigridlayout(app.InfrequentTab);
         app.WaitForEquilibrium488 = uicheckbox(app.GridLayout7);
         app.WaitForEquilibrium488.ValueChangedFcn = createCallbackFcn(app, @WaitForEquilibrium488ValueChanged, true);
         app.WaitForEquilibrium488.Text = '488 laser wait for equilibrium';
         app.WaitForEquilibrium561 = uicheckbox(app.GridLayout7);
         app.WaitForEquilibrium561.ValueChangedFcn = createCallbackFcn(app, @WaitForEquilibrium561ValueChanged, true);
         app.WaitForEquilibrium561.Text = '561 laser wait for equilibrium';
         app.WaitForEquilibrium640 = uicheckbox(app.GridLayout7);
         app.WaitForEquilibrium640.ValueChangedFcn = createCallbackFcn(app, @WaitForEquilibrium640ValueChanged, true);
         app.WaitForEquilibrium640.Text = '640 laser wait for equilibrium';
         app.OffWhenCleaned488 = uicheckbox(app.GridLayout7);
         app.OffWhenCleaned488.ValueChangedFcn = createCallbackFcn(app, @OffWhenCleaned488ValueChanged, true);
         app.OffWhenCleaned488.Text = {'Turn 488 laser off'; 'when unloaded'};
         app.OffWhenCleaned561 = uicheckbox(app.GridLayout7);
         app.OffWhenCleaned561.ValueChangedFcn = createCallbackFcn(app, @OffWhenCleaned561ValueChanged, true);
         app.OffWhenCleaned561.Text = {'Turn 561 laser off'; 'when unloaded'};
         app.OffWhenCleaned640 = uicheckbox(app.GridLayout7);
         app.OffWhenCleaned640.ValueChangedFcn = createCallbackFcn(app, @OffWhenCleaned640ValueChanged, true);
         app.OffWhenCleaned640.Text = {'Turn 640 laser off'; 'when unloaded'};
         app.IgnorePIstagesettlingtimeCheckBox = uicheckbox(app.GridLayout7);
         app.IgnorePIstagesettlingtimeCheckBox.Text = 'Ignore PI stage settling time';
         app.XFineReset_4 = uibutton(app.GridLayout7, 'push');
         app.XFineReset_4.Text = {'Fine axis'; 'x reset'};
         app.XFineReset_5 = uibutton(app.GridLayout7, 'push');
         app.XFineReset_5.Text = {'Fine axis'; 'y reset'};
         app.XFineReset_6 = uibutton(app.GridLayout7, 'push');
         app.XFineReset_6.Text = {'Fine axis'; 'z reset'};
         app.DefectcorrectionlevelDropDownLabel = uilabel(app.GridLayout7);
         app.DefectcorrectionlevelDropDownLabel.Text = 'Defect correction level';
         app.DefectcorrectionlevelDropDown = uidropdown(app.GridLayout7);
         app.DefectcorrectionlevelDropDown.Items = {'minimal', 'standard', 'high'};
         app.DefectcorrectionlevelDropDown.Value = 'minimal';
         app.DefectCorrectionSwitchLabel = uilabel(app.GridLayout7);
         app.DefectCorrectionSwitchLabel.Text = 'Defect Correction';
         app.DefectCorrectionSwitch = uiswitch(app.GridLayout7, 'slider');
         app.PausedurationforfinestagemovementsmsLabel = uilabel(app.GridLayout7);
         app.PausedurationforfinestagemovementsmsLabel.Text = {'Pause duration'; 'for stage'; 'movements (ms)'};
         app.StagePauseTime = uieditfield(app.GridLayout7, 'numeric');
         app.StagePauseTime.ValueChangedFcn = createCallbackFcn(app, @StagePauseTimeValueChanged, true);
         app.Panel = uipanel(app.UIFigure);
         app.GridLayout2 = uigridlayout(app.Panel);
         app.OutputPanel = uipanel(app.GridLayout2);
         app.StartButton = uibutton(app.GridLayout2, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.ResetButton = uibutton(app.GridLayout2, 'push');
         app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
         app.ResetButton.Text = 'Reset';
         app.OutputInfo = uitextarea(app.GridLayout2);
         app.OutputInfo.Editable = 'off';
         app.ColorBarPanel = uipanel(app.GridLayout2);
         app.StatusLamp = uilamp(app.GridLayout2);
         app.ScreenshotButton = uibutton(app.GridLayout2, 'push');
         app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
         app.ScreenshotButton.Text = 'Screenshot';
         app.AbortButton = uibutton(app.GridLayout2, 'push');
         app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
         app.AbortButton.Text = 'Abort';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = ExperimentEditor_OOP

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFunction)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end